classdef change_patches_3dplots < matlab.apps.AppBase

	% Properties that correspond to app components
	properties (Access = public)
		ChangeVisUIFigure              matlab.ui.Figure
		ShowCameratoolbar_Button       matlab.ui.control.Button
		HideAxis_Button                matlab.ui.control.Button
		ShowAxis_Button                matlab.ui.control.Button
		Close_Button                   matlab.ui.control.Button
		MapObjects_Hide_Button         matlab.ui.control.Button
		Set_FT_Button                  matlab.ui.control.Button
		Set_ET_Button                  matlab.ui.control.Button
		EdgetransparencyETSlider       matlab.ui.control.Slider
		EdgetransparencyETSliderLabel  matlab.ui.control.Label
		FacetransparencyFTSlider       matlab.ui.control.Slider
		FacetransparencyFTSliderLabel  matlab.ui.control.Label
		PatchUITable                   matlab.ui.control.Table
		MapObjects_Show_Button         matlab.ui.control.Button
	end

	
	properties (Access = private)
		fig			% Current figure object
		fig_Name		% Current figure name
		fig_ch		% Current figure children
		ax				% Current axis object
		i_ch_ax_v	% % axis children indices corresponding to the table row number
	end
	
	methods (Access = public)
		
	end
	
	methods (Access = private)
		
		function tf=fig_exists(app)
			global GV_H
			try
				if ~isvalid(app.fig)
					tf		= true;
				else
					if ~isequal(app.fig_ch,app.fig.Children)
						tf		= true;
					else
						tf		= false;
					end
				end
				if tf
					if isfield(GV_H.warndlg,'change_patches_3dplots')
						if ishandle(GV_H.warndlg.change_patches_3dplots)
							close(GV_H.warndlg.change_patches_3dplots);
						end
					end
					GV_H.warndlg.change_patches_3dplots		= warndlg(sprintf(...
						'The figure "%s"\ndoes not exist any more or has been changed.',app.fig_Name),...
						'Warning');
					GV_H.warndlg.change_patches_3dplots.Tag	= 'maplab3d_figure';
				end
			catch ME
				errormessage('',ME);
			end
		end
		
	end
	

	% Callbacks that handle component events
	methods (Access = private)

		% Code that executes after component creation
		function startupFcn(app)
			% If this function is called, the project parameters are already loaded.
			global GV_H APP
			
			try
				
				% Figure position:
				maplab3d_pos				= APP.MapLab3D.Position;
				create_symsamp_pos		= app.ChangeVisUIFigure.Position;
				create_symsamp_pos(1)	= maplab3d_pos(1)+maplab3d_pos(3)/2-create_symsamp_pos(3)/2;
				create_symsamp_pos(2)	= maplab3d_pos(2)+maplab3d_pos(4)/2-create_symsamp_pos(4)/2;
				create_symsamp_pos(2)	= min(...
					create_symsamp_pos(2),...
					maplab3d_pos(2)+maplab3d_pos(4)-create_symsamp_pos(4));
				create_symsamp_pos(2)	= max(create_symsamp_pos(2),50);
				app.ChangeVisUIFigure.Position			= create_symsamp_pos;
				app.ChangeVisUIFigure.HandleVisibility	= 'off';
				
				% Get the current figure and the current axis:
				app.fig			= get(groot,'CurrentFigure');
				warntext			= '';
				if isempty(app.fig)
					if isfield(GV_H.warndlg,'change_patches_3dplots')
						if ishandle(GV_H.warndlg.change_patches_3dplots)
							close(GV_H.warndlg.change_patches_3dplots);
						end
					end
					GV_H.warndlg.change_patches_3dplots		= warndlg(sprintf([...
						'To use this feature, a 3D figure of\n',...
						'one color, one tile or the whole map\n',...
						'must be opened beforehand.'],warntext),...
						'Warning');
					GV_H.warndlg.change_patches_3dplots.Tag	= 'maplab3d_figure';
					ChangeVisUIFigureCloseRequest(app);
					return
				else
					app.fig_Name	= app.fig.Name;
					figure(app.fig);
					% figures UserData:
					% ud_figure.version.no1	= VER.no1
					% ud_figure.version.no2	= VER.no2
					% ud_figure.version.no3	= VER.no3
					% ud_figure.tile_no		= tile_no;			% []: the whole map
					% ud_figure.colno			= colno;				% []: more than one color
					% ud_figure.comment		= 'MapLab3D: 3D plot of one color';
					% ud_figure.comment		= 'MapLab3D: 3D plot of one tile';
					% ud_figure.comment		= 'MapLab3D: 3D plot of the whole map';
					ud_figure	= app.fig.UserData;
					if    ~isfield(ud_figure,'version')||...
							~isfield(ud_figure,'tile_no')||...
							~isfield(ud_figure,'colno')  ||...
							~isfield(ud_figure,'comment')
						warntext	= sprintf([...
							'The current figure is not a 3D figure\n',...
							'of one color, one tile or the whole map,\n',...
							'or it does not contain the necessary data.']);
					else
						if    ~strcmp(ud_figure.comment,'MapLab3D: 3D plot of one color')    &&...
								~strcmp(ud_figure.comment,'MapLab3D: 3D plot of one tile')     &&...
								~strcmp(ud_figure.comment,'MapLab3D: 3D plot of the whole map')
							warntext	= sprintf([...
								'The current figure is not a 3D figure\n',...
								'of one color, one tile or the whole map,\n',...
								'or it does not contain the necessary data.']);
						else
							% Get the current axis;
							app.fig_ch	= app.fig.Children;
							i				= 1;
							while i<=length(app.fig_ch)
								if strcmp(app.fig_ch(i).Type,'axes')
									app.ax	= app.fig_ch(i);
									break
								end
								i		= i+1;
							end
							if i>length(app.fig_ch)
								warntext	= 'The figure does not contain an axis.';
							else
								ch_ax				= app.ax.Children;
								app.i_ch_ax_v	= zeros(0,1);	% axis children indices corresponding to the table row number
								RGB_m				= zeros(0,3);	% RGB values sorted by color number
								colno_v			= zeros(0,1);	% color number of every table row
								rt					= 0;				% table row number
								sz					= [1 6];			% 1 row, 6 columns
								vartypes			= {...
									'logical';...					% Vis
									'double';...					% FT
									'double';...					% ET
									'double';...					% ColNo
									'cellstr';...					% Description
									'cellstr'};						% Color
								varnames		= {...
									'Vis';...
									'FT';...
									'ET';...
									'ColNo';...
									'Description';...
									'Color'};
								table_one_row				= table('Size',sz,'VariableTypes',vartypes,'VariableNames',varnames);
								app.PatchUITable.Data	= table('Size',sz,'VariableTypes',vartypes,'VariableNames',varnames);
								for i=length(ch_ax):-1:1
									if strcmp(ch_ax(i).Type,'patch')
										% patch objects UserData:
										% ud_patch.colno
										% ud_patch.pp_color.prio
										% ud_patch.pp_color.description
										% ud_patch.pp_color.spec
										% ud_patch.pp_color.standalone_color
										% ud_patch.pp_color.rgb
										% ud_patch.pp_color.rgb
										% ud_patch.pp_color.rgb
										% ud_patch.pp_color.color_short_text
										% ud_patch.pp_color.color_property
										% ud_patch.pp_color.color_identification
										% ud_patch.pp_color.material
										% ud_patch.pp_color.brand
										% ud_patch.pp_color.manufacturer
										% ud_patch.pp_color.weblink
										ud_patch		= ch_ax(i).UserData;
										if    isfield(ud_patch,'colno')   &&...
												isfield(ud_patch,'pp_color')
											if ud_patch.colno>0
												rt										= rt+1;
												RGB_m(ud_patch.colno,:)			= ud_patch.pp_color.rgb/255;
												colno_v(rt,1)						= ud_patch.colno;
												app.i_ch_ax_v(rt,1)				= i;
												table_one_row.Vis					= ch_ax(i).Visible;
												table_one_row.FT					= round((1-ch_ax(i).FaceAlpha)*100);
												table_one_row.ET					= round((1-ch_ax(i).EdgeAlpha)*100);
												table_one_row.ColNo				= ud_patch.colno;
												table_one_row.Description		= {ud_patch.pp_color.description};
												table_one_row.Color				= {ud_patch.pp_color.color_short_text};
												app.PatchUITable.Data(rt,:)	= table_one_row;
											end
										end
									end
								end
							end
						end
					end
				end
				if ~isempty(warntext)
					if isfield(GV_H.warndlg,'change_patches_3dplots')
						if ishandle(GV_H.warndlg.change_patches_3dplots)
							close(GV_H.warndlg.change_patches_3dplots);
						end
					end
					GV_H.warndlg.change_patches_3dplots		= warndlg(sprintf([...
						'It is not possible to change the visibility\n',...
						'of map objects in this figure:\n%s'],warntext),...
						'Warning');
					GV_H.warndlg.change_patches_3dplots.Tag	= 'maplab3d_figure';
					ChangeVisUIFigureCloseRequest(app);
					return
				end
				
				% Table row style:
				for colno=1:size(RGB_m,1)
					rt_v				= find(colno_v==colno);
					if ~isempty(rt_v)
						HSV_v				= rgb2hsv(RGB_m(colno,:));
						if HSV_v(3)>0.5
							fontcolor		= 'k';
						else
							fontcolor		= 'w';
						end
						uistyle_color		= uistyle("BackgroundColor",RGB_m(colno,:),"FontColor",fontcolor);
						addStyle(app.PatchUITable,uistyle_color,"row",rt_v);
					end
				end
				
				% Figure name:
				app.ChangeVisUIFigure.Name		= sprintf('Change visibility of 3D map objects: "%s"',app.fig_Name);
				
			catch ME
				errormessage('',ME);
			end
			
		end

		% Close request function: ChangeVisUIFigure
		function ChangeVisUIFigureCloseRequest(app, event)
			try
				delete(app);
			catch ME
				errormessage('',ME);
			end
			
		end

		% Button pushed function: Set_FT_Button
		function Set_FT_ButtonPushed(app, event)
			try
				if fig_exists(app)
					ChangeVisUIFigureCloseRequest(app);
					return
				end
				if isempty(app.PatchUITable.Selection)
					return
				end
				r_sel_v	= app.PatchUITable.Selection(:,1);
				i_ch_v	= app.i_ch_ax_v(r_sel_v,1);
				ft			= app.FacetransparencyFTSlider.Value;
				fa			= min(max(1-ft/100,0),1);
				set(app.ax.Children(i_ch_v),'FaceAlpha',fa);
				app.PatchUITable.Data.FT(r_sel_v)	= ft;
			catch ME
				errormessage('',ME);
			end
			
		end

		% Button pushed function: Set_ET_Button
		function Set_ET_ButtonPushed(app, event)
			try
				if fig_exists(app)
					ChangeVisUIFigureCloseRequest(app);
					return
				end
				if isempty(app.PatchUITable.Selection)
					return
				end
				r_sel_v	= app.PatchUITable.Selection(:,1);
				i_ch_v	= app.i_ch_ax_v(r_sel_v,1);
				et			= app.EdgetransparencyETSlider.Value;
				ea			= min(max(1-et/100,0),1);
				set(app.ax.Children(i_ch_v),'EdgeAlpha',ea);
				app.PatchUITable.Data.ET(r_sel_v)	= et;
			catch ME
				errormessage('',ME);
			end
			
		end

		% Button pushed function: MapObjects_Show_Button
		function MapObjects_Show_ButtonPushed(app, event)
			try
				if fig_exists(app)
					ChangeVisUIFigureCloseRequest(app);
					return
				end
				if isempty(app.PatchUITable.Selection)
					return
				end
				r_sel_v	= app.PatchUITable.Selection(:,1);
				i_ch_v	= app.i_ch_ax_v(r_sel_v,1);
				set(app.ax.Children(i_ch_v),'Visible','on');
				app.PatchUITable.Data.Vis(r_sel_v)	= true;
			catch ME
				errormessage('',ME);
			end
			
		end

		% Button pushed function: MapObjects_Hide_Button
		function MapObjects_Hide_ButtonPushed(app, event)
			try
				if fig_exists(app)
					ChangeVisUIFigureCloseRequest(app);
					return
				end
				if isempty(app.PatchUITable.Selection)
					return
				end
				r_sel_v	= app.PatchUITable.Selection(:,1);
				i_ch_v	= app.i_ch_ax_v(r_sel_v,1);
				set(app.ax.Children(i_ch_v),'Visible','off');
				app.PatchUITable.Data.Vis(r_sel_v)	= false;
			catch ME
				errormessage('',ME);
			end
			
		end

		% Button pushed function: Close_Button
		function Close_ButtonPushed(app, event)
			try
				% Close app:
				ChangeVisUIFigureCloseRequest(app, event);
			catch ME
				errormessage('',ME);
			end
			
		end

		% Cell edit callback: PatchUITable
		function PatchUITableCellEdit(app, event)
			% indices = event.Indices;
			% newData = event.NewData;
			try
				if fig_exists(app)
					ChangeVisUIFigureCloseRequest(app);
					return
				end
				column_no	= event.Indices(1,2);
				r_sel_v	= event.Indices(1,1);
				i_ch_v	= app.i_ch_ax_v(r_sel_v,1);
				switch column_no
					case 1
						% Vis
						set(app.ax.Children(i_ch_v),'Visible',event.NewData);
					case 2
						% FT
						ft			= event.NewData;
						fa			= min(max(1-ft/100,0),1);
						set(app.ax.Children(i_ch_v),'FaceAlpha',fa);
					case 3
						% ET
						et			= event.NewData;
						ea			= min(max(1-et/100,0),1);
						set(app.ax.Children(i_ch_v),'EdgeAlpha',ea);
				end
			catch ME
				errormessage('',ME);
			end
			
		end

		% Button pushed function: ShowAxis_Button
		function ShowAxis_ButtonPushed(app, event)
			try
				if fig_exists(app)
					ChangeVisUIFigureCloseRequest(app);
					return
				end
				app.ax.Visible		= 'on';
			catch ME
				errormessage('',ME);
			end
			
		end

		% Button pushed function: HideAxis_Button
		function HideAxis_ButtonPushed(app, event)
			try
				if fig_exists(app)
					ChangeVisUIFigureCloseRequest(app);
					return
				end
				app.ax.Visible		= 'off';
			catch ME
				errormessage('',ME);
			end
			
		end

		% Button pushed function: ShowCameratoolbar_Button
		function ShowCameratoolbar_ButtonPushed(app, event)
			try
				if fig_exists(app)
					ChangeVisUIFigureCloseRequest(app);
					return
				end
				cameratoolbar(app.fig,'show');
			catch ME
				errormessage('',ME);
			end
			
		end
	end

	% Component initialization
	methods (Access = private)

		% Create UIFigure and components
		function createComponents(app)
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			% Get the file path for locating images
			pathToMLAPP = fileparts(mfilename('fullpath'));
			app.ChangeVisUIFigure = uifigure('Visible', 'off');
			app.ChangeVisUIFigure.Name = 'Change visibility of 3D map objects';
			app.ChangeVisUIFigure.Icon = fullfile(pathToMLAPP, 'MapLab3D_Logo_400x400_RGB.png');
			app.ChangeVisUIFigure.CloseRequestFcn = createCallbackFcn(app, @ChangeVisUIFigureCloseRequest, true);
			app.ChangeVisUIFigure.Tag = 'maplab3d_figure';
			app.MapObjects_Show_Button = uibutton(app.ChangeVisUIFigure, 'push');
			app.MapObjects_Show_Button.ButtonPushedFcn = createCallbackFcn(app, @MapObjects_Show_ButtonPushed, true);
			app.MapObjects_Show_Button.Text = 'Show';
			app.PatchUITable = uitable(app.ChangeVisUIFigure);
			app.PatchUITable.ColumnName = {'Vis'; 'FT'; 'ET'; 'ColNo'; 'Description'; 'Color'};
			app.PatchUITable.RowName = {};
			app.PatchUITable.ColumnSortable = true;
			app.PatchUITable.ColumnEditable = [true true true false false false];
			app.PatchUITable.CellEditCallback = createCallbackFcn(app, @PatchUITableCellEdit, true);
			app.FacetransparencyFTSliderLabel = uilabel(app.ChangeVisUIFigure);
			app.FacetransparencyFTSliderLabel.Text = 'Face transparency (FT) / %';
			app.FacetransparencyFTSlider = uislider(app.ChangeVisUIFigure);
			app.FacetransparencyFTSlider.Step = 1;
			app.EdgetransparencyETSliderLabel = uilabel(app.ChangeVisUIFigure);
			app.EdgetransparencyETSliderLabel.Text = 'Edge transparency (ET) / %';
			app.EdgetransparencyETSlider = uislider(app.ChangeVisUIFigure);
			app.EdgetransparencyETSlider.Step = 1;
			app.EdgetransparencyETSlider.Value = 100;
			app.Set_ET_Button = uibutton(app.ChangeVisUIFigure, 'push');
			app.Set_ET_Button.ButtonPushedFcn = createCallbackFcn(app, @Set_ET_ButtonPushed, true);
			app.Set_ET_Button.Text = 'Set';
			app.Set_FT_Button = uibutton(app.ChangeVisUIFigure, 'push');
			app.Set_FT_Button.ButtonPushedFcn = createCallbackFcn(app, @Set_FT_ButtonPushed, true);
			app.Set_FT_Button.Text = 'Set';
			app.MapObjects_Hide_Button = uibutton(app.ChangeVisUIFigure, 'push');
			app.MapObjects_Hide_Button.ButtonPushedFcn = createCallbackFcn(app, @MapObjects_Hide_ButtonPushed, true);
			app.MapObjects_Hide_Button.Text = 'Hide';
			app.Close_Button = uibutton(app.ChangeVisUIFigure, 'push');
			app.Close_Button.ButtonPushedFcn = createCallbackFcn(app, @Close_ButtonPushed, true);
			app.Close_Button.Text = 'Close';
			app.ShowAxis_Button = uibutton(app.ChangeVisUIFigure, 'push');
			app.ShowAxis_Button.ButtonPushedFcn = createCallbackFcn(app, @ShowAxis_ButtonPushed, true);
			app.ShowAxis_Button.Text = 'Show axis';
			app.HideAxis_Button = uibutton(app.ChangeVisUIFigure, 'push');
			app.HideAxis_Button.ButtonPushedFcn = createCallbackFcn(app, @HideAxis_ButtonPushed, true);
			app.HideAxis_Button.Text = 'Hide axis';
			app.ShowCameratoolbar_Button = uibutton(app.ChangeVisUIFigure, 'push');
			app.ShowCameratoolbar_Button.ButtonPushedFcn = createCallbackFcn(app, @ShowCameratoolbar_ButtonPushed, true);
			app.ShowCameratoolbar_Button.Text = 'Show camera toolbar';
			% Show the figure after all components are created
			app.ChangeVisUIFigure.Visible = 'on';
		end
	end

	% App creation and deletion
	methods (Access = public)

		% Construct app
		function app = change_patches_3dplots

			% Create UIFigure and components
			createComponents(app)

			% Register the app with App Designer
			registerApp(app, app.ChangeVisUIFigure)

			% Execute the startup function
			runStartupFcn(app, @startupFcn)

			if nargout == 0
				clear app
			end
		end

		% Code that executes before app deletion
		function delete(app)

			% Delete UIFigure when app is deleted
			delete(app.ChangeVisUIFigure)
		end
	end
end