classdef create_charstylesample_getuserinput < matlab.apps.AppBase

	% Properties that correspond to app components
	properties (Access = public)
		CharstyleSampleSettingsUIFigure  matlab.ui.Figure
		SampleText_EditField            matlab.ui.control.EditField
		EnterthesampletextEditFieldLabel  matlab.ui.control.Label
		CopyCharStylesToClipboard_Button  matlab.ui.control.Button
		Cancel_Button                   matlab.ui.control.Button
		OK_Button                       matlab.ui.control.Button
		DistanceMarginText_EditField    matlab.ui.control.NumericEditField
		EnterthedistancebetweenmarginandtextmmLabel  matlab.ui.control.Label
		LiftingBackground_EditField     matlab.ui.control.NumericEditField
		EntertheliftingofthetextbackgroundmmLabel  matlab.ui.control.Label
		LiftingForeground_EditField     matlab.ui.control.NumericEditField
		EntertheliftingofthetextforegroundmmLabel  matlab.ui.control.Label
		TotalTextHeight_EditField       matlab.ui.control.NumericEditField
		EnterthetotaltextheightmmLabel  matlab.ui.control.Label
		CharStyles_ListBox              matlab.ui.control.ListBox
		SymbolsLabel                    matlab.ui.control.Label
	end

	
	properties (Access = private)
		charstyles_str		% Character styles listbox content to be copied to the clipboard
	end
	
	methods (Access = public)
		
	end
	
	methods (Access = private)
		
	end
	

	% Callbacks that handle component events
	methods (Access = private)

		% Code that executes after component creation
		function startupFcn(app)
			% If this function is called, the project parameters are already loaded.
			global GV GV_H PP APP
			
			try
				
				% For polling:
				GV_H.create_charstylesample_getuserinput	= app;
				
				% Figure position:
				maplab3d_pos				= APP.MapLab3D.Position;
				create_symsamp_pos		= app.CharstyleSampleSettingsUIFigure.Position;
				create_symsamp_pos(1)	= maplab3d_pos(1)+maplab3d_pos(3)/2-create_symsamp_pos(3)/2;
				create_symsamp_pos(2)	= maplab3d_pos(2)+maplab3d_pos(4)/2-create_symsamp_pos(4)/2;
				create_symsamp_pos(2)	= min(...
					create_symsamp_pos(2),...
					maplab3d_pos(2)+maplab3d_pos(4)-create_symsamp_pos(4));
				create_symsamp_pos(2)	= max(create_symsamp_pos(2),50);
				app.CharstyleSampleSettingsUIFigure.Position	= create_symsamp_pos;
				
				% Listbox items:
				items						= cell(1,0);
				app.charstyles_str	= '';
				for chstno=1:size(PP.charstyle,1)
					items{1,chstno}	= sprintf('Character style %g: %s',chstno,PP.charstyle(chstno,1).description);
					app.charstyles_str	= sprintf('%s%s',app.charstyles_str,items{1,chstno});
					if chstno<size(PP.charstyle,1)
						app.charstyles_str	= sprintf('%s\n',app.charstyles_str);
					end
				end
				fprintf(1,'\n%s\n\n',app.charstyles_str);
				app.CharStyles_ListBox.Items					= items;
				app.CharStyles_ListBox.Value					= items{1,1};
				
				% Editboxes default values:
				app.SampleText_EditField.Value				= PP.testsample.charstylesample.sampletext;
				app.TotalTextHeight_EditField.Value			= PP.testsample.charstylesample.totalheight;
				app.LiftingForeground_EditField.Value		= PP.testsample.charstylesample.dz_text;
				app.LiftingBackground_EditField.Value		= PP.testsample.charstylesample.dz_bgd;
				app.DistanceMarginText_EditField.Value		= PP.testsample.charstylesample.distmargintext;
				
				GV.create_charstylesamp.input_ok				= false;
				
			catch ME
				errormessage('',ME);
			end
			
		end

		% Button pushed function: OK_Button
		function OK_ButtonPushed(app, event)
			global GV PP
			
			try
				
				% Save the user inputs:
				GV.create_charstylesamp.CharStyles_ListBox.ValueIndex			= app.CharStyles_ListBox.ValueIndex;
				GV.create_charstylesamp.SampleText_EditField.Value				= app.SampleText_EditField.Value;
				GV.create_charstylesamp.TotalTextHeight_EditField.Value		= app.TotalTextHeight_EditField.Value;
				GV.create_charstylesamp.LiftingForeground_EditField.Value	= app.LiftingForeground_EditField.Value;
				GV.create_charstylesamp.LiftingBackground_EditField.Value	= app.LiftingBackground_EditField.Value;
				GV.create_charstylesamp.DistanceMarginText_EditField.Value	= app.DistanceMarginText_EditField.Value;
				GV.create_charstylesamp.input_ok										= true;
				
				% Overwrite the project parameters:
				PP.testsample.charstylesample.sampletext							= app.SampleText_EditField.Value;
				PP.testsample.charstylesample.totalheight							= app.TotalTextHeight_EditField.Value;
				PP.testsample.charstylesample.dz_text								= app.LiftingForeground_EditField.Value;
				PP.testsample.charstylesample.dz_bgd								= app.LiftingBackground_EditField.Value;
				PP.testsample.charstylesample.distmargintext						= app.DistanceMarginText_EditField.Value;
				
				% Close app:
				CharstyleSampleSettingsUIFigureCloseRequest(app, event);
				
			catch ME
				errormessage('',ME);
			end
			
		end

		% Button pushed function: Cancel_Button
		function Cancel_ButtonPushed(app, event)
			% Close app:
			try
				CharstyleSampleSettingsUIFigureCloseRequest(app, event);
			catch ME
				errormessage('',ME);
			end
			
		end

		% Close request function: CharstyleSampleSettingsUIFigure
		function CharstyleSampleSettingsUIFigureCloseRequest(app, event)
			try
				delete(app);
			catch ME
				errormessage('',ME);
			end
			
		end

		% Button pushed function: CopyCharStylesToClipboard_Button
		function CopyCharStylesToClipboard_ButtonPushed(app, event)
			try
				% Copy data to clipboard:
				clipboard('copy',app.charstyles_str);
			catch ME
				errormessage('',ME);
			end
			
		end
	end

	% Component initialization
	methods (Access = private)

		% Create UIFigure and components
		function createComponents(app)
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			% Get the file path for locating images
			pathToMLAPP = fileparts(mfilename('fullpath'));
			app.CharstyleSampleSettingsUIFigure = uifigure('Visible', 'off');
			app.CharstyleSampleSettingsUIFigure.Name = 'Character style sample settings';
			app.CharstyleSampleSettingsUIFigure.Icon = fullfile(pathToMLAPP, 'MapLab3D_Logo_400x400_RGB.png');
			app.CharstyleSampleSettingsUIFigure.CloseRequestFcn = createCallbackFcn(app, @CharstyleSampleSettingsUIFigureCloseRequest, true);
			app.CharstyleSampleSettingsUIFigure.Tag = 'maplab3d_figure';
			app.SymbolsLabel = uilabel(app.CharstyleSampleSettingsUIFigure);
			app.SymbolsLabel.Text = 'Select the character styles to be printed:';
			app.CharStyles_ListBox = uilistbox(app.CharstyleSampleSettingsUIFigure);
			app.CharStyles_ListBox.Items = {'Item 1', 'Item 2', 'Item 3', 'Item 4', 'Item 5', 'Item 6', 'Item 7', 'Item 8', 'Item 9', 'Item 10', 'Item 11', 'Item 12', 'Item 13', 'Item 14'};
			app.CharStyles_ListBox.Multiselect = 'on';
			app.CharStyles_ListBox.Value = {'Item 1'};
			app.EnterthetotaltextheightmmLabel = uilabel(app.CharstyleSampleSettingsUIFigure);
			app.EnterthetotaltextheightmmLabel.Text = 'Enter the total height [mm]:';
			app.TotalTextHeight_EditField = uieditfield(app.CharstyleSampleSettingsUIFigure, 'numeric');
			app.TotalTextHeight_EditField.Limits = [0 Inf];
			app.EntertheliftingofthetextforegroundmmLabel = uilabel(app.CharstyleSampleSettingsUIFigure);
			app.EntertheliftingofthetextforegroundmmLabel.Text = 'Enter the lifting of the text foreground [mm]:';
			app.LiftingForeground_EditField = uieditfield(app.CharstyleSampleSettingsUIFigure, 'numeric');
			app.LiftingForeground_EditField.Limits = [0 Inf];
			app.EntertheliftingofthetextbackgroundmmLabel = uilabel(app.CharstyleSampleSettingsUIFigure);
			app.EntertheliftingofthetextbackgroundmmLabel.Text = 'Enter the lifting of the text background [mm]:';
			app.LiftingBackground_EditField = uieditfield(app.CharstyleSampleSettingsUIFigure, 'numeric');
			app.LiftingBackground_EditField.Limits = [0 Inf];
			app.EnterthedistancebetweenmarginandtextmmLabel = uilabel(app.CharstyleSampleSettingsUIFigure);
			app.EnterthedistancebetweenmarginandtextmmLabel.Text = 'Enter the distance between margin and text [mm]:';
			app.DistanceMarginText_EditField = uieditfield(app.CharstyleSampleSettingsUIFigure, 'numeric');
			app.DistanceMarginText_EditField.Limits = [0 Inf];
			app.OK_Button = uibutton(app.CharstyleSampleSettingsUIFigure, 'push');
			app.OK_Button.ButtonPushedFcn = createCallbackFcn(app, @OK_ButtonPushed, true);
			app.OK_Button.Text = 'OK';
			app.Cancel_Button = uibutton(app.CharstyleSampleSettingsUIFigure, 'push');
			app.Cancel_Button.ButtonPushedFcn = createCallbackFcn(app, @Cancel_ButtonPushed, true);
			app.Cancel_Button.Text = 'Cancel';
			app.CopyCharStylesToClipboard_Button = uibutton(app.CharstyleSampleSettingsUIFigure, 'push');
			app.CopyCharStylesToClipboard_Button.ButtonPushedFcn = createCallbackFcn(app, @CopyCharStylesToClipboard_ButtonPushed, true);
			app.CopyCharStylesToClipboard_Button.Text = 'Copy character style listbox to clipboard';
			app.EnterthesampletextEditFieldLabel = uilabel(app.CharstyleSampleSettingsUIFigure);
			app.EnterthesampletextEditFieldLabel.Text = 'Enter the sample text:';
			app.SampleText_EditField = uieditfield(app.CharstyleSampleSettingsUIFigure, 'text');
			app.SampleText_EditField.Value = 'Ajnma!%®';
			% Show the figure after all components are created
			app.CharstyleSampleSettingsUIFigure.Visible = 'on';
		end
	end

	% App creation and deletion
	methods (Access = public)

		% Construct app
		function app = create_charstylesample_getuserinput

			runningApp = getRunningApp(app);

			% Check for running singleton app
			if isempty(runningApp)

				% Create UIFigure and components
				createComponents(app)

				% Register the app with App Designer
				registerApp(app, app.CharstyleSampleSettingsUIFigure)

				% Execute the startup function
				runStartupFcn(app, @startupFcn)
			else

				% Focus the running singleton app
				figure(runningApp.CharstyleSampleSettingsUIFigure)

				app = runningApp;
			end

			if nargout == 0
				clear app
			end
		end

		% Code that executes before app deletion
		function delete(app)

			% Delete UIFigure when app is deleted
			delete(app.CharstyleSampleSettingsUIFigure)
		end
	end
end