classdef create_symbolsample_getuserinput < matlab.apps.AppBase

	% Properties that correspond to app components
	properties (Access = public)
		SymbolsamplesettingsUIFigure    matlab.ui.Figure
		CopySyToClipboard_Button        matlab.ui.control.Button
		Cancel_Button                   matlab.ui.control.Button
		OK_Button                       matlab.ui.control.Button
		DistanceMarginSymbol_EditField  matlab.ui.control.NumericEditField
		EnterthedistancebetweenmarginandsymbolmmEditFieldLabel  matlab.ui.control.Label
		LiftingBackground_EditField     matlab.ui.control.NumericEditField
		EntertheliftingofthesymbolbackgroundmmEditFieldLabel  matlab.ui.control.Label
		LiftingForeground_EditField     matlab.ui.control.NumericEditField
		EntertheliftingofthesymbolforegroundmmEditFieldLabel  matlab.ui.control.Label
		TotalSymbolHeight_EditField     matlab.ui.control.NumericEditField
		EnterthetotalheightmmLabel      matlab.ui.control.Label
		ScaleUpFactor_EditField         matlab.ui.control.NumericEditField
		EnterthescaleupfactorEditFieldLabel  matlab.ui.control.Label
		Symbols_ListBox                 matlab.ui.control.ListBox
		SymbolsLabel                    matlab.ui.control.Label
	end

	
	properties (Access = private)
		syno_str		% Symbol listbox content to be copied to the clipboard
	end

	methods (Access = public)

	end

	methods (Access = private)

	end


	% Callbacks that handle component events
	methods (Access = private)

		% Code that executes after component creation
		function startupFcn(app)
			% If this function is called, the project parameters are already loaded.
			global GV GV_H PP SY APP

			try

				% For polling:
				GV_H.create_symbolsample_getuserinput	= app;

				% Figure position:
				maplab3d_pos				= APP.MapLab3D.Position;
				create_symsamp_pos		= app.SymbolsamplesettingsUIFigure.Position;
				create_symsamp_pos(1)	= maplab3d_pos(1)+maplab3d_pos(3)/2-create_symsamp_pos(3)/2;
				create_symsamp_pos(2)	= maplab3d_pos(2)+maplab3d_pos(4)/2-create_symsamp_pos(4)/2;
				create_symsamp_pos(2)	= min(...
					create_symsamp_pos(2),...
					maplab3d_pos(2)+maplab3d_pos(4)-create_symsamp_pos(4));
				create_symsamp_pos(2)	= max(create_symsamp_pos(2),50);
				app.SymbolsamplesettingsUIFigure.Position	= create_symsamp_pos;

				% Listbox items:
				items		= cell(1,0);
				app.syno_str	= '';
				for isym=1:size(SY,1)
					items{1,isym}	= sprintf('S%g: %s=%s',isym,SY(isym,1).k,SY(isym,1).v);
					app.syno_str	= sprintf('%s%s',app.syno_str,items{1,isym});
					if isym<size(SY,1)
						app.syno_str	= sprintf('%s\n',app.syno_str);
					end
				end
				fprintf(1,'\n%s\n\n',app.syno_str);
				app.Symbols_ListBox.Items						= items;
				app.Symbols_ListBox.Value						= items{1,1};

				% Editboxes default values:
				app.ScaleUpFactor_EditField.Value			= PP.testsample.symbolsample.scaleup;
				app.TotalSymbolHeight_EditField.Value		= PP.testsample.symbolsample.totalheight;
				app.LiftingForeground_EditField.Value		= PP.testsample.symbolsample.dz_symbol;
				app.LiftingBackground_EditField.Value		= PP.testsample.symbolsample.dz_bgd;
				app.DistanceMarginSymbol_EditField.Value	= PP.testsample.symbolsample.distmarginsymbol;

				GV.create_symsamp.input_ok											= false;

			catch ME
				errormessage('',ME);
			end

		end

		% Button pushed function: OK_Button
		function OK_ButtonPushed(app, event)
			global GV PP

			try

				% Save the user inputs:
				GV.create_symsamp.Symbols_ListBox.ValueIndex					= app.Symbols_ListBox.ValueIndex;
				GV.create_symsamp.ScaleUpFactor_EditField.Value				= app.ScaleUpFactor_EditField.Value;
				GV.create_symsamp.TotalSymbolHeight_EditField.Value		= app.TotalSymbolHeight_EditField.Value;
				GV.create_symsamp.LiftingForeground_EditField.Value		= app.LiftingForeground_EditField.Value;
				GV.create_symsamp.LiftingBackground_EditField.Value		= app.LiftingBackground_EditField.Value;
				GV.create_symsamp.DistanceMarginSymbol_EditField.Value	= app.DistanceMarginSymbol_EditField.Value;
				GV.create_symsamp.input_ok											= true;

				% Overwrite the project parameters:
				PP.testsample.symbolsample.scaleup								= app.ScaleUpFactor_EditField.Value;
				PP.testsample.symbolsample.totalheight							= app.TotalSymbolHeight_EditField.Value;
				PP.testsample.symbolsample.dz_symbol							= app.LiftingForeground_EditField.Value;
				PP.testsample.symbolsample.dz_bgd								= app.LiftingBackground_EditField.Value;
				PP.testsample.symbolsample.distmarginsymbol					= app.DistanceMarginSymbol_EditField.Value;

				% Close app:
				SymbolsamplesettingsUIFigureCloseRequest(app, event);

			catch ME
				errormessage('',ME);
			end

		end

		% Button pushed function: Cancel_Button
		function Cancel_ButtonPushed(app, event)
			% Close app:
			try
				SymbolsamplesettingsUIFigureCloseRequest(app, event);
			catch ME
				errormessage('',ME);
			end

		end

		% Close request function: SymbolsamplesettingsUIFigure
		function SymbolsamplesettingsUIFigureCloseRequest(app, event)
			try
				delete(app);
			catch ME
				errormessage('',ME);
			end

		end

		% Button pushed function: CopySyToClipboard_Button
		function CopySyToClipboard_ButtonPushed(app, event)
			try
				% Copy data to clipboard:
				clipboard('copy',app.syno_str);
			catch ME
				errormessage('',ME);
			end
			
		end
	end

	% Component initialization
	methods (Access = private)

		% Create UIFigure and components
		function createComponents(app)
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			% Get the file path for locating images
			pathToMLAPP = fileparts(mfilename('fullpath'));
			app.SymbolsamplesettingsUIFigure = uifigure('Visible', 'off');
			app.SymbolsamplesettingsUIFigure.Name = 'Symbol sample settings';
			app.SymbolsamplesettingsUIFigure.Icon = fullfile(pathToMLAPP, 'MapLab3D_Logo_400x400_RGB.png');
			app.SymbolsamplesettingsUIFigure.CloseRequestFcn = createCallbackFcn(app, @SymbolsamplesettingsUIFigureCloseRequest, true);
			app.SymbolsamplesettingsUIFigure.Tag = 'maplab3d_figure';
			app.SymbolsLabel = uilabel(app.SymbolsamplesettingsUIFigure);
			app.SymbolsLabel.Text = 'Select the symbols to be printed:';
			app.Symbols_ListBox = uilistbox(app.SymbolsamplesettingsUIFigure);
			app.Symbols_ListBox.Items = {'Item 1', 'Item 2', 'Item 3', 'Item 4', 'Item 5', 'Item 6', 'Item 7', 'Item 8', 'Item 9', 'Item 10', 'Item 11', 'Item 12', 'Item 13', 'Item 14'};
			app.Symbols_ListBox.Multiselect = 'on';
			app.Symbols_ListBox.Value = {'Item 1'};
			app.EnterthescaleupfactorEditFieldLabel = uilabel(app.SymbolsamplesettingsUIFigure);
			app.EnterthescaleupfactorEditFieldLabel.Text = 'Enter the scale-up factor:';
			app.ScaleUpFactor_EditField = uieditfield(app.SymbolsamplesettingsUIFigure, 'numeric');
			app.ScaleUpFactor_EditField.Limits = [0 Inf];
			app.EnterthetotalheightmmLabel = uilabel(app.SymbolsamplesettingsUIFigure);
			app.EnterthetotalheightmmLabel.Text = 'Enter the total height [mm]:';
			app.TotalSymbolHeight_EditField = uieditfield(app.SymbolsamplesettingsUIFigure, 'numeric');
			app.TotalSymbolHeight_EditField.Limits = [0 Inf];
			app.EntertheliftingofthesymbolforegroundmmEditFieldLabel = uilabel(app.SymbolsamplesettingsUIFigure);
			app.EntertheliftingofthesymbolforegroundmmEditFieldLabel.Text = 'Enter the lifting of the symbol foreground [mm]:';
			app.LiftingForeground_EditField = uieditfield(app.SymbolsamplesettingsUIFigure, 'numeric');
			app.LiftingForeground_EditField.Limits = [0 Inf];
			app.EntertheliftingofthesymbolbackgroundmmEditFieldLabel = uilabel(app.SymbolsamplesettingsUIFigure);
			app.EntertheliftingofthesymbolbackgroundmmEditFieldLabel.Text = 'Enter the lifting of the symbol background [mm]:';
			app.LiftingBackground_EditField = uieditfield(app.SymbolsamplesettingsUIFigure, 'numeric');
			app.LiftingBackground_EditField.Limits = [0 Inf];
			app.EnterthedistancebetweenmarginandsymbolmmEditFieldLabel = uilabel(app.SymbolsamplesettingsUIFigure);
			app.EnterthedistancebetweenmarginandsymbolmmEditFieldLabel.Text = 'Enter the distance between margin and symbol [mm]:';
			app.DistanceMarginSymbol_EditField = uieditfield(app.SymbolsamplesettingsUIFigure, 'numeric');
			app.DistanceMarginSymbol_EditField.Limits = [0 Inf];
			app.OK_Button = uibutton(app.SymbolsamplesettingsUIFigure, 'push');
			app.OK_Button.ButtonPushedFcn = createCallbackFcn(app, @OK_ButtonPushed, true);
			app.OK_Button.Text = 'OK';
			app.Cancel_Button = uibutton(app.SymbolsamplesettingsUIFigure, 'push');
			app.Cancel_Button.ButtonPushedFcn = createCallbackFcn(app, @Cancel_ButtonPushed, true);
			app.Cancel_Button.Text = 'Cancel';
			app.CopySyToClipboard_Button = uibutton(app.SymbolsamplesettingsUIFigure, 'push');
			app.CopySyToClipboard_Button.ButtonPushedFcn = createCallbackFcn(app, @CopySyToClipboard_ButtonPushed, true);
			app.CopySyToClipboard_Button.Text = 'Copy symbol listbox to clipboard';
			% Show the figure after all components are created
			app.SymbolsamplesettingsUIFigure.Visible = 'on';
		end
	end

	% App creation and deletion
	methods (Access = public)

		% Construct app
		function app = create_symbolsample_getuserinput

			runningApp = getRunningApp(app);

			% Check for running singleton app
			if isempty(runningApp)

				% Create UIFigure and components
				createComponents(app)

				% Register the app with App Designer
				registerApp(app, app.SymbolsamplesettingsUIFigure)

				% Execute the startup function
				runStartupFcn(app, @startupFcn)
			else

				% Focus the running singleton app
				figure(runningApp.SymbolsamplesettingsUIFigure)

				app = runningApp;
			end

			if nargout == 0
				clear app
			end
		end

		% Code that executes before app deletion
		function delete(app)

			% Delete UIFigure when app is deleted
			delete(app.SymbolsamplesettingsUIFigure)
		end
	end
end