classdef maplab3d < matlab.apps.AppBase

	% Properties that correspond to app components
	properties (Access = public)
		MapLab3D                        matlab.ui.Figure
		FileMenu                        matlab.ui.container.Menu
		LoadprojectMenu                 matlab.ui.container.Menu
		ReloadprojectMenu               matlab.ui.container.Menu
		SaveProjectMenu                 matlab.ui.container.Menu
		SaveProjectAsMenu               matlab.ui.container.Menu
		ChangeprojectdirectoryMenu      matlab.ui.container.Menu
		LoadprojectparametersMenu       matlab.ui.container.Menu
		ReloadprojectparametersMenu     matlab.ui.container.Menu
		LoadOSMandElevationDataMenu     matlab.ui.container.Menu
		LoadOSMandelevationdatasettingsMenu  matlab.ui.container.Menu
		LoadSettingsCompleteOSMfileMenu  matlab.ui.container.Menu
		LoadSettingsFilteredOSMfileMenu  matlab.ui.container.Menu
		LoadSettingsOnlyOSMboundariesMenu  matlab.ui.container.Menu
		LoadSettingsElevationDataMenu   matlab.ui.container.Menu
		LoadSettingsDoNotLoadElevationDataMenu  matlab.ui.container.Menu
		CreatemapMenu                   matlab.ui.container.Menu
		CreatemapSettingsMenu           matlab.ui.container.Menu
		CreatemapSettingsRecreateWholeMapMenu  matlab.ui.container.Menu
		CreatemapSettingsRecreateCertainObjectsMenu  matlab.ui.container.Menu
		CreatemapSettingsRecreateLegendMenu  matlab.ui.container.Menu
		CreatemapSettingsCreateTextsMenu  matlab.ui.container.Menu
		CreatemapSettingsCreateSymbolsMenu  matlab.ui.container.Menu
		CreatemapSettingsShowTestplotsMenu  matlab.ui.container.Menu
		CreatemapSettingsShowLegendTestplotMenu  matlab.ui.container.Menu
		File_MapEditing_Menu            matlab.ui.container.Menu
		MapEdit_DetectOverlappedTextsSymbols_Menu  matlab.ui.container.Menu
		MapEdit_DetectMisplacedTextsSymbols_Menu  matlab.ui.container.Menu
		MapEdit_SimplifyMap_Menu        matlab.ui.container.Menu
		MapEdit_SimplifyMapSettings_ShowTestplots_Menu  matlab.ui.container.Menu
		MapEdit_CutIntoPiecesMapobjects_Menu  matlab.ui.container.Menu
		MapEdit_DetectSmallPieces_Menu  matlab.ui.container.Menu
		MapEdit_DetectFragilePieces_Menu  matlab.ui.container.Menu
		MapEdit_DetectSmallHoles_Menu   matlab.ui.container.Menu
		CreatemapSTLfilesMenu           matlab.ui.container.Menu
		CreateframeSTLfilesMenu         matlab.ui.container.Menu
		CreatetestsampleSTLfilesMenu    matlab.ui.container.Menu
		CreatecolorsampleSTLfilesMenu   matlab.ui.container.Menu
		CreateTextSampleMenu            matlab.ui.container.Menu
		CreateSymbolSampleMenu          matlab.ui.container.Menu
		CreateCharStyleSampleMenu       matlab.ui.container.Menu
		FileExitMenu                    matlab.ui.container.Menu
		View                            matlab.ui.container.Menu
		ShowMapObjectsTable_Menu        matlab.ui.container.Menu
		AutoSortNewMapObjects_Menu      matlab.ui.container.Menu
		ShowMapfigureLegend_Menu        matlab.ui.container.Menu
		ShowTooltips_Menu               matlab.ui.container.Menu
		Cre3dPrevMenu                   matlab.ui.container.Menu
		Cre3dPrevSetMenu                matlab.ui.container.Menu
		Cre3dPrevSet_re_filtint_Menu    matlab.ui.container.Menu
		Cre3dPrevSet_re_origfilt_Menu   matlab.ui.container.Menu
		Cre3dPrevSet_re_orig_Menu       matlab.ui.container.Menu
		Cre3dPrevSet_sh_ele_Menu        matlab.ui.container.Menu
		Cre3dPrevSet_sh_lines_Menu      matlab.ui.container.Menu
		Cre3dPrevSet_sh_patches_Menu    matlab.ui.container.Menu
		Cre3dPrevSet_ti_all_Menu        matlab.ui.container.Menu
		Cre3dPrevSet_ti_1_Menu          matlab.ui.container.Menu
		Cre3dPrevSet_ti_ask_Menu        matlab.ui.container.Menu
		Cre3dPrevSet_shlimti_Menu       matlab.ui.container.Menu
		Cre3dPrevSet_Filtersettings_Tilebase_Menu  matlab.ui.container.Menu
		Cre3dPrevSet_Filtersettings_Ask_Menu  matlab.ui.container.Menu
		Cre3dPrevSet_Filtersettings_Finalresult_Menu  matlab.ui.container.Menu
		PlotOSMrawDataMenu              matlab.ui.container.Menu
		View_ShowContourLines_Menu      matlab.ui.container.Menu
		View_ShowContourLinesSettings_Menu  matlab.ui.container.Menu
		View_ShowContourLines_Minor_Menu  matlab.ui.container.Menu
		View_ShowContourLines_MajorLabels_Menu  matlab.ui.container.Menu
		View_ShowContourLines_MinorLabels_Menu  matlab.ui.container.Menu
		View_ShowTileNo_Menu            matlab.ui.container.Menu
		View_OpenFigure_Menu            matlab.ui.container.Menu
		View_Currentfigure_Menu         matlab.ui.container.Menu
		View_SetFigureSize_Menu         matlab.ui.container.Menu
		View_CopyToClipboard_Menu       matlab.ui.container.Menu
		View_SaveToFile_Menu            matlab.ui.container.Menu
		View_ShowCameraToolbar_Menu     matlab.ui.container.Menu
		View_ChangeVisibilityOf3DObjects_Menu  matlab.ui.container.Menu
		View_SwitchLightDarkTheme_Menu  matlab.ui.container.Menu
		ExtraMenu                       matlab.ui.container.Menu
		Save_ErrorLog_Menu              matlab.ui.container.Menu
		CharStylePreview_Menu           matlab.ui.container.Menu
		LoadsymbolsMenu                 matlab.ui.container.Menu
		Call_Osmosis_Menu               matlab.ui.container.Menu
		Call_Osmosis_Settings_Menu      matlab.ui.container.Menu
		Osmosis_Source_File_PBF_Menu    matlab.ui.container.Menu
		Osmosis_Source_File_OSM_Menu    matlab.ui.container.Menu
		Osmosis_Dest_File_PBF_Menu      matlab.ui.container.Menu
		Osmosis_Dest_File_OSM_Menu      matlab.ui.container.Menu
		Osmosis_Extract_Bounding_Box_Menu  matlab.ui.container.Menu
		Osmosis_Do_Not_Reduce_The_Size_Menu  matlab.ui.container.Menu
		Osmosis_Do_Not_Reduce_The_Data_Menu  matlab.ui.container.Menu
		Osmosis_Extract_Include_Tags_Menu  matlab.ui.container.Menu
		Set_Osmosis_InstallPath_Menu    matlab.ui.container.Menu
		Open_CommandPromptWindow_Menu   matlab.ui.container.Menu
		Convert_GeoRasterData_Menu      matlab.ui.container.Menu
		Convert_GeoRasterDataSettings_Menu  matlab.ui.container.Menu
		Convert_GeoRasterDataSettings_CrSFile_Menu  matlab.ui.container.Menu
		Convert_GeoRasterDataSettings_CrIndFiles_Menu  matlab.ui.container.Menu
		Convert_GeoRasterDataSettings_WholeArea_Menu  matlab.ui.container.Menu
		Convert_GeoRasterDataSettings_ExtractBB_Menu  matlab.ui.container.Menu
		Convert_GeoRasterDataSettings_HighestRes_Menu  matlab.ui.container.Menu
		Convert_GeoRasterDataSettings_SetRes_Menu  matlab.ui.container.Menu
		Convert_GeoRasterDataSettings_ReplMissData_Menu  matlab.ui.container.Menu
		Convert_GeoRasterDataSettings_Testplot_Menu  matlab.ui.container.Menu
		Show_Diary_Path_Menu            matlab.ui.container.Menu
		Command_Line_Menu               matlab.ui.container.Menu
		AboutHelp                       matlab.ui.container.Menu
		AboutMenu                       matlab.ui.container.Menu
		TabGroup                        matlab.ui.container.TabGroup
		EditmapTab                      matlab.ui.container.Tab
		MapView_All_Button              matlab.ui.control.Button
		MapView_Fit_Button              matlab.ui.control.Button
		MAP_OBJECTS_TABLE               matlab.ui.control.Table
		Mod_DelObj_Button               matlab.ui.control.Button
		Mod_GrayOut_Button              matlab.ui.control.Button
		Mod_Hide_Button                 matlab.ui.control.Button
		Mod_Show_Button                 matlab.ui.control.Button
		Mod_Vis_Label                   matlab.ui.control.Label
		MapView_D3_Button               matlab.ui.control.Button
		MapView_R3_Button               matlab.ui.control.Button
		MapView_L3_Button               matlab.ui.control.Button
		MapView_U3_Button               matlab.ui.control.Button
		MapView_D2_Button               matlab.ui.control.Button
		MapView_R2_Button               matlab.ui.control.Button
		MapView_L2_Button               matlab.ui.control.Button
		MapView_U2_Button               matlab.ui.control.Button
		MapView_D1_Button               matlab.ui.control.Button
		MapView_R1_Button               matlab.ui.control.Button
		MapView_L1_Button               matlab.ui.control.Button
		MapView_U1_Button               matlab.ui.control.Button
		MapView_Sel_Button              matlab.ui.control.Button
		MapView_ReDo_Button             matlab.ui.control.Button
		MapView_Out_Button              matlab.ui.control.Button
		MapView_UnDo_Button             matlab.ui.control.Button
		MapView_In_Button               matlab.ui.control.StateButton
		MapViewLabel                    matlab.ui.control.Label
		TabGroup2                       matlab.ui.container.TabGroup
		BasicTab                        matlab.ui.container.Tab
		Mod_HideTemporarily_Button_2    matlab.ui.control.Button
		SeparatorImage_19               matlab.ui.control.Image
		SeparatorImage_18               matlab.ui.control.Image
		SeparatorImage_17               matlab.ui.control.Image
		SeparatorImage_15               matlab.ui.control.Image
		SeparatorImage_16               matlab.ui.control.Image
		SeparatorImage_14               matlab.ui.control.Image
		SeparatorImage_10               matlab.ui.control.Image
		SeparatorImage_5                matlab.ui.control.Image
		SeparatorImage_4                matlab.ui.control.Image
		Mod_Set_Dimensions_Button       matlab.ui.control.Button
		Mod_Arrange_Set_PlotNoEditField  matlab.ui.control.NumericEditField
		Mod_Scal_yperc_EditField        matlab.ui.control.NumericEditField
		Mod_Scal_xperc_EditField        matlab.ui.control.NumericEditField
		Mod_Dup_ymm_EditField           matlab.ui.control.NumericEditField
		Mod_Dup_xmm_EditField           matlab.ui.control.NumericEditField
		Mod_Arrange_SortByPrio_Button   matlab.ui.control.Button
		Mod_Reset_Button                matlab.ui.control.Button
		Mod_Color_0_Button              matlab.ui.control.Button
		Mod_Arrange_Ungroup_Button      matlab.ui.control.Button
		Mod_Deselectall_Button          matlab.ui.control.Button
		Mod_Arrange_Set_Button          matlab.ui.control.Button
		Mod_CopyMapObjectsTableToClipboard_Button_2  matlab.ui.control.Button
		Mod_ColorReset_Button           matlab.ui.control.Button
		Mod_Arrange_Group_Button        matlab.ui.control.Button
		Mod_SelectWithFilter_Button     matlab.ui.control.Button
		Mod_Arrange_First_Button        matlab.ui.control.Button
		Mod_ChangeDescription_Button    matlab.ui.control.Button
		Mod_ChangeTextTag_Button        matlab.ui.control.Button
		Mod_Color_Label                 matlab.ui.control.Label
		Mod_Arrange_Label               matlab.ui.control.Label
		Mod_SelectionLabel              matlab.ui.control.Label
		Mod_Arrange_Down_Button         matlab.ui.control.Button
		Mod_Miscellaneous_Label         matlab.ui.control.Label
		Mod_Scal_Button                 matlab.ui.control.Button
		Mod_Dup_Button                  matlab.ui.control.Button
		Mod_Rot_R3_Button               matlab.ui.control.Button
		Mod_Rot_R2_Button               matlab.ui.control.Button
		Mod_Rot_R1_Button               matlab.ui.control.Button
		Mod_Move_RD3_Button             matlab.ui.control.Button
		Mod_Move_RD2_Button             matlab.ui.control.Button
		Mod_Move_RD1_Button             matlab.ui.control.Button
		Mod_Move_D3_Button              matlab.ui.control.Button
		Mod_Move_D1_Button              matlab.ui.control.Button
		Mod_Move_D2_Button              matlab.ui.control.Button
		Mod_Move_LD1_Button             matlab.ui.control.Button
		Mod_Move_LD2_Button             matlab.ui.control.Button
		Mod_Move_LD3_Button             matlab.ui.control.Button
		Mod_Arrange_Up_Button           matlab.ui.control.Button
		Mod_HideTemporarily_Button      matlab.ui.control.Button
		Mod_HideTempAll_Button          matlab.ui.control.Button
		Mod_Restore_Button              matlab.ui.control.Button
		yEditFieldLabel                 matlab.ui.control.Label
		ymmEditFieldLabel               matlab.ui.control.Label
		Mod_Rot_deg_EditField           matlab.ui.control.NumericEditField
		Mod_Move_R3_Button              matlab.ui.control.Button
		Mod_Move_R2_Button              matlab.ui.control.Button
		Mod_Move_R1_Button              matlab.ui.control.Button
		Mod_Move_mm_EditField           matlab.ui.control.NumericEditField
		Mod_Move_L1_Button              matlab.ui.control.Button
		Mod_Move_L2_Button              matlab.ui.control.Button
		Mod_Move_L3_Button              matlab.ui.control.Button
		Mod_Arrange_Last_Button         matlab.ui.control.Button
		xEditFieldLabel                 matlab.ui.control.Label
		xmmEditFieldLabel               matlab.ui.control.Label
		Mod_Rot_L3_Button               matlab.ui.control.Button
		Mod_Rot_L2_Button               matlab.ui.control.Button
		Mod_Rot_L1_Button               matlab.ui.control.Button
		Mod_Move_RU3_Button             matlab.ui.control.Button
		Mod_Move_RU2_Button             matlab.ui.control.Button
		Mod_Move_RU1_Button             matlab.ui.control.Button
		Mod_Move_U3_Button              matlab.ui.control.Button
		Mod_Move_U1_Button              matlab.ui.control.Button
		Mod_Move_U2_Button              matlab.ui.control.Button
		Mod_Move_LU1_Button             matlab.ui.control.Button
		Mod_Move_LU2_Button             matlab.ui.control.Button
		Mod_Move_LU3_Button             matlab.ui.control.Button
		Mod_MapObjectsOrder_Label       matlab.ui.control.Label
		Mod_TempVis_Label               matlab.ui.control.Label
		Mod_Scal_Label                  matlab.ui.control.Label
		Mod_Dup_Label                   matlab.ui.control.Label
		RotateLabel                     matlab.ui.control.Label
		MovemmEditFieldLabel            matlab.ui.control.Label
		AdvancedTab                     matlab.ui.container.Tab
		SeparatorImage_13               matlab.ui.control.Image
		SeparatorImage_11               matlab.ui.control.Image
		SeparatorImage_9                matlab.ui.control.Image
		SeparatorImage_8                matlab.ui.control.Image
		SeparatorImage_6                matlab.ui.control.Image
		SeparatorImage_3                matlab.ui.control.Image
		Mod_ConvPrevLineToLine_EditField  matlab.ui.control.NumericEditField
		Mod_AddPrevToOutput_ObjNo_EditField  matlab.ui.control.NumericEditField
		Mod_Polygons_PlotNo2_EditField  matlab.ui.control.NumericEditField
		Mod_Polygons_PlotNo1_EditField  matlab.ui.control.NumericEditField
		Mod_Polygons_BuffDist_EditField  matlab.ui.control.NumericEditField
		Mod_LV_ymm_EditField            matlab.ui.control.NumericEditField
		Mod_LV_xmm_EditField            matlab.ui.control.NumericEditField
		Mod_LV_NewLine_Descr_EditField  matlab.ui.control.EditField
		LinewidthmmLabel                matlab.ui.control.Label
		Mod_UnitedColors_CreateCLAutomatically_Button  matlab.ui.control.Button
		Mod_UnitedColors_Reset_Button   matlab.ui.control.Button
		Mod_LV_Cut_Button               matlab.ui.control.Button
		Mod_LV_Close_Button             matlab.ui.control.Button
		Mod_EnterFormFromFile_Button    matlab.ui.control.Button
		Mod_EnterFormToFile_Button      matlab.ui.control.Button
		Mod_EnterRectangle_Button       matlab.ui.control.Button
		Mod_EnterCircle_Button          matlab.ui.control.Button
		Mod_ConvPrevLineToLine_Button   matlab.ui.control.Button
		Mod_UnitedColors_PrevCL_to_CL_Button  matlab.ui.control.Button
		Mod_CreateUnitedColors_Button   matlab.ui.control.Button
		Mod_Lines_Connect_Button        matlab.ui.control.Button
		Mod_Lines_ToPolygon_Button      matlab.ui.control.Button
		Mod_LV_New_Button               matlab.ui.control.Button
		Mod_LV_Delete_Button            matlab.ui.control.Button
		Mod_LV_Add_Button               matlab.ui.control.Button
		Mod_Lines_Form_Label            matlab.ui.control.Label
		Mod_ConvPrevLineToConnLine_Button  matlab.ui.control.Button
		Mod_UnitedColors_Cut_Button     matlab.ui.control.Button
		Mod_UnitedColors_SelectColNo_DropDown  matlab.ui.control.DropDown
		CuttinglinesLabel               matlab.ui.control.Label
		Mod_Lines_Label                 matlab.ui.control.Label
		xymmLabel                       matlab.ui.control.Label
		Mod_LV_FirstLast_ButtonGroup    matlab.ui.container.ButtonGroup
		Mod_LV_Last_Button              matlab.ui.control.ToggleButton
		Mod_LV_First_Button             matlab.ui.control.ToggleButton
		Mod_Lines_Nodes_Label           matlab.ui.control.Label
		Mod_MergeWithExistObj_CheckBox  matlab.ui.control.CheckBox
		Mod_ConvPrevToMapobj_Button     matlab.ui.control.Button
		Mod_Polygons_ResetPrintout_Button  matlab.ui.control.Button
		Mod_Polygons_PrintoutLimitsToPoly_Button  matlab.ui.control.Button
		Mod_Polygons_Intersect_Button   matlab.ui.control.Button
		Mod_Polygons_Xor_Button         matlab.ui.control.Button
		PlotNo2Label                    matlab.ui.control.Label
		MapobjectnumberLabel            matlab.ui.control.Label
		Mod_Polygons_SetPrintout_Button  matlab.ui.control.Button
		Mod_PrintoutLimits_Label        matlab.ui.control.Label
		Mod_Polygons_Subtract_Button    matlab.ui.control.Button
		Mod_Polygons_Union_Button       matlab.ui.control.Button
		PlotNo1Label                    matlab.ui.control.Label
		Mod_ConvertPrevToMapObj_Label   matlab.ui.control.Label
		Mod_Polygons_Addboundary_Button  matlab.ui.control.Button
		Mod_Polygons_Simplify_Button    matlab.ui.control.Button
		Mod_Polygons_Polybuffer_Button  matlab.ui.control.Button
		BuffdistLabel                   matlab.ui.control.Label
		Mod_Lines_Draw_Label            matlab.ui.control.Label
		Mod_LiReVe_ButtonGroup          matlab.ui.container.ButtonGroup
		DeleteVerticesButton            matlab.ui.control.ToggleButton
		DrawrectangleButton             matlab.ui.control.ToggleButton
		InsertVerticesButton            matlab.ui.control.ToggleButton
		DrawlineButton                  matlab.ui.control.ToggleButton
		OffButton                       matlab.ui.control.ToggleButton
		MoveVerticesButton              matlab.ui.control.ToggleButton
		SplitObjectButton               matlab.ui.control.ToggleButton
		MoveObjectButton                matlab.ui.control.ToggleButton
		Mod_ConvMapobjToPrev_Button     matlab.ui.control.Button
		Mod_ConvMapobjToPrev_Apart_CheckBox  matlab.ui.control.CheckBox
		Mod_Polygons_Subtract_dside_Button  matlab.ui.control.Button
		Mod_Polygons_DissolveBound_Button  matlab.ui.control.Button
		Mod_Polygons_ToLine_Button      matlab.ui.control.Button
		Mod_Polygons_Regions_Button     matlab.ui.control.Button
		Mod_Polygons_Label              matlab.ui.control.Label
		DescriptionLabel                matlab.ui.control.Label
		Mod_Lines_Label_2               matlab.ui.control.Label
		FilterOSMdataTab                matlab.ui.container.Tab
		FilterOSMData_FilterResults_Label  matlab.ui.control.Label
		FilterOSMData_InExcludeTags_Label  matlab.ui.control.Label
		SeparatorImage_20               matlab.ui.control.Image
		FilterOSMDataDeleteAllEntriesButton  matlab.ui.control.Button
		filterOSMdata_relations_CheckBox  matlab.ui.control.CheckBox
		filterOSMdata_ways_CheckBox     matlab.ui.control.CheckBox
		filterOSMdata_nodes_CheckBox    matlab.ui.control.CheckBox
		FilterOSMDataObjNoDropDown      matlab.ui.control.DropDown
		PreviewdescriptionEditField     matlab.ui.control.EditField
		include_keys                    matlab.ui.control.Table
		include_values                  matlab.ui.control.Table
		exclude_keys                    matlab.ui.control.Table
		exclude_values                  matlab.ui.control.Table
		OSMDATA_TABLE                   matlab.ui.control.Table
		copyfilterresultstoclipboardButton  matlab.ui.control.Button
		clearosmdataselectionButton     matlab.ui.control.Button
		copyosmdataselectiontomapButton  matlab.ui.control.Button
		filterOSMdataButton             matlab.ui.control.Button
		ObjNoLabel                      matlab.ui.control.Label
		SelectiondescriptionEditFieldLabel  matlab.ui.control.Label
		FilterOSMData_AutofillSource_ButtonGroup  matlab.ui.container.ButtonGroup
		FilterOSMData_AutofillSourceButtonGroup_Label  matlab.ui.control.Label
		FilterOSMData_AutofillSource_Symb_Button  matlab.ui.control.ToggleButton
		FilterOSMData_AutofillSource_Text_Button  matlab.ui.control.ToggleButton
		FilterOSMData_AutofillSource_LiAr_Button  matlab.ui.control.ToggleButton
		LatLonXYTab                     matlab.ui.container.Tab
		LatLonXYTab_Map_ymaxmmEditField  matlab.ui.control.NumericEditField
		LatLonXYTab_Map_yminmmEditField  matlab.ui.control.NumericEditField
		LatLonXYTab_Map_xmaxmmEditField  matlab.ui.control.NumericEditField
		LatLonXYTab_Map_xminmmEditField  matlab.ui.control.NumericEditField
		LatLonXYTab_Map_LatCenter_EditField  matlab.ui.control.NumericEditField
		LatLonXYTab_Map_LonCenter_EditField  matlab.ui.control.NumericEditField
		LatLonXYTab_Map_latmaxEditField  matlab.ui.control.NumericEditField
		LatLonXYTab_Map_latminEditField  matlab.ui.control.NumericEditField
		LatLonXYTab_Map_lonmaxEditField  matlab.ui.control.NumericEditField
		LatLonXYTab_Map_lonminEditField  matlab.ui.control.NumericEditField
		LatLonXYTab_Osmosis_ymaxmmEditField  matlab.ui.control.NumericEditField
		LatLonXYTab_Osmosis_yminmmEditField  matlab.ui.control.NumericEditField
		LatLonXYTab_Osmosis_xmaxmmEditField  matlab.ui.control.NumericEditField
		LatLonXYTab_Osmosis_xminmmEditField  matlab.ui.control.NumericEditField
		LatLonXYTab_Osmosis_LatCenter_EditField  matlab.ui.control.NumericEditField
		LatLonXYTab_Osmosis_LonCenter_EditField  matlab.ui.control.NumericEditField
		LatLonXYTab_Osmosis_latmaxEditField  matlab.ui.control.NumericEditField
		LatLonXYTab_Osmosis_latminEditField  matlab.ui.control.NumericEditField
		LatLonXYTab_Osmosis_lonmaxEditField  matlab.ui.control.NumericEditField
		LatLonXYTab_Osmosis_lonminEditField  matlab.ui.control.NumericEditField
		LatLonXYTab_OSM_ymaxmmEditField  matlab.ui.control.NumericEditField
		LatLonXYTab_OSM_yminmmEditField  matlab.ui.control.NumericEditField
		LatLonXYTab_OSM_xmaxmmEditField  matlab.ui.control.NumericEditField
		LatLonXYTab_OSM_xminmmEditField  matlab.ui.control.NumericEditField
		LatLonXYTab_OSM_LatCenter_EditField  matlab.ui.control.NumericEditField
		LatLonXYTab_OSM_LonCenter_EditField  matlab.ui.control.NumericEditField
		LatLonXYTab_OSM_latmaxEditField  matlab.ui.control.NumericEditField
		LatLonXYTab_OSM_latminEditField  matlab.ui.control.NumericEditField
		LatLonXYTab_OSM_lonmaxEditField  matlab.ui.control.NumericEditField
		LatLonXYTab_OSM_lonminEditField  matlab.ui.control.NumericEditField
		LatLonXYTab_TileHeight_EditField  matlab.ui.control.NumericEditField
		LatLonXYTab_TileWidth_EditField  matlab.ui.control.NumericEditField
		LatLonXYTab_FrameWidth_EditField  matlab.ui.control.NumericEditField
		LatLonXYTab_Dist_OSM_MapPrintout_EditField  matlab.ui.control.NumericEditField
		LatLonXYTab_OriginLatitudeEditField  matlab.ui.control.NumericEditField
		LatLonXYTab_OriginLongitudeEditField  matlab.ui.control.NumericEditField
		LatLonXYTab_ScaleEditField      matlab.ui.control.NumericEditField
		LatLonXYTab_Map_Hmaxmm_Label    matlab.ui.control.Label
		LatLonXYTab_Map_Wmaxmm_Label    matlab.ui.control.Label
		LatLonXYTab_Map_To2_Label       matlab.ui.control.Label
		LatLonXYTab_Map_To1_Label       matlab.ui.control.Label
		LatLonXYTab_Map_Hminmm_Label    matlab.ui.control.Label
		LatLonXYTab_Map_Wminmm_Label    matlab.ui.control.Label
		LatLonXYTab_Map_Height1_Label   matlab.ui.control.Label
		LatLonXYTab_Map_Width1_Label    matlab.ui.control.Label
		LatLonXYTab_Map_CurrOriginNotAtBBCenter_Label  matlab.ui.control.Label
		LatitudeofthecenteroftheboundingboxdegreeLabel_3  matlab.ui.control.Label
		LongitudeofthecenteroftheboundingboxdegreeLabel_3  matlab.ui.control.Label
		LatLonXYTab_Map_THmaxmm_Label   matlab.ui.control.Label
		LatLonXYTab_Map_To4_Label       matlab.ui.control.Label
		LatLonXYTab_Map_THminmm_Label   matlab.ui.control.Label
		LatLonXYTab_Map_Height2_Label   matlab.ui.control.Label
		LatLonXYTab_Map_NoTilesH_Label  matlab.ui.control.Label
		LatLonXYTab_Map_ymaxmm_Label    matlab.ui.control.Label
		ymaxmmLabel                     matlab.ui.control.Label
		LatitudeofthetopedgeoftheboundingboxdegreeLabel_3  matlab.ui.control.Label
		LatLonXYTab_Map_TWmaxmm_Label   matlab.ui.control.Label
		LatLonXYTab_Map_To3_Label       matlab.ui.control.Label
		LatLonXYTab_Map_TWminmm_Label   matlab.ui.control.Label
		LatLonXYTab_Map_Width2_Label    matlab.ui.control.Label
		LatLonXYTab_Map_NoTilesW_Label  matlab.ui.control.Label
		LatLonXYTab_Map_Tiles_Label     matlab.ui.control.Label
		LatLonXYTab_Map_yminmm_Label    matlab.ui.control.Label
		LatLonXYTab_Map_xmaxmm_Label    matlab.ui.control.Label
		yminmmLabel                     matlab.ui.control.Label
		xmaxmmLabel                     matlab.ui.control.Label
		LongitudeoftherightedgeoftheboundingboxdegreeLabel_3  matlab.ui.control.Label
		LatitudeofthebottomedgeoftheboundingboxdegreeLabel_3  matlab.ui.control.Label
		LatLonXYTab_Map_Hmm_Label       matlab.ui.control.Label
		LatLonXYTab_Map_Wmm_Label       matlab.ui.control.Label
		LatLonXYTab_Map_H_Label         matlab.ui.control.Label
		LatLonXYTab_Map_W_Label         matlab.ui.control.Label
		LatLonXYTab_Map_Printoutsize_Label  matlab.ui.control.Label
		LatLonXYTab_Map_ResetButton     matlab.ui.control.Button
		LatLonXYTab_Map_Frame_Image     matlab.ui.control.Image
		LatLonXYTab_Map_xminmm_Label    matlab.ui.control.Label
		LatLonXYTab_Map_PlotButton      matlab.ui.control.Button
		xminmmLabel                     matlab.ui.control.Label
		LatLonXYTab_Map_Origin_Label    matlab.ui.control.Label
		LatLonXYTab_Map_Label           matlab.ui.control.Label
		LongitudeoftheleftedgeoftheboundingboxdegreeLabel_3  matlab.ui.control.Label
		LatLonXYTab_Osmosis_Hmaxmm_Label  matlab.ui.control.Label
		LatLonXYTab_Osmosis_Wmaxmm_Label  matlab.ui.control.Label
		LatLonXYTab_Osmosis_To2_Label   matlab.ui.control.Label
		LatLonXYTab_Osmosis_To1_Label   matlab.ui.control.Label
		LatLonXYTab_Osmosis_Hminmm_Label  matlab.ui.control.Label
		LatLonXYTab_Osmosis_Wminmm_Label  matlab.ui.control.Label
		LatLonXYTab_Osmosis_Height1_Label  matlab.ui.control.Label
		LatLonXYTab_Osmosis_Width1_Label  matlab.ui.control.Label
		LatLonXYTab_Osmosis_CurrOriginNotAtBBCenter_Label  matlab.ui.control.Label
		LatitudeofthecenteroftheboundingboxdegreeLabel_2  matlab.ui.control.Label
		LongitudeofthecenteroftheboundingboxdegreeLabel_2  matlab.ui.control.Label
		LatLonXYTab_Osmosis_THmaxmm_Label  matlab.ui.control.Label
		LatLonXYTab_Osmosis_To4_Label   matlab.ui.control.Label
		LatLonXYTab_Osmosis_THminmm_Label  matlab.ui.control.Label
		LatLonXYTab_Osmosis_Height2_Label  matlab.ui.control.Label
		LatLonXYTab_Osmosis_NoTilesH_Label  matlab.ui.control.Label
		ymaxmmEditFieldLabel_2          matlab.ui.control.Label
		LatitudeofthetopedgeoftheboundingboxdegreeLabel_2  matlab.ui.control.Label
		LatLonXYTab_Osmosis_latmax_Label  matlab.ui.control.Label
		LatLonXYTab_Osmosis_TWmaxmm_Label  matlab.ui.control.Label
		LatLonXYTab_Osmosis_To3_Label   matlab.ui.control.Label
		LatLonXYTab_Osmosis_TWminmm_Label  matlab.ui.control.Label
		LatLonXYTab_Osmosis_Width2_Label  matlab.ui.control.Label
		LatLonXYTab_Osmosis_NoTilesW_Label  matlab.ui.control.Label
		LatLonXYTab_Osmosis_Tiles_Label  matlab.ui.control.Label
		yminmmEditFieldLabel_2          matlab.ui.control.Label
		xmaxmmEditFieldLabel_2          matlab.ui.control.Label
		LongitudeoftherightedgeoftheboundingboxdegreeLabel_2  matlab.ui.control.Label
		LatitudeofthebottomedgeoftheboundingboxdegreeLabel_2  matlab.ui.control.Label
		LatLonXYTab_Osmosis_latmin_Label  matlab.ui.control.Label
		LatLonXYTab_Osmosis_lonmax_Label  matlab.ui.control.Label
		LatLonXYTab_Osmosis_Hmm_Label   matlab.ui.control.Label
		LatLonXYTab_Osmosis_H_Label     matlab.ui.control.Label
		xminmmEditFieldLabel_2          matlab.ui.control.Label
		LongitudeoftheleftedgeoftheboundingboxdegreeLabel_2  matlab.ui.control.Label
		LatLonXYTab_Osmosis_lonmin_Label  matlab.ui.control.Label
		LatLonXYTab_Osmosis_Wmm_Label   matlab.ui.control.Label
		LatLonXYTab_Osmosis_W_Label     matlab.ui.control.Label
		LatLonXYTab_Osmosis_Printoutsize_Label  matlab.ui.control.Label
		LatLonXYTab_Osmosis_PlotButton  matlab.ui.control.Button
		LatLonXYTab_Osmosis_ResetButton  matlab.ui.control.Button
		LatLonXYTab_Osmosis_Frame_Image  matlab.ui.control.Image
		LatLonXYTab_Osmosis_SetOriginToBBC_CheckBox  matlab.ui.control.CheckBox
		LatLonXYTab_Osmosis_Label       matlab.ui.control.Label
		LatLonXYTab_OSM_Hmaxmm_Label    matlab.ui.control.Label
		LatLonXYTab_OSM_Wmaxmm_Label    matlab.ui.control.Label
		LatLonXYTab_OSM_To2_Label       matlab.ui.control.Label
		LatLonXYTab_OSM_To1_Label       matlab.ui.control.Label
		LatLonXYTab_OSM_Hminmm_Label    matlab.ui.control.Label
		LatLonXYTab_OSM_Wminmm_Label    matlab.ui.control.Label
		LatLonXYTab_OSM_Height1_Label   matlab.ui.control.Label
		LatLonXYTab_OSM_Width1_Label    matlab.ui.control.Label
		LatLonXYTab_OSM_CurrOriginNotAtBBCenter_Label  matlab.ui.control.Label
		LatitudeofthecenteroftheboundingboxdegreeLabel  matlab.ui.control.Label
		LongitudeofthecenteroftheboundingboxdegreeLabel  matlab.ui.control.Label
		LatLonXYTab_OSM_THmaxmm_Label   matlab.ui.control.Label
		LatLonXYTab_OSM_To4_Label       matlab.ui.control.Label
		LatLonXYTab_OSM_THminmm_Label   matlab.ui.control.Label
		LatLonXYTab_OSM_Height2_Label   matlab.ui.control.Label
		LatLonXYTab_OSM_NoTilesH_Label  matlab.ui.control.Label
		ymaxmmEditFieldLabel            matlab.ui.control.Label
		LatitudeofthetopedgeoftheboundingboxdegreeLabel  matlab.ui.control.Label
		LatLonXYTab_OSM_TWmaxmm_Label   matlab.ui.control.Label
		LatLonXYTab_OSM_To3_Label       matlab.ui.control.Label
		LatLonXYTab_OSM_TWminmm_Label   matlab.ui.control.Label
		LatLonXYTab_OSM_Width2_Label    matlab.ui.control.Label
		LatLonXYTab_OSM_NoTilesW_Label  matlab.ui.control.Label
		LatLonXYTab_OSM_Tiles_Label     matlab.ui.control.Label
		yminmmEditFieldLabel            matlab.ui.control.Label
		xmaxmmEditFieldLabel            matlab.ui.control.Label
		LongitudeoftherightedgeoftheboundingboxdegreeLabel  matlab.ui.control.Label
		LatitudeofthebottomedgeoftheboundingboxdegreeLabel  matlab.ui.control.Label
		LatLonXYTab_OSM_Hmm_Label       matlab.ui.control.Label
		LatLonXYTab_OSM_H_Label         matlab.ui.control.Label
		xminmmEditFieldLabel            matlab.ui.control.Label
		LongitudeoftheleftedgeoftheboundingboxdegreeLabel  matlab.ui.control.Label
		LatLonXYTab_OSM_Wmm_Label       matlab.ui.control.Label
		LatLonXYTab_OSM_W_Label         matlab.ui.control.Label
		LatLonXYTab_OSM_Printoutsize_Label  matlab.ui.control.Label
		LatLonXYTab_OSM_PlotButton      matlab.ui.control.Button
		LatLonXYTab_OSM_ResetButton     matlab.ui.control.Button
		LatLonXYTab_OSM_SetOriginToBBC_CheckBox  matlab.ui.control.CheckBox
		LatLonXYTab_OSM_Frame_Image     matlab.ui.control.Image
		LatLonXYTab_OSM_Label           matlab.ui.control.Label
		TileheightmmLabel               matlab.ui.control.Label
		LatLonXYTab_TileHeight_Label    matlab.ui.control.Label
		DistancebetweenOSMdataandmapprintoutlimitsmmLabel  matlab.ui.control.Label
		OriginlatitudedegreeLabel       matlab.ui.control.Label
		LatLonXYTab_Dist_OSM_MapPrintout_Label  matlab.ui.control.Label
		LatLonXYTab_OriginLatitude_Label  matlab.ui.control.Label
		LatLonXYTab_TileSize_ResetButton  matlab.ui.control.Button
		TilewidthmmLabel                matlab.ui.control.Label
		LatLonXYTab_TileWidth_Label     matlab.ui.control.Label
		LatLonXYTab_Origin_ResetButton  matlab.ui.control.Button
		OriginlongitudedegreeLabel      matlab.ui.control.Label
		LatLonXYTab_OriginLongitude_Label  matlab.ui.control.Label
		LatLonXYTab_OuterFrameWidth_ResetButton  matlab.ui.control.Button
		OuterframewidthmmLabel          matlab.ui.control.Label
		LatLonXYTab_FrameWidth_Label    matlab.ui.control.Label
		LatLonXYTab_Scale_ResetButton   matlab.ui.control.Button
		ScaleLabel                      matlab.ui.control.Label
		LatLonXYTab_Scale_Label         matlab.ui.control.Label
		LatLonXYTab_NumberOfTilesAndPrintoutSize_Label  matlab.ui.control.Label
		LatLonXYTab_GeneralData_Label   matlab.ui.control.Label
		pathnameELEDataTextArea         matlab.ui.control.TextArea
		pathfilenameOSMDataTextArea     matlab.ui.control.TextArea
		projectdirectoryTextArea        matlab.ui.control.TextArea
		StateTextArea                   matlab.ui.control.TextArea
		pathfilenameprojectparfileTextArea  matlab.ui.control.TextArea
		LogTextArea                     matlab.ui.control.TextArea
		LogLabel                        matlab.ui.control.Label
		WaitbarUIAxes                   matlab.ui.control.UIAxes
	end

	
	methods (Access = private)
		
	end
	

	% Callbacks that handle component events
	methods (Access = private)

		% Code that executes after component creation
		function startupFcn(app)
			global GV_H VER APP APP_UIOBJECTS GV
			
			try
				
				APP = app;
				
				% Collect the objects for user interactions:
				APP_UIOBJECTS		= [];
				fn						= fieldnames(APP);
				for k=1:size(fn,1)
					if   (strcmp(app.(fn{k,1}).Type,'uibutton')          ||...
							strcmp(app.(fn{k,1}).Type,'uibuttongroup')     ||...
							strcmp(app.(fn{k,1}).Type,'uitogglebutton')    ||...
							strcmp(app.(fn{k,1}).Type,'uistatebutton')     ||...
							strcmp(app.(fn{k,1}).Type,'uinumericeditfield')||...
							strcmp(app.(fn{k,1}).Type,'uieditfield')       ||...
							strcmp(app.(fn{k,1}).Type,'uidropdown')        ||...
							strcmp(app.(fn{k,1}).Type,'uislider')          ||...
							strcmp(app.(fn{k,1}).Type,'uitable')           ||...
							strcmp(app.(fn{k,1}).Type,'uicheckbox')             )&&...
							~strcmp(fn{k,1},'Mod_LV_FirstLast_ButtonGroup')      &&...
							~strcmp(fn{k,1},'Mod_LiReVe_ButtonGroup')
						APP_UIOBJECTS(end+1,1)	= APP.(fn{k,1});
					elseif strcmp(APP.(fn{k,1}).Type,'uimenu')
						if strcmp(APP.(fn{k,1}).Parent.Type,'figure')
							APP_UIOBJECTS(end+1,1)	= APP.(fn{k,1});
						end
					end
				end
				
				% Display state:
				display_on_gui('state','Initialization ... ','busy','add');
				drawnow;
				
				% Initialization:
				GV_H.osmdata_table			= APP.OSMDATA_TABLE;			% before executing globalinits!
				GV_H.map_objects_table		= APP.MAP_OBJECTS_TABLE;	% before executing globalinits!
				GV_H.patch_waitbar			= patch(APP.WaitbarUIAxes,[0 0.5 0.5 0],[0 0 1 1],'green','EdgeColor',[1 1 1]*0.73);
				GV_H.patch_waitbar.XData	= [0 0 0 0];
				GV_H.text_waitbar				= text(APP.WaitbarUIAxes,0.005,0.5,'abc','FontSize',12,'Interpreter','none');
				GV_H.text_waitbar.String	= '';
				
				% Initialize waitbar:
				APP.WaitbarUIAxes.PositionConstraint	= 'innerposition';
				APP.WaitbarUIAxes.InnerPosition			= [10 582 1004 18];
				APP.WaitbarUIAxes.XLim						= [0 1];
				APP.WaitbarUIAxes.YLim						= [0 1];
				
				% Save tooltips:
				set_tooltips('init');
				% Set tooltips (checkbox on):
				set_tooltips('set');
				
				% Initialization:
				globalinits;
				
				% Color themes light/dark: Get initial values when starting the app:
				update_color_theme('init',false);
				update_color_theme('set',false);
				
				% Positions of the figure and figure children: preparation to hide the map objects table:
				globalinits_figuresize;
				
				% Set figure name:
				APP.MapLab3D.Name = sprintf('MapLab3D %g.%g.%g.%g',VER.no1,VER.no2,VER.no3,VER.no4);
				
				% lon,lat-x,y-calculator reset:
				calculator_latlonxy_reset;
				
				% Timer process (TIMERPROCESS):
				GV.timer_activated						= true;
				GV.timer_signal_lamp_blink				= false;			% true: signal lamp is blinking if state is 'notbusy'
				timer_initialization;
				
				% deactivated:
				% % timer process that displays the last line of the diary.txt file (command window):
				% if isfield(GV_H,'timer_display_diary')
				% 	if isvalid(GV_H.timer_display_diary)
				% 		stop(GV_H.timer_display_diary);
				% 		delete(GV_H.timer_display_diary);
				% 	end
				% end
				% GV_H.timer_display_diary	= timer(...
				% 	'TimerFcn'     ,'display_diary;',...
				% 	'ExecutionMode','fixedSpacing'  ,...
				% 	'BusyMode'     ,'drop'          ,...
				% 	'Period'       ,1.5            );
				
				% Display state:
				drawnow;
				display_on_gui('state','Initialization ... done','notbusy','replace');
				
			catch ME
				errormessage('',ME);
			end
			
		end

		% Menu selected function: LoadprojectparametersMenu
		function LoadprojectparametersMenuSelected(app, event)
			try
				open_pp(1);
			catch ME
				errormessage('',ME);
			end
			
		end

		% Menu selected function: LoadOSMandElevationDataMenu
		function LoadOSMandElevationDataMenuMenuSelected(app, event)
			try
				open_osm;
			catch ME
				errormessage('',ME);
			end
			
		end

		% Menu selected function: ReloadprojectparametersMenu
		function ReloadprojectparametersMenuSelected(app, event)
			try
				open_pp;
			catch ME
				errormessage('',ME);
			end
			
		end

		% Menu selected function: CreatemapMenu
		function CreatemapMenuSelected(app, event)
			global GV
			try
				if app.CreatemapSettingsRecreateWholeMapMenu.Checked
					% Recreate the whole map:
					create_map;
				elseif app.CreatemapSettingsRecreateCertainObjectsMenu.Checked
					% Recreate certain objects, keep the rest:
					prompt		= {sprintf([...
						'Enter a list of object numbers.\n',...
						'The existing objects with these numbers will be deleted\n',...
						'and recreated using the current project parameters\n',...
						'E.g.: 1,2,5:7   -->   Recreate ObjNo 1, 2, 5, 6, 7'])};
					dlgtitle		= 'Enter object numbers';
					dims			= 1;
					if isfield(GV,'CreatemapMenu_RecreateCertainObjects_settings')
						definput		= GV.CreatemapMenu_RecreateCertainObjects_settings;
					else
						definput		= {''};
					end
					answer		= inputdlg_local(prompt,dlgtitle,dims,definput);
					if ~isempty(answer)
						k			= strfind(answer{1},'-');
						answer{1}(k)	= ':';
						iobj_v	= str2num(answer{1});
						iobj_v	= iobj_v(:);
						if ~isempty(iobj_v)
							GV.CreatemapMenu_RecreateCertainObjects_settings	= answer;
							create_map(iobj_v);
						else
							% Cancel: nop
						end
					else
						% Cancel: nop
					end
				else
					% Recreate the legend:
					create_map([]);
				end
			catch ME
				errormessage('',ME);
			end
			
		end

		% Menu selected function: SaveProjectMenu
		function SaveProjectMenuSelected(app, event)
			try
				save_project;
			catch ME
				errormessage('',ME);
			end
			
		end

		% Menu selected function: CreatemapSTLfilesMenu
		function CreatemapSTLfilesMenuSelected(app, event)
			global GV GV_H WAITBAR PP MAP_OBJECTS
			try
				if isempty(PP)
					errortext	= sprintf([...
						'The project parameters have not yet been loaded.\n',...
						'First load the project parameters.']);
					errormessage(errortext);
				end
				if isempty(MAP_OBJECTS)
					errortext	= sprintf([...
						'The map has not yet been created.\n',...
						'First create the map.']);
					errormessage(errortext);
				end
				map_tile_no			= [];
				WAITBAR.t1			= clock;										% Time of the last update
				% Assign project directory:
				askforprojdir		= false;
				if ~isfield(GV,'projectdirectory_stl')
					askforprojdir		= true;
				else
					if exist(GV.projectdirectory_stl,'dir')~=7
						askforprojdir	= true;
					end
				end
				if ~isfield(GV,'projectdirectory_stl_repaired')
					askforprojdir		= true;
				else
					if exist(GV.projectdirectory_stl_repaired,'dir')~=7
						askforprojdir	= true;
					end
				end
				if askforprojdir
					get_projectdirectory;	% Assign GV.projectdirectory_stl and GV.projectdirectory_stl_repaired
				end
				% Set the value of the cutting lines dropdown menu to 'None' (make the map objects visible):
				set_previewtype_dropdown(1);
				% % Autosave:
				% [map_filename_autosave,~,~]	= filenames_savefiles;
				% set(GV_H.text_waitbar,'String',sprintf('Autosave "%s"',map_filename_autosave));
				% save_project;
				% Autosave:
				filename_add						= ' - before create map STL files';
				[map_filename_autosave,~,~]	= filenames_savefiles(filename_add);
				set(GV_H.text_waitbar,'String',sprintf('Autosave "%s"',map_filename_autosave));
				save_project(0,filename_add);
				% Map2stl:
				map2stl(...
					map_tile_no,...								% map_tile_no
					GV.projectdirectory,...						% map_pathname
					GV.projectdirectory_stl,...				% map_pathname_stl
					GV.projectdirectory_stl_repaired,...	% map_pathname_stl_repaired
					map_filename_autosave,...					% map_filename
					'',...											% stl_filename
					'Create STL-files',...						% msg
					0);												% maptype
			catch ME
				errormessage('',ME);
			end
			
		end

		% Button pushed function: filterOSMdataButton
		function filterOSMdataButtonPushed(app, event)
			try
				% Display state:
				t_start_statebusy	= clock;
				display_on_gui('state','Filtering OSM-data ...','busy','add');
				
				general_filter_incl				= struct('crit','','lolim','','uplim','');
				general_filter_excl				= struct('crit','','lolim','','uplim','');
				obj_filter_incl					= struct('crit','','lolim','','uplim','');
				obj_filter_excl					= struct('crit','','lolim','','uplim','');
				[obj_tag_incl,obj_tag_excl]	= get_obj_tag_inclexcl;
				filter_osmdata(...
					1,...
					obj_tag_incl,...
					obj_tag_excl,...
					general_filter_incl,obj_filter_incl,...
					general_filter_excl,obj_filter_excl,...
					'',...															% msg
					app.filterOSMdata_nodes_CheckBox.Value,...			% filter nodes
					app.filterOSMdata_ways_CheckBox.Value,...				% filter ways
					app.filterOSMdata_relations_CheckBox.Value);			% filter relations
				
				% Display state:
				display_on_gui('state',...
					sprintf('Filtering OSM-data ... done (%s).',dt_string(etime(clock,t_start_statebusy))),...
					'notbusy','replace');
			catch ME
				errormessage('',ME);
			end
			
		end

		% Cell selection callback: OSMDATA_TABLE
		function OSMDATA_TABLECellSelection(app, event)
			try
				stateisbusy	= display_on_gui('state','','isbusy');
				if ~stateisbusy
					% Do not allow user actions if state is busy (red). In this way, setting
					% APP.OSMDATA_TABLE.Enable='off' can be avoided because it doesn't work reliably.
					indices = event.Indices;
					itable = indices(:,1);
					plot_osmdata_preview(itable,'temp');
					%             if app.addButton.Value
					%                 par = 'add';
					%                 plot_osmdata(1:30 ,par);
					%             end
					%             if app.replaceButton.Value
					%                 par ='replace';
					%                 plot_osmdata(rows,par);
					%             end
					%             if app.previewButton.Value
					%                 par = 'temp';
					%                 plot_osmdata_preview(rows,par);
					%             end
					%
					% %
					% %             if app.replaceButton.Value
					% %                 display_map_objects;
					% %             end
					% %             if app.addButton.Value
					% %                 display_map_objects;
					% %             end
				end
			catch ME
				errormessage('',ME);
			end
			
		end

		% Menu selected function: LoadprojectMenu
		function LoadprojectMenuSelected(app, event)
			try
				load_project;
			catch ME
				errormessage('',ME);
			end
			
		end

		% Menu selected function: ChangeprojectdirectoryMenu
		function ChangeprojectdirectoryMenuSelected(app, event)
			try
				get_projectdirectory(1);
			catch ME
				errormessage('',ME);
			end
			
		end

		% Menu selected function: CreatecolorsampleSTLfilesMenu
		function CreatecolorsampleSTLfilesMenuSelected(app, event)
			try
				testsamples(1);			% testsample_no=1: Color samples
			catch ME
				errormessage('',ME);
			end
			
		end

		% Cell selection callback: MAP_OBJECTS_TABLE
		function MAP_OBJECTS_TABLE_CellSelection(app, event)
			global MAP_OBJECTS
			try
				stateisbusy	= display_on_gui('state','','isbusy');
				if ~stateisbusy
					% Do not allow user actions if state is busy (red). In this way, setting
					% APP.MAP_OBJECTS_TABLE.Enable='off' can be avoided because it doesn't work reliably.
					indices = event.Indices;
					indices_selected	= false(size(MAP_OBJECTS,1),1);
					for imapobj=1:size(MAP_OBJECTS,1)
						indices_selected(imapobj,1)	= MAP_OBJECTS(imapobj,1).h(1,1).Selected;
					end
					indices_select						= false(size(MAP_OBJECTS,1),1);
					indices_select(indices(:,1))	= true;
					indices_deselect					= find(indices_selected&~indices_select);
					indices_select						= find(indices_select  &~indices_selected);
					plot_modify('deselect',indices_deselect,0);
					plot_modify('select',indices_select,0);
					% 			% alt:
					% 			global MAP_OBJECTS_TABLE
					% 			% display_on_gui('state','','busy');
					% 			indices = event.Indices;
					% 			% Update MAP_OBJECTS_TABLE only if necessary (faster execution):
					% 			indices_select						= false(size(MAP_OBJECTS_TABLE,1),1);
					% 			indices_select(indices(:,1))	= true;
					% 			indices_deselect					= find(indices_selected&~indices_select);
					% 			indices_select						= find(indices_select  &~indices_selected);
					% 			plot_modify('deselect',indices_deselect,0);
					% 			plot_modify('select',indices_select,0);
					% 			% Update MAP_OBJECTS_TABLE:
					% 			% display_map_objects(unique([indices_deselect;indices_select]));
					% 			% display_on_gui('state','','notbusy');
				end
			catch ME
				errormessage('',ME);
			end
			MAP_OBJECTS_TABLE_KeyRelease(app);
		end

		% Key release function: MAP_OBJECTS_TABLE
		function MAP_OBJECTS_TABLE_KeyRelease(app, event)
			% try
			% 	% disp('-------------------------------------------------------------');
			% 	% key = event.Key;
			% catch ME
			% 	errormessage('',ME);
			% end
			
		end

		% Menu selected function: LoadsymbolsMenu
		function LoadsymbolsMenuSelected(app, event)
			try
				load_symbols(1);
			catch ME
				errormessage('',ME);
			end
			
		end

		% Menu selected function: Cre3dPrevSet_re_orig_Menu
		function Cre3dPrevSet_re_orig_MenuSel(app, event)
			try
				app.Cre3dPrevSet_re_orig_Menu.Checked		= 'on';
				app.Cre3dPrevSet_re_origfilt_Menu.Checked	= 'off';
				app.Cre3dPrevSet_re_filtint_Menu.Checked	= 'off';
			catch ME
				errormessage('',ME);
			end
			
		end

		% Menu selected function: Cre3dPrevSet_re_origfilt_Menu
		function Cre3dPrevSet_re_origfilt_MenuSel(app, event)
			try
				app.Cre3dPrevSet_re_orig_Menu.Checked		= 'off';
				app.Cre3dPrevSet_re_origfilt_Menu.Checked	= 'on';
				app.Cre3dPrevSet_re_filtint_Menu.Checked	= 'off';
			catch ME
				errormessage('',ME);
			end
			
		end

		% Menu selected function: Cre3dPrevSet_re_filtint_Menu
		function Cre3dPrevSet_re_filtint_MenuSel(app, event)
			try
				app.Cre3dPrevSet_re_orig_Menu.Checked		= 'off';
				app.Cre3dPrevSet_re_origfilt_Menu.Checked	= 'off';
				app.Cre3dPrevSet_re_filtint_Menu.Checked	= 'on';
			catch ME
				errormessage('',ME);
			end
			
		end

		% Menu selected function: Cre3dPrevSet_sh_ele_Menu
		function Cre3dPrevSet_sh_ele_MenuSel(app, event)
			try
				app.Cre3dPrevSet_sh_ele_Menu.Checked		= 'on';
				app.Cre3dPrevSet_sh_lines_Menu.Checked		= 'off';
				app.Cre3dPrevSet_sh_patches_Menu.Checked	= 'off';
			catch ME
				errormessage('',ME);
			end
			
		end

		% Menu selected function: Cre3dPrevSet_sh_lines_Menu
		function Cre3dPrevSet_sh_lines_MenuSel(app, event)
			try
				app.Cre3dPrevSet_sh_ele_Menu.Checked		= 'off';
				app.Cre3dPrevSet_sh_lines_Menu.Checked		= 'on';
				app.Cre3dPrevSet_sh_patches_Menu.Checked	= 'off';
			catch ME
				errormessage('',ME);
			end
			
		end

		% Menu selected function: Cre3dPrevSet_sh_patches_Menu
		function Cre3dPrevSet_sh_patches_MenuSel(app, event)
			try
				app.Cre3dPrevSet_sh_ele_Menu.Checked		= 'off';
				app.Cre3dPrevSet_sh_lines_Menu.Checked		= 'off';
				app.Cre3dPrevSet_sh_patches_Menu.Checked	= 'on';
			catch ME
				errormessage('',ME);
			end
			
		end

		% Menu selected function: Cre3dPrevSet_ti_1_Menu
		function Cre3dPrevSet_ti_1_MenuSel(app, event)
			try
				app.Cre3dPrevSet_ti_1_Menu.Checked			= 'on';
				app.Cre3dPrevSet_ti_all_Menu.Checked		= 'off';
				app.Cre3dPrevSet_ti_ask_Menu.Checked		= 'off';
			catch ME
				errormessage('',ME);
			end
			
		end

		% Menu selected function: Cre3dPrevSet_ti_all_Menu
		function Cre3dPrevSet_ti_all_MenuSel(app, event)
			try
				app.Cre3dPrevSet_ti_1_Menu.Checked			= 'off';
				app.Cre3dPrevSet_ti_all_Menu.Checked		= 'on';
				app.Cre3dPrevSet_ti_ask_Menu.Checked		= 'off';
			catch ME
				errormessage('',ME);
			end
			
		end

		% Menu selected function: Cre3dPrevSet_ti_ask_Menu
		function Cre3dPrevSet_ti_ask_MenuSel(app, event)
			try
				app.Cre3dPrevSet_ti_1_Menu.Checked			= 'off';
				app.Cre3dPrevSet_ti_all_Menu.Checked		= 'off';
				app.Cre3dPrevSet_ti_ask_Menu.Checked		= 'on';
			catch ME
				errormessage('',ME);
			end
			
		end

		% Menu selected function: Cre3dPrevMenu
		function Cre3dPrevMenuSel(app, event)
			try
				create_map_3dpreview;
			catch ME
				errormessage('',ME);
			end
			
		end

		% Menu selected function: PlotOSMrawDataMenu
		function PlotOSMrawDataMenuSel(app, event)
			try
				plot_osm_rawdata;
			catch ME
				errormessage('',ME);
			end
			
		end

		% Button pushed function: clearosmdataselectionButton
		function clearosmdataselectionButtonPushed(app, event)
			try
				plot_osmdata_preview([],'temp');
			catch ME
				errormessage('',ME);
			end
			
		end

		% Button pushed function: copyosmdataselectiontomapButton
		function copyosmdataselectiontomapButtonPushed(app, event)
			global I_OSMDATA_TABLE_TEMPPREV
			try
				plot_osmdata_preview(I_OSMDATA_TABLE_TEMPPREV,'add',app.PreviewdescriptionEditField.Value);
			catch ME
				errormessage('',ME);
			end
			
		end

		% Button pushed function: copyfilterresultstoclipboardButton
		function copyfilterresultstoclipboardButtonPushed(app, event)
			try
				copy2clipboard_table(app.OSMDATA_TABLE);
			catch ME
				errormessage('',ME);
			end
			
		end

		% Button pushed function: Mod_Move_LU2_Button
		function Mod_Move_LU2_ButtonPushed(app, event)
			try
				plot_modify('move',-1,-app.Mod_Move_mm_EditField.Value,app.Mod_Move_mm_EditField.Value);
			catch ME
				errormessage('',ME);
			end
			
		end

		% Button pushed function: Mod_Move_L2_Button
		function Mod_Move_L2_ButtonPushed(app, event)
			try
				plot_modify('move',-1,-app.Mod_Move_mm_EditField.Value,0);
			catch ME
				errormessage('',ME);
			end
			
		end

		% Button pushed function: Mod_Move_LD2_Button
		function Mod_Move_LD2_ButtonPushed(app, event)
			try
				plot_modify('move',-1,-app.Mod_Move_mm_EditField.Value,-app.Mod_Move_mm_EditField.Value);
			catch ME
				errormessage('',ME);
			end
			
		end

		% Button pushed function: Mod_Move_RU2_Button
		function Mod_Move_RU2_ButtonPushed(app, event)
			try
				plot_modify('move',-1,app.Mod_Move_mm_EditField.Value,app.Mod_Move_mm_EditField.Value);
			catch ME
				errormessage('',ME);
			end
			
		end

		% Button pushed function: Mod_Move_R2_Button
		function Mod_Move_R2_ButtonPushed(app, event)
			try
				plot_modify('move',-1,app.Mod_Move_mm_EditField.Value,0);
			catch ME
				errormessage('',ME);
			end
			
		end

		% Button pushed function: Mod_Move_RD2_Button
		function Mod_Move_RD2_ButtonPushed(app, event)
			try
				plot_modify('move',-1,app.Mod_Move_mm_EditField.Value,-app.Mod_Move_mm_EditField.Value);
			catch ME
				errormessage('',ME);
			end
			
		end

		% Button pushed function: Mod_Move_U2_Button
		function Mod_Move_U2_ButtonPushed(app, event)
			try
				plot_modify('move',-1,0,app.Mod_Move_mm_EditField.Value);
			catch ME
				errormessage('',ME);
			end
			
		end

		% Button pushed function: Mod_Move_D2_Button
		function Mod_Move_D2_ButtonPushed(app, event)
			try
				plot_modify('move',-1,0,-app.Mod_Move_mm_EditField.Value);
			catch ME
				errormessage('',ME);
			end
			
		end

		% Button pushed function: Mod_Dup_Button
		function Mod_Dup_ButtonPushed(app, event)
			try
				plot_modify('duplicate',-1,app.Mod_Dup_xmm_EditField.Value,app.Mod_Dup_ymm_EditField.Value);
			catch ME
				errormessage('',ME);
			end
			
		end

		% Button pushed function: Mod_Scal_Button
		function Mod_Scal_ButtonPushed(app, event)
			try
				plot_modify('scale',-1,app.Mod_Scal_xperc_EditField.Value/100,app.Mod_Scal_yperc_EditField.Value/100);
			catch ME
				errormessage('',ME);
			end
			
		end

		% Button pushed function: Mod_Hide_Button
		function Mod_Hide_ButtonPushed(app, event)
			try
				plot_modify('hide',-1);
			catch ME
				errormessage('',ME);
			end
			
		end

		% Button pushed function: Mod_Show_Button
		function Mod_Show_ButtonPushed(app, event)
			try
				plot_modify('show',-1);
			catch ME
				errormessage('',ME);
			end
			
		end

		% Button pushed function: Mod_Arrange_Ungroup_Button
		function Mod_Arrange_Ungroup_ButtonPushed(app, event)
			try
				plot_modify('ungroup',-1);
			catch ME
				errormessage('',ME);
			end
			
		end

		% Button pushed function: Mod_Arrange_Group_Button
		function Mod_Arrange_Group_ButtonPushed(app, event)
			try
				plot_modify('group',-1);
			catch ME
				errormessage('',ME);
			end
			
		end

		% Button pushed function: Mod_Deselectall_Button
		function Mod_Deselectall_ButtonPushed(app, event)
			try
				plot_modify('deselect',-1);
			catch ME
				errormessage('',ME);
			end
			
		end

		% Button pushed function: Mod_Reset_Button
		function Mod_Reset_ButtonPushed(app, event)
			try
				plot_modify('reset',-1);
			catch ME
				errormessage('',ME);
			end
			
		end

		% Button pushed function: Mod_DelObj_Button
		function Mod_DelObj_ButtonPushed(app, event)
			try
				plot_modify('delete',-1);
			catch ME
				errormessage('',ME);
			end
			
		end

		% Button pushed function: Mod_Rot_L2_Button
		function Mod_Rot_L2_ButtonPushed(app, event)
			try
				plot_modify('rotate',-1,app.Mod_Rot_deg_EditField.Value);
			catch ME
				errormessage('',ME);
			end
			
		end

		% Button pushed function: Mod_Rot_R2_Button
		function Mod_Rot_R2_ButtonPushed(app, event)
			try
				plot_modify('rotate',-1,-app.Mod_Rot_deg_EditField.Value);
			catch ME
				errormessage('',ME);
			end
			
		end

		% Button pushed function: Mod_LV_Add_Button
		function Mod_LV_Add_ButtonPushed(app, event)
			try
				if strcmp(app.Mod_LV_FirstLast_ButtonGroup.SelectedObject.Text,'First')
					par2='first';
				else
					par2='last';
				end
				plot_modify('mod_vertex',...
					-1,...												% imapobj_v
					'add',par2,...
					app.Mod_LV_xmm_EditField.Value,...			% x-value of the new vertex
					app.Mod_LV_ymm_EditField.Value);				% y-value of the new vertex
			catch ME
				errormessage('',ME);
			end
			
		end

		% Button pushed function: Mod_LV_Delete_Button
		function Mod_LV_Delete_ButtonPushed(app, event)
			try
				if strcmp(app.Mod_LV_FirstLast_ButtonGroup.SelectedObject.Text,'First')
					par2='first';
				else
					par2='last';
				end
				plot_modify('mod_vertex',...
					-1,...												% imapobj_v
					'delvertex',par2);
			catch ME
				errormessage('',ME);
			end
			
		end

		% Button pushed function: Mod_LV_Close_Button
		function Mod_LV_Close_ButtonPushed(app, event)
			try
				plot_modify('mod_vertex',-1,'close');
			catch ME
				errormessage('',ME);
			end
			
		end

		% Button pushed function: Mod_ConvPrevToMapobj_Button
		function Mod_ConvPrevToMapobj_ButtonPushed(app, event)
			try
				plotosmdata_convprev2mapobj(app.Mod_AddPrevToOutput_ObjNo_EditField.Value);
			catch ME
				errormessage('',ME);
			end
			
		end

		% Button pushed function: Mod_LV_New_Button
		function Mod_LV_New_ButtonPushed(app, event)
			try
				plot_modify('new_line',0,...
					app.Mod_LV_xmm_EditField.Value,...			% x-value of the new vertex/vertices
					app.Mod_LV_ymm_EditField.Value);         	% y-value of the new vertex/vertices
			catch ME
				errormessage('',ME);
			end
			
		end

		% Button pushed function: Mod_Lines_Connect_Button
		function Mod_Lines_Connect_ButtonPushed(app, event)
			try
				plot_modify('connect',-1);
			catch ME
				errormessage('',ME);
			end
			
		end

		% Button pushed function: Mod_ConvMapobjToPrev_Button
		function Mod_ConvMapobjToPrev_ButtonPushed(app, event)
			try
				plotosmdata_convmapobj2prev;
			catch ME
				errormessage('',ME);
			end
			
		end

		% Selection changed function: Mod_LV_FirstLast_ButtonGroup
		function Mod_LV_FirstLast_ButtonGroupSelChanged(app, event)
			global MAP_OBJECTS GV GV_H
			try
				imapobj_v	= [];
				for imapobj=1:size(MAP_OBJECTS,1)
					for r=1:size(MAP_OBJECTS(imapobj,1).h,1)
						for c=1:size(MAP_OBJECTS(imapobj,1).h,2)
							if MAP_OBJECTS(imapobj,1).h(r,c).Selected
								imapobj_v	= [imapobj_v;imapobj];
							end
						end
					end
				end
				imapobj_v	= unique(imapobj_v);
				if length(imapobj_v)==1
					if length(MAP_OBJECTS(imapobj_v,1).h)==1
						if strcmp(MAP_OBJECTS(imapobj_v,1).h.Type,'line')
							if strcmp(app.Mod_LV_FirstLast_ButtonGroup.SelectedObject.Text,'First')
								x	= MAP_OBJECTS(imapobj_v,1).h.XData(1);
								y	= MAP_OBJECTS(imapobj_v,1).h.YData(1);
							else
								x	= MAP_OBJECTS(imapobj_v,1).h.XData(end);
								y	= MAP_OBJECTS(imapobj_v,1).h.YData(end);
							end
							if ishandle(GV_H.ax_2dmap)
								hplot	= plot(GV_H.ax_2dmap,x,y,...
									'Color'     ,GV.preview.Color,...
									'LineStyle' ,GV.preview.LineStyle,...
									'LineWidth' ,GV.preview.LineWidth,...
									'Marker'    ,GV.preview.Marker,...
									'MarkerSize',GV.preview.MarkerSizeFlash);
								% Create/modify legend:
								create_legend_mapfigure;
								pause(1);
								delete(hplot);
							end
						end
					end
				end
			catch ME
				errormessage('',ME);
			end
			
		end

		% Button pushed function: Mod_Lines_ToPolygon_Button
		function Mod_Lines_ToPolygon_ButtonPushed(app, event)
			try
				plot_modify('line2poly',-1);
			catch ME
				errormessage('',ME);
			end
			
		end

		% Button pushed function: Mod_Polygons_ToLine_Button
		function Mod_Polygons_ToLine_ButtonPushed(app, event)
			try
				plot_modify('poly2line',-1);
			catch ME
				errormessage('',ME);
			end
			
		end

		% Button pushed function: Mod_Polygons_Union_Button
		function Mod_Polygons_Union_ButtonPushed(app, event)
			try
				plot_modify('poly12',0,'union');
			catch ME
				errormessage('',ME);
			end
			
		end

		% Button pushed function: Mod_Polygons_Subtract_Button
		function Mod_Polygons_Subtract_ButtonPushed(app, event)
			try
				plot_modify('poly12',0,'subtract');
			catch ME
				errormessage('',ME);
			end
			
		end

		% Button pushed function: Mod_Polygons_Intersect_Button
		function Mod_Polygons_Intersect_ButtonPushed(app, event)
			try
				plot_modify('poly12',0,'intersect');
			catch ME
				errormessage('',ME);
			end
			
		end

		% Button pushed function: Mod_Polygons_Polybuffer_Button
		function Mod_Polygons_Polybuffer_ButtonPushed(app, event)
			try
				plot_modify('poly',-1,'polybuffer',app.Mod_Polygons_BuffDist_EditField.Value);
			catch ME
				errormessage('',ME);
			end
			
		end

		% Button pushed function: Mod_Polygons_Xor_Button
		function Mod_Polygons_Xor_ButtonPushed(app, event)
			try
				plot_modify('poly12',0,'xor');
			catch ME
				errormessage('',ME);
			end
			
		end

		% Button pushed function: Mod_Polygons_Simplify_Button
		function Mod_Polygons_Simplify_ButtonPushed(app, event)
			try
				plot_modify('poly',-1,'simplify');
			catch ME
				errormessage('',ME);
			end
			
		end

		% Button pushed function: Mod_Polygons_Addboundary_Button
		function Mod_Polygons_Addboundary_ButtonPushed(app, event)
			try
				plot_modify('poly12',0,'addboundary');
			catch ME
				errormessage('',ME);
			end
			
		end

		% Button pushed function: Mod_Polygons_DissolveBound_Button
		function Mod_Polygons_DissolveBound_ButtonPushed(app, event)
			try
				plot_modify('poly',-1,'dissolve_bound');
			catch ME
				errormessage('',ME);
			end
			
		end

		% Button pushed function: Mod_Polygons_PrintoutLimitsToPoly_Button
		function Mod_Polygons_PrintoutLimitsToPoly_ButtonPushed(app, event)
			try
				plot_modify('printoutlim2poly',0);
			catch ME
				errormessage('',ME);
			end
			
		end

		% Button pushed function: Mod_Polygons_SetPrintout_Button
		function Mod_Polygons_SetPrintout_ButtonPushed(app, event)
			try
				plot_modify('set_printout',-1);
			catch ME
				errormessage('',ME);
			end
			
		end

		% Button pushed function: Mod_Polygons_Regions_Button
		function Mod_Polygons_Regions_ButtonPushed(app, event)
			try
				plot_modify('poly',-1,'regions');
			catch ME
				errormessage('',ME);
			end
			
		end

		% Menu selected function: View_ShowTileNo_Menu
		function View_ShowTileNo_MenuSel(app, event)
			global GV_H PP
			try
				if isempty(PP)
					errortext	= sprintf([...
						'The project parameters have not yet been loaded.\n',...
						'First load the project parameters.']);
					errormessage(errortext);
				end
				if isfield(GV_H,'poly_tileno')
					if app.View_ShowTileNo_Menu.Checked
						app.View_ShowTileNo_Menu.Checked	= 'off';
						for i=1:size(GV_H.poly_tileno,1)
							GV_H.poly_tileno{i,1}.Visible	= 'off';
							drawnow;
						end
					else
						app.View_ShowTileNo_Menu.Checked	= 'on';
						for tile_no=1:size(GV_H.poly_tileno,1)
							i				= 1:length(GV_H.ax_2dmap.Children);
							i_tile		= find(GV_H.ax_2dmap.Children==GV_H.poly_tileno{tile_no,1});
							i(i_tile)	= [];
							i				= [i_tile i];
							GV_H.ax_2dmap.Children	= GV_H.ax_2dmap.Children(i);
							GV_H.poly_tileno{tile_no,1}.Visible	= 'on';
							% The sorting can take a long time at large maps:
							drawnow;
						end
					end
				end
			catch ME
				errormessage('',ME);
			end
			
		end

		% Menu selected function: CreatemapSettingsShowTestplotsMenu
		function CreatemapSettingsShowTestplotsMenuSel(app, event)
			global GV
			try
				prompt		= {sprintf([...
					'Enter a list of object numbers.\n',...
					'During map creation, test plots of the\n',...
					'data reduction steps will be created also.\n',...
					'E.g.: 1,2,5:7   -->   Show ObjNo 1, 2, 5, 6, 7\n',...
					'No input   -->   no test plots'])};
				dlgtitle		= 'Enter object numbers';
				fieldsize	= [1 45];
				definput		= {num2str(GV.iobj_testplot_simplify_v(:)')};
				answer		= inputdlg_local(prompt,dlgtitle,fieldsize,definput);
				if ~isempty(answer)
					GV.iobj_testplot_simplify_v	= str2num(answer{1});
					GV.iobj_testplot_simplify_v	= GV.iobj_testplot_simplify_v(:);
					if isempty(GV.iobj_testplot_simplify_v)
						app.CreatemapSettingsShowTestplotsMenu.Checked='off';
					else
						app.CreatemapSettingsShowTestplotsMenu.Checked='on';
					end
				else
					% Cancel: nop
				end
			catch ME
				errormessage('',ME);
			end
			
		end

		% Menu selected function: ReloadprojectMenu
		function ReloadprojectMenuSelected(app, event)
			global GV
			try
				exec_not_possible			= false;
				if ~isfield(GV,'pp_projectfilename')
					exec_not_possible		= true;
				else
					if isempty(GV.pp_projectfilename)
						exec_not_possible		= true;
					end
				end
				if ~isfield(GV,'projectdirectory')
					exec_not_possible		= true;
				else
					if isempty(GV.projectdirectory)
						exec_not_possible		= true;
					end
				end
				if exec_not_possible
					load_project;
				else
					[map_filename,~,~]	= filenames_savefiles;			% needs GV.pp_projectfilename
					load_project(GV.projectdirectory,map_filename);
				end
			catch ME
				errormessage('',ME);
			end
			
		end

		% Menu selected function: 
		% MapEdit_SimplifyMapSettings_ShowTestplots_Menu
		function MapEdit_SimplifyMapSettings_ShowTestplots_MenuSel(app, event)
			global GV
			try
				prompt		= {sprintf([...
					'Enter a list of color numbers.\n',...
					'During map simplification, test plots of the\n',...
					'data reduction steps will be created also.\n',...
					'E.g.: 1,2,5:7   -->   Show ColNo 1, 2, 5, 6, 7\n',...
					'No input   -->   no test plots'])};
				dlgtitle		= 'Enter color numbers';
				dims			= 1;
				definput		= {num2str(GV.colno_testplot_simplify_v(:)')};
				answer		= inputdlg_local(prompt,dlgtitle,dims,definput);
				if ~isempty(answer)
					GV.colno_testplot_simplify_v	= str2num(answer{1});
					GV.colno_testplot_simplify_v	= GV.colno_testplot_simplify_v(:);
					if isempty(GV.colno_testplot_simplify_v)
						app.MapEdit_SimplifyMapSettings_ShowTestplots_Menu.Checked='off';
					else
						app.MapEdit_SimplifyMapSettings_ShowTestplots_Menu.Checked='on';
					end
				else
					% Cancel: nop
				end
			catch ME
				errormessage('',ME);
			end
			
		end

		% Menu selected function: SaveProjectAsMenu
		function SaveProjectAsMenuSelected(app, event)
			try
				save_project(1);
			catch ME
				errormessage('',ME);
			end
			
		end

		% Menu selected function: View_OpenFigure_Menu
		function View_OpenFigure_MenuSel(app, event)
			try
				open_figure;
			catch ME
				errormessage('',ME);
			end
			
		end

		% Menu selected function: LoadSettingsCompleteOSMfileMenu
		function LoadSettingsCompleteOSMfileMenuSelected(app, event)
			try
				app.LoadSettingsCompleteOSMfileMenu.Checked		= 'on';
				app.LoadSettingsFilteredOSMfileMenu.Checked		= 'off';
				app.LoadSettingsOnlyOSMboundariesMenu.Checked	= 'off';
			catch ME
				errormessage('',ME);
			end
			
		end

		% Menu selected function: LoadSettingsFilteredOSMfileMenu
		function LoadSettingsFilteredOSMfileMenuSelected(app, event)
			try
				app.LoadSettingsCompleteOSMfileMenu.Checked		= 'off';
				app.LoadSettingsFilteredOSMfileMenu.Checked		= 'on';
				app.LoadSettingsOnlyOSMboundariesMenu.Checked	= 'off';
			catch ME
				errormessage('',ME);
			end
			
		end

		% Menu selected function: LoadSettingsOnlyOSMboundariesMenu
		function LoadSettingsOnlyOSMboundariesMenuSelected(app, event)
			try
				app.LoadSettingsCompleteOSMfileMenu.Checked		= 'off';
				app.LoadSettingsFilteredOSMfileMenu.Checked		= 'off';
				app.LoadSettingsOnlyOSMboundariesMenu.Checked	= 'on';
			catch ME
				errormessage('',ME);
			end
			
		end

		% Menu selected function: LoadSettingsElevationDataMenu
		function LoadSettingsElevationDataMenuSelected(app, event)
			try
				app.LoadSettingsElevationDataMenu.Checked				= 'on';
				app.LoadSettingsDoNotLoadElevationDataMenu.Checked	= 'off';
			catch ME
				errormessage('',ME);
			end
			
		end

		% Menu selected function: LoadSettingsDoNotLoadElevationDataMenu
		function LoadSettingsDoNotLoadElevationDataMenuSelected(app, event)
			try
				app.LoadSettingsElevationDataMenu.Checked				= 'off';
				app.LoadSettingsDoNotLoadElevationDataMenu.Checked	= 'on';
			catch ME
				errormessage('',ME);
			end
			
		end

		% Menu selected function: CreateTextSampleMenu
		function CreateTextSampleMenuSelected(app, event)
			try
				testsamples(2);			% testsample_no=2: Text samples
			catch ME
				errormessage('',ME);
			end
			
		end

		% Menu selected function: CreatemapSettingsRecreateWholeMapMenu
		function CreatemapSettingsRecreateWholeMapMenuSelected(app, event)
			try
				app.CreatemapSettingsRecreateWholeMapMenu.Checked			= 'on';
				app.CreatemapSettingsRecreateCertainObjectsMenu.Checked	= 'off';
				app.CreatemapSettingsRecreateLegendMenu.Checked				= 'off';
			catch ME
				errormessage('',ME);
			end
			
		end

		% Menu selected function: 
		% CreatemapSettingsRecreateCertainObjectsMenu
		function CreatemapSettingsRecreateCertainObjectsMenuSelected(app, event)
			try
				app.CreatemapSettingsRecreateWholeMapMenu.Checked			= 'off';
				app.CreatemapSettingsRecreateCertainObjectsMenu.Checked	= 'on';
				app.CreatemapSettingsRecreateLegendMenu.Checked				= 'off';
			catch ME
				errormessage('',ME);
			end
			
		end

		% Menu selected function: View_ShowContourLines_Menu
		function View_ShowContourLines_MenuSelected(app, event)
			global GV_H
			try
				if app.View_ShowContourLines_Menu.Checked
					app.View_ShowContourLines_Menu.Checked	= 'off';
					if isfield(GV_H,'poly_contour')
						for i=1:size(GV_H.poly_contour,1)
							if ishandle(GV_H.poly_contour{i,1})
								delete(GV_H.poly_contour{i,1});
							end
						end
					end
				else
					app.View_ShowContourLines_Menu.Checked	= plot_poly_contour;
				end
			catch ME
				errormessage('',ME);
			end
			
		end

		% Button pushed function: Mod_Move_LU1_Button
		function Mod_Move_LU1_ButtonPushed(app, event)
			global GV
			try
				plot_modify('move',-1,-app.Mod_Move_mm_EditField.Value/GV.pp_stepwidth_move_object_factor,app.Mod_Move_mm_EditField.Value/GV.pp_stepwidth_move_object_factor);
			catch ME
				errormessage('',ME);
			end
			
		end

		% Button pushed function: Mod_Move_LU3_Button
		function Mod_Move_LU3_ButtonPushed(app, event)
			global GV
			try
				plot_modify('move',-1,-app.Mod_Move_mm_EditField.Value*GV.pp_stepwidth_move_object_factor,app.Mod_Move_mm_EditField.Value*GV.pp_stepwidth_move_object_factor);
			catch ME
				errormessage('',ME);
			end
			
		end

		% Button pushed function: Mod_Move_U1_Button
		function Mod_Move_U1_ButtonPushed(app, event)
			global GV
			try
				plot_modify('move',-1,0,app.Mod_Move_mm_EditField.Value/GV.pp_stepwidth_move_object_factor);
			catch ME
				errormessage('',ME);
			end
		end

		% Button pushed function: Mod_Move_U3_Button
		function Mod_Move_U3_ButtonPushed(app, event)
			global GV
			try
				plot_modify('move',-1,0,app.Mod_Move_mm_EditField.Value*GV.pp_stepwidth_move_object_factor);
			catch ME
				errormessage('',ME);
			end
			
		end

		% Button pushed function: Mod_Move_RU1_Button
		function Mod_Move_RU1_ButtonPushed(app, event)
			global GV
			try
				plot_modify('move',-1,app.Mod_Move_mm_EditField.Value/GV.pp_stepwidth_move_object_factor,app.Mod_Move_mm_EditField.Value/GV.pp_stepwidth_move_object_factor);
			catch ME
				errormessage('',ME);
			end
			
		end

		% Button pushed function: Mod_Move_RU3_Button
		function Mod_Move_RU3_ButtonPushed(app, event)
			global GV
			try
				plot_modify('move',-1,app.Mod_Move_mm_EditField.Value*GV.pp_stepwidth_move_object_factor,app.Mod_Move_mm_EditField.Value*GV.pp_stepwidth_move_object_factor);
			catch ME
				errormessage('',ME);
			end
			
		end

		% Button pushed function: Mod_Move_R1_Button
		function Mod_Move_R1_ButtonPushed(app, event)
			global GV
			try
				plot_modify('move',-1,app.Mod_Move_mm_EditField.Value/GV.pp_stepwidth_move_object_factor,0);
			catch ME
				errormessage('',ME);
			end
			
		end

		% Button pushed function: Mod_Move_R3_Button
		function Mod_Move_R3_ButtonPushed(app, event)
			global GV
			try
				plot_modify('move',-1,app.Mod_Move_mm_EditField.Value*GV.pp_stepwidth_move_object_factor,0);
			catch ME
				errormessage('',ME);
			end
			
		end

		% Button pushed function: Mod_Move_RD1_Button
		function Mod_Move_RD1_ButtonPushed(app, event)
			global GV
			try
				plot_modify('move',-1,app.Mod_Move_mm_EditField.Value/GV.pp_stepwidth_move_object_factor,-app.Mod_Move_mm_EditField.Value/GV.pp_stepwidth_move_object_factor);
			catch ME
				errormessage('',ME);
			end
			
		end

		% Button pushed function: Mod_Move_RD3_Button
		function Mod_Move_RD3_ButtonPushed(app, event)
			global GV
			try
				plot_modify('move',-1,app.Mod_Move_mm_EditField.Value*GV.pp_stepwidth_move_object_factor,-app.Mod_Move_mm_EditField.Value*GV.pp_stepwidth_move_object_factor);
			catch ME
				errormessage('',ME);
			end
			
		end

		% Button pushed function: Mod_Move_D1_Button
		function Mod_Move_D1_ButtonPushed(app, event)
			global GV
			try
				plot_modify('move',-1,0,-app.Mod_Move_mm_EditField.Value/GV.pp_stepwidth_move_object_factor);
			catch ME
				errormessage('',ME);
			end
			
		end

		% Button pushed function: Mod_Move_D3_Button
		function Mod_Move_D3_ButtonPushed(app, event)
			global GV
			try
				plot_modify('move',-1,0,-app.Mod_Move_mm_EditField.Value*GV.pp_stepwidth_move_object_factor);
			catch ME
				errormessage('',ME);
			end
			
		end

		% Button pushed function: Mod_Move_LD1_Button
		function Mod_Move_LD1_ButtonPushed(app, event)
			global GV
			try
				plot_modify('move',-1,-app.Mod_Move_mm_EditField.Value/GV.pp_stepwidth_move_object_factor,-app.Mod_Move_mm_EditField.Value/GV.pp_stepwidth_move_object_factor);
			catch ME
				errormessage('',ME);
			end
			
		end

		% Button pushed function: Mod_Move_LD3_Button
		function Mod_Move_LD3_ButtonPushed(app, event)
			global GV
			try
				plot_modify('move',-1,-app.Mod_Move_mm_EditField.Value*GV.pp_stepwidth_move_object_factor,-app.Mod_Move_mm_EditField.Value*GV.pp_stepwidth_move_object_factor);
			catch ME
				errormessage('',ME);
			end
			
		end

		% Button pushed function: Mod_Move_L1_Button
		function Mod_Move_L1_ButtonPushed(app, event)
			global GV
			try
				plot_modify('move',-1,-app.Mod_Move_mm_EditField.Value/GV.pp_stepwidth_move_object_factor,0);
			catch ME
				errormessage('',ME);
			end
			
		end

		% Button pushed function: Mod_Move_L3_Button
		function Mod_Move_L3_ButtonPushed(app, event)
			global GV
			try
				plot_modify('move',-1,-app.Mod_Move_mm_EditField.Value*GV.pp_stepwidth_move_object_factor,0);
			catch ME
				errormessage('',ME);
			end
			
		end

		% Button pushed function: Mod_Rot_L1_Button
		function Mod_Rot_L1_ButtonPushed(app, event)
			global GV
			try
				plot_modify('rotate',-1,app.Mod_Rot_deg_EditField.Value/GV.pp_stepwidth_rotate_object_factor);
			catch ME
				errormessage('',ME);
			end
			
		end

		% Button pushed function: Mod_Rot_L3_Button
		function Mod_Rot_L3_ButtonPushed(app, event)
			global GV
			try
				plot_modify('rotate',-1,app.Mod_Rot_deg_EditField.Value*GV.pp_stepwidth_rotate_object_factor);
			catch ME
				errormessage('',ME);
			end
			
		end

		% Button pushed function: Mod_Rot_R1_Button
		function Mod_Rot_R1_ButtonPushed(app, event)
			global GV
			try
				plot_modify('rotate',-1,-app.Mod_Rot_deg_EditField.Value/GV.pp_stepwidth_rotate_object_factor);
			catch ME
				errormessage('',ME);
			end
			
		end

		% Button pushed function: Mod_Rot_R3_Button
		function Mod_Rot_R3_ButtonPushed(app, event)
			global GV
			try
				plot_modify('rotate',-1,-app.Mod_Rot_deg_EditField.Value*GV.pp_stepwidth_rotate_object_factor);
			catch ME
				errormessage('',ME);
			end
			
		end

		% Menu selected function: CreateframeSTLfilesMenu
		function CreateframeSTLfilesMenuSelected(app, event)
			try
				frame2stl;
			catch ME
				errormessage('',ME);
			end
			
		end

		% Menu selected function: Osmosis_Source_File_PBF_Menu
		function Osmosis_Source_File_PBF_MenuSelected(app, event)
			try
				app.Osmosis_Source_File_PBF_Menu.Checked		= 'on';
				app.Osmosis_Source_File_OSM_Menu.Checked		= 'off';
			catch ME
				errormessage('',ME);
			end
			
		end

		% Menu selected function: Osmosis_Source_File_OSM_Menu
		function Osmosis_Source_File_OSM_MenuSelected(app, event)
			try
				app.Osmosis_Source_File_PBF_Menu.Checked		= 'off';
				app.Osmosis_Source_File_OSM_Menu.Checked		= 'on';
			catch ME
				errormessage('',ME);
			end
			
		end

		% Menu selected function: Osmosis_Dest_File_PBF_Menu
		function Osmosis_Dest_File_PBF_MenuSelected(app, event)
			try
				app.Osmosis_Dest_File_PBF_Menu.Checked		= 'on';
				app.Osmosis_Dest_File_OSM_Menu.Checked		= 'off';
			catch ME
				errormessage('',ME);
			end
			
		end

		% Menu selected function: Osmosis_Dest_File_OSM_Menu
		function Osmosis_Dest_File_OSM_MenuSelected(app, event)
			try
				app.Osmosis_Dest_File_PBF_Menu.Checked		= 'off';
				app.Osmosis_Dest_File_OSM_Menu.Checked		= 'on';
			catch ME
				errormessage('',ME);
			end
			
		end

		% Menu selected function: Osmosis_Extract_Bounding_Box_Menu
		function Osmosis_Extract_Bounding_Box_MenuSelected(app, event)
			try
				app.Osmosis_Extract_Bounding_Box_Menu.Checked		= 'on';
				app.Osmosis_Do_Not_Reduce_The_Size_Menu.Checked	= 'off';
			catch ME
				errormessage('',ME);
			end
			
		end

		% Menu selected function: Osmosis_Do_Not_Reduce_The_Size_Menu
		function Osmosis_Do_Not_Reduce_The_Size_MenuSelected(app, event)
			try
				app.Osmosis_Extract_Bounding_Box_Menu.Checked		= 'off';
				app.Osmosis_Do_Not_Reduce_The_Size_Menu.Checked	= 'on';
			catch ME
				errormessage('',ME);
			end
			
		end

		% Menu selected function: Osmosis_Extract_Include_Tags_Menu
		function Osmosis_Extract_Include_Tags_MenuSelected(app, event)
			try
				app.Osmosis_Extract_Include_Tags_Menu.Checked		= 'on';
				app.Osmosis_Do_Not_Reduce_The_Data_Menu.Checked	= 'off';
			catch ME
				errormessage('',ME);
			end
			
		end

		% Menu selected function: Osmosis_Do_Not_Reduce_The_Data_Menu
		function Osmosis_Do_Not_Reduce_The_Data_MenuSelected(app, event)
			try
				app.Osmosis_Extract_Include_Tags_Menu.Checked		= 'off';
				app.Osmosis_Do_Not_Reduce_The_Data_Menu.Checked	= 'on';
			catch ME
				errormessage('',ME);
			end
			
		end

		% Menu selected function: Set_Osmosis_InstallPath_Menu
		function Set_Osmosis_InstallPath_MenuSelected(app, event)
			try
				call_osmosis(1);
			catch ME
				errormessage('',ME);
			end
			
		end

		% Menu selected function: Call_Osmosis_Menu
		function Call_Osmosis_MenuSelected(app, event)
			try
				call_osmosis(0);
			catch ME
				errormessage('',ME);
			end
			
		end

		% Menu selected function: CharStylePreview_Menu
		function CharStylePreview_MenuSelected(app, event)
			try
				display_charstyle_preview;
			catch ME
				errormessage('',ME);
			end
			
		end

		% Menu selected function: CreatemapSettingsCreateTextsMenu
		function CreatemapSettingsCreateTextsMenuSelected(app, event)
			try
				if app.CreatemapSettingsCreateTextsMenu.Checked
					app.CreatemapSettingsCreateTextsMenu.Checked	= 'off';
				else
					app.CreatemapSettingsCreateTextsMenu.Checked	= 'on';
				end
			catch ME
				errormessage('',ME);
			end
			
		end

		% Menu selected function: CreatemapSettingsCreateSymbolsMenu
		function CreatemapSettingsCreateSymbolsMenuSelected(app, event)
			try
				if app.CreatemapSettingsCreateSymbolsMenu.Checked
					app.CreatemapSettingsCreateSymbolsMenu.Checked	= 'off';
				else
					app.CreatemapSettingsCreateSymbolsMenu.Checked	= 'on';
				end
			catch ME
				errormessage('',ME);
			end
			
		end

		% Button pushed function: Mod_GrayOut_Button
		function Mod_GrayOut_ButtonPushed(app, event)
			try
				plot_modify('gray_out',-1);
			catch ME
				errormessage('',ME);
			end
			
		end

		% Button pushed function: Mod_Arrange_Up_Button
		function Mod_Arrange_Up_ButtonPushed(app, event)
			try
				plot_modify('arrange',-1,'up');
			catch ME
				errormessage('',ME);
			end
			
		end

		% Button pushed function: Mod_Arrange_Down_Button
		function Mod_Arrange_Down_ButtonPushed(app, event)
			try
				plot_modify('arrange',-1,'down');
			catch ME
				errormessage('',ME);
			end
			
		end

		% Button pushed function: Mod_Arrange_First_Button
		function Mod_Arrange_First_ButtonPushed(app, event)
			try
				plot_modify('arrange',-1,'first');
			catch ME
				errormessage('',ME);
			end
			
		end

		% Button pushed function: Mod_Arrange_Last_Button
		function Mod_Arrange_Last_ButtonPushed(app, event)
			try
				plot_modify('arrange',-1,'last');
			catch ME
				errormessage('',ME);
			end
			
		end

		% Button pushed function: Mod_Arrange_Set_Button
		function Mod_Arrange_Set_ButtonPushed(app, event)
			try
				plot_modify('arrange',-1,'set',app.Mod_Arrange_Set_PlotNoEditField.Value);
			catch ME
				errormessage('',ME);
			end
			
		end

		% Selection changed function: Mod_LiReVe_ButtonGroup
		function Mod_LiReVe_ButtonGroupSelChanged(app, event)
			% selectedButton = app.Mod_LiReVe_ButtonGroup.SelectedObject;
		end

		% Button pushed function: Mod_UnitedColors_PrevCL_to_CL_Button
		function Mod_UnitedColors_PrevCL_to_CL_ButtonPushed(app, event)
			try
				plot_modify('prevcutline2cutline',-1);
			catch ME
				errormessage('',ME);
			end
			
		end

		% Button pushed function: 
		% Mod_UnitedColors_CreateCLAutomatically_Button
		function Mod_UnitedColors_CreateCLAutomatically_ButtonPushed(app, event)
			global PRINTDATA GV_H
			try
				colno			= get_colno_cuttingline;				% Color number of cutting lines (=0: normal preview):
				if colno>0
					% Display state:
					t_start_statebusy	= clock;
					display_on_gui('state','Create cutting lines ...','busy','add');
					poly_cut	= polyshape();
					msg		= sprintf('Cut into pieces: color %g',colno);
					if ~isempty(PRINTDATA.obj_union_equalcolors)
						if numboundaries(PRINTDATA.obj_union_equalcolors(colno,1))>0
							[~,...
								poly_cut]	= cut_into_pieces(...
								PRINTDATA.obj_union_equalcolors(colno,1),...
								poly_cut,...
								GV_H.poly_tiles,...
								colno,...
								msg);
							plot_modify('new_cutline',0,poly_cut,colno);		% Create a new cutting line
						end
					end
					% Display state:
					t_end_statebusy					= clock;
					dt_statebusy						= etime(t_end_statebusy,t_start_statebusy);
					dt_statebusy_str					= dt_string(dt_statebusy);
					display_on_gui('state',...
						sprintf('Create cutting lines ... done (%s).',dt_statebusy_str),...
						'notbusy','replace');
				end
			catch ME
				errormessage('',ME);
			end
			
		end

		% Button pushed function: Mod_UnitedColors_Cut_Button
		function Mod_UnitedColors_Cut_ButtonPushed(app, event)
			global PRINTDATA MAP_OBJECTS
			try
				colno			= get_colno_cuttingline;				% Color number of cutting lines (=0: normal preview):
				if colno>0
					% Display state:
					t_start_statebusy	= clock;
					display_on_gui_str	= sprintf('Cut united equal colors No %g ...',colno);
					display_on_gui('state',display_on_gui_str,'busy','add');
					% Cutting:
					imapobj_v		= find([MAP_OBJECTS.cncl]==colno);
					for k=1:length(imapobj_v)
						for i=1:size(MAP_OBJECTS(imapobj_v(k),1).h,1)
							if strcmp(MAP_OBJECTS(imapobj_v(k),1).h(i,1).Type,'polygon')
								PRINTDATA.obj_union_equalcolors(colno,1)	= subtract(...
									PRINTDATA.obj_union_equalcolors(colno,1),...
									MAP_OBJECTS(imapobj_v(k),1).h(i,1).Shape,...
									'KeepCollinearPoints',false);
							end
						end
					end
					% Update united equal colors plot object:
					create_unitedcolors(...
						0,...				% userconfirmation
						0,...				% calc_uec
						0,...				% reset_uec
						colno);			% createplot_colno
					% Update MAP_OBJECTS_TABLE:
					display_map_objects(imapobj_v);
					% Display state:
					t_end_statebusy					= clock;
					dt_statebusy						= etime(t_end_statebusy,t_start_statebusy);
					dt_statebusy_str					= dt_string(dt_statebusy);
					display_on_gui('state',...
						sprintf('%s done (%s).',display_on_gui_str,dt_statebusy_str),...
						'notbusy','replace');
				end
			catch ME
				errormessage('',ME);
			end
			
		end

		% Button pushed function: Mod_UnitedColors_Reset_Button
		function Mod_UnitedColors_Reset_ButtonPushed(app, event)
			try
				% Reset united equal colors plot objects:
				colno			= get_colno_cuttingline;
				create_unitedcolors(...
					0,...				% userconfirmation
					0,...				% calc_uec
					1,...				% reset_uec
					colno);			% createplot_colno
			catch ME
				errormessage('',ME);
			end
			
		end

		% Value changed function: Mod_UnitedColors_SelectColNo_DropDown
		function Mod_UnitedColors_SelectColNo_DropDownValueChanged(app, event)
			try
				% value = app.Mod_UnitedColors_SelectColNo_DropDown.Value;
				set_previewtype_dropdown(0);	% Called if the value of APP.Mod_UnitedColors_SelectColNo_DropDown has changed.
			catch ME
				errormessage('',ME);
			end
			
		end

		% Button pushed function: Mod_CreateUnitedColors_Button
		function Mod_CreateUnitedColors_ButtonPushed(app, event)
			try
				% Create united equal colors plot objects:
				create_unitedcolors(...
					1,...				% userconfirmation
					1,...				% calc_uec
					0,...				% reset_uec
					0);				% createplot_colno
			catch ME
				errormessage('',ME);
			end
			
		end

		% Button pushed function: Mod_LV_Cut_Button
		function Mod_LV_Cut_ButtonPushed(app, event)
			try
				plot_modify('mod_vertex',-1,'cut');
			catch ME
				errormessage('',ME);
			end
			
		end

		% Value changed function: LatLonXYTab_ScaleEditField
		function LatLonXYTab_ScaleEditFieldValueChanged(app, event)
			try
				calculator_latlonxy_recalculate;
			catch ME
				errormessage('',ME);
			end
			
		end

		% Value changed function: LatLonXYTab_OriginLongitudeEditField
		function LatLonXYTab_OriginLongitudeEditFieldValueChanged(app, event)
			try
				calculator_latlonxy_recalculate;
			catch ME
				errormessage('',ME);
			end
			
		end

		% Value changed function: LatLonXYTab_OriginLatitudeEditField
		function LatLonXYTab_OriginLatitudeEditFieldValueChanged(app, event)
			try
				calculator_latlonxy_recalculate;
			catch ME
				errormessage('',ME);
			end
			
		end

		% Value changed function: LatLonXYTab_OSM_lonminEditField
		function LatLonXYTab_OSM_lonminEditFieldValueChanged(app, event)
			try
				calculator_latlon_xy('OSM');
			catch ME
				errormessage('',ME);
			end
			
		end

		% Value changed function: LatLonXYTab_OSM_lonmaxEditField
		function LatLonXYTab_OSM_lonmaxEditFieldValueChanged(app, event)
			try
				calculator_latlon_xy('OSM');
			catch ME
				errormessage('',ME);
			end
			
		end

		% Value changed function: LatLonXYTab_OSM_latminEditField
		function LatLonXYTab_OSM_latminEditFieldValueChanged(app, event)
			try
				calculator_latlon_xy('OSM');
			catch ME
				errormessage('',ME);
			end
			
		end

		% Value changed function: LatLonXYTab_OSM_latmaxEditField
		function LatLonXYTab_OSM_latmaxEditFieldValueChanged(app, event)
			try
				calculator_latlon_xy('OSM');
			catch ME
				errormessage('',ME);
			end
			
		end

		% Value changed function: LatLonXYTab_Osmosis_lonminEditField
		function LatLonXYTab_Osmosis_lonminEditFieldValueChanged(app, event)
			try
				calculator_latlon_xy('Osmosis');
			catch ME
				errormessage('',ME);
			end
			
		end

		% Value changed function: LatLonXYTab_Osmosis_lonmaxEditField
		function LatLonXYTab_Osmosis_lonmaxEditFieldValueChanged(app, event)
			try
				calculator_latlon_xy('Osmosis');
			catch ME
				errormessage('',ME);
			end
			
		end

		% Value changed function: LatLonXYTab_Osmosis_latminEditField
		function LatLonXYTab_Osmosis_latminEditFieldValueChanged(app, event)
			try
				calculator_latlon_xy('Osmosis');
			catch ME
				errormessage('',ME);
			end
			
		end

		% Value changed function: LatLonXYTab_Osmosis_latmaxEditField
		function LatLonXYTab_Osmosis_latmaxEditFieldValueChanged(app, event)
			try
				calculator_latlon_xy('Osmosis');
			catch ME
				errormessage('',ME);
			end
			
		end

		% Value changed function: LatLonXYTab_Map_lonminEditField
		function LatLonXYTab_Map_lonminEditFieldValueChanged(app, event)
			try
				calculator_latlon_xy('Map');
			catch ME
				errormessage('',ME);
			end
			
		end

		% Value changed function: LatLonXYTab_Map_lonmaxEditField
		function LatLonXYTab_Map_lonmaxEditFieldValueChanged(app, event)
			try
				calculator_latlon_xy('Map');
			catch ME
				errormessage('',ME);
			end
			
		end

		% Value changed function: LatLonXYTab_Map_latminEditField
		function LatLonXYTab_Map_latminEditFieldValueChanged(app, event)
			try
				calculator_latlon_xy('Map');
			catch ME
				errormessage('',ME);
			end
			
		end

		% Value changed function: LatLonXYTab_Map_latmaxEditField
		function LatLonXYTab_Map_latmaxEditFieldValueChanged(app, event)
			try
				calculator_latlon_xy('Map');
			catch ME
				errormessage('',ME);
			end
			
		end

		% Value changed function: LatLonXYTab_OSM_xminmmEditField
		function LatLonXYTab_OSM_xminmmEditFieldValueChanged(app, event)
			try
				calculator_xy_latlon('OSM');
			catch ME
				errormessage('',ME);
			end
			
		end

		% Value changed function: LatLonXYTab_OSM_xmaxmmEditField
		function LatLonXYTab_OSM_xmaxmmEditFieldValueChanged(app, event)
			try
				calculator_xy_latlon('OSM');
			catch ME
				errormessage('',ME);
			end
			
		end

		% Value changed function: LatLonXYTab_OSM_yminmmEditField
		function LatLonXYTab_OSM_yminmmEditFieldValueChanged(app, event)
			try
				calculator_xy_latlon('OSM');
			catch ME
				errormessage('',ME);
			end
			
		end

		% Value changed function: LatLonXYTab_OSM_ymaxmmEditField
		function LatLonXYTab_OSM_ymaxmmEditFieldValueChanged(app, event)
			try
				calculator_xy_latlon('OSM');
			catch ME
				errormessage('',ME);
			end
			
		end

		% Value changed function: LatLonXYTab_Osmosis_xminmmEditField
		function LatLonXYTab_Osmosis_xminmmEditFieldValueChanged(app, event)
			try
				calculator_xy_latlon('Osmosis');
			catch ME
				errormessage('',ME);
			end
			
		end

		% Value changed function: LatLonXYTab_Osmosis_xmaxmmEditField
		function LatLonXYTab_Osmosis_xmaxmmEditFieldValueChanged(app, event)
			try
				calculator_xy_latlon('Osmosis');
			catch ME
				errormessage('',ME);
			end
			
		end

		% Value changed function: LatLonXYTab_Osmosis_yminmmEditField
		function LatLonXYTab_Osmosis_yminmmEditFieldValueChanged(app, event)
			try
				calculator_xy_latlon('Osmosis');
			catch ME
				errormessage('',ME);
			end
			
		end

		% Value changed function: LatLonXYTab_Osmosis_ymaxmmEditField
		function LatLonXYTab_Osmosis_ymaxmmEditFieldValueChanged(app, event)
			try
				calculator_xy_latlon('Osmosis');
			catch ME
				errormessage('',ME);
			end
			
		end

		% Value changed function: LatLonXYTab_Map_xminmmEditField
		function LatLonXYTab_Map_xminmmEditFieldValueChanged(app, event)
			try
				calculator_xy_latlon('Map');
			catch ME
				errormessage('',ME);
			end
			
		end

		% Value changed function: LatLonXYTab_Map_xmaxmmEditField
		function LatLonXYTab_Map_xmaxmmEditFieldValueChanged(app, event)
			try
				calculator_xy_latlon('Map');
			catch ME
				errormessage('',ME);
			end
			
		end

		% Value changed function: LatLonXYTab_Map_yminmmEditField
		function LatLonXYTab_Map_yminmmEditFieldValueChanged(app, event)
			try
				calculator_xy_latlon('Map');
			catch ME
				errormessage('',ME);
			end
			
		end

		% Value changed function: LatLonXYTab_Map_ymaxmmEditField
		function LatLonXYTab_Map_ymaxmmEditFieldValueChanged(app, event)
			try
				calculator_xy_latlon('Map');
			catch ME
				errormessage('',ME);
			end
			
		end

		% Button pushed function: LatLonXYTab_OSM_ResetButton
		function LatLonXYTab_OSM_ResetButtonPushed(app, event)
			try
				calculator_latlonxy_reset('OSM');
			catch ME
				errormessage('',ME);
			end
			
		end

		% Button pushed function: LatLonXYTab_Osmosis_ResetButton
		function LatLonXYTab_Osmosis_ResetButtonPushed(app, event)
			try
				calculator_latlonxy_reset('Osmosis');
			catch ME
				errormessage('',ME);
			end
			
		end

		% Button pushed function: LatLonXYTab_Map_ResetButton
		function LatLonXYTab_Map_ResetButtonPushed(app, event)
			try
				calculator_latlonxy_reset('Map');
			catch ME
				errormessage('',ME);
			end
			
		end

		% Button pushed function: LatLonXYTab_OSM_PlotButton
		function LatLonXYTab_OSM_PlotButtonPushed(app, event)
			try
				calculator_latlonxy_plot('OSM');
			catch ME
				errormessage('',ME);
			end
			
		end

		% Button pushed function: LatLonXYTab_Osmosis_PlotButton
		function LatLonXYTab_Osmosis_PlotButtonPushed(app, event)
			try
				calculator_latlonxy_plot('Osmosis');
			catch ME
				errormessage('',ME);
			end
			
		end

		% Button pushed function: LatLonXYTab_Map_PlotButton
		function LatLonXYTab_Map_PlotButtonPushed(app, event)
			try
				calculator_latlonxy_plot('Map');
			catch ME
				errormessage('',ME);
			end
			
		end

		% Button pushed function: LatLonXYTab_Scale_ResetButton
		function LatLonXYTab_Scale_ResetButtonPushed(app, event)
			try
				calculator_latlonxy_reset('Scale');
			catch ME
				errormessage('',ME);
			end
			
		end

		% Button pushed function: LatLonXYTab_Origin_ResetButton
		function LatLonXYTab_Origin_ResetButtonPushed(app, event)
			try
				calculator_latlonxy_reset('Origin');
			catch ME
				errormessage('',ME);
			end
			
		end

		% Menu selected function: CreatemapSettingsRecreateLegendMenu
		function CreatemapSettingsRecreateLegendMenuSelected(app, event)
			try
				app.CreatemapSettingsRecreateWholeMapMenu.Checked			= 'off';
				app.CreatemapSettingsRecreateCertainObjectsMenu.Checked	= 'off';
				app.CreatemapSettingsRecreateLegendMenu.Checked				= 'on';
			catch ME
				errormessage('',ME);
			end
			
		end

		% Button pushed function: 
		% Mod_CopyMapObjectsTableToClipboard_Button_2
		function Mod_CopyMapObjectsTableToClipboard_Button_2Pushed(app, event)
			try
				copy2clipboard_mapobjectstable;
			catch ME
				errormessage('',ME);
			end
			
		end

		% Menu selected function: View_ShowContourLines_Minor_Menu
		function View_ShowContourLines_Minor_MenuSelected(app, event)
			try
				if app.View_ShowContourLines_Minor_Menu.Checked
					app.View_ShowContourLines_Minor_Menu.Checked	= 'off';
				else
					app.View_ShowContourLines_Minor_Menu.Checked	= 'on';
				end
			catch ME
				errormessage('',ME);
			end
			
		end

		% Menu selected function: View_ShowContourLines_MajorLabels_Menu
		function View_ShowContourLines_MajorLabels_MenuSelected(app, event)
			try
				if app.View_ShowContourLines_MajorLabels_Menu.Checked
					app.View_ShowContourLines_MajorLabels_Menu.Checked	= 'off';
				else
					app.View_ShowContourLines_MajorLabels_Menu.Checked	= 'on';
				end
			catch ME
				errormessage('',ME);
			end
			
		end

		% Button pushed function: Mod_ColorReset_Button
		function Mod_ColorReset_ButtonPushed(app, event)
			try
				plot_modify('change_color',-1,-1,-1);
			catch ME
				errormessage('',ME);
			end
			
		end

		% Button pushed function: Mod_Color_0_Button
		function Mod_Color_0_ButtonPushed(app, event)
			try
				plot_modify('change_color',-1,0,0);
			catch ME
				errormessage('',ME);
			end
			
		end

		% Button pushed function: Mod_SelectWithFilter_Button
		function Mod_SelectWithFilter_ButtonPushed(app, event)
			global PP MAP_OBJECTS
			try
				if isempty(PP)||isempty(MAP_OBJECTS)
					errormessage(sprintf([...
						'Before map objects can be selected,\n',...
						'a project must be loaded and\n',...
						'the map must have been created.']));
				end
				mapobjects_select_by_filter;
			catch ME
				errormessage('',ME);
			end
			
		end

		% Button pushed function: Mod_Polygons_Subtract_dside_Button
		function Mod_Polygons_Subtract_dside_ButtonPushed(app, event)
			try
				plot_modify('poly12',0,'subtract_dside');
			catch ME
				errormessage('',ME);
			end
			
		end

		% Button pushed function: MapView_U1_Button
		function MapView_U1_ButtonPushed(app, event)
			global GV_H GV
			try
				if ~isfield(GV_H,'ax_2dmap')
					return
				end
				if ~ishandle(GV_H.ax_2dmap)
					return
				end
				xlim_new		= GV_H.ax_2dmap.XLim;
				ylim_new		= GV_H.ax_2dmap.YLim+diff(GV_H.ax_2dmap.YLim)*GV.pp_stepwidth_move_mapview_small/100;
				ax_2dmap_zoom('set',xlim_new(1),ylim_new(1),xlim_new(2),ylim_new(2));
			catch ME
				errormessage('',ME);
			end
			
		end

		% Button pushed function: MapView_D1_Button
		function MapView_D1_ButtonPushed(app, event)
			global GV_H GV
			try
				if ~isfield(GV_H,'ax_2dmap')
					return
				end
				if ~ishandle(GV_H.ax_2dmap)
					return
				end
				xlim_new		= GV_H.ax_2dmap.XLim;
				ylim_new		= GV_H.ax_2dmap.YLim-diff(GV_H.ax_2dmap.YLim)*GV.pp_stepwidth_move_mapview_small/100;
				ax_2dmap_zoom('set',xlim_new(1),ylim_new(1),xlim_new(2),ylim_new(2));
			catch ME
				errormessage('',ME);
			end
			
		end

		% Button pushed function: MapView_L1_Button
		function MapView_L1_ButtonPushed(app, event)
			global GV_H GV
			try
				if ~isfield(GV_H,'ax_2dmap')
					return
				end
				if ~ishandle(GV_H.ax_2dmap)
					return
				end
				xlim_new		= GV_H.ax_2dmap.XLim-diff(GV_H.ax_2dmap.XLim)*GV.pp_stepwidth_move_mapview_small/100;
				ylim_new		= GV_H.ax_2dmap.YLim;
				ax_2dmap_zoom('set',xlim_new(1),ylim_new(1),xlim_new(2),ylim_new(2));
			catch ME
				errormessage('',ME);
			end
			
		end

		% Button pushed function: MapView_R1_Button
		function MapView_R1_ButtonPushed(app, event)
			global GV_H GV
			try
				if ~isfield(GV_H,'ax_2dmap')
					return
				end
				if ~ishandle(GV_H.ax_2dmap)
					return
				end
				xlim_new		= GV_H.ax_2dmap.XLim+diff(GV_H.ax_2dmap.XLim)*GV.pp_stepwidth_move_mapview_small/100;
				ylim_new		= GV_H.ax_2dmap.YLim;
				ax_2dmap_zoom('set',xlim_new(1),ylim_new(1),xlim_new(2),ylim_new(2));
			catch ME
				errormessage('',ME);
			end
			
		end

		% Button pushed function: MapView_U2_Button
		function MapView_U2_ButtonPushed(app, event)
			global GV_H GV
			try
				if ~isfield(GV_H,'ax_2dmap')
					return
				end
				if ~ishandle(GV_H.ax_2dmap)
					return
				end
				xlim_new		= GV_H.ax_2dmap.XLim;
				ylim_new		= GV_H.ax_2dmap.YLim+diff(GV_H.ax_2dmap.YLim)*GV.pp_stepwidth_move_mapview_medium/100;
				ax_2dmap_zoom('set',xlim_new(1),ylim_new(1),xlim_new(2),ylim_new(2));
			catch ME
				errormessage('',ME);
			end
			
		end

		% Button pushed function: MapView_D2_Button
		function MapView_D2_ButtonPushed(app, event)
			global GV_H GV
			try
				if ~isfield(GV_H,'ax_2dmap')
					return
				end
				if ~ishandle(GV_H.ax_2dmap)
					return
				end
				xlim_new		= GV_H.ax_2dmap.XLim;
				ylim_new		= GV_H.ax_2dmap.YLim-diff(GV_H.ax_2dmap.YLim)*GV.pp_stepwidth_move_mapview_medium/100;
				ax_2dmap_zoom('set',xlim_new(1),ylim_new(1),xlim_new(2),ylim_new(2));
			catch ME
				errormessage('',ME);
			end
			
		end

		% Button pushed function: MapView_L2_Button
		function MapView_L2_ButtonPushed(app, event)
			global GV_H GV
			try
				if ~isfield(GV_H,'ax_2dmap')
					return
				end
				if ~ishandle(GV_H.ax_2dmap)
					return
				end
				xlim_new		= GV_H.ax_2dmap.XLim-diff(GV_H.ax_2dmap.XLim)*GV.pp_stepwidth_move_mapview_medium/100;
				ylim_new		= GV_H.ax_2dmap.YLim;
				ax_2dmap_zoom('set',xlim_new(1),ylim_new(1),xlim_new(2),ylim_new(2));
			catch ME
				errormessage('',ME);
			end
			
		end

		% Button pushed function: MapView_R2_Button
		function MapView_R2_ButtonPushed(app, event)
			global GV_H GV
			try
				if ~isfield(GV_H,'ax_2dmap')
					return
				end
				if ~ishandle(GV_H.ax_2dmap)
					return
				end
				xlim_new		= GV_H.ax_2dmap.XLim+diff(GV_H.ax_2dmap.XLim)*GV.pp_stepwidth_move_mapview_medium/100;
				ylim_new		= GV_H.ax_2dmap.YLim;
				ax_2dmap_zoom('set',xlim_new(1),ylim_new(1),xlim_new(2),ylim_new(2));
			catch ME
				errormessage('',ME);
			end
			
		end

		% Button pushed function: MapView_U3_Button
		function MapView_U3_ButtonPushed(app, event)
			global GV_H GV
			try
				if ~isfield(GV_H,'ax_2dmap')
					return
				end
				if ~ishandle(GV_H.ax_2dmap)
					return
				end
				xlim_new		= GV_H.ax_2dmap.XLim;
				ylim_new		= GV_H.ax_2dmap.YLim+diff(GV_H.ax_2dmap.YLim)*GV.pp_stepwidth_move_mapview_large/100;
				ax_2dmap_zoom('set',xlim_new(1),ylim_new(1),xlim_new(2),ylim_new(2));
			catch ME
				errormessage('',ME);
			end
			
		end

		% Button pushed function: MapView_D3_Button
		function MapView_D3_ButtonPushed(app, event)
			global GV_H GV
			try
				if ~isfield(GV_H,'ax_2dmap')
					return
				end
				if ~ishandle(GV_H.ax_2dmap)
					return
				end
				xlim_new		= GV_H.ax_2dmap.XLim;
				ylim_new		= GV_H.ax_2dmap.YLim-diff(GV_H.ax_2dmap.YLim)*GV.pp_stepwidth_move_mapview_large/100;
				ax_2dmap_zoom('set',xlim_new(1),ylim_new(1),xlim_new(2),ylim_new(2));
			catch ME
				errormessage('',ME);
			end
			
		end

		% Button pushed function: MapView_L3_Button
		function MapView_L3_ButtonPushed(app, event)
			global GV_H GV
			try
				if ~isfield(GV_H,'ax_2dmap')
					return
				end
				if ~ishandle(GV_H.ax_2dmap)
					return
				end
				xlim_new		= GV_H.ax_2dmap.XLim-diff(GV_H.ax_2dmap.XLim)*GV.pp_stepwidth_move_mapview_large/100;
				ylim_new		= GV_H.ax_2dmap.YLim;
				ax_2dmap_zoom('set',xlim_new(1),ylim_new(1),xlim_new(2),ylim_new(2));
			catch ME
				errormessage('',ME);
			end
			
		end

		% Button pushed function: MapView_R3_Button
		function MapView_R3_ButtonPushed(app, event)
			global GV_H GV
			try
				if ~isfield(GV_H,'ax_2dmap')
					return
				end
				if ~ishandle(GV_H.ax_2dmap)
					return
				end
				xlim_new		= GV_H.ax_2dmap.XLim+diff(GV_H.ax_2dmap.XLim)*GV.pp_stepwidth_move_mapview_large/100;
				ylim_new		= GV_H.ax_2dmap.YLim;
				ax_2dmap_zoom('set',xlim_new(1),ylim_new(1),xlim_new(2),ylim_new(2));
			catch ME
				errormessage('',ME);
			end
			
		end

		% Value changed function: MapView_In_Button
		function MapView_In_ButtonValueChanged(app, event)
			try
				ax_2dmap_zoominbutton_bgdcolor;
			catch ME
				errormessage('',ME);
			end
			
		end

		% Button pushed function: MapView_Out_Button
		function MapView_Out_ButtonPushed(app, event)
			global GV_H
			try
				if ~isfield(GV_H,'ax_2dmap')
					return
				end
				if ~ishandle(GV_H.ax_2dmap)
					return
				end
				% Enlargement of the displayed limits by a factor of 3:
				xlim_new		= GV_H.ax_2dmap.XLim+[-1 1]*diff(GV_H.ax_2dmap.XLim)*1;
				ylim_new		= GV_H.ax_2dmap.YLim+[-1 1]*diff(GV_H.ax_2dmap.YLim)*1;
				ax_2dmap_zoom('set',xlim_new(1),ylim_new(1),xlim_new(2),ylim_new(2));
			catch ME
				errormessage('',ME);
			end
			
		end

		% Button pushed function: MapView_Fit_Button
		function MapView_Fit_ButtonPushed(app, event)
			try
				SizeChangedFcn_fig_2dmap([],[],1,1);
			catch ME
				errormessage('',ME);
			end
			
		end

		% Button pushed function: MapView_Sel_Button
		function MapView_Sel_ButtonPushed(app, event)
			try
				ax_2dmap_zoomselection;
			catch ME
				errormessage('',ME);
			end
			
		end

		% Button pushed function: MapView_UnDo_Button
		function MapView_UnDo_ButtonPushed(app, event)
			try
				ax_2dmap_zoom('undo');
			catch ME
				errormessage('',ME);
			end
			
		end

		% Button pushed function: MapView_ReDo_Button
		function MapView_ReDo_ButtonPushed(app, event)
			try
				ax_2dmap_zoom('redo');
			catch ME
				errormessage('',ME);
			end
			
		end

		% Button pushed function: Mod_Polygons_ResetPrintout_Button
		function Mod_Polygons_ResetPrintout_ButtonPushed(app, event)
			try
				plot_poly_map_printout;
			catch ME
				errormessage('',ME);
			end
			
		end

		% Menu selected function: CreateSymbolSampleMenu
		function CreateSymbolSampleMenuSelected(app, event)
			try
				testsamples(3);			% testsample_no=3: Symbol samples
			catch ME
				errormessage('',ME);
			end
			
		end

		% Menu selected function: FileExitMenu
		function FileExitMenuSelected(app, event)
			global GV GV_H TIMERPROCESS_RESTART
			try
				
				% Confirmation query:
				question	= sprintf([...
					'Exiting the app closes all windows\n',...
					'and all unsaved changes are lost.\n',...
					'\n',...
					'Do you really want to exit the app?']);
				answer	= questdlg_local(question,'Exit the app?','Exit','Cancel','Cancel');
				if isempty(answer)||strcmp(answer,'Cancel')
					display_on_gui('state','Exit ... canceled.','notbusy','replace');
					set(GV_H.patch_waitbar,'XData',[0 0 0 0]);
					set(GV_H.text_waitbar,'String','');
					drawnow;
					return
				end
				
				% Disable logging to the diary:
				diary off;
				
				% Stop the timer:
				TIMERPROCESS_RESTART				= 0;
				out		= timerfind('Name','timer_errorhandling_maplab3d');
				if ~isempty(out)
					stop(out);
					delete(out);
				end
				
				% Delete all figures related to MapLab3D:
				delete(findobj('Type','figure','-and','Tag','maplab3d_figure'));
				
				% Delete test sample figures, that have a different tag than 'maplab3d_figure':
				if isfield(GV,'testsample_no_max')
					if ~isempty(GV.testsample_no_max)
						for testsample_no=1:GV.testsample_no_max
							map_tag	= sprintf('map2stl_mapfigure_testsample_%1.0f',testsample_no);
							delete(findobj('Type','figure','-and','Tag',map_tag));
						end
					end
				end
				
				% Close app:
				delete(app)
				
				% Or: Close all figures:
				% close all force
				
			catch ME
				errormessage('',ME);
			end
			
		end

		% Close request function: MapLab3D
		function MapLab3DCloseRequest(app, event)
			try
				FileExitMenuSelected(app, event);
			catch ME
				errormessage('',ME);
			end
			
		end

		% Button pushed function: FilterOSMDataDeleteAllEntriesButton
		function FilterOSMDataDeleteAllEntriesButtonPushed(app, event)
			try
				% Initialize the include and exclude tags table:
				set_inclexcltags_table('reset');
			catch ME
				errormessage('',ME);
			end
			
		end

		% Value changed function: FilterOSMDataObjNoDropDown
		function FilterOSMDataObjNoDropDownValueChanged(app, event)
			try
				% Set the include and exclude keys and values depending on the selected object number:
				set_inclexcltags_table('set');
			catch ME
				errormessage('',ME);
			end
			
		end

		% Cell edit callback: include_keys
		function include_keysCellEdit(app, event)
			try
				% indices = event.Indices;
				% newData = event.NewData;
				[obj_tag_incl,~]	= get_obj_tag_inclexcl;
				filter_osmdata_filterstring(obj_tag_incl,[]);
			catch ME
				errormessage('',ME);
			end
			
		end

		% Cell edit callback: include_values
		function include_valuesCellEdit(app, event)
			try
				% indices = event.Indices;
				% newData = event.NewData;
				[obj_tag_incl,~]	= get_obj_tag_inclexcl;
				filter_osmdata_filterstring(obj_tag_incl,[]);
			catch ME
				errormessage('',ME);
			end
			
		end

		% Cell edit callback: exclude_keys
		function exclude_keysCellEdit(app, event)
			try
				% indices = event.Indices;
				% newData = event.NewData;
				% [obj_tag_incl,obj_tag_excl]	= get_obj_tag_inclexcl;
				% filter_osmdata_filterstring(obj_tag_incl,[]);
			catch ME
				errormessage('',ME);
			end
			
		end

		% Cell edit callback: exclude_values
		function exclude_valuesCellEdit(app, event)
			try
				% indices = event.Indices;
				% newData = event.NewData;
				% [obj_tag_incl,obj_tag_excl]	= get_obj_tag_inclexcl;
				% filter_osmdata_filterstring(obj_tag_incl,[]);
			catch ME
				errormessage('',ME);
			end
			
		end

		% Menu selected function: Show_Diary_Path_Menu
		function Show_Diary_Path_MenuSelected(app, event)
			global GV GV_H
			try
				set(GV_H.text_waitbar,'String',sprintf('diary file: %s',[GV.pathname_diary 'diary.txt']));
			catch ME
				errormessage('',ME);
			end
			
		end

		% Value changed function: 
		% LatLonXYTab_Dist_OSM_MapPrintout_EditField
		function LatLonXYTab_Dist_OSM_MapPrintout_EditFieldValueChanged(app, event)
			try
				calculator_latlonxy_recalculate;
			catch ME
				errormessage('',ME);
			end
			
		end

		% Value changed function: LatLonXYTab_FrameWidth_EditField
		function LatLonXYTab_FrameWidth_EditFieldValueChanged(app, event)
			try
				calculator_latlonxy_recalculate('PrintoutSize');
			catch ME
				errormessage('',ME);
			end
			
		end

		% Value changed function: LatLonXYTab_TileWidth_EditField
		function LatLonXYTab_TileWidth_EditFieldValueChanged(app, event)
			try
				calculator_latlonxy_recalculate('PrintoutSize');
			catch ME
				errormessage('',ME);
			end
			
		end

		% Value changed function: LatLonXYTab_TileHeight_EditField
		function LatLonXYTab_TileHeight_EditFieldValueChanged(app, event)
			try
				calculator_latlonxy_recalculate('PrintoutSize');
			catch ME
				errormessage('',ME);
			end
			
		end

		% Button pushed function: LatLonXYTab_OuterFrameWidth_ResetButton
		function LatLonXYTab_OuterFrameWidth_ResetButtonPushed(app, event)
			try
				calculator_latlonxy_reset('FrameWidth');
			catch ME
				errormessage('',ME);
			end
			
		end

		% Button pushed function: LatLonXYTab_TileSize_ResetButton
		function LatLonXYTab_TileSize_ResetButtonPushed(app, event)
			try
				calculator_latlonxy_reset('TileSize');
			catch ME
				errormessage('',ME);
			end
			
		end

		% Value changed function: LatLonXYTab_OSM_SetOriginToBBC_CheckBox
		function LatLonXYTab_OSM_SetOriginToBBC_CheckBoxValueChanged(app, event)
			try
				calculator_latlonxy_recalculate('OSM');
			catch ME
				errormessage('',ME);
			end
			
		end

		% Value changed function: 
		% LatLonXYTab_Osmosis_SetOriginToBBC_CheckBox
		function LatLonXYTab_Osmosis_SetOriginToBBC_CheckBoxValueChanged(app, event)
			try
				calculator_latlonxy_recalculate('Osmosis');
			catch ME
				errormessage('',ME);
			end
			
		end

		% Button pushed function: Mod_EnterCircle_Button
		function Mod_EnterCircle_ButtonPushed(app, event)
			try
				plot_modify('enter_circle');
			catch ME
				errormessage('',ME);
			end
			
		end

		% Button pushed function: Mod_ChangeDescription_Button
		function Mod_ChangeDescription_ButtonPushed(app, event)
			try
				plot_modify('change_description',-1);
			catch ME
				errormessage('',ME);
			end
			
		end

		% Menu selected function: MapEdit_DetectOverlappedTextsSymbols_Menu
		function MapEdit_DetectOverlappedTextsSymbols_MenuSelected(app, event)
			try
				find_overlapped_mapobjects;
			catch ME
				errormessage('',ME);
			end
			
		end

		% Menu selected function: MapEdit_SimplifyMap_Menu
		function MapEdit_SimplifyMap_MenuSelected(app, event)
			try
				plotosmdata_simplify;
			catch ME
				errormessage('',ME);
			end
			
		end

		% Menu selected function: MapEdit_CutIntoPiecesMapobjects_Menu
		function MapEdit_CutIntoPiecesMapobjects_MenuSelected(app, event)
			try
				cut_into_pieces_mapobjects;
			catch ME
				errormessage('',ME);
			end
			
		end

		% Menu selected function: Command_Line_Menu
		function Command_Line_MenuSelected(app, event)
			global GV
			try
				prompt		= {sprintf([...
					'Enter a MATLAB command and click OK to execute it.\n',...
					'Use at your own risk.'])};
				dlgtitle		= 'Enter MATLAB command';
				dims			= [20 100];
				definput		= {GV.user_command};
				answer		= inputdlg_local(prompt,dlgtitle,dims,definput);
				if ~isempty(answer)
					GV.user_command	= answer{1};
					command				= '';
					imax					= size(GV.user_command,1);
					for i=1:imax
						command				= [command GV.user_command(i,:)];
						while strcmp(command(1,end),' ')
							command				= command(1,1:(end-1));
						end
						if i<imax
							if    ~strcmp(command(1,end),',')&&...
									~strcmp(command(1,end),';')
								command				= [command ','];
							end
						end
					end
					globaldefs;
					eval(command);
				else
					% Cancel: nop
				end
			catch ME
				errormessage('',ME);
			end
			
		end

		% Menu selected function: MapEdit_DetectMisplacedTextsSymbols_Menu
		function MapEdit_DetectMisplacedTextsSymbols_MenuSelected(app, event)
			try
				analyze_unitedcolors('Detect misplaced texts and symbols');
			catch ME
				errormessage('',ME);
			end
			
		end

		% Menu selected function: MapEdit_DetectSmallHoles_Menu
		function MapEdit_DetectSmallHoles_MenuSelected(app, event)
			try
				analyze_unitedcolors('Detect small holes');
			catch ME
				errormessage('',ME);
			end
			
		end

		% Menu selected function: MapEdit_DetectFragilePieces_Menu
		function MapEdit_DetectFragilePieces_MenuSelected(app, event)
			try
				analyze_unitedcolors('Detect fragile pieces');
			catch ME
				errormessage('',ME);
			end
			
		end

		% Button pushed function: Mod_HideTemporarily_Button
		function Mod_HideTemporarily_ButtonPushed(app, event)
			try
				plot_modify('hide',-1,...
					1,...			% execute function display_map_objects
					1);			% hide temporarily
			catch ME
				errormessage('',ME);
			end
			
		end

		% Button pushed function: Mod_Restore_Button
		function Mod_Restore_ButtonPushed(app, event)
			try
				app.Mod_UnitedColors_SelectColNo_DropDown.Value='None';
				set_previewtype_dropdown(0);	% Called if the value of APP.Mod_UnitedColors_SelectColNo_DropDown has changed.
			catch ME
				errormessage('',ME);
			end
			
		end

		% Button pushed function: Mod_EnterRectangle_Button
		function Mod_EnterRectangle_ButtonPushed(app, event)
			try
				plot_modify('enter_rectangle');
			catch ME
				errormessage('',ME);
			end
			
		end

		% Button pushed function: Mod_EnterFormFromFile_Button
		function Mod_EnterFormFromFile_ButtonPushed(app, event)
			try
				plot_modify('textfile',0,'from');
			catch ME
				errormessage('',ME);
			end
			
		end

		% Button pushed function: Mod_ConvPrevLineToConnLine_Button
		function Mod_ConvPrevLineToConnLine_ButtonPushed(app, event)
			try
				plot_modify('convprevline',-1,'2connlinemapobj');
			catch ME
				errormessage('',ME);
			end
			
		end

		% Button pushed function: Mod_ConvPrevLineToLine_Button
		function Mod_ConvPrevLineToLine_ButtonPushed(app, event)
			try
				plot_modify('convprevline',-1,'2linemapobj');
			catch ME
				errormessage('',ME);
			end
			
		end

		% Menu selected function: ShowMapObjectsTable_Menu
		function ShowMapObjectsTable_MenuSelected(app, event)
			try
				% Set the visibility of the map objects table:
				show_mapobjectstable('toggle');
			catch ME
				errormessage('',ME);
			end
			
		end

		% Button pushed function: Mod_EnterFormToFile_Button
		function Mod_EnterFormToFile_ButtonPushed(app, event)
			try
				plot_modify('textfile',-1,'to');
			catch ME
				errormessage('',ME);
			end
			
		end

		% Selection change function: TabGroup
		function TabGroupSelectionChanged(app, event)
			% selectedTab = app.TabGroup.SelectedTab;
			try
				% Set the size of the window depending on whether the map objects table is displayed:
				set_maplab3d_figuresize;
			catch ME
				errormessage('',ME);
			end
			
		end

		% Menu selected function: CreatemapSettingsShowLegendTestplotMenu
		function CreatemapSettingsShowLegendTestplotMenuSelected(app, event)
			try
				if app.CreatemapSettingsShowLegendTestplotMenu.Checked
					app.CreatemapSettingsShowLegendTestplotMenu.Checked	= 'off';
				else
					app.CreatemapSettingsShowLegendTestplotMenu.Checked	= 'on';
				end
			catch ME
				errormessage('',ME);
			end
			
		end

		% Menu selected function: ShowTooltips_Menu
		function ShowTooltips_MenuSelected(app, event)
			try
				if app.ShowTooltips_Menu.Checked
					% Clear all tooltips:
					set_tooltips('clear');
				else
					% Set tooltips:
					set_tooltips('set');
				end
			catch ME
				errormessage('',ME);
			end
			
		end

		% Button pushed function: Mod_Arrange_SortByPrio_Button
		function Mod_Arrange_SortByPrio_ButtonPushed(app, event)
			try
				arrange_map_objects;
			catch ME
				errormessage('',ME);
			end
			
		end

		% Menu selected function: AutoSortNewMapObjects_Menu
		function AutoSortNewMapObjects_MenuSelected(app, event)
			try
				if app.AutoSortNewMapObjects_Menu.Checked
					% Do not arrange new map objects:
					app.AutoSortNewMapObjects_Menu.Checked	= 'off';
				else
					% Arrange new map objects automatically:
					app.AutoSortNewMapObjects_Menu.Checked	= 'on';
				end
			catch ME
				errormessage('',ME);
			end
			
		end

		% Value changed function: Mod_Move_mm_EditField
		function Mod_Move_mm_EditFieldValueChanged(app, event)
			% Set tooltips of move map objects buttons:
			try
				set_tooltips('move_mapobject');
			catch ME
				errormessage('',ME);
			end
			
		end

		% Value changed function: Mod_Rot_deg_EditField
		function Mod_Rot_deg_EditFieldValueChanged(app, event)
			% Set tooltips of rotate map objects buttons:
			try
				set_tooltips('rotate_mapobject');
			catch ME
				errormessage('',ME);
			end
			
		end

		% Value changed function: Mod_MergeWithExistObj_CheckBox
		function Mod_MergeWithExistObj_CheckBoxValueChanged(app, event)
			
			%{

de:

Funktionsweise der Checkbox "Merge":

"Polygon to map object", Checkbox "Merge" aktiviert:
Die markierten Vorschauobjekte werden den aus den OSM-Rohdaten herausgefilterten Daten hinzugefügt und alle Objekte der spezifizierten Objektnummer neu erstellt.
Bemerkungen:
- Mit "Merge" können neue Flächen mit den vorhandenen Flächen verschmelzen oder innen liegende Grenzen zu einem Loch werden.
- Löcher können nur entstehen, wenn die inneren und äußeren Grenzen zu derselben relation gehören. Wird ein Loch oder eine äußere Grenze auf diese Art hinzugefügt, muss dem Vorschauobjekt die richtige relation-ID zugewiesen werden (über das Kontextmenü des Vorschauobjekts).
- Bei der Umwandlung von Vorschauobjekten MIT einer relation-ID sollten die Ränder keine Ränder von vorhandenen Kartenobjekte derselben Objektnummer berühren, sonst kommt es zu unerwünschten Effekten. Auch sollte nicht zweimal dasselbe Vorschauobjekt umgewandelt werden.
- Bei der Umwandlung von Vorschauobjekten OHNE eine relation-ID kommt es bei einer Überlappung mit vorhandenen Kartenobjekten derselben Objektnummer zu einer Vereinigung der Flächen.
- Vorherige Änderungen ohne "Merge" an Kartenobjekte der spezifizierten Objektnummer gehen verloren. Dies sollte daher nur zu Beginn der Bearbeitung der Karte erfolgen.
- Die OSM-Rohdaten werden nicht verändert, deshalb setzt ein Neuerstellen der Karte ("File - Create map") alle Änderungen zurück. Nur so lassen sich die Änderungen mit "Merge" wieder rückgängig machen.
- Texte und Symbole werden zwar auch neu erzeugt, aber nicht unter Berücksichtigung der Änderungen mit "Merge": die Texte und Symbole müssen daher eventuell neu plaziert werden.
- Vor der Ausführung mit "Merge" sollte das Projekt gespeichert werden.
Anwendungsbeispiele:
- Hinzufügen einer unvollständigen Waldfläche ("area - not closed") am Rand der Karte zu dem restlichen Wald: offene Linie manuell schließen ("Close") und mit "Merge" umwandeln ("Polygon to map object"). Weil die manuell geschlossene Linie den vorhandenen Daten hinzugefügt wird, ist die ursprüngliche offene Linie nach der Umwandlung immer noch vorhanden. Diese offene Vorschaulinie kann danach gelöscht werden.
- Wasserfläche um eine einzelne Insel im Meer: Wenn die äußere Grenze der Wasserfläche zu weit weg ist wird die Küstenlinie der Insel als äußere Wassergrenze interpretiert: Manuelles Hinzufügen einer äußeren Wassergrenze außerhalb des Druckbereichs, damit die Insel als Loch im Wasser erscheint.

"Polygon to map object", Checkbox "Merge" nicht aktiviert:
Mit dieser Methode wird das Vorschauobjekt mit den Einstellungen der angegebenen Objektnummer umgewandelt und mit einer eigenen PlotNo der Karte hinzugefügt.
Bemerkungen:
- Die Ausführungszeit ist kürzer als mit "Merge" aktiviert.
- Ein Verschmelzen mit vorhandenen Kartenobjekten findet nicht hier statt, sondern erst beim Erstellen der STL-Daten werden sich überlappende Flächen mit gleicher Farbnummer vereinigt.
- Weil die Daten des Vorschauobjekts den aus den OSM-Rohdaten herausgefilterten Daten nicht hinzugefügt werden, sind bestimmte Funktionen zur Vereinfachung und Verbesserung hier nicht möglich (zum Beispiel die automatische Trennung von langen Linien bei "File - Map editing - Simplify map").
- Das Vorschauobjekt wird nach der Umwandlung gelöscht.
Anwendungen:
- Schließen von kleinen Löchern.
- Verbinden von kleinen Objekten.
- Verstärken von zerbrechlichen Teilen.


en:

How the “Merge” checkbox works:

“Polygon to map object”, “Merge” checkbox activated:
The selected preview objects are added to the data filtered out of the OSM raw data and all objects of the specified object number are recreated.
Remarks:
- With “Merge”, new areas can merge with existing areas or inner boundaries can become a hole.
- Holes can only be created if the inner and outer boundaries belong to the same relation. If a hole or an outer boundary is added in this way, the preview object must be assigned the correct relation ID (via the context menu of the preview object).
- When converting preview objects WITH a relation ID, the boundaries should not touch any boundaries of existing map objects with the same object number, otherwise undesirable effects will occur. The same preview object should also not be converted twice.
- When converting preview objects WITHOUT a relation ID, the areas will be united if they overlap with existing map objects of the same object number.
- Previous changes without “Merge” to map objects of the specified object number are lost. This should therefore only be done at the beginning of editing the map.
- The OSM raw data is not changed, so recreating the map (“File - Create map”) resets all changes. This is the only way to undo the changes with “Merge”.
- Texts and symbols are also recreated, but not taking into account the changes made with “Merge”: the texts and symbols may therefore have to be rearranged.
- The project should be saved before using “Merge”.
Application examples:
- Adding an incomplete forest area (“area - not closed”) at the edge of the map to the existing forest: close the open line manually (“Close”) and convert it with “Merge” (“Polygon to map object”). Because the manually closed line is added to the existing data, the original open line is still present after the conversion. This open preview line can then be deleted.
- Water area around a single island in the sea: If the outer boundary of the water area is too far away, the coastline of the island is interpreted as the outer water boundary: Manually add an outer water boundary outside the print area so that the island appears as a hole in the water.

“Polygon to map object”, ‘Merge’ checkbox not activated:
With this method, the preview object is converted with the settings of the specified object number and added to the map with its own PlotNo.
Remarks:
- The execution time is shorter than with “Merge” activated.
- Merging with existing map objects does not take place here; overlapping areas with the same color number are only merged when the STL data is created.
- Because the data of the preview object is not added to the data filtered out of the OSM raw data, certain functions for simplification and improvement are not possible here (for example, the automatic separation of long lines using “File - Map editing - Simplify map”).
- The preview object is deleted after the conversion.
Applications:
- Closing small holes.
- Connecting small objects.
- Reinforcing fragile parts.

			%}
			
			try
				if app.ShowTooltips_Menu.Checked
					% Show Tooltips:
					title	= 'How the “Merge” checkbox works';
					if app.Mod_MergeWithExistObj_CheckBox.Value
						% The Checkbox is set:
						msg	= sprintf([...
							'“Polygon to map object”, “Merge” checkbox activated:\n',...
							'The selected preview objects are added to the data filtered out of the OSM raw data and all objects of the specified object number are recreated.\n',...
							'\n',...
							'Remarks:\n',...
							'- With “Merge”, new areas can merge with existing areas or inner boundaries can become a hole.\n',...
							'- Holes can only be created if the inner and outer boundaries belong to the same relation. If a hole or an outer boundary is added in this way, the preview object must be assigned the correct relation ID (via the context menu of the preview object) and the correct text/tag (via the button "Tab: Edit map - Basic - Change text/tag").\n',...
							'- When converting preview objects WITH a relation ID, the boundaries should not touch any boundaries of existing map objects with the same object number, otherwise undesirable effects will occur. The same preview object should also not be converted twice.\n',...
							'- When converting preview objects WITHOUT a relation ID, the areas will be united if they overlap with existing map objects of the same object number.\n',...
							'- Previous changes without “Merge” to map objects of the specified object number are lost. This should therefore only be done at the beginning of editing the map.\n',...
							'- The OSM raw data is not changed, so recreating the map (“File - Create map”) resets all changes. This is the only way to undo the changes with “Merge”.\n',...
							'- Texts and symbols are also recreated, but not taking into account the changes made with “Merge”: the texts and symbols may therefore have to be rearranged.\n',...
							'- The project should be saved before using “Merge”.\n',...
							'\n',...
							'Application examples:\n',...
							'- Adding an incomplete forest area (“area - not closed”) at the edge of the map to the existing forest: close the open line manually (“Close”) and convert it with “Merge” (“Polygon to map object”). Because the manually closed line is added to the existing data, the original open line is still present after the conversion. This open preview line can then be deleted.\n',...
							'- Water area around a single island in the sea: If the outer boundary of the water area is too far away, the coastline of the island is interpreted as the outer water boundary: Manually add an outer water boundary outside the print area so that the island appears as a hole in the water.\n',...
							]);
					else
						% The Checkbox is not set:
						msg	= sprintf([...
							'“Polygon to map object”, ‘Merge’ checkbox not activated:\n',...
							'With this method, the preview object is converted with the settings of the specified object number and added to the map with its own PlotNo.\n',...
							'\n',...
							'Remarks:\n',...
							'- The execution time is shorter than with “Merge” activated.\n',...
							'- Merging with existing map objects does not take place here; overlapping areas with the same color number are only merged when the STL data is created.\n',...
							'- Because the data of the preview object is not added to the data filtered out of the OSM raw data, certain functions for simplification and improvement are not possible here (for example, the automatic separation of long lines using “File - Map editing - Simplify map”).\n',...
							'- The preview object is deleted after the conversion.\n',...
							'\n',...
							'Applications:\n',...
							'- Closing small holes.\n',...
							'- Connecting small objects.\n',...
							'- Reinforcing fragile parts.',...
							]);
					end
					helpdlg(msg,title);
				end
			catch ME
				errormessage('',ME);
			end
			
			
		end

		% Menu selected function: Cre3dPrevSet_shlimti_Menu
		function Cre3dPrevSet_shlimti_MenuSelected(app, event)
			try
				if app.Cre3dPrevSet_shlimti_Menu.Checked
					% Do not show limits and tiles:
					app.Cre3dPrevSet_shlimti_Menu.Checked	= 'off';
				else
					% Show limits and tiles:
					app.Cre3dPrevSet_shlimti_Menu.Checked	= 'on';
				end
			catch ME
				errormessage('',ME);
			end
			
		end

		% Menu selected function: View_ShowContourLines_MinorLabels_Menu
		function View_ShowContourLines_MinorLabels_MenuSelected(app, event)
			try
				if app.View_ShowContourLines_MinorLabels_Menu.Checked
					app.View_ShowContourLines_MinorLabels_Menu.Checked	= 'off';
				else
					app.View_ShowContourLines_MinorLabels_Menu.Checked	= 'on';
				end
			catch ME
				errormessage('',ME);
			end
			
		end

		% Menu selected function: Convert_GeoRasterData_Menu
		function Convert_GeoRasterData_MenuSelected(app, event)
			try
				% Select a source and destination directory and convert the source files to the mat format:
				open_ele;
			catch ME
				errormessage('',ME);
			end
			
			
		end

		% Menu selected function: 
		% Convert_GeoRasterDataSettings_CrIndFiles_Menu
		function Convert_GeoRasterDataSettings_CrIndFiles_MenuSelected(app, event)
			try
				app.Convert_GeoRasterDataSettings_CrIndFiles_Menu.Checked		= 'on';
				app.Convert_GeoRasterDataSettings_CrSFile_Menu.Checked			= 'off';
			catch ME
				errormessage('',ME);
			end
			
		end

		% Menu selected function: 
		% Convert_GeoRasterDataSettings_CrSFile_Menu
		function Convert_GeoRasterDataSettings_CrSFile_MenuSelected(app, event)
			try
				app.Convert_GeoRasterDataSettings_CrSFile_Menu.Checked			= 'on';
				app.Convert_GeoRasterDataSettings_CrIndFiles_Menu.Checked		= 'off';
			catch ME
				errormessage('',ME);
			end
			
		end

		% Menu selected function: 
		% Convert_GeoRasterDataSettings_Testplot_Menu
		function Convert_GeoRasterDataSettings_Testplot_MenuSelected(app, event)
			try
				if app.Convert_GeoRasterDataSettings_Testplot_Menu.Checked
					app.Convert_GeoRasterDataSettings_Testplot_Menu.Checked	= 'off';
				else
					app.Convert_GeoRasterDataSettings_Testplot_Menu.Checked	= 'on';
				end
			catch ME
				errormessage('',ME);
			end
			
		end

		% Menu selected function: ShowMapfigureLegend_Menu
		function ShowMapfigureLegend_MenuSelected(app, event)
			try
				if app.ShowMapfigureLegend_Menu.Checked
					% Do not show the 2D map figure legend (faster program execution):
					app.ShowMapfigureLegend_Menu.Checked	= 'off';
					show_mapfigure_legend						= false;
				else
					% Show the 2D map figure legend:
					app.ShowMapfigureLegend_Menu.Checked	= 'on';
					show_mapfigure_legend						= true;
				end
				create_legend_mapfigure(show_mapfigure_legend);
			catch ME
				errormessage('',ME);
			end
			
		end

		% Selection changed function: 
		% FilterOSMData_AutofillSource_ButtonGroup
		function FilterOSMData_AutofillSource_ButtonGroupSelectionChanged(app, event)
			try
				% Set the include and exclude keys and values depending on the selected object number:
				set_inclexcltags_table('set');
			catch ME
				errormessage('',ME);
			end
			
		end

		% Button pushed function: MapView_All_Button
		function MapView_All_ButtonPushed(app, event)
			try
				SizeChangedFcn_fig_2dmap([],[],1,2);
			catch ME
				errormessage('',ME);
			end
			
		end

		% Menu selected function: MapEdit_DetectSmallPieces_Menu
		function MapEdit_DetectSmallPieces_MenuSelected(app, event)
			try
				analyze_unitedcolors('Detect small pieces');
			catch ME
				errormessage('',ME);
			end
			
		end

		% Menu selected function: Open_CommandPromptWindow_Menu
		function Open_CommandPromptWindow_MenuSelected(app, event)
			try
				call_osmosis(2);
			catch ME
				errormessage('',ME);
			end
			
		end

		% Menu selected function: Cre3dPrevSet_Filtersettings_Tilebase_Menu
		function Cre3dPrevSet_Filtersettings_Tilebase_MenuSelected(app, event)
			try
				app.Cre3dPrevSet_Filtersettings_Tilebase_Menu.Checked		= 'on';
				app.Cre3dPrevSet_Filtersettings_Ask_Menu.Checked			= 'off';
				app.Cre3dPrevSet_Filtersettings_Finalresult_Menu.Checked	= 'off';
			catch ME
				errormessage('',ME);
			end
			
		end

		% Menu selected function: Cre3dPrevSet_Filtersettings_Ask_Menu
		function Cre3dPrevSet_Filtersettings_Ask_MenuSelected(app, event)
			try
				app.Cre3dPrevSet_Filtersettings_Tilebase_Menu.Checked		= 'off';
				app.Cre3dPrevSet_Filtersettings_Ask_Menu.Checked			= 'on';
				app.Cre3dPrevSet_Filtersettings_Finalresult_Menu.Checked	= 'off';
			catch ME
				errormessage('',ME);
			end
			
		end

		% Menu selected function: 
		% Cre3dPrevSet_Filtersettings_Finalresult_Menu
		function Cre3dPrevSet_Filtersettings_Finalresult_MenuSelected(app, event)
			try
				app.Cre3dPrevSet_Filtersettings_Tilebase_Menu.Checked		= 'off';
				app.Cre3dPrevSet_Filtersettings_Ask_Menu.Checked			= 'off';
				app.Cre3dPrevSet_Filtersettings_Finalresult_Menu.Checked	= 'on';
			catch ME
				errormessage('',ME);
			end
			
		end

		% Menu selected function: View_SetFigureSize_Menu
		function View_SetFigureSize_MenuSelected(app, event)
			global GV_H
			try
				
				% Display state:
				display_on_gui('state','','busy');
				% Ask for the new figure size:
				
				fig		= get(groot,'CurrentFigure');
				if ~isempty(fig)
					pos_old		= fig.Position;
					x_old			= pos_old(1,1);
					y_old			= pos_old(1,2);
					w_old			= pos_old(1,3);
					h_old			= pos_old(1,4);
					prompt		= {...
						'Width in pixels:';...
						'Height in pixels:'};
					dlgtitle		= 'Enter figure size';
					dims			= 1;
					definput		= {...
						sprintf('%1.0f',w_old);...
						sprintf('%1.0f',h_old)};
					warntext		= 'xxxxx';
					while ~isempty(warntext)
						answer		= inputdlg_local(prompt,dlgtitle,dims,definput);
						if size(answer,1)~=2
							% Cancel:
							display_on_gui('state','','notbusy');
							return
						end
						warntext			= '';
						wh_new			= cell(2,1);
						for i=1:2
							if ~isempty(strfind(answer{i,1},','))
								warntext	= sprintf([...
									'Error:\n',...
									'The value\n',...
									'%s = %s\n',...
									'must be positive, numeric and scalar.'],prompt{i,1},answer{i,1});
								break
							else
								wh_new{i,1}	= round(str2double(answer{i,1}));
								if isnan(wh_new{i,1})||(wh_new{i,1}<1)
									warntext	= sprintf([...
										'Error:\n',...
										'The value\n',...
										'%s = %s\n',...
										'must be positive, numeric and scalar.'],prompt{i,1},answer{i,1});
									break
								end
							end
						end
						if ~isempty(warntext)
							if isfield(GV_H.warndlg,'maplab3d')
								if ishandle(GV_H.warndlg.maplab3d)
									close(GV_H.warndlg.maplab3d);
								end
							end
							warntext	= sprintf('%s\nPress OK to repeat.',warntext);
							GV_H.warndlg.maplab3d		= warndlg(warntext,'Warning');
							GV_H.warndlg.maplab3d.Tag	= 'maplab3d_figure';
							while ishandle(GV_H.warndlg.maplab3d)
								pause(0.2);
							end
						end
					end
					w			= wh_new{1,1};
					h			= wh_new{2,1};
					% Do not change the position of the upper left corner of the figure:
					x			= x_old;
					y			= y_old-h+h_old;
					fig.Position		= [x y w h];
					% Bring to top:
					figure(fig);
				end
				
				% Display state:
				display_on_gui('state','','notbusy');
				
			catch ME
				errormessage('',ME);
			end
			
		end

		% Menu selected function: View_CopyToClipboard_Menu
		function View_CopyToClipboard_MenuSelected(app, event)
			global GV_H
			try
				fig		= get(groot,'CurrentFigure');
				% Display state:
				stateisbusy	= display_on_gui('state','','isbusy');
				if ~stateisbusy
					display_on_gui('state',sprintf('Copy figure "%s" to clipboard...',fig.Name),'busy','add');
				end
				if ~isempty(fig)
					
					% print(fig,'-clipboard',...
					% 	'-dbitmap',...			% clipboardformat: image file
					% 	'-r0');					% screen resolution
					
					fig_settings=figure_theme(fig,'set',[],'light');
					copygraphics(fig,...
						'ContentType','image',...
						'Resolution',get(groot,"ScreenPixelsPerInch"),...
						'Padding','figure');	% Include the same relative amount of padding as shown in the figure window.
					figure_theme(fig,'reset',fig_settings);
					% Bring to top:
					figure(fig);
					% Display state:
					if ~stateisbusy
						display_on_gui('state',sprintf('Figure "%s" copied to clipboard.',fig.Name),'notbusy','replace');
					end
				end
			catch ME
				errormessage('',ME);
			end
			
		end

		% Menu selected function: View_SaveToFile_Menu
		function View_SaveToFile_MenuSelected(app, event)
			try
				save_current_figure;
			catch ME
				errormessage('',ME);
			end
			
		end

		% Menu selected function: View_ShowCameraToolbar_Menu
		function View_ShowCameraToolbar_MenuSelected(app, event)
			global GV_H
			try
				fig		= get(groot,'CurrentFigure');
				if ~isempty(fig)
					show_cameratoolbar	= true;
					if isfield(GV_H,'ax_2dmap')
						if isequal(fig,GV_H.fig_2dmap)
							% cameratoolbar disabled, because it changes the axis position:
							% The modification of lines and polygons like "Move vertex" will not work.
							show_cameratoolbar	= false;
						end
					end
					if show_cameratoolbar
						cameratoolbar(fig,'show');
						figure(fig);
					end
				end
			catch ME
				if isfield(GV_H.warndlg,'maplab3d')
					if ishandle(GV_H.warndlg.maplab3d)
						close(GV_H.warndlg.maplab3d);
					end
				end
				% warntext	= sprintf([...
				% 	'Error when switching the toolbar:\n',...
				% 	'%s'],ME.message);
				GV_H.warndlg.maplab3d		= warndlg('Switching the toolbar is not possible in this figure.','Warning');
				GV_H.warndlg.maplab3d.Tag	= 'maplab3d_figure';
			end
			
		end

		% Menu selected function: View_ChangeVisibilityOf3DObjects_Menu
		function View_ChangeVisibilityOf3DObjects_MenuSelected(app, event)
			try
				change_patches_3dplots;
			catch ME
				errormessage('',ME);
			end
			
		end

		% Menu selected function: View_SwitchLightDarkTheme_Menu
		function View_SwitchLightDarkTheme_MenuSelected(app, event)
			try
				fig		= gcf;
				if fig.Theme.BaseColorStyle=="light"
					fig.Theme.BaseColorStyle='dark';
				else
					fig.Theme.BaseColorStyle='light';
				end
			catch ME
				errormessage('',ME);
			end
			
		end

		% Size changed function: MapLab3D
		function MapLab3DSizeChanged(app, event)
			% position = app.MapLab3D.Position;
			% disp('Test: MapLab3DSizeChanged');
			
		end

		% Menu selected function: Convert_GeoRasterDataSettings_SetRes_Menu
		function Convert_GeoRasterDataSettings_SetRes_MenuSelected(app, event)
			try
				if app.Convert_GeoRasterDataSettings_SetRes_Menu.Checked
					app.Convert_GeoRasterDataSettings_SetRes_Menu.Checked			= 'off';
					app.Convert_GeoRasterDataSettings_HighestRes_Menu.Checked	= 'on';
				else
					app.Convert_GeoRasterDataSettings_SetRes_Menu.Checked			= 'on';
					app.Convert_GeoRasterDataSettings_HighestRes_Menu.Checked	= 'off';
				end
			catch ME
				errormessage('',ME);
			end
			
		end

		% Menu selected function: 
		% Convert_GeoRasterDataSettings_HighestRes_Menu
		function Convert_GeoRasterDataSettings_HighestRes_MenuSelected(app, event)
			try
				if app.Convert_GeoRasterDataSettings_HighestRes_Menu.Checked
					app.Convert_GeoRasterDataSettings_HighestRes_Menu.Checked	= 'off';
					app.Convert_GeoRasterDataSettings_SetRes_Menu.Checked			= 'on';
				else
					app.Convert_GeoRasterDataSettings_HighestRes_Menu.Checked	= 'on';
					app.Convert_GeoRasterDataSettings_SetRes_Menu.Checked			= 'off';
				end
			catch ME
				errormessage('',ME);
			end
			
		end

		% Menu selected function: 
		% Convert_GeoRasterDataSettings_WholeArea_Menu
		function Convert_GeoRasterDataSettings_WholeArea_MenuSelected(app, event)
			try
				if app.Convert_GeoRasterDataSettings_WholeArea_Menu.Checked
					app.Convert_GeoRasterDataSettings_WholeArea_Menu.Checked		= 'off';
					app.Convert_GeoRasterDataSettings_ExtractBB_Menu.Checked		= 'on';
				else
					app.Convert_GeoRasterDataSettings_WholeArea_Menu.Checked		= 'on';
					app.Convert_GeoRasterDataSettings_ExtractBB_Menu.Checked		= 'off';
				end
			catch ME
				errormessage('',ME);
			end
			
		end

		% Menu selected function: 
		% Convert_GeoRasterDataSettings_ExtractBB_Menu
		function Convert_GeoRasterDataSettings_ExtractBB_MenuSelected(app, event)
			try
				if app.Convert_GeoRasterDataSettings_ExtractBB_Menu.Checked
					app.Convert_GeoRasterDataSettings_ExtractBB_Menu.Checked		= 'off';
					app.Convert_GeoRasterDataSettings_WholeArea_Menu.Checked		= 'on';
				else
					app.Convert_GeoRasterDataSettings_ExtractBB_Menu.Checked		= 'on';
					app.Convert_GeoRasterDataSettings_WholeArea_Menu.Checked		= 'off';
				end
			catch ME
				errormessage('',ME);
			end
			
		end

		% Menu selected function: 
		% Convert_GeoRasterDataSettings_ReplMissData_Menu
		function Convert_GeoRasterDataSettings_ReplMissData_MenuSelected(app, event)
			try
				if app.Convert_GeoRasterDataSettings_ReplMissData_Menu.Checked
					app.Convert_GeoRasterDataSettings_ReplMissData_Menu.Checked		= 'off';
				else
					app.Convert_GeoRasterDataSettings_ReplMissData_Menu.Checked		= 'on';
				end
			catch ME
				errormessage('',ME);
			end
			
		end

		% Button pushed function: Mod_ChangeTextTag_Button
		function Mod_ChangeTextTag_ButtonPushed(app, event)
			try
				plot_modify('change_texttag',-1);
			catch ME
				errormessage('',ME);
			end
			
		end

		% Button pushed function: Mod_HideTempAll_Button
		function Mod_HideTempAll_ButtonPushed(app, event)
			global MAP_OBJECTS
			try
				% Get imapobj of all visible objects:
				imapobj_v_logical	= false(size(MAP_OBJECTS,1),1);
				for imapobj=1:size(MAP_OBJECTS,1)
					for i=1:size(MAP_OBJECTS(imapobj,1).h,1)
						if MAP_OBJECTS(imapobj,1).h(i,1).Visible
							imapobj_v_logical(imapobj,1)	= true;
							break
						end
					end
				end
				imapobj_v		= find(imapobj_v_logical);
				% Hide all visible objects temporarily:
				plot_modify('hide',imapobj_v,...
					1,...			% execute function display_map_objects
					1);			% hide temporarily
			catch ME
				errormessage('',ME);
			end
			
		end

		% Menu selected function: CreateCharStyleSampleMenu
		function CreateCharStyleSampleMenuSelected(app, event)
			try
				testsamples(4);			% testsample_no=3: Character style samples
			catch ME
				errormessage('',ME);
			end
			
		end

		% Menu selected function: AboutMenu
		function AboutMenuSelected(app, event)
			try
				maplab3d_about;
			catch ME
				errormessage('',ME);
			end
			
		end

		% Menu selected function: Save_ErrorLog_Menu
		function Save_ErrorLog_MenuSelected(app, event)
			try
				if app.Save_ErrorLog_Menu.Checked
					% Do not arrange new map objects:
					app.Save_ErrorLog_Menu.Checked	= 'off';
				else
					% Arrange new map objects automatically:
					app.Save_ErrorLog_Menu.Checked	= 'on';
				end
			catch ME
				errormessage('',ME);
			end
			
		end

		% Button pushed function: Mod_Set_Dimensions_Button
		function Mod_Set_Dimensions_ButtonPushed(app, event)
			try
				plot_modify('set_dim',-1);
			catch ME
				errormessage('',ME);
			end
			
		end

		% Button pushed function: Mod_HideTemporarily_Button_2
		function Mod_HideTemporarily_Button_2Pushed(app, event)
			try
				plot_modify('restore',-1);
			catch ME
				errormessage('',ME);
			end
			
		end
	end

	% Component initialization
	methods (Access = private)

		% Create UIFigure and components
		function createComponents(app)
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			% Get the file path for locating images
			pathToMLAPP = fileparts(mfilename('fullpath'));
			app.MapLab3D = uifigure('Visible', 'off');
			app.MapLab3D.Name = 'MapLab3D 1.1.0.1';
			app.MapLab3D.Icon = fullfile(pathToMLAPP, 'MapLab3D_Logo_400x400_RGB.png');
			app.MapLab3D.CloseRequestFcn = createCallbackFcn(app, @MapLab3DCloseRequest, true);
			app.MapLab3D.SizeChangedFcn = createCallbackFcn(app, @MapLab3DSizeChanged, true);
			app.FileMenu = uimenu(app.MapLab3D);
			app.FileMenu.Text = 'File';
			app.LoadprojectMenu = uimenu(app.FileMenu);
			app.LoadprojectMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadprojectMenuSelected, true);
			app.LoadprojectMenu.Tooltip = {'Load a previously saved project (...MAP.fig).'; 'The version number of the saved project and the program must match in the first and second position, otherwise the project cannot be loaded. A difference in the third position has no effect on saved projects or project parameters and the project can be loaded.'};
			app.LoadprojectMenu.Text = 'Load project';
			app.ReloadprojectMenu = uimenu(app.FileMenu);
			app.ReloadprojectMenu.MenuSelectedFcn = createCallbackFcn(app, @ReloadprojectMenuSelected, true);
			app.ReloadprojectMenu.Tooltip = {'Reload the current project.'; 'Search the current project directory for the files “PROJECTFILENAME - vx.x.x - MAP.fig” and “PROJECTFILENAME - vx.x.x - MAPDATA.mat” and load these files. PROJECTFILENAME is the current project file name, defined in the project parameters or assigned under “File - Save project as”.'};
			app.ReloadprojectMenu.Text = 'Reload project';
			app.SaveProjectMenu = uimenu(app.FileMenu);
			app.SaveProjectMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveProjectMenuSelected, true);
			app.SaveProjectMenu.Tooltip = {'Save the current project.'; '1) A project is saved in two files:'; '- ...MAP.fig: This file contains the map and can be displayed with “View - Open figure file (.fig)” without having to load the project.'; '- ...MAPDATA.mat: This file contains additional data so that the “...MAP.fig” is not too large and can be loaded faster.'; '2) When saving, the last N save versions are saved under a sequential number (parameter “save_n_backups”). The oldest version has the highest number and is lost when the project is saved again. Because there is no “Undo” function, you should save frequently! In addition, the project is automatically saved before and after execution of the following functions:'; '- "File - Load OSM- and elevation-data"'; '- "File - Create map"'; '- "File - Map editing - Simplify map"'; '- "File - Map editing - Cut into pieces"'; '- "File - Create map STL files"'};
			app.SaveProjectMenu.Separator = 'on';
			app.SaveProjectMenu.Text = 'Save project';
			app.SaveProjectAsMenu = uimenu(app.FileMenu);
			app.SaveProjectAsMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveProjectAsMenuSelected, true);
			app.SaveProjectAsMenu.Tooltip = {'Changes the project filename and saves the project under this filename.'};
			app.SaveProjectAsMenu.Text = 'Save project as';
			app.ChangeprojectdirectoryMenu = uimenu(app.FileMenu);
			app.ChangeprojectdirectoryMenu.MenuSelectedFcn = createCallbackFcn(app, @ChangeprojectdirectoryMenuSelected, true);
			app.ChangeprojectdirectoryMenu.Tooltip = {'Changes the project directory.'};
			app.ChangeprojectdirectoryMenu.Text = 'Change project directory';
			app.LoadprojectparametersMenu = uimenu(app.FileMenu);
			app.LoadprojectparametersMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadprojectparametersMenuSelected, true);
			app.LoadprojectparametersMenu.Tooltip = {'Load project parameters.'; '1) The project parameters are saved in a MS Excel spreadsheet with the name “PP”. They contain all project-specific settings such as scale, colors, fonts or objects to be displayed. After loading, a text file with useful information is saved in the project directory (“* - projpar summary.txt”).'; '2) A project parameter file can only be used together with a program version that has an identical version number in the 1st and 2nd position. If the program version is different, the project parameters must be modified. A deviation of the 3rd version number has no effect on the project parameters.'; '3) When loading, the project file is also checked for missing, unnecessary and duplicate project parameters.'; 'If such an error occurs, a text file is generated with instructions for revising the project parameters. A check for implausible parameter values does not currently take place systematically. Incorrect values can lead to a program crash, so changes should only be made in small steps!'; '4) When changing project parameters, the map usually has to be recreated; for some parameters, the OSM data must also be reloaded beforehand. Changes to the following parameters are instead applied to an existing map without having to recreate it:'; '- Superelevation'; '- Map objects priority, color number, surface lifting, surface type (flat/terrain)'; '- Text character styles'};
			app.LoadprojectparametersMenu.Separator = 'on';
			app.LoadprojectparametersMenu.Text = 'Load project parameters';
			app.ReloadprojectparametersMenu = uimenu(app.FileMenu);
			app.ReloadprojectparametersMenu.MenuSelectedFcn = createCallbackFcn(app, @ReloadprojectparametersMenuSelected, true);
			app.ReloadprojectparametersMenu.Tooltip = {'Reload the current project parameter file.'};
			app.ReloadprojectparametersMenu.Text = 'Reload project parameters';
			app.LoadOSMandElevationDataMenu = uimenu(app.FileMenu);
			app.LoadOSMandElevationDataMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadOSMandElevationDataMenuMenuSelected, true);
			app.LoadOSMandElevationDataMenu.Tooltip = {'Loading the OSM and elevation data. Procedure:'; '1) Selection of: '; '- OSM XML file (.osm) of the desired map section. These files can be very large and take a long time to load. Use Osmosis (“Extra - Call Osmosis”) to create an .osm file containing only the required area and data.'; 'Examples of data sources: https://www.openstreetmap.org (only small areas), https://download.geofabrik.de/ (requires Osmosis)'; '- directory with the elevation data. The directory may only contain elevation data files and, if possible, only the elevation data of the selected map section. It is possible to convert the elevation data into the .mat format beforehand to speed up loading.'; 'Tested with: GeoTIFF from https://earthexplorer.usgs.gov (Digital Elevation: SRTM 1 Arc-Second Global).'; 'For other possibly supported file formats, see MatLab-command “readgeoraster”.'; '2) Processing of the elevation data: Repair of missing data, filtering of noise and interpolation to the required grid, additional filtering in case of too many grid points (large scale factors).'; '3) Conversion of latitude and longitude from degrees to x and y in mm.'; '4) Indexing+sorting of OSM keys and values for easier use.'; '5) Downsampling of ways to a minimum distance between vertices (for large scale numbers, can be deactivated). This can lead to small objects being deleted.'};
			app.LoadOSMandElevationDataMenu.Separator = 'on';
			app.LoadOSMandElevationDataMenu.Text = 'Load OSM- and elevation-data';
			app.LoadOSMandelevationdatasettingsMenu = uimenu(app.FileMenu);
			app.LoadOSMandelevationdatasettingsMenu.Tooltip = {'Settings for the “Load OSM- and elevation-data” function.'};
			app.LoadOSMandelevationdatasettingsMenu.Text = 'Load OSM- and elevation-data settings';
			app.LoadSettingsCompleteOSMfileMenu = uimenu(app.LoadOSMandelevationdatasettingsMenu);
			app.LoadSettingsCompleteOSMfileMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadSettingsCompleteOSMfileMenuSelected, true);
			app.LoadSettingsCompleteOSMfileMenu.Tooltip = {'Load all data contained in the selected OSM file without filtering.'; 'The “Filter OSM-data” tab can be used to search for all data contained in this area, even if it does not appear as an object on the map. However, loading can take a very long time, so this option is only recommended for small OSM files.'};
			app.LoadSettingsCompleteOSMfileMenu.Text = 'Load complete OSM-file (only small .osm-files)';
			app.LoadSettingsFilteredOSMfileMenu = uimenu(app.LoadOSMandelevationdatasettingsMenu);
			app.LoadSettingsFilteredOSMfileMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadSettingsFilteredOSMfileMenuSelected, true);
			app.LoadSettingsFilteredOSMfileMenu.Tooltip = {'Load the data contained in the selected OSM file with previous filtering.'; 'This option uses the included “Osmfilter” program to filter the OSM file using all the include tags of the objects to be displayed specified in the project parameters. The reduced OSM file is saved under “*_filt.osm” in the project directory and then loaded.'; '- This option should always be selected because it significantly reduces the loading time.'; '- Please note that the loaded data does not contain all OSM data for the selected area.'; '- The exclude tags specified in the project parameters have no influence on “Osmfilter”.'; '- Filtering with Osmfilter can also use logical operators and therefore filters better than Osmosis. This means that this option should always be activated for large maps, even if the OSM file was created with Osmosis and filtering activated.'};
			app.LoadSettingsFilteredOSMfileMenu.Checked = 'on';
			app.LoadSettingsFilteredOSMfileMenu.Text = 'Load OSM-file with filtering (large files, recommended)';
			app.LoadSettingsOnlyOSMboundariesMenu = uimenu(app.LoadOSMandelevationdatasettingsMenu);
			app.LoadSettingsOnlyOSMboundariesMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadSettingsOnlyOSMboundariesMenuSelected, true);
			app.LoadSettingsOnlyOSMboundariesMenu.Tooltip = {'Only load the boundaries of the map section contained in the selected OSM file.'; '- This option uses the supplied program “Osmfilter” to remove all nodes, ways and relations contained in the OSM file. The reduced OSM file is saved under “*_filt.osm” in the project directory and then loaded.'; '- This is useful to display the selected area as a 3D model without the map objects (“Create 3D map preview”) and thus get an impression of the appearance of the map. This preview is also suitable for setting the scale and superelevation.'};
			app.LoadSettingsOnlyOSMboundariesMenu.Text = 'Load only OSM-boundaries (set scale+superelevation)';
			app.LoadSettingsElevationDataMenu = uimenu(app.LoadOSMandelevationdatasettingsMenu);
			app.LoadSettingsElevationDataMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadSettingsElevationDataMenuSelected, true);
			app.LoadSettingsElevationDataMenu.Tooltip = {'Loading the elevation data is activated (default setting).'};
			app.LoadSettingsElevationDataMenu.Separator = 'on';
			app.LoadSettingsElevationDataMenu.Checked = 'on';
			app.LoadSettingsElevationDataMenu.Text = 'Load elevation-data (default)';
			app.LoadSettingsDoNotLoadElevationDataMenu = uimenu(app.LoadOSMandelevationdatasettingsMenu);
			app.LoadSettingsDoNotLoadElevationDataMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadSettingsDoNotLoadElevationDataMenuSelected, true);
			app.LoadSettingsDoNotLoadElevationDataMenu.Tooltip = {'Elevation data is not loaded.'; 'This results in a flat map. Another way to create a flat map is to set the superelevation to zero.'};
			app.LoadSettingsDoNotLoadElevationDataMenu.Text = 'Do not load elevation-data (flat map)';
			app.CreatemapMenu = uimenu(app.FileMenu);
			app.CreatemapMenu.MenuSelectedFcn = createCallbackFcn(app, @CreatemapMenuSelected, true);
			app.CreatemapMenu.Tooltip = {'Create the 2D map.'; 'This is the drawing layer that allows manual changes to the map layout. Procedure:'; '- Delete existing map objects.'; '- Filter the OSM data using the object include and exclude tags.'; '- Connect lines with the same start and end point.'; '- Reduce data: delete lines that are too short, and areas that are too small (see project parameters reduce_nodes, reduce_lines, reduce_areas).'; '- Print the map objects to the 2D map.'; '- Print the legend in the 2D map (if activated).'; '- Sort map objects by object priority (see button of the same name).'; '- Create a text file “* - create map log.txt” with useful information such as the length of open lines and the area of closed lines. The log is only created if the options “Recreate the whole map”, “Create Texts” and “Create Symbols” are activated in the “Create map settings”.'};
			app.CreatemapMenu.Separator = 'on';
			app.CreatemapMenu.Text = 'Create map';
			app.CreatemapSettingsMenu = uimenu(app.FileMenu);
			app.CreatemapSettingsMenu.Tooltip = {'Settings for the “Create map” function.'};
			app.CreatemapSettingsMenu.Text = 'Create map settings';
			app.CreatemapSettingsRecreateWholeMapMenu = uimenu(app.CreatemapSettingsMenu);
			app.CreatemapSettingsRecreateWholeMapMenu.MenuSelectedFcn = createCallbackFcn(app, @CreatemapSettingsRecreateWholeMapMenuSelected, true);
			app.CreatemapSettingsRecreateWholeMapMenu.Tooltip = {'All existing map objects and the legend are deleted and the entire map is recreated.'; 'This is the default setting.'};
			app.CreatemapSettingsRecreateWholeMapMenu.Checked = 'on';
			app.CreatemapSettingsRecreateWholeMapMenu.Text = 'Recreate the whole map (default)';
			app.CreatemapSettingsRecreateCertainObjectsMenu = uimenu(app.CreatemapSettingsMenu);
			app.CreatemapSettingsRecreateCertainObjectsMenu.MenuSelectedFcn = createCallbackFcn(app, @CreatemapSettingsRecreateCertainObjectsMenuSelected, true);
			app.CreatemapSettingsRecreateCertainObjectsMenu.Tooltip = {'Only the map objects of specified object numbers are deleted and recreated. The remaining map objects and the legend are not changed.'; '- This option is useful for applying changes to the project parameters of map objects.'; '- The object numbers of the new map objects to be created are entered when “Create map” is executed.'};
			app.CreatemapSettingsRecreateCertainObjectsMenu.Text = 'Recreate certain objects, keep the rest';
			app.CreatemapSettingsRecreateLegendMenu = uimenu(app.CreatemapSettingsMenu);
			app.CreatemapSettingsRecreateLegendMenu.MenuSelectedFcn = createCallbackFcn(app, @CreatemapSettingsRecreateLegendMenuSelected, true);
			app.CreatemapSettingsRecreateLegendMenu.Tooltip = {'Only the legend is deleted and recreated.'; '- The legend only contains map objects that are specified in the project parameters of the legend AND also appear on the map. This option is therefore useful after changes to the project parameters of the legend or if map objects with certain object numbers have been newly added or completely deleted.'; '- The creation of the legend must be activated in the project parameters.'};
			app.CreatemapSettingsRecreateLegendMenu.Text = 'Recreate the legend';
			app.CreatemapSettingsCreateTextsMenu = uimenu(app.CreatemapSettingsMenu);
			app.CreatemapSettingsCreateTextsMenu.MenuSelectedFcn = createCallbackFcn(app, @CreatemapSettingsCreateTextsMenuSelected, true);
			app.CreatemapSettingsCreateTextsMenu.Tooltip = {'When creating line and area map objects, the texts are also generated.'; 'This is the default setting. Deactivating this option can save time when testing changes to the project parameters of map objects.'};
			app.CreatemapSettingsCreateTextsMenu.Separator = 'on';
			app.CreatemapSettingsCreateTextsMenu.Checked = 'on';
			app.CreatemapSettingsCreateTextsMenu.Text = 'Create Texts (default)';
			app.CreatemapSettingsCreateSymbolsMenu = uimenu(app.CreatemapSettingsMenu);
			app.CreatemapSettingsCreateSymbolsMenu.MenuSelectedFcn = createCallbackFcn(app, @CreatemapSettingsCreateSymbolsMenuSelected, true);
			app.CreatemapSettingsCreateSymbolsMenu.Tooltip = {'When creating line and area map objects, the symbols are also created.'; 'This is the default setting. Deactivating this option can save time when testing changes to the project parameters of map objects.'};
			app.CreatemapSettingsCreateSymbolsMenu.Checked = 'on';
			app.CreatemapSettingsCreateSymbolsMenu.Text = 'Create Symbols (default)';
			app.CreatemapSettingsShowTestplotsMenu = uimenu(app.CreatemapSettingsMenu);
			app.CreatemapSettingsShowTestplotsMenu.MenuSelectedFcn = createCallbackFcn(app, @CreatemapSettingsShowTestplotsMenuSel, true);
			app.CreatemapSettingsShowTestplotsMenu.Tooltip = {'Activates test plots that explain the reducing of the data during "Create map":'; '1) The test plots are generated during the execution of "Create map".'; '2) When you click on the menu item, you are asked for the object numbers for which test plots are to be created (e.g.: 1,2,5:7 --> Show ObjNo 1, 2, 5, 6, 7). If the inputs are deleted, no more test plots are generated and the checkbox is no longer visible.'; '3) Display of data reduction for lines with six plots:'; '- On the left you can see the lines of the selected object number.'; '- On the right you can see the lines of all objects  with the same color.'; '- The first line shows the unreduced data.'; '- The middle line shows the result after deleting unbranched lines. Parameter:'; 'obj(ObjNo).reduce_lines.mindiag_unbranched'; 'obj(ObjNo).reduce_lines.minlength_unbranched'; '- The bottom line shows the result after branched lines have been deleted. Parameters:'; 'obj(ObjNo).reduce_lines.mindiag_branched'; 'obj(ObjNo).reduce_lines.minlength_branched'; '4) Display of data reduction for areas:'; 'The simplification of areas is controlled by the parameters PP.obj(ObjNo).reduce_areas. The outlines are alternately shifted inwards and outwards or vice versa. In this way, for example, small areas can disappear or combine to form larger areas. Any remaining areas that are too small are then deleted. These steps are visualized in the test plot.'};
			app.CreatemapSettingsShowTestplotsMenu.Separator = 'on';
			app.CreatemapSettingsShowTestplotsMenu.Text = 'Create map: Show test plots';
			app.CreatemapSettingsShowLegendTestplotMenu = uimenu(app.CreatemapSettingsMenu);
			app.CreatemapSettingsShowLegendTestplotMenu.MenuSelectedFcn = createCallbackFcn(app, @CreatemapSettingsShowLegendTestplotMenuSelected, true);
			app.CreatemapSettingsShowLegendTestplotMenu.Tooltip = {'When a new legend is created, a test plot is generated with the row and column spacing of the legend entries.'; 'This is useful when editing the project parameters of the legend.'};
			app.CreatemapSettingsShowLegendTestplotMenu.Text = 'Create legend: Show test plot';
			app.File_MapEditing_Menu = uimenu(app.FileMenu);
			app.File_MapEditing_Menu.Tooltip = {'After “Create map”, the map usually has to be edited manually. All functions that support editing and improve the result are listed here.'};
			app.File_MapEditing_Menu.Separator = 'on';
			app.File_MapEditing_Menu.Text = 'Map editing';
			app.MapEdit_DetectOverlappedTextsSymbols_Menu = uimenu(app.File_MapEditing_Menu);
			app.MapEdit_DetectOverlappedTextsSymbols_Menu.MenuSelectedFcn = createCallbackFcn(app, @MapEdit_DetectOverlappedTextsSymbols_MenuSelected, true);
			app.MapEdit_DetectOverlappedTextsSymbols_Menu.Tooltip = {'Detect overlapping texts and symbols.'; '- Texts and symbols should always be fully visible and therefore not overlap each other.'; '- This function creates a preview polygon with all intersections of texts and symbols. Move the texts and symbols until no more intersections are detected. To ensure that the preview polygon does not cover the remaining map objects, you can move it to the background using the “Back / First” button.'};
			app.MapEdit_DetectOverlappedTextsSymbols_Menu.Text = 'Detect overlapping texts and symbols';
			app.MapEdit_DetectMisplacedTextsSymbols_Menu = uimenu(app.File_MapEditing_Menu);
			app.MapEdit_DetectMisplacedTextsSymbols_Menu.MenuSelectedFcn = createCallbackFcn(app, @MapEdit_DetectMisplacedTextsSymbols_MenuSelected, true);
			app.MapEdit_DetectMisplacedTextsSymbols_Menu.Tooltip = {'This function detects whether and how many texts and symbols overlap other areas with a certain color number.'; '- In some cases, texts and symbols should serve as labels for other pieces: for example, in a puzzle, each piece should have a handle (realized with a high symbol) and a label. This function can be used to systematically check that each region of a certain color is overlapped by a minimum and maximum number of texts and symbols with a certain object number. If possible, such an error should not be discovered after printing.'; '- It is also checked whether texts and symbols stand alone and do not overlap any region of a certain color.'; '- The result is a preview polygon assigned to the selected color number with all incorrectly placed texts and symbols. Move the texts and symbols until no more errors are detected. To prevent the preview polygon from covering the remaining map objects, you can move it to the background using the “Back / First” button.'; '- This function uses the “united equal colors”: This combines all overlapping equal colors of visible pieces into a single area, just like when creating the STL files. Before execution, the united equal colors must be created by pressing the button “Create united equal colors”.'; '- This function should be executed before “Simplify map” and also afterwards.'};
			app.MapEdit_DetectMisplacedTextsSymbols_Menu.Text = 'Detect misplaced texts and symbols';
			app.MapEdit_SimplifyMap_Menu = uimenu(app.File_MapEditing_Menu);
			app.MapEdit_SimplifyMap_Menu.MenuSelectedFcn = createCallbackFcn(app, @MapEdit_SimplifyMap_MenuSelected, true);
			app.MapEdit_SimplifyMap_Menu.Tooltip = {'Simplify map.'; 'The purpose of this function is to reduce the height of the map and thus the amount of material and time required for printing. This is achieved by cutting up the map objects and thus preventing parts with different colors from having to be stacked on top of each other. This function should only be executed once all objects have been arranged!'; 'Steps performed in detail:'; '- Cut all map objects to the printout limits.'; '- Cut all objects of the legend to the limits of the legend.'; '- Subtract the area of the legend from all objects that do not belong to the legend.'; '- Cut map objects to each other to reduce overlaps. '; 'An overview of which objects cut each other can be found after loading the project parameters in the text file “* - projpar summary.txt” saved in the project directory. The behavior depends on the object priorities, color priorities and the object-specific parameters cut_obj_of_lp, cut_by_obj_of_hp.'; '- Delete parts that are too small (for tolerances, see project parameters colorspec.simplify_map).'; '- Separation of lines: Cut lines at the edges of areas (if activated, see project parameters colorspec.simplify_map).'; '- Separation of lines: Cut lines that have large differences in height (if activated, see project parameters colorspec.simplify_map).'; '- Calculation of “united equal colors” in preparation for the “Cut into pieces” function.'};
			app.MapEdit_SimplifyMap_Menu.Separator = 'on';
			app.MapEdit_SimplifyMap_Menu.Text = 'Simplify map';
			app.MapEdit_SimplifyMapSettings_ShowTestplots_Menu = uimenu(app.File_MapEditing_Menu);
			app.MapEdit_SimplifyMapSettings_ShowTestplots_Menu.MenuSelectedFcn = createCallbackFcn(app, @MapEdit_SimplifyMapSettings_ShowTestplots_MenuSel, true);
			app.MapEdit_SimplifyMapSettings_ShowTestplots_Menu.Tooltip = {'Activates test plots that display the polygons of selected color numbers before and after “Simplify map”:'; '- The test plots are generated during the execution of “Simplify map”.'; '- When you click on the menu item, you are asked for the color numbers for which test plots are to be created (e.g.: 1,2,5:7 --> Show ColNo 1, 2, 5, 6, 7). If the entries are deleted, no more test plots are created and the checkbox is no longer visible.'};
			app.MapEdit_SimplifyMapSettings_ShowTestplots_Menu.Text = 'Simplify map setting: Show test plots';
			app.MapEdit_CutIntoPiecesMapobjects_Menu = uimenu(app.File_MapEditing_Menu);
			app.MapEdit_CutIntoPiecesMapobjects_Menu.MenuSelectedFcn = createCallbackFcn(app, @MapEdit_CutIntoPiecesMapobjects_MenuSelected, true);
			app.MapEdit_CutIntoPiecesMapobjects_Menu.Tooltip = {'Final cutting of map objects by using previously defined "cutting lines".'; 'This has three possible purposes:'; '- Limit the maximum size of the individual parts to be printed to the maximum object size in x and y direction. This should be done after executing “File - Map editing - Simplify map”, because the size of the lines and areas can still change.'; '- Cut the lines and areas into smaller puzzle pieces if desired.'; '- Divide lines manually so that the printed lines don''t get too high and the slots don''t get too deep. This is only necessary if the automatic cutting of lines in “File - Map editing - Simplify map” is switched off or is not working properly.'};
			app.MapEdit_CutIntoPiecesMapobjects_Menu.Separator = 'on';
			app.MapEdit_CutIntoPiecesMapobjects_Menu.Text = 'Cut into pieces';
			app.MapEdit_DetectSmallPieces_Menu = uimenu(app.File_MapEditing_Menu);
			app.MapEdit_DetectSmallPieces_Menu.MenuSelectedFcn = createCallbackFcn(app, @MapEdit_DetectSmallPieces_MenuSelected, true);
			app.MapEdit_DetectSmallPieces_Menu.Tooltip = {'This function detects small pieces of the same color, based on the "united equal colors".'; 'Small parts can also be selected using the “Select” button. However, it is possible that several small parts of different map objects, but with the same color, are combined to form a larger part. In such cases, this function should be used to identify parts that are too small.'};
			app.MapEdit_DetectSmallPieces_Menu.Separator = 'on';
			app.MapEdit_DetectSmallPieces_Menu.Text = 'Detect small pieces';
			app.MapEdit_DetectFragilePieces_Menu = uimenu(app.File_MapEditing_Menu);
			app.MapEdit_DetectFragilePieces_Menu.MenuSelectedFcn = createCallbackFcn(app, @MapEdit_DetectFragilePieces_MenuSelected, true);
			app.MapEdit_DetectFragilePieces_Menu.Tooltip = {'This function detects whether a part is fragile by shifting the contour inwards by half the minimum width. If there is a difference between before and after and the difference area is not at the margin, the part may be too narrow at this point.'; '- The detected narrow sections can be reinforced, for example, by moving the vertices at these sections with “Move vertex” or by placing lines of the desired width over these sections with “Preview line to line map object”.'; '- The result is a preview polygon assigned to the selected color number with all possible points of breakage.'; '- This function uses the "united equal colors": This combines all overlapping equal colors of visible pieces into a single area, just like when creating the STL files. Before execution, the united equal colors must be created by pressing the button "Create united equal colors".'};
			app.MapEdit_DetectFragilePieces_Menu.Text = 'Detect fragile pieces';
			app.MapEdit_DetectSmallHoles_Menu = uimenu(app.File_MapEditing_Menu);
			app.MapEdit_DetectSmallHoles_Menu.MenuSelectedFcn = createCallbackFcn(app, @MapEdit_DetectSmallHoles_MenuSelected, true);
			app.MapEdit_DetectSmallHoles_Menu.Tooltip = {'This function can be used to detect holes (inner boundaries) in map objects in order to close them if desired.'; '- Reason: If an area has a hole in it (for example a clearing in a forest), it can cause the underlying color to fill the hole with a tall, narrow and therefore fragile column. Holes can also occur if no OSM data is defined for certain areas.'; '- The hole can be removed by covering the hole with a polygon like a patch, which is assigned the same object number as the rest of the area by using “Preview to map object” (without “Merge”).'; '- The result is a preview polygon assigned to the selected color number with all holes that are too small.'; '- This function uses the “united equal colors”: This combines all overlapping equal colors of visible pieces into a single area, just like when creating the STL files. Before execution, the united equal colors must be created by pressing the button “Create united equal colors”.'};
			app.MapEdit_DetectSmallHoles_Menu.Text = 'Detect small holes';
			app.CreatemapSTLfilesMenu = uimenu(app.FileMenu);
			app.CreatemapSTLfilesMenu.MenuSelectedFcn = createCallbackFcn(app, @CreatemapSTLfilesMenuSelected, true);
			app.CreatemapSTLfilesMenu.Tooltip = {'Create print data of the map.'; '1) This function can take a long time. It is divided into two phases:'; '- Calculate triangulation data of the top sides of all parts.'; '- Calculate the z-value of the bottom side of all tiles (parameter z_bottom_tilebase).'; '- Calculate the bottom sides of all parts, connect them to the top sides and save them as STL. A preview of the print data is also displayed in this phase.'; '2) The following files are created (partially selectable):'; '- A separate STL file is created for each tile and each color.'; 'For multi-material prints, the non-stand-alone colors of a tile are divided into the respective underlying stand-alone colors and each saved as a separate file. The optional specification of zmin and zcenter in the file name makes vertical alignment in the slicer program easier.'; '- 3D images of the print data with the same file names as the STL files, for example in jpg (bitmap), fig (MATLAB figure) or pdf format.'; '- Corresponding 3D images of all individual tiles and the entire map.'; '- A 2D image of the entire map (“* - map topview”), which can serve as a template for assembling the printed parts.'; '- A text file with useful information (“* - map2stl summary.txt”, in the project directory).'; '- A copy of the project parameter file (in the project directory).'; '3) The geometries calculated by the program may contain errors such as holes. Such errors can be easily corrected with online tools or directly in the slicer program of the 3D printer. However, to reduce this effort, a function has been implemented that can correct simple errors in advance. Two versions are therefore saved in different subdirectories:'; '- The STL data calculated by the program.'; '- The STL data repaired by the program. If the repair was not successful, the file is saved with the addition “* - to be repaired.stl”.'};
			app.CreatemapSTLfilesMenu.Separator = 'on';
			app.CreatemapSTLfilesMenu.Text = 'Create map STL files';
			app.CreateframeSTLfilesMenu = uimenu(app.FileMenu);
			app.CreateframeSTLfilesMenu.MenuSelectedFcn = createCallbackFcn(app, @CreateframeSTLfilesMenuSelected, true);
			app.CreateframeSTLfilesMenu.Tooltip = {'Create print data for the frame of the map.'; '1) Before execution, the dimensions of the frame profile from the project parameter file are displayed and can be changed directly without having to reload the project parameters. This should make it easier to set the frame profile as, for example, the appropriate frame height also depends on the terrain height of the map. The frame settings should nevertheless be transferred to the project parameter file for documentation purposes, as the frame settings will be overwritten after reloading the project parameters.'; '2) A separate STL file is created for each tile.'; '3) After execution, a preview of the frame and its cross-section is also displayed.'; 'If the map STL files have already been created, the frame is also displayed in the overall view of the map. This makes it easy to set the frame height.'; '4) The geometries calculated by the program may contain errors such as holes. Such errors can be easily corrected with online tools or directly in the slicer program of the 3D printer. However, to reduce this effort, a function has been implemented that can correct simple errors in advance. Two versions are therefore saved in different subdirectories:'; '- The STL data calculated by the program.'; '- The STL data repaired by the program. If the repair was not successful, the file is saved with the addition “* - to be repaired.stl”.'};
			app.CreateframeSTLfilesMenu.Text = 'Create frame STL files';
			app.CreatetestsampleSTLfilesMenu = uimenu(app.FileMenu);
			app.CreatetestsampleSTLfilesMenu.Tooltip = {'Selection of test samples.'};
			app.CreatetestsampleSTLfilesMenu.Separator = 'on';
			app.CreatetestsampleSTLfilesMenu.Text = 'Create test sample STL files';
			app.CreatecolorsampleSTLfilesMenu = uimenu(app.CreatetestsampleSTLfilesMenu);
			app.CreatecolorsampleSTLfilesMenu.MenuSelectedFcn = createCallbackFcn(app, @CreatecolorsampleSTLfilesMenuSelected, true);
			app.CreatecolorsampleSTLfilesMenu.Tooltip = {'Create print data of a color sample.'; 'Procedure:'; '1) Enter the data of the filament or resin color under a free color number in the project parameter file (see parameter "color").'; '2) If required, adjust the settings of the color sample in the project file (see parameter "testsample.colorsample").'; '3) Load project parameters.'; '4) Call the function, select a storage location and select the color number.'; '5) Print the generated file with the relevant color.'};
			app.CreatecolorsampleSTLfilesMenu.Text = 'Create color sample STL files';
			app.CreateTextSampleMenu = uimenu(app.CreatetestsampleSTLfilesMenu);
			app.CreateTextSampleMenu.MenuSelectedFcn = createCallbackFcn(app, @CreateTextSampleMenuSelected, true);
			app.CreateTextSampleMenu.Tooltip = {'Create print data of a text sample.'; 'The aim is to find text settings with which all characters can be printed correctly and completely. The challenge here is to print fonts as small as possible in order to be able to display a lot of information on the map. Because texts for 3D printing must have a certain minimum line width, the following strategies may be helpful:'; '- Choose a font with wide letters (for example "Arial Black").'; '- Choose a font with thin letters and widen it using the “fontwidening” parameter. This ensures that all lines have almost the same width.'; 'Procedure:'; '1) Adjust the settings of the text sample in the project file (parameter "testsample.textsample"):'; '- The fontname, fontweight and fontangle settings are fixed for the text sample.'; '- The fontsize and fontwidening settings can be varied step by step.'; '- The nozzle diameter has a major influence on the result with Fused Deposition Modeling (FDM) and should be documented on the text sample (parameter “testsample.textsample.print_settings”).'; '2) Load project parameters.'; '3) Call the function and select a storage location.'; '4) Print the generated file.'; '5) Selection of the best settings and editing of the already defined character styles or definition of new character styles (parameter “charstyle”). These character styles can then be assigned to the texts of map objects (parameter “obj.textpar.charstyle_no”).'};
			app.CreateTextSampleMenu.Text = 'Create text sample STL files';
			app.CreateSymbolSampleMenu = uimenu(app.CreatetestsampleSTLfilesMenu);
			app.CreateSymbolSampleMenu.MenuSelectedFcn = createCallbackFcn(app, @CreateSymbolSampleMenuSelected, true);
			app.CreateSymbolSampleMenu.Tooltip = {'Create print data of symbol samples.'; 'The symbol samples can be used to check whether the symbols used on the map can be printed correctly. This should be done in these cases, for example:'; '- Use of a larger nozzle diameter with Fused Deposition Modeling (FDM).'; '- Creating your own symbols.'; 'Procedure:'; '1) Adjust the settings of the symbol pattern in the project file (parameter "testsample.symbolsample"):'; '2) Load the project parameters.'; '3) Call up the function, select a storage location and select the symbol numbers to be printed (multiple selection possible).'; '4) Print out the generated file. The nozzle diameter has a major influence on the result in fused deposition modeling (FDM) and should be documented on the symbol sample after printing.'};
			app.CreateSymbolSampleMenu.Text = 'Create symbol sample STL files';
			app.CreateCharStyleSampleMenu = uimenu(app.CreatetestsampleSTLfilesMenu);
			app.CreateCharStyleSampleMenu.MenuSelectedFcn = createCallbackFcn(app, @CreateCharStyleSampleMenuSelected, true);
			app.CreateCharStyleSampleMenu.Tooltip = {'Create print data of character style samples.'; 'The character style samples can be used to check whether the character style settings can be printed correctly. This should be done in these cases, for example:'; '- Use of a larger nozzle diameter with Fused Deposition Modeling (FDM).'; '- Changing character style settings.'; 'Procedure:'; '1) Adjust the character style settings in the project file (parameters "testsample.charstylesample" and "charstyle"):'; '2) Load the project parameters.'; '3) Call up the function, select a storage location and select the character style numbers to be printed (multiple selection possible).'; '4) Print out the generated file. The nozzle diameter has a major influence on the result in fused deposition modeling (FDM) and should be documented on the character style sample after printing.'};
			app.CreateCharStyleSampleMenu.Text = 'Create character style sample STL files';
			app.FileExitMenu = uimenu(app.FileMenu);
			app.FileExitMenu.MenuSelectedFcn = createCallbackFcn(app, @FileExitMenuSelected, true);
			app.FileExitMenu.Tooltip = {'Exit program.'; 'When you exit the program, all windows are closed and all unsaved changes are lost.'};
			app.FileExitMenu.Separator = 'on';
			app.FileExitMenu.Text = 'Exit';
			app.View = uimenu(app.MapLab3D);
			app.View.Text = 'View';
			app.ShowMapObjectsTable_Menu = uimenu(app.View);
			app.ShowMapObjectsTable_Menu.MenuSelectedFcn = createCallbackFcn(app, @ShowMapObjectsTable_MenuSelected, true);
			app.ShowMapObjectsTable_Menu.Tooltip = {'Shows and hides the map objects table.'; '- Active: The map objects table is visible and map objects can be selected in the table.'; '- Deactivated: The map objects table is not visible. The execution time of functions is faster and the size of the window is smaller.'};
			app.ShowMapObjectsTable_Menu.Checked = 'on';
			app.ShowMapObjectsTable_Menu.Text = 'Show map objects table (off: faster)';
			app.AutoSortNewMapObjects_Menu = uimenu(app.View);
			app.AutoSortNewMapObjects_Menu.MenuSelectedFcn = createCallbackFcn(app, @AutoSortNewMapObjects_MenuSelected, true);
			app.AutoSortNewMapObjects_Menu.Tooltip = {'Automatic sorting of map objects.'; '- Active: For example, map objects newly created after duplication are sorted directly below the old object in the map objects table'; '- Deactive: Newly created map objects are appended to the end of the table. The execution time of functions is faster.'};
			app.AutoSortNewMapObjects_Menu.Checked = 'on';
			app.AutoSortNewMapObjects_Menu.Text = 'Automatically sort new map objects (off: faster)';
			app.ShowMapfigureLegend_Menu = uimenu(app.View);
			app.ShowMapfigureLegend_Menu.MenuSelectedFcn = createCallbackFcn(app, @ShowMapfigureLegend_MenuSelected, true);
			app.ShowMapfigureLegend_Menu.Tooltip = {'Display of the 2D map figure legend.'; 'This legend explains the line style of the OSM data limits, the printout limits, the tile limits and the frame. Because displaying the legend slows down program execution considerably and partially covers the drawing layer, it is recommended to hide this legend by default.'};
			app.ShowMapfigureLegend_Menu.Text = 'Show mapfigure legend (off: faster)';
			app.ShowTooltips_Menu = uimenu(app.View);
			app.ShowTooltips_Menu.MenuSelectedFcn = createCallbackFcn(app, @ShowTooltips_MenuSelected, true);
			app.ShowTooltips_Menu.Tooltip = {'tooltip_is_set_by_app'};
			app.ShowTooltips_Menu.Separator = 'on';
			app.ShowTooltips_Menu.Text = 'Show Tooltips';
			app.Cre3dPrevMenu = uimenu(app.View);
			app.Cre3dPrevMenu.MenuSelectedFcn = createCallbackFcn(app, @Cre3dPrevMenuSel, true);
			app.Cre3dPrevMenu.Tooltip = {'Create a 3D preview of the map.'; 'This function gives an impression of the final look of the map and is helpful for setting the superelevation (enlargement of the height compared to the length).'; '- It is recommended to display only the elevation data, otherwise the execution time can be very long. It is also possible to load only the OSM-boundaries when loading the OSM and elevation data (see “Load OSM- and elevation-data settings”) in order to save time with large maps.'};
			app.Cre3dPrevMenu.Separator = 'on';
			app.Cre3dPrevMenu.Text = 'Create 3D map preview';
			app.Cre3dPrevSetMenu = uimenu(app.View);
			app.Cre3dPrevSetMenu.Tooltip = {'Settings for the “Create 3D map preview” function.'};
			app.Cre3dPrevSetMenu.Text = 'Create 3D map preview settings';
			app.Cre3dPrevSet_re_filtint_Menu = uimenu(app.Cre3dPrevSetMenu);
			app.Cre3dPrevSet_re_filtint_Menu.MenuSelectedFcn = createCallbackFcn(app, @Cre3dPrevSet_re_filtint_MenuSel, true);
			app.Cre3dPrevSet_re_filtint_Menu.Tooltip = {'Display of interpolated and filtered elevation data.'; '- For large maps, many data points must be displayed with this setting and the display will be slower.'; '- For small maps and for a large scale factor, this setting is recommended compared to the original resolution.'};
			app.Cre3dPrevSet_re_filtint_Menu.Checked = 'on';
			app.Cre3dPrevSet_re_filtint_Menu.Text = 'Interpolated and filtered';
			app.Cre3dPrevSet_re_origfilt_Menu = uimenu(app.Cre3dPrevSetMenu);
			app.Cre3dPrevSet_re_origfilt_Menu.MenuSelectedFcn = createCallbackFcn(app, @Cre3dPrevSet_re_origfilt_MenuSel, true);
			app.Cre3dPrevSet_re_origfilt_Menu.Tooltip = {'Display of the original resolution after filtering. The original resolution may be limited to a certain size to save memory space.'; 'It is possible to smooth the elevation data with a 2-D Gaussian filter (see parameters “colorspec.ele_filtset”). For large scale numbers or if the original resolution has many more points than the interpolated height data, the span of the filter is automatically increased.'};
			app.Cre3dPrevSet_re_origfilt_Menu.Text = 'Original resolution filtered';
			app.Cre3dPrevSet_re_orig_Menu = uimenu(app.Cre3dPrevSetMenu);
			app.Cre3dPrevSet_re_orig_Menu.MenuSelectedFcn = createCallbackFcn(app, @Cre3dPrevSet_re_orig_MenuSel, true);
			app.Cre3dPrevSet_re_orig_Menu.Tooltip = {'Display of the original resolution, limited to a certain size to save memory space.'};
			app.Cre3dPrevSet_re_orig_Menu.Text = 'Original resolution';
			app.Cre3dPrevSet_sh_ele_Menu = uimenu(app.Cre3dPrevSetMenu);
			app.Cre3dPrevSet_sh_ele_Menu.MenuSelectedFcn = createCallbackFcn(app, @Cre3dPrevSet_sh_ele_MenuSel, true);
			app.Cre3dPrevSet_sh_ele_Menu.Tooltip = {'Only display height data.'; 'This setting results in the shortest execution time. It is not necessary to execute “Create map” beforehand.'};
			app.Cre3dPrevSet_sh_ele_Menu.Separator = 'on';
			app.Cre3dPrevSet_sh_ele_Menu.Checked = 'on';
			app.Cre3dPrevSet_sh_ele_Menu.Text = 'Show only elevation (recommended)';
			app.Cre3dPrevSet_sh_lines_Menu = uimenu(app.Cre3dPrevSetMenu);
			app.Cre3dPrevSet_sh_lines_Menu.MenuSelectedFcn = createCallbackFcn(app, @Cre3dPrevSet_sh_lines_MenuSel, true);
			app.Cre3dPrevSet_sh_lines_Menu.Tooltip = {'Display map objects with their outlines on top of the elevation data.'; 'Notes:'; '- The additional display of map objects increases the execution time.'; '- The “Create map” function must be executed beforehand. To save time and still get an impression of the later result, it may be possible to display only one object number, for example only highways or settlement areas.'};
			app.Cre3dPrevSet_sh_lines_Menu.Text = 'Show objects as lines';
			app.Cre3dPrevSet_sh_patches_Menu = uimenu(app.Cre3dPrevSetMenu);
			app.Cre3dPrevSet_sh_patches_Menu.MenuSelectedFcn = createCallbackFcn(app, @Cre3dPrevSet_sh_patches_MenuSel, true);
			app.Cre3dPrevSet_sh_patches_Menu.Tooltip = {'Display of map objects as colored areas.'; 'This representation comes very close to the final result. Notes:'; '- As of all areas only the part visible from above is initially calculated, the execution time is very long.'; '- The “Create map” function must be executed beforehand. To save time and still get an impression of the later result, it may be possible to display only one object number, for example only highways or settlement areas.'; '- To save time, texts and symbols are not displayed.'};
			app.Cre3dPrevSet_sh_patches_Menu.Text = 'Show objects as patches';
			app.Cre3dPrevSet_ti_all_Menu = uimenu(app.Cre3dPrevSetMenu);
			app.Cre3dPrevSet_ti_all_Menu.MenuSelectedFcn = createCallbackFcn(app, @Cre3dPrevSet_ti_all_MenuSel, true);
			app.Cre3dPrevSet_ti_all_Menu.Tooltip = {'Display the entire map with all tiles.'};
			app.Cre3dPrevSet_ti_all_Menu.Separator = 'on';
			app.Cre3dPrevSet_ti_all_Menu.Checked = 'on';
			app.Cre3dPrevSet_ti_all_Menu.Text = 'Show all';
			app.Cre3dPrevSet_ti_1_Menu = uimenu(app.Cre3dPrevSetMenu);
			app.Cre3dPrevSet_ti_1_Menu.MenuSelectedFcn = createCallbackFcn(app, @Cre3dPrevSet_ti_1_MenuSel, true);
			app.Cre3dPrevSet_ti_1_Menu.Tooltip = {'Only display tile no. 1.'};
			app.Cre3dPrevSet_ti_1_Menu.Text = 'Show tile number 1';
			app.Cre3dPrevSet_ti_ask_Menu = uimenu(app.Cre3dPrevSetMenu);
			app.Cre3dPrevSet_ti_ask_Menu.MenuSelectedFcn = createCallbackFcn(app, @Cre3dPrevSet_ti_ask_MenuSel, true);
			app.Cre3dPrevSet_ti_ask_Menu.Tooltip = {'Display a single user-selected tile.'};
			app.Cre3dPrevSet_ti_ask_Menu.Text = 'Ask for tile number';
			app.Cre3dPrevSet_shlimti_Menu = uimenu(app.Cre3dPrevSetMenu);
			app.Cre3dPrevSet_shlimti_Menu.MenuSelectedFcn = createCallbackFcn(app, @Cre3dPrevSet_shlimti_MenuSelected, true);
			app.Cre3dPrevSet_shlimti_Menu.Tooltip = {'Display of the OSM bounding box, the printout limits and the tile limits.'};
			app.Cre3dPrevSet_shlimti_Menu.Separator = 'on';
			app.Cre3dPrevSet_shlimti_Menu.Checked = 'on';
			app.Cre3dPrevSet_shlimti_Menu.Text = 'Show limits and tiles';
			app.Cre3dPrevSet_Filtersettings_Tilebase_Menu = uimenu(app.Cre3dPrevSetMenu);
			app.Cre3dPrevSet_Filtersettings_Tilebase_Menu.MenuSelectedFcn = createCallbackFcn(app, @Cre3dPrevSet_Filtersettings_Tilebase_MenuSelected, true);
			app.Cre3dPrevSet_Filtersettings_Tilebase_Menu.Tooltip = {'Selection of the filter settings for the display of filtered elevation data:'; 'The elevation data filtered with the filter settings for the tile base is displayed (color specification number 1).'};
			app.Cre3dPrevSet_Filtersettings_Tilebase_Menu.Separator = 'on';
			app.Cre3dPrevSet_Filtersettings_Tilebase_Menu.Checked = 'on';
			app.Cre3dPrevSet_Filtersettings_Tilebase_Menu.Text = 'Filter settings: Tile base';
			app.Cre3dPrevSet_Filtersettings_Ask_Menu = uimenu(app.Cre3dPrevSetMenu);
			app.Cre3dPrevSet_Filtersettings_Ask_Menu.MenuSelectedFcn = createCallbackFcn(app, @Cre3dPrevSet_Filtersettings_Ask_MenuSelected, true);
			app.Cre3dPrevSet_Filtersettings_Ask_Menu.Tooltip = {'Selection of the filter settings for the display of filtered elevation data:'; 'You will be asked for the color specification number.'};
			app.Cre3dPrevSet_Filtersettings_Ask_Menu.Text = 'Filter settings: Ask for color specification number';
			app.Cre3dPrevSet_Filtersettings_Finalresult_Menu = uimenu(app.Cre3dPrevSetMenu);
			app.Cre3dPrevSet_Filtersettings_Finalresult_Menu.MenuSelectedFcn = createCallbackFcn(app, @Cre3dPrevSet_Filtersettings_Finalresult_MenuSelected, true);
			app.Cre3dPrevSet_Filtersettings_Finalresult_Menu.Tooltip = {'Selection of filter settings for the display of filtered elevation data:'; 'Calculation of the height data as for “Create map STL files” to obtain a preview that is as realistic as possible. This requires the prior calculation of the “united equal colors”.'};
			app.Cre3dPrevSet_Filtersettings_Finalresult_Menu.Text = 'Filter settings: Final result';
			app.PlotOSMrawDataMenu = uimenu(app.View);
			app.PlotOSMrawDataMenu.MenuSelectedFcn = createCallbackFcn(app, @PlotOSMrawDataMenuSel, true);
			app.PlotOSMrawDataMenu.Tooltip = {'Display of a plot with all nodes and ways of the loaded OSM raw data.'};
			app.PlotOSMrawDataMenu.Separator = 'on';
			app.PlotOSMrawDataMenu.Text = 'Create OSM raw data preview';
			app.View_ShowContourLines_Menu = uimenu(app.View);
			app.View_ShowContourLines_Menu.MenuSelectedFcn = createCallbackFcn(app, @View_ShowContourLines_MenuSelected, true);
			app.View_ShowContourLines_Menu.Tooltip = {'Display of contour lines in the 2D map.'; 'The elevation data calculated with the filter settings for the tile base is displayed.'; 'Application examples:'; '- The coordinates of the natural peaks in the OSM data do not always match the loaded elevation data. The contour lines can be used to move the natural peaks to the highest point of a hill or mountain.'; '- When placing texts, care could be taken to ensure that the letters are approximately at the same height.'; '- When manually cutting lines that run above an area, the height (position in z-direction) of all cuts should not be lower than the lowest point of the area covered by the line. Otherwise, the bottom of the area would be lowered unnecessarily.'};
			app.View_ShowContourLines_Menu.Separator = 'on';
			app.View_ShowContourLines_Menu.Text = 'Show contour lines';
			app.View_ShowContourLinesSettings_Menu = uimenu(app.View);
			app.View_ShowContourLinesSettings_Menu.Tooltip = {'Settings for the “Show contour lines” function.'};
			app.View_ShowContourLinesSettings_Menu.Text = 'Show contour lines settings';
			app.View_ShowContourLines_Minor_Menu = uimenu(app.View_ShowContourLinesSettings_Menu);
			app.View_ShowContourLines_Minor_Menu.MenuSelectedFcn = createCallbackFcn(app, @View_ShowContourLines_Minor_MenuSelected, true);
			app.View_ShowContourLines_Minor_Menu.Tooltip = {'Show minor contour lines between the major contour lines.'; 'This increases the resolution and the clarity.'};
			app.View_ShowContourLines_Minor_Menu.Checked = 'on';
			app.View_ShowContourLines_Minor_Menu.Text = 'Show minor contour lines';
			app.View_ShowContourLines_MajorLabels_Menu = uimenu(app.View_ShowContourLinesSettings_Menu);
			app.View_ShowContourLines_MajorLabels_Menu.MenuSelectedFcn = createCallbackFcn(app, @View_ShowContourLines_MajorLabels_MenuSelected, true);
			app.View_ShowContourLines_MajorLabels_Menu.Tooltip = {'Show the heights in mm of the major contour lines.'};
			app.View_ShowContourLines_MajorLabels_Menu.Checked = 'on';
			app.View_ShowContourLines_MajorLabels_Menu.Text = 'Show contours with major labels';
			app.View_ShowContourLines_MinorLabels_Menu = uimenu(app.View_ShowContourLinesSettings_Menu);
			app.View_ShowContourLines_MinorLabels_Menu.MenuSelectedFcn = createCallbackFcn(app, @View_ShowContourLines_MinorLabels_MenuSelected, true);
			app.View_ShowContourLines_MinorLabels_Menu.Tooltip = {'Show the heights in mm of the minor contour lines.'};
			app.View_ShowContourLines_MinorLabels_Menu.Text = 'Show contours with minor labels';
			app.View_ShowTileNo_Menu = uimenu(app.View);
			app.View_ShowTileNo_Menu.MenuSelectedFcn = createCallbackFcn(app, @View_ShowTileNo_MenuSel, true);
			app.View_ShowTileNo_Menu.Tooltip = {'Show the tile numbers in the 2D map.'; 'For large maps with several tiles, the generated STL files are numbered with the tile number. This function can be used to display the arrangement of the tiles: from left to right and from top to bottom.'};
			app.View_ShowTileNo_Menu.Separator = 'on';
			app.View_ShowTileNo_Menu.Text = 'Show tile numbers on map';
			app.View_OpenFigure_Menu = uimenu(app.View);
			app.View_OpenFigure_Menu.MenuSelectedFcn = createCallbackFcn(app, @View_OpenFigure_MenuSel, true);
			app.View_OpenFigure_Menu.Tooltip = {'Open a MATLAB figure.'; 'When generating the STL data, the entire map, the individual tiles and the individual colors sorted by tile can also be saved as a MATLAB figure (see parameters “savefig_map”, ‘savefig_tile’, “savefig_tile_color”). This function can be used to reload this saved data. The advantage over an image as a jpg or pdf is that the image in the MATLAB figure can be enlarged, reduced in size or, in the case of a 3D image, rotated.'};
			app.View_OpenFigure_Menu.Separator = 'on';
			app.View_OpenFigure_Menu.Text = 'Open figure file (.fig)';
			app.View_Currentfigure_Menu = uimenu(app.View);
			app.View_Currentfigure_Menu.Text = 'Current figure';
			app.View_SetFigureSize_Menu = uimenu(app.View_Currentfigure_Menu);
			app.View_SetFigureSize_Menu.MenuSelectedFcn = createCallbackFcn(app, @View_SetFigureSize_MenuSelected, true);
			app.View_SetFigureSize_Menu.Tooltip = {'Enter the width and height of the currently active MATLAB figure in pixels:'; 'This is useful for taking screenshots of a figure that are always the same size.'; 'The size of a figure can also be changed by clicking and dragging with the mouse.'};
			app.View_SetFigureSize_Menu.Text = 'Set figure size';
			app.View_CopyToClipboard_Menu = uimenu(app.View_Currentfigure_Menu);
			app.View_CopyToClipboard_Menu.MenuSelectedFcn = createCallbackFcn(app, @View_CopyToClipboard_MenuSelected, true);
			app.View_CopyToClipboard_Menu.Tooltip = {'Copy a screenshot of the currently active MATLAB figure to the clipboard. The figure is always copied with a white background.'};
			app.View_CopyToClipboard_Menu.Text = 'Copy to clipboard';
			app.View_SaveToFile_Menu = uimenu(app.View_Currentfigure_Menu);
			app.View_SaveToFile_Menu.MenuSelectedFcn = createCallbackFcn(app, @View_SaveToFile_MenuSelected, true);
			app.View_SaveToFile_Menu.Tooltip = {'Save a screenshot of the currently active MATLAB figure as an image or vector graphic in a file. The figure is always saved with a white background.'};
			app.View_SaveToFile_Menu.Text = 'Save to file';
			app.View_ShowCameraToolbar_Menu = uimenu(app.View_Currentfigure_Menu);
			app.View_ShowCameraToolbar_Menu.MenuSelectedFcn = createCallbackFcn(app, @View_ShowCameraToolbar_MenuSelected, true);
			app.View_ShowCameraToolbar_Menu.Tooltip = {'Show the camera toolbar in the currently active MATLAB figure.'; 'The Camera Toolbar can be used to rotate, move or enlarge the view, for example.'};
			app.View_ShowCameraToolbar_Menu.Text = 'Show camera toolbar';
			app.View_ChangeVisibilityOf3DObjects_Menu = uimenu(app.View_Currentfigure_Menu);
			app.View_ChangeVisibilityOf3DObjects_Menu.MenuSelectedFcn = createCallbackFcn(app, @View_ChangeVisibilityOf3DObjects_MenuSelected, true);
			app.View_ChangeVisibilityOf3DObjects_Menu.Tooltip = {'Change the visibility of 3D map objects in the currently active MATLAB figure.'; 'This function can be used with the 3D plots of the map after executing "Menu: File - Create map STL files" (3D plot of one color, of one tile or of the whole map). This is useful to see how the individual parts are assembled.'};
			app.View_ChangeVisibilityOf3DObjects_Menu.Text = 'Change visibility of 3D objects';
			app.View_SwitchLightDarkTheme_Menu = uimenu(app.View_Currentfigure_Menu);
			app.View_SwitchLightDarkTheme_Menu.MenuSelectedFcn = createCallbackFcn(app, @View_SwitchLightDarkTheme_MenuSelected, true);
			app.View_SwitchLightDarkTheme_Menu.Tooltip = {'Switches the background in the currently active MATLAB figure between light and dark.'};
			app.View_SwitchLightDarkTheme_Menu.Text = 'Switch light/dark theme';
			app.ExtraMenu = uimenu(app.MapLab3D);
			app.ExtraMenu.Text = 'Extra';
			app.Save_ErrorLog_Menu = uimenu(app.ExtraMenu);
			app.Save_ErrorLog_Menu.MenuSelectedFcn = createCallbackFcn(app, @Save_ErrorLog_MenuSelected, true);
			app.Save_ErrorLog_Menu.Tooltip = {'Saving information for troubleshooting:'; '- Active: If an unexpected error occurs, a file (errorlog_data.mat) is saved that may help reproduce and fix the error. The file can be very large and saving it can take a long time, so this option is disabled by default.'; '- Deactivated: No error log is saved if an unexpected error occurs.'};
			app.Save_ErrorLog_Menu.Text = 'Save error log when an error occurs';
			app.CharStylePreview_Menu = uimenu(app.ExtraMenu);
			app.CharStylePreview_Menu.MenuSelectedFcn = createCallbackFcn(app, @CharStylePreview_MenuSelected, true);
			app.CharStylePreview_Menu.Tooltip = {'Shows a preview of all character styles set up in the project parameters file.'};
			app.CharStylePreview_Menu.Separator = 'on';
			app.CharStylePreview_Menu.Text = 'Display character style preview';
			app.LoadsymbolsMenu = uimenu(app.ExtraMenu);
			app.LoadsymbolsMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadsymbolsMenuSelected, true);
			app.LoadsymbolsMenu.Tooltip = {'Loading of self-created map symbols.'; '1) This function only needs to be carried out when changing the map symbols.'; '2) The use of symbols is set up in the project parameters. Symbols are either fixed for an object number or the selection is based on the key and value of the symbol. This makes it possible to display different symbols in just one object number, for example different hiking trail markings.'; '3) It is possible to create your own symbols. There are two options to do this:'; '- Create bitmaps with exactly 3 colors: symbol foreground (pure yellow), symbol background (pure green) and the remaining area not belonging to the symbol (pure black).'; '- Enter the xy-coordinates.'; '4) The file names of the bitmaps or the xy coordinates and other parameters are set up in the symbol parameter file in MS Excel format (e.g. “symbol_parameters.xlsx”). After calling this function, the symbol parameter file and the storage folder used to save the bitmaps must be selected (e.g. “C:\MapLab3D\Symbols”). All bitmaps are then loaded and a preview of each symbol is displayed. The preview is saved as a bitmap in a subdirectory for documentation purposes (e.g. “C:\MapLab3D\Symbols\Preview”).'; '5) The loaded symbols are saved permanently and are still available the next time the program is started.'};
			app.LoadsymbolsMenu.Separator = 'on';
			app.LoadsymbolsMenu.Text = 'Load symbols';
			app.Call_Osmosis_Menu = uimenu(app.ExtraMenu);
			app.Call_Osmosis_Menu.MenuSelectedFcn = createCallbackFcn(app, @Call_Osmosis_MenuSelected, true);
			app.Call_Osmosis_Menu.Tooltip = {'Create a command to call the additional program Osmosis to convert files into the OSM XML format (.osm).'; '1) OSM data can only be loaded by MapLab3D in OSM XML format (.osm). These files can become very large. For this reason, OSM data for large areas is only available in other formats and must be converted.'; '2) Osmosis is a command line Java application for processing OSM data (see https://wiki.openstreetmap.org/wiki/Osmosis). Osmosis is not part of MapLab3D, but must be installed and called by the user. The program just supports the use of Osmosis by generating a command to call Osmosis depending on the project parameters.'; '3) The following options are available in the settings:'; '- Selection of the file formats PBF (.pbf, highly compressed and optimized binary format) or OSM XML (.osm, human readable) of source and target.'; '- Extracting data within a specific bounding box.'; '- Reduction of the data depending on the project parameters.'; '4) When this function is called, the Osmosis command is copied to the clipboard and optionally the command prompt window is opened to simplify the execution of Osmosis. In addition, a text file with the same name as the destination is created for documentation purposes, which contains the Osmosis command and further information.'};
			app.Call_Osmosis_Menu.Separator = 'on';
			app.Call_Osmosis_Menu.Text = 'Create Osmosis call';
			app.Call_Osmosis_Settings_Menu = uimenu(app.ExtraMenu);

			app.Call_Osmosis_Settings_Menu.Tooltip = {'Settings for the “Call Osmosis” function.'};
			app.Call_Osmosis_Settings_Menu.Text = 'Create Osmosis call settings';
			app.Osmosis_Source_File_PBF_Menu = uimenu(app.Call_Osmosis_Settings_Menu);
			app.Osmosis_Source_File_PBF_Menu.MenuSelectedFcn = createCallbackFcn(app, @Osmosis_Source_File_PBF_MenuSelected, true);
			app.Osmosis_Source_File_PBF_Menu.Tooltip = {'Loading a source file in PBF format.'; 'With this source format, the data from “https://download.geofabrik.de/”, for example, can be used.'};
			app.Osmosis_Source_File_PBF_Menu.Checked = 'on';
			app.Osmosis_Source_File_PBF_Menu.Text = 'Source file extension: .pbf';
			app.Osmosis_Source_File_OSM_Menu = uimenu(app.Call_Osmosis_Settings_Menu);
			app.Osmosis_Source_File_OSM_Menu.MenuSelectedFcn = createCallbackFcn(app, @Osmosis_Source_File_OSM_MenuSelected, true);
			app.Osmosis_Source_File_OSM_Menu.Tooltip = {'Loading a source file in OSM XML format.'};
			app.Osmosis_Source_File_OSM_Menu.Text = 'Source file extension: .osm';
			app.Osmosis_Dest_File_PBF_Menu = uimenu(app.Call_Osmosis_Settings_Menu);
			app.Osmosis_Dest_File_PBF_Menu.MenuSelectedFcn = createCallbackFcn(app, @Osmosis_Dest_File_PBF_MenuSelected, true);
			app.Osmosis_Dest_File_PBF_Menu.Tooltip = {'The destination format is PBF.'; 'This option should be selected in order to initially reduce a large map section to the desired size without reducing the data it contains.'};
			app.Osmosis_Dest_File_PBF_Menu.Separator = 'on';
			app.Osmosis_Dest_File_PBF_Menu.Checked = 'on';
			app.Osmosis_Dest_File_PBF_Menu.Text = 'Destination file extension: .pbf';
			app.Osmosis_Dest_File_OSM_Menu = uimenu(app.Call_Osmosis_Settings_Menu);
			app.Osmosis_Dest_File_OSM_Menu.MenuSelectedFcn = createCallbackFcn(app, @Osmosis_Dest_File_OSM_MenuSelected, true);
			app.Osmosis_Dest_File_OSM_Menu.Tooltip = {'The destination format is OSM XML.'; 'This format can be loaded by MapLab3D (“File - Load OSM- and elevation-data”).'};
			app.Osmosis_Dest_File_OSM_Menu.Text = 'Destination file extension: .osm';
			app.Osmosis_Extract_Bounding_Box_Menu = uimenu(app.Call_Osmosis_Settings_Menu);
			app.Osmosis_Extract_Bounding_Box_Menu.MenuSelectedFcn = createCallbackFcn(app, @Osmosis_Extract_Bounding_Box_MenuSelected, true);
			app.Osmosis_Extract_Bounding_Box_Menu.Tooltip = {'Extraction of data within a specific bounding box.'; 'If activated, the minimum and maximum longitude and latitude of a bounding box to which the map section is limited is queried. These four values can be entered beforehand in the project parameters. This has the advantage that the area of OSM data used is documented in the project parameters and these values do not have to be entered again when Osmosis is called up.'};
			app.Osmosis_Extract_Bounding_Box_Menu.Separator = 'on';
			app.Osmosis_Extract_Bounding_Box_Menu.Checked = 'on';
			app.Osmosis_Extract_Bounding_Box_Menu.Text = 'Extract bounding box';
			app.Osmosis_Do_Not_Reduce_The_Size_Menu = uimenu(app.Call_Osmosis_Settings_Menu);
			app.Osmosis_Do_Not_Reduce_The_Size_Menu.MenuSelectedFcn = createCallbackFcn(app, @Osmosis_Do_Not_Reduce_The_Size_MenuSelected, true);
			app.Osmosis_Do_Not_Reduce_The_Size_Menu.Tooltip = {'No reduction of the map area.'};
			app.Osmosis_Do_Not_Reduce_The_Size_Menu.Text = 'Do not reduce the map size';
			app.Osmosis_Do_Not_Reduce_The_Data_Menu = uimenu(app.Call_Osmosis_Settings_Menu);
			app.Osmosis_Do_Not_Reduce_The_Data_Menu.MenuSelectedFcn = createCallbackFcn(app, @Osmosis_Do_Not_Reduce_The_Data_MenuSelected, true);
			app.Osmosis_Do_Not_Reduce_The_Data_Menu.Tooltip = {'No reduction of the OSM data.'; '- This option can lead to very large OSM XML files (.osm).'; '- Osmosis does not have to be executed again if the project parameters are changed.'};
			app.Osmosis_Do_Not_Reduce_The_Data_Menu.Separator = 'on';
			app.Osmosis_Do_Not_Reduce_The_Data_Menu.Checked = 'on';
			app.Osmosis_Do_Not_Reduce_The_Data_Menu.Text = 'Do not reduce the data';
			app.Osmosis_Extract_Include_Tags_Menu = uimenu(app.Call_Osmosis_Settings_Menu);
			app.Osmosis_Extract_Include_Tags_Menu.MenuSelectedFcn = createCallbackFcn(app, @Osmosis_Extract_Include_Tags_MenuSelected, true);
			app.Osmosis_Extract_Include_Tags_Menu.Tooltip = {'Reduction of the OSM data.'; '- This option can significantly reduce the size of an OSM XML file (.osm). However, because not all OSM data is then included in the file, Osmosis may have to be re-executed if the project parameters are changed.'; '- The reduction is carried out using the object parameters include tags (parameter “obj.tag_incl”). Only the first element of the include tags is used for filtering because Osmosis does not support logical combinations of tags. Therefore, the information in the first element should limit the data as much as possible. The other include tags and the exclude tags are not taken into account.'};
			app.Osmosis_Extract_Include_Tags_Menu.Text = 'Extract all include tags';
			app.Set_Osmosis_InstallPath_Menu = uimenu(app.ExtraMenu);
			app.Set_Osmosis_InstallPath_Menu.MenuSelectedFcn = createCallbackFcn(app, @Set_Osmosis_InstallPath_MenuSelected, true);
			app.Set_Osmosis_InstallPath_Menu.Tooltip = {'In order for the Osmosis command to be executed, you must specify the path in which the osmosis file is located (for example “C:\Program Files (x86)\osmosis-0.48.3\bin”). The command prompt window is then opened in this directory.'};
			app.Set_Osmosis_InstallPath_Menu.Text = 'Enter Osmosis install path';
			app.Open_CommandPromptWindow_Menu = uimenu(app.ExtraMenu);
			app.Open_CommandPromptWindow_Menu.MenuSelectedFcn = createCallbackFcn(app, @Open_CommandPromptWindow_MenuSelected, true);
			app.Open_CommandPromptWindow_Menu.Tooltip = {'Opens the command prompt window at the previously entered Osmosis install path in which the osmosis file is located (for example “C:\Program Files (x86)\osmosis-0.48.3\bin”).'};
			app.Open_CommandPromptWindow_Menu.Text = 'Open command prompt window';
			app.Convert_GeoRasterData_Menu = uimenu(app.ExtraMenu);
			app.Convert_GeoRasterData_Menu.MenuSelectedFcn = createCallbackFcn(app, @Convert_GeoRasterData_MenuSelected, true);
			app.Convert_GeoRasterData_Menu.Tooltip = {'Conversion of georaster data into the .mat format.'; '- This function reads in the elevation data, repairs it if necessary and saves it in MATLAB format .mat. Loading these files with (“File - Load OSM- and elevation-data”) is significantly faster and the files are usually smaller.'; '- A source directory and a different target directory must be selected. All files in the source directory are read in and converted.'; '- The source directory may contain elevation data in GeoTIFF format or .mat format. If a bounding box is extracted and the source data is in .mat format, only the relevant files are loaded based on their file names. This significantly speeds up the extraction of a bounding box.'; '- It is possible that elevation data is missing in the GeoTIFF data. In this case, the program attempts to repair the missing data by interpolating the neighboring valid points. This is done automatically during import (“File - Load OSM- and elevation-data”).'; '- It is also possible to convert several files into one file that covers a larger area. In this case, all individual files in the source directory must cover a complete contiguous rectangular area.'; '- Georaster data is freely available, for example at https://earthexplorer.usgs.gov/ (Digital Elevation: SRTM 1 Arc-Second Global). The GeoTIFF format was tested, for other possibly supported file formats see MATLAB-command “readgeoraster”.'; '- The recommended procedure is as follows: 1) Download the elevation data for a large area in GeoTIFF format. 2) Convert the GeoTIFF files into individual .mat files. 3) Extract sub-areas for specific projects.'};
			app.Convert_GeoRasterData_Menu.Separator = 'on';
			app.Convert_GeoRasterData_Menu.Text = 'Convert georaster data';
			app.Convert_GeoRasterDataSettings_Menu = uimenu(app.ExtraMenu);
			app.Convert_GeoRasterDataSettings_Menu.Tooltip = {'Settings for the “Convert georaster data” function.'};
			app.Convert_GeoRasterDataSettings_Menu.Text = 'Convert georaster data: Settings';
			app.Convert_GeoRasterDataSettings_CrSFile_Menu = uimenu(app.Convert_GeoRasterDataSettings_Menu);
			app.Convert_GeoRasterDataSettings_CrSFile_Menu.MenuSelectedFcn = createCallbackFcn(app, @Convert_GeoRasterDataSettings_CrSFile_MenuSelected, true);
			app.Convert_GeoRasterDataSettings_CrSFile_Menu.Tooltip = {'All files in the source directory are converted, combined and saved in a single larger file in the target directory.'; 'Note: After loading the elevation data with (“File - Load OSM- and elevation-data”), only the area of the elevation data required for the OSM map section is saved together with the project. Loading a large elevation file therefore does not increase the project storage size.'};
			app.Convert_GeoRasterDataSettings_CrSFile_Menu.Checked = 'on';
			app.Convert_GeoRasterDataSettings_CrSFile_Menu.Text = 'Create a single file';
			app.Convert_GeoRasterDataSettings_CrIndFiles_Menu = uimenu(app.Convert_GeoRasterDataSettings_Menu);
			app.Convert_GeoRasterDataSettings_CrIndFiles_Menu.MenuSelectedFcn = createCallbackFcn(app, @Convert_GeoRasterDataSettings_CrIndFiles_MenuSelected, true);
			app.Convert_GeoRasterDataSettings_CrIndFiles_Menu.Tooltip = {'Each file in the source directory is converted and saved individually in the target directory.'};
			app.Convert_GeoRasterDataSettings_CrIndFiles_Menu.Text = 'Create individual files';
			app.Convert_GeoRasterDataSettings_WholeArea_Menu = uimenu(app.Convert_GeoRasterDataSettings_Menu);
			app.Convert_GeoRasterDataSettings_WholeArea_Menu.MenuSelectedFcn = createCallbackFcn(app, @Convert_GeoRasterDataSettings_WholeArea_MenuSelected, true);
			app.Convert_GeoRasterDataSettings_WholeArea_Menu.Tooltip = {'All imported data is converted completely.'};
			app.Convert_GeoRasterDataSettings_WholeArea_Menu.Separator = 'on';
			app.Convert_GeoRasterDataSettings_WholeArea_Menu.Checked = 'on';
			app.Convert_GeoRasterDataSettings_WholeArea_Menu.Text = 'Convert the whole area';
			app.Convert_GeoRasterDataSettings_ExtractBB_Menu = uimenu(app.Convert_GeoRasterDataSettings_Menu);
			app.Convert_GeoRasterDataSettings_ExtractBB_Menu.MenuSelectedFcn = createCallbackFcn(app, @Convert_GeoRasterDataSettings_ExtractBB_MenuSelected, true);
			app.Convert_GeoRasterDataSettings_ExtractBB_Menu.Tooltip = {'Extraction of data within a specific bounding box.'; 'If activated, the minimum and maximum longitude and latitude of a bounding box to which the elevation data area is limited is queried.'; '- This feature only has an effect if the data is stored in a single file.'; '- If the source data is in .mat format, only the relevant files are loaded based on their file names. This significantly speeds up the extraction of a bounding box.'; '- It is also possible to enlarge the area by a certain number of additional sampling points on all sides. This is recommended if the elevation data is to be filtered when executing “Menu: File - Load OSM and elevation data.”'; '- When a project has been loaded, the Osmosis bounding box data is suggested for this purpose. After that, the last values entered are suggested.'};
			app.Convert_GeoRasterDataSettings_ExtractBB_Menu.Text = 'Extract bounding box';
			app.Convert_GeoRasterDataSettings_HighestRes_Menu = uimenu(app.Convert_GeoRasterDataSettings_Menu);
			app.Convert_GeoRasterDataSettings_HighestRes_Menu.MenuSelectedFcn = createCallbackFcn(app, @Convert_GeoRasterDataSettings_HighestRes_MenuSelected, true);
			app.Convert_GeoRasterDataSettings_HighestRes_Menu.Tooltip = {'The resolution is not reduced. If several files have different resolutions in points per degree, the data is linearly interpolated to the highest available resolution.'};
			app.Convert_GeoRasterDataSettings_HighestRes_Menu.Separator = 'on';
			app.Convert_GeoRasterDataSettings_HighestRes_Menu.Checked = 'on';
			app.Convert_GeoRasterDataSettings_HighestRes_Menu.Text = 'Highest resolution';
			app.Convert_GeoRasterDataSettings_SetRes_Menu = uimenu(app.Convert_GeoRasterDataSettings_Menu);
			app.Convert_GeoRasterDataSettings_SetRes_Menu.MenuSelectedFcn = createCallbackFcn(app, @Convert_GeoRasterDataSettings_SetRes_MenuSelected, true);
			app.Convert_GeoRasterDataSettings_SetRes_Menu.Tooltip = {'After calling “Extra - Convert georaster data”, you will be asked to enter the desired resolution in points per degree for the geographical longitude and latitude. The imported data will be linearly interpolated to this resolution.'; '- This function is useful when preparing elevation data for large areas in order to reduce the required storage space and execution times.'; '- Since the data is linearly interpolated and not filtered when the resolution is reduced, information is lost. The elevation data should therefore not be reduced too much compared to the resolution in the map.'};
			app.Convert_GeoRasterDataSettings_SetRes_Menu.Text = 'Set resolution';
			app.Convert_GeoRasterDataSettings_ReplMissData_Menu = uimenu(app.Convert_GeoRasterDataSettings_Menu);
			app.Convert_GeoRasterDataSettings_ReplMissData_Menu.MenuSelectedFcn = createCallbackFcn(app, @Convert_GeoRasterDataSettings_ReplMissData_MenuSelected, true);
			app.Convert_GeoRasterDataSettings_ReplMissData_Menu.Tooltip = {'Missing data is replaced with the value zero.'; '- Activating this function is useful for large bodies of water, because no GeoTIFF data is available for such areas, as the elevation is zero anyway. With this function, the result is no longer considered incomplete.'; '- Activating this function does not increase the file size.'; '- If elevation data is missing when the “Load OSM and elevation data” function is executed, it is automatically replaced with zeros. However, replacing it with zeros beforehand prevents a warning from being issued.'};
			app.Convert_GeoRasterDataSettings_ReplMissData_Menu.Separator = 'on';
			app.Convert_GeoRasterDataSettings_ReplMissData_Menu.Text = 'Replace missing data with zeros';
			app.Convert_GeoRasterDataSettings_Testplot_Menu = uimenu(app.Convert_GeoRasterDataSettings_Menu);
			app.Convert_GeoRasterDataSettings_Testplot_Menu.MenuSelectedFcn = createCallbackFcn(app, @Convert_GeoRasterDataSettings_Testplot_MenuSelected, true);
			app.Convert_GeoRasterDataSettings_Testplot_Menu.Tooltip = {'When saving a file in the target directory, the elevation data it contains is displayed graphically.'};
			app.Convert_GeoRasterDataSettings_Testplot_Menu.Separator = 'on';
			app.Convert_GeoRasterDataSettings_Testplot_Menu.Checked = 'on';
			app.Convert_GeoRasterDataSettings_Testplot_Menu.Text = 'Create Testplots';
			app.Show_Diary_Path_Menu = uimenu(app.ExtraMenu);
			app.Show_Diary_Path_Menu.MenuSelectedFcn = createCallbackFcn(app, @Show_Diary_Path_MenuSelected, true);
			app.Show_Diary_Path_Menu.Tooltip = {'Shows the path to the diary file in the event display below the signal lamp.'; 'The diary logs certain program outputs and error messages in a text file. It should not be opened during an active program function (when the signal lamp is red) and closed again before another program function is executed, otherwise the program will no longer be able to access it. Another option is to create a copy before opening it.'; 'The diary is restarted if the project directory changes. An existing diary is overwritten at restart. This may be the case:'; '- when the project directory is changed (“File - Change project directory”)'; '- when a project is loaded (“File - Load project”)'; '- when the project parameters are loaded (“File - Load project parameters”).'; 'An alternative to the diary is the Windows Command Shell (console) or the MapLab3D log file, if they are activated in the standalone app.'};
			app.Show_Diary_Path_Menu.Separator = 'on';
			app.Show_Diary_Path_Menu.Text = 'Show diary path';
			app.Command_Line_Menu = uimenu(app.ExtraMenu);
			app.Command_Line_Menu.MenuSelectedFcn = createCallbackFcn(app, @Command_Line_MenuSelected, true);
			app.Command_Line_Menu.Tooltip = {'Input option for MATLAB commands.'; 'This console extends the possibilities when running the standalone version and should only be used with knowledge of the source code.'};
			app.Command_Line_Menu.Separator = 'on';
			app.Command_Line_Menu.Text = 'Command line';
			app.AboutHelp = uimenu(app.MapLab3D);
			app.AboutHelp.Text = 'Help';
			app.AboutMenu = uimenu(app.AboutHelp);
			app.AboutMenu.MenuSelectedFcn = createCallbackFcn(app, @AboutMenuSelected, true);
			app.AboutMenu.Tooltip = {'Displays information about MapLab3D.'};
			app.AboutMenu.Text = 'About';
			app.WaitbarUIAxes = uiaxes(app.MapLab3D);
			app.WaitbarUIAxes.XTick = [];
			app.WaitbarUIAxes.YTick = [];
			app.LogLabel = uilabel(app.MapLab3D);
			app.LogLabel.Text = 'Log';
			app.LogTextArea = uitextarea(app.MapLab3D);
			app.LogTextArea.Editable = 'off';
			app.LogTextArea.Tooltip = {'Functions log.'};
			app.pathfilenameprojectparfileTextArea = uitextarea(app.MapLab3D);
			app.pathfilenameprojectparfileTextArea.Editable = 'off';
			app.pathfilenameprojectparfileTextArea.Tooltip = {'Path and file name of the project parameter file and the column number used (DATASET_x).'};
			app.StateTextArea = uitextarea(app.MapLab3D);
			app.StateTextArea.Tooltip = {'Signal lamp to indicate the current status of the app:'; '- red: A function is active.'; '- green: Waiting for user input.'; 'Some functions have such a short execution time that the status does not change to red (e.g. selecting map objects).'; ''; 'During or after the execution of a function, additional messages are displayed in the event display below the signal lamp:'; '- Display of the number and PlotNo''s of the selected map objects'; '- With some functions: Display of the progress (waitbar).'; '- With some functions: Display of the currently executed program section.'; 'Some functions such as “File - Create map STL files” can take a very long time with large maps. It is not important what is displayed, but that the display changes. Think of it as a sign that the program is alive.'};
			app.projectdirectoryTextArea = uitextarea(app.MapLab3D);
			app.projectdirectoryTextArea.Editable = 'off';
			app.projectdirectoryTextArea.Tooltip = {'Path and file names of the saved project. A project is saved in two files:'; '- ...MAP.fig: This file contains the map and can be displayed with “View - Open figure file (.fig)” without having to load the project.'; '- ...MAPDATA.mat: This file contains additional data so that “...MAP.fig” does not become too large and can be loaded faster.'};
			app.pathfilenameOSMDataTextArea = uitextarea(app.MapLab3D);
			app.pathfilenameOSMDataTextArea.Editable = 'off';
			app.pathfilenameOSMDataTextArea.Tooltip = {'Path and file name of the loaded OpenStreetMap data.'};
			app.pathnameELEDataTextArea = uitextarea(app.MapLab3D);
			app.pathnameELEDataTextArea.Editable = 'off';
			app.pathnameELEDataTextArea.Tooltip = {'Name of the directory containing the loaded elevation data.'};
			app.TabGroup = uitabgroup(app.MapLab3D);
			app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
			app.EditmapTab = uitab(app.TabGroup);
			app.EditmapTab.Tooltip = {'Functions for manual modifications to the map.'};
			app.EditmapTab.Title = 'Edit map';
			app.TabGroup2 = uitabgroup(app.EditmapTab);
			app.BasicTab = uitab(app.TabGroup2);
			app.BasicTab.Tooltip = {'Simple functions for editing the map.'};
			app.BasicTab.Title = 'Basic';
			app.MovemmEditFieldLabel = uilabel(app.BasicTab);
			app.MovemmEditFieldLabel.Tooltip = {'Move selected map objects in constant step sizes.'; 'Moving a selected map object is also possible by clicking and dragging with the mouse (tab "Edit map - Advanced", activate "Move object").'};
			app.MovemmEditFieldLabel.Text = 'Move / mm';
			app.RotateLabel = uilabel(app.BasicTab);
			app.RotateLabel.Tooltip = {'Rotate selected map objects.'};
			app.RotateLabel.Text = 'Rotate / °';
			app.Mod_Dup_Label = uilabel(app.BasicTab);
			app.Mod_Dup_Label.Tooltip = {'Duplicate selected map objects.'};
			app.Mod_Dup_Label.Text = 'Duplicate';
			app.Mod_Scal_Label = uilabel(app.BasicTab);
			app.Mod_Scal_Label.Tooltip = {'Scale selected map objects.'};
			app.Mod_Scal_Label.Text = 'Scale';
			app.Mod_TempVis_Label = uilabel(app.BasicTab);
			app.Mod_TempVis_Label.Tooltip = {'Temporary hiding of selected map objects.'};
			app.Mod_TempVis_Label.Text = 'Temporary visibility';
			app.Mod_MapObjectsOrder_Label = uilabel(app.BasicTab);
			app.Mod_MapObjectsOrder_Label.Tooltip = {'Change the order of selected map objects in the map and the sorting in the map objects table.'};
			app.Mod_MapObjectsOrder_Label.Text = 'Reorder / PlotNo';
			app.Mod_Move_LU3_Button = uibutton(app.BasicTab, 'push');
			app.Mod_Move_LU3_Button.ButtonPushedFcn = createCallbackFcn(app, @Mod_Move_LU3_ButtonPushed, true);
			app.Mod_Move_LU3_Button.Icon = 'LU3.png';
			app.Mod_Move_LU3_Button.Text = '';
			app.Mod_Move_LU2_Button = uibutton(app.BasicTab, 'push');
			app.Mod_Move_LU2_Button.ButtonPushedFcn = createCallbackFcn(app, @Mod_Move_LU2_ButtonPushed, true);
			app.Mod_Move_LU2_Button.Icon = 'LU2.png';
			app.Mod_Move_LU2_Button.Tooltip = {'tooltip_is_set_by_app'};
			app.Mod_Move_LU2_Button.Text = '';
			app.Mod_Move_LU1_Button = uibutton(app.BasicTab, 'push');
			app.Mod_Move_LU1_Button.ButtonPushedFcn = createCallbackFcn(app, @Mod_Move_LU1_ButtonPushed, true);
			app.Mod_Move_LU1_Button.Icon = 'LU1.png';
			app.Mod_Move_LU1_Button.Tooltip = {'tooltip_is_set_by_app'};
			app.Mod_Move_LU1_Button.Text = '';
			app.Mod_Move_U2_Button = uibutton(app.BasicTab, 'push');
			app.Mod_Move_U2_Button.ButtonPushedFcn = createCallbackFcn(app, @Mod_Move_U2_ButtonPushed, true);
			app.Mod_Move_U2_Button.Icon = 'U2.png';
			app.Mod_Move_U2_Button.Tooltip = {'tooltip_is_set_by_app'};
			app.Mod_Move_U2_Button.Text = '';
			app.Mod_Move_U1_Button = uibutton(app.BasicTab, 'push');
			app.Mod_Move_U1_Button.ButtonPushedFcn = createCallbackFcn(app, @Mod_Move_U1_ButtonPushed, true);
			app.Mod_Move_U1_Button.Icon = 'U1.png';
			app.Mod_Move_U1_Button.Tooltip = {'tooltip_is_set_by_app'};
			app.Mod_Move_U1_Button.Text = '';
			app.Mod_Move_U3_Button = uibutton(app.BasicTab, 'push');
			app.Mod_Move_U3_Button.ButtonPushedFcn = createCallbackFcn(app, @Mod_Move_U3_ButtonPushed, true);
			app.Mod_Move_U3_Button.Icon = 'U3.png';
			app.Mod_Move_U3_Button.Tooltip = {'tooltip_is_set_by_app'};
			app.Mod_Move_U3_Button.Text = '';
			app.Mod_Move_RU1_Button = uibutton(app.BasicTab, 'push');
			app.Mod_Move_RU1_Button.ButtonPushedFcn = createCallbackFcn(app, @Mod_Move_RU1_ButtonPushed, true);
			app.Mod_Move_RU1_Button.Icon = 'RU1.png';
			app.Mod_Move_RU1_Button.Tooltip = {'tooltip_is_set_by_app'};
			app.Mod_Move_RU1_Button.Text = '';
			app.Mod_Move_RU2_Button = uibutton(app.BasicTab, 'push');
			app.Mod_Move_RU2_Button.ButtonPushedFcn = createCallbackFcn(app, @Mod_Move_RU2_ButtonPushed, true);
			app.Mod_Move_RU2_Button.Icon = 'RU2.png';
			app.Mod_Move_RU2_Button.Tooltip = {'tooltip_is_set_by_app'};
			app.Mod_Move_RU2_Button.Text = '';
			app.Mod_Move_RU3_Button = uibutton(app.BasicTab, 'push');
			app.Mod_Move_RU3_Button.ButtonPushedFcn = createCallbackFcn(app, @Mod_Move_RU3_ButtonPushed, true);
			app.Mod_Move_RU3_Button.Icon = 'RU3.png';
			app.Mod_Move_RU3_Button.Tooltip = {'tooltip_is_set_by_app'};
			app.Mod_Move_RU3_Button.Text = '';
			app.Mod_Rot_L1_Button = uibutton(app.BasicTab, 'push');
			app.Mod_Rot_L1_Button.ButtonPushedFcn = createCallbackFcn(app, @Mod_Rot_L1_ButtonPushed, true);
			app.Mod_Rot_L1_Button.Icon = 'L1.png';
			app.Mod_Rot_L1_Button.Tooltip = {'tooltip_is_set_by_app'};
			app.Mod_Rot_L1_Button.Text = '';
			app.Mod_Rot_L2_Button = uibutton(app.BasicTab, 'push');
			app.Mod_Rot_L2_Button.ButtonPushedFcn = createCallbackFcn(app, @Mod_Rot_L2_ButtonPushed, true);
			app.Mod_Rot_L2_Button.Icon = 'L2.png';
			app.Mod_Rot_L2_Button.Tooltip = {'tooltip_is_set_by_app'};
			app.Mod_Rot_L2_Button.Text = '';
			app.Mod_Rot_L3_Button = uibutton(app.BasicTab, 'push');
			app.Mod_Rot_L3_Button.ButtonPushedFcn = createCallbackFcn(app, @Mod_Rot_L3_ButtonPushed, true);
			app.Mod_Rot_L3_Button.Icon = 'L3.png';
			app.Mod_Rot_L3_Button.Tooltip = {'tooltip_is_set_by_app'};
			app.Mod_Rot_L3_Button.Text = '';
			app.xmmEditFieldLabel = uilabel(app.BasicTab);
			app.xmmEditFieldLabel.Tooltip = {'Step size in x-direction in mm.'};
			app.xmmEditFieldLabel.Text = 'x / mm';
			app.xEditFieldLabel = uilabel(app.BasicTab);
			app.xEditFieldLabel.Tooltip = {'Scale factor in x-direction in percent.'};
			app.xEditFieldLabel.Text = 'x / %';
			app.Mod_Arrange_Last_Button = uibutton(app.BasicTab, 'push');
			app.Mod_Arrange_Last_Button.ButtonPushedFcn = createCallbackFcn(app, @Mod_Arrange_Last_ButtonPushed, true);
			app.Mod_Arrange_Last_Button.Tooltip = {'Move the object to the top of the map so that it overlaps all other objects and place the entry in the map objects table at the end with the highest PlotNo.'; 'This also works for several selected objects with consecutive PlotNo.'};
			app.Mod_Arrange_Last_Button.Text = 'Front / Last';
			app.Mod_Move_L3_Button = uibutton(app.BasicTab, 'push');
			app.Mod_Move_L3_Button.ButtonPushedFcn = createCallbackFcn(app, @Mod_Move_L3_ButtonPushed, true);
			app.Mod_Move_L3_Button.Icon = 'L3.png';
			app.Mod_Move_L3_Button.Tooltip = {'tooltip_is_set_by_app'};
			app.Mod_Move_L3_Button.Text = '';
			app.Mod_Move_L2_Button = uibutton(app.BasicTab, 'push');
			app.Mod_Move_L2_Button.ButtonPushedFcn = createCallbackFcn(app, @Mod_Move_L2_ButtonPushed, true);
			app.Mod_Move_L2_Button.Icon = 'L2.png';
			app.Mod_Move_L2_Button.Tooltip = {'tooltip_is_set_by_app'};
			app.Mod_Move_L2_Button.Text = '';
			app.Mod_Move_L1_Button = uibutton(app.BasicTab, 'push');
			app.Mod_Move_L1_Button.ButtonPushedFcn = createCallbackFcn(app, @Mod_Move_L1_ButtonPushed, true);
			app.Mod_Move_L1_Button.Icon = 'L1.png';
			app.Mod_Move_L1_Button.Tooltip = {'tooltip_is_set_by_app'};
			app.Mod_Move_L1_Button.Text = '';
			app.Mod_Move_mm_EditField = uieditfield(app.BasicTab, 'numeric');
			app.Mod_Move_mm_EditField.Limits = [0 Inf];
			app.Mod_Move_mm_EditField.ValueDisplayFormat = '%1.9g';
			app.Mod_Move_mm_EditField.ValueChangedFcn = createCallbackFcn(app, @Mod_Move_mm_EditFieldValueChanged, true);
			app.Mod_Move_mm_EditField.Tooltip = {'Step size in mm.'};
			app.Mod_Move_mm_EditField.Value = 1;
			app.Mod_Move_R1_Button = uibutton(app.BasicTab, 'push');
			app.Mod_Move_R1_Button.ButtonPushedFcn = createCallbackFcn(app, @Mod_Move_R1_ButtonPushed, true);
			app.Mod_Move_R1_Button.Icon = 'R1.png';
			app.Mod_Move_R1_Button.Tooltip = {'tooltip_is_set_by_app'};
			app.Mod_Move_R1_Button.Text = '';
			app.Mod_Move_R2_Button = uibutton(app.BasicTab, 'push');
			app.Mod_Move_R2_Button.ButtonPushedFcn = createCallbackFcn(app, @Mod_Move_R2_ButtonPushed, true);
			app.Mod_Move_R2_Button.Icon = 'R2.png';
			app.Mod_Move_R2_Button.Tooltip = {'tooltip_is_set_by_app'};
			app.Mod_Move_R2_Button.Text = '';
			app.Mod_Move_R3_Button = uibutton(app.BasicTab, 'push');
			app.Mod_Move_R3_Button.ButtonPushedFcn = createCallbackFcn(app, @Mod_Move_R3_ButtonPushed, true);
			app.Mod_Move_R3_Button.Icon = 'R3.png';
			app.Mod_Move_R3_Button.Tooltip = {'tooltip_is_set_by_app'};
			app.Mod_Move_R3_Button.Text = '';
			app.Mod_Rot_deg_EditField = uieditfield(app.BasicTab, 'numeric');
			app.Mod_Rot_deg_EditField.Limits = [0 Inf];
			app.Mod_Rot_deg_EditField.ValueDisplayFormat = '%1.9g';
			app.Mod_Rot_deg_EditField.ValueChangedFcn = createCallbackFcn(app, @Mod_Rot_deg_EditFieldValueChanged, true);
			app.Mod_Rot_deg_EditField.Tooltip = {'Rotation angle in degrees.'};
			app.Mod_Rot_deg_EditField.Value = 4.5;
			app.ymmEditFieldLabel = uilabel(app.BasicTab);
			app.ymmEditFieldLabel.Tooltip = {'Step size in y-direction in mm.'};
			app.ymmEditFieldLabel.Text = 'y / mm';
			app.yEditFieldLabel = uilabel(app.BasicTab);
			app.yEditFieldLabel.Tooltip = {'Scale factor in y-direction in percent.'};
			app.yEditFieldLabel.Text = 'y / %';
			app.Mod_Restore_Button = uibutton(app.BasicTab, 'push');
			app.Mod_Restore_Button.ButtonPushedFcn = createCallbackFcn(app, @Mod_Restore_ButtonPushed, true);
			app.Mod_Restore_Button.Tooltip = {'Restore the visibility of all temporarily hidden map objects (as previously shown or grayed out).'};
			app.Mod_Restore_Button.Text = 'Restore all';
			app.Mod_HideTempAll_Button = uibutton(app.BasicTab, 'push');
			app.Mod_HideTempAll_Button.ButtonPushedFcn = createCallbackFcn(app, @Mod_HideTempAll_ButtonPushed, true);
			app.Mod_HideTempAll_Button.Tooltip = {'Temporarily hide all selected map objects if they are not already hidden.'};
			app.Mod_HideTempAll_Button.Text = 'Hide all';
			app.Mod_HideTemporarily_Button = uibutton(app.BasicTab, 'push');
			app.Mod_HideTemporarily_Button.ButtonPushedFcn = createCallbackFcn(app, @Mod_HideTemporarily_ButtonPushed, true);
			app.Mod_HideTemporarily_Button.Tooltip = {'Hide all selected map objects temporarily.'; 'Examples:'; '- Overlapped objects can be marked with the mouse by temporarily hiding objects above them.'; '- The complexity of the map can be temporarily reduced for a better overview.'};
			app.Mod_HideTemporarily_Button.Text = 'Hide';
			app.Mod_Arrange_Up_Button = uibutton(app.BasicTab, 'push');
			app.Mod_Arrange_Up_Button.ButtonPushedFcn = createCallbackFcn(app, @Mod_Arrange_Up_ButtonPushed, true);
			app.Mod_Arrange_Up_Button.Tooltip = {'Move the object up one step on the map and increase the PlotNo in the map objects table by one.'; 'This also works for several selected objects with consecutive PlotNo.'};
			app.Mod_Arrange_Up_Button.Text = 'Forward / Up';
			app.Mod_Move_LD3_Button = uibutton(app.BasicTab, 'push');
			app.Mod_Move_LD3_Button.ButtonPushedFcn = createCallbackFcn(app, @Mod_Move_LD3_ButtonPushed, true);
			app.Mod_Move_LD3_Button.Icon = 'LD3.png';
			app.Mod_Move_LD3_Button.Tooltip = {'tooltip_is_set_by_app'};
			app.Mod_Move_LD3_Button.Text = '';
			app.Mod_Move_LD2_Button = uibutton(app.BasicTab, 'push');
			app.Mod_Move_LD2_Button.ButtonPushedFcn = createCallbackFcn(app, @Mod_Move_LD2_ButtonPushed, true);
			app.Mod_Move_LD2_Button.Icon = 'LD2.png';
			app.Mod_Move_LD2_Button.Tooltip = {'tooltip_is_set_by_app'};
			app.Mod_Move_LD2_Button.Text = '';
			app.Mod_Move_LD1_Button = uibutton(app.BasicTab, 'push');
			app.Mod_Move_LD1_Button.ButtonPushedFcn = createCallbackFcn(app, @Mod_Move_LD1_ButtonPushed, true);
			app.Mod_Move_LD1_Button.Icon = 'LD1.png';
			app.Mod_Move_LD1_Button.Tooltip = {'tooltip_is_set_by_app'};
			app.Mod_Move_LD1_Button.Text = '';
			app.Mod_Move_D2_Button = uibutton(app.BasicTab, 'push');
			app.Mod_Move_D2_Button.ButtonPushedFcn = createCallbackFcn(app, @Mod_Move_D2_ButtonPushed, true);
			app.Mod_Move_D2_Button.Icon = 'D2.png';
			app.Mod_Move_D2_Button.Tooltip = {'tooltip_is_set_by_app'};
			app.Mod_Move_D2_Button.Text = '';
			app.Mod_Move_D1_Button = uibutton(app.BasicTab, 'push');
			app.Mod_Move_D1_Button.ButtonPushedFcn = createCallbackFcn(app, @Mod_Move_D1_ButtonPushed, true);
			app.Mod_Move_D1_Button.Icon = 'D1.png';
			app.Mod_Move_D1_Button.Tooltip = {'tooltip_is_set_by_app'};
			app.Mod_Move_D1_Button.Text = '';
			app.Mod_Move_D3_Button = uibutton(app.BasicTab, 'push');
			app.Mod_Move_D3_Button.ButtonPushedFcn = createCallbackFcn(app, @Mod_Move_D3_ButtonPushed, true);
			app.Mod_Move_D3_Button.Icon = 'D3.png';
			app.Mod_Move_D3_Button.Tooltip = {'tooltip_is_set_by_app'};
			app.Mod_Move_D3_Button.Text = '';
			app.Mod_Move_RD1_Button = uibutton(app.BasicTab, 'push');
			app.Mod_Move_RD1_Button.ButtonPushedFcn = createCallbackFcn(app, @Mod_Move_RD1_ButtonPushed, true);
			app.Mod_Move_RD1_Button.Icon = 'RD1.png';
			app.Mod_Move_RD1_Button.Tooltip = {'tooltip_is_set_by_app'};
			app.Mod_Move_RD1_Button.Text = '';
			app.Mod_Move_RD2_Button = uibutton(app.BasicTab, 'push');
			app.Mod_Move_RD2_Button.ButtonPushedFcn = createCallbackFcn(app, @Mod_Move_RD2_ButtonPushed, true);
			app.Mod_Move_RD2_Button.Icon = 'RD2.png';
			app.Mod_Move_RD2_Button.Tooltip = {'tooltip_is_set_by_app'};
			app.Mod_Move_RD2_Button.Text = '';
			app.Mod_Move_RD3_Button = uibutton(app.BasicTab, 'push');
			app.Mod_Move_RD3_Button.ButtonPushedFcn = createCallbackFcn(app, @Mod_Move_RD3_ButtonPushed, true);
			app.Mod_Move_RD3_Button.Icon = 'RD3.png';
			app.Mod_Move_RD3_Button.Tooltip = {'tooltip_is_set_by_app'};
			app.Mod_Move_RD3_Button.Text = '';
			app.Mod_Rot_R1_Button = uibutton(app.BasicTab, 'push');
			app.Mod_Rot_R1_Button.ButtonPushedFcn = createCallbackFcn(app, @Mod_Rot_R1_ButtonPushed, true);
			app.Mod_Rot_R1_Button.Icon = 'R1.png';
			app.Mod_Rot_R1_Button.Tooltip = {'tooltip_is_set_by_app'};
			app.Mod_Rot_R1_Button.Text = '';
			app.Mod_Rot_R2_Button = uibutton(app.BasicTab, 'push');
			app.Mod_Rot_R2_Button.ButtonPushedFcn = createCallbackFcn(app, @Mod_Rot_R2_ButtonPushed, true);
			app.Mod_Rot_R2_Button.Icon = 'R2.png';
			app.Mod_Rot_R2_Button.Tooltip = {'tooltip_is_set_by_app'};
			app.Mod_Rot_R2_Button.Text = '';
			app.Mod_Rot_R3_Button = uibutton(app.BasicTab, 'push');
			app.Mod_Rot_R3_Button.ButtonPushedFcn = createCallbackFcn(app, @Mod_Rot_R3_ButtonPushed, true);
			app.Mod_Rot_R3_Button.Icon = 'R3.png';
			app.Mod_Rot_R3_Button.Tooltip = {'tooltip_is_set_by_app'};
			app.Mod_Rot_R3_Button.Text = '';
			app.Mod_Dup_Button = uibutton(app.BasicTab, 'push');
			app.Mod_Dup_Button.ButtonPushedFcn = createCallbackFcn(app, @Mod_Dup_ButtonPushed, true);
			app.Mod_Dup_Button.Tooltip = {'Duplicate and move in x and y direction.'};
			app.Mod_Dup_Button.Text = 'Duplicate';
			app.Mod_Scal_Button = uibutton(app.BasicTab, 'push');
			app.Mod_Scal_Button.ButtonPushedFcn = createCallbackFcn(app, @Mod_Scal_ButtonPushed, true);
			app.Mod_Scal_Button.Tooltip = {'Scaling in x and y direction.'};
			app.Mod_Scal_Button.Text = 'Scale';
			app.Mod_Miscellaneous_Label = uilabel(app.BasicTab);
			app.Mod_Miscellaneous_Label.Tooltip = {'The selection of map objects is possible with:'; '- Map:'; 'Left-clicking always selects only one object.'; 'Clicking with the middle mouse button selects several objects.'; 'A left-click on an object with a right-click at the same position opens a context menu.'; '- Map objects table:'; 'Left-click on a cell or drag an area with the mouse'; 'Left-click on a table heading to select all objects (may be slow)'; '- Select by filter:'; 'Automatic selection by entering filter criteria.'};
			app.Mod_Miscellaneous_Label.Text = 'Miscellaneous';
			app.Mod_Arrange_Down_Button = uibutton(app.BasicTab, 'push');
			app.Mod_Arrange_Down_Button.ButtonPushedFcn = createCallbackFcn(app, @Mod_Arrange_Down_ButtonPushed, true);
			app.Mod_Arrange_Down_Button.Tooltip = {'Move the object on the map down one step and reduce the PlotNo in the map objects table by one.'; 'This also works for several selected objects with consecutive PlotNo.'};
			app.Mod_Arrange_Down_Button.Text = 'Backward / Down';
			app.Mod_SelectionLabel = uilabel(app.BasicTab);
			app.Mod_SelectionLabel.Tooltip = {'The selection of map objects is possible with:'; '1) Map:'; '- Left-clicking always selects only one object.'; '- Clicking with the middle mouse button selects several objects.'; '- If the "Off" button is pressed in the "Tab: Edit map - Advanced - Preview (L/P) - Draw" section: Left-clicking and dragging with the mouse selects all map objects that are completely within the selected area.'; '- A left-click on an object with a right-click at the same position opens a context menu.'; '2) Map objects table:'; '- Left-click on a cell or drag an area with the mouse'; '- Left-click on a table heading to select all objects (may be slow)'; '3) Select by filter:'; '- Automatic selection by entering filter criteria.'};
			app.Mod_SelectionLabel.Text = 'Selection';
			app.Mod_Arrange_Label = uilabel(app.BasicTab);
			app.Mod_Arrange_Label.Tooltip = {'Group or ungroup selected map objects.'};
			app.Mod_Arrange_Label.Text = 'Arrange';
			app.Mod_Color_Label = uilabel(app.BasicTab);
			app.Mod_Color_Label.Tooltip = {'Change the color of selected map objects.'};
			app.Mod_Color_Label.Text = 'Color';
			app.Mod_ChangeTextTag_Button = uibutton(app.BasicTab, 'push');
			app.Mod_ChangeTextTag_Button.ButtonPushedFcn = createCallbackFcn(app, @Mod_ChangeTextTag_ButtonPushed, true);
			app.Mod_ChangeTextTag_Button.Tooltip = {'Change the map object text/tag.'};
			app.Mod_ChangeTextTag_Button.Text = 'Change text/tag';
			app.Mod_ChangeDescription_Button = uibutton(app.BasicTab, 'push');
			app.Mod_ChangeDescription_Button.ButtonPushedFcn = createCallbackFcn(app, @Mod_ChangeDescription_ButtonPushed, true);
			app.Mod_ChangeDescription_Button.Tooltip = {'Change the map object description.'};
			app.Mod_ChangeDescription_Button.Text = 'Change description';
			app.Mod_Arrange_First_Button = uibutton(app.BasicTab, 'push');
			app.Mod_Arrange_First_Button.ButtonPushedFcn = createCallbackFcn(app, @Mod_Arrange_First_ButtonPushed, true);
			app.Mod_Arrange_First_Button.Tooltip = {'Move the object down on the map so that it is overlapped by all other objects and place the entry in the map objects table at the beginning with the PlotNo one.'; 'This also works for several selected objects with consecutive PlotNo.'};
			app.Mod_Arrange_First_Button.Text = 'Back / First';
			app.Mod_SelectWithFilter_Button = uibutton(app.BasicTab, 'push');
			app.Mod_SelectWithFilter_Button.ButtonPushedFcn = createCallbackFcn(app, @Mod_SelectWithFilter_ButtonPushed, true);
			app.Mod_SelectWithFilter_Button.Tooltip = {'Selection of map objects based on criteria such as object number, color number, font or size.'};
			app.Mod_SelectWithFilter_Button.Text = 'Select';
			app.Mod_Arrange_Group_Button = uibutton(app.BasicTab, 'push');
			app.Mod_Arrange_Group_Button.ButtonPushedFcn = createCallbackFcn(app, @Mod_Arrange_Group_ButtonPushed, true);
			app.Mod_Arrange_Group_Button.Tooltip = {'Group two or more map objects. Only matching map objects are grouped together, i.e. several object groups can be created. The conditions are:'; 'Only lines, areas, symbols, texts and previews are grouped if they have'; '- the same type (line/polygon),'; '- the same values ObjNo (if>=0),'; '- the same values Text/Tag,'; '- the same values DispAs,'; '- the same color number of cutting lines and'; '- the same color number of united equal colors'; 'Texts (DispAs=text) are only grouped if they:'; '- consist of one text foreground and one text background that overlap (more text objects in one group are not allowed),'; '- have the same character style number,'; '- have the same character style settings (fontsize,..),'; '- have the same rotation angle.'; 'Symbols (DispAs=symbol) are only grouped if they:'; '- consist of one symbol foreground and one symbol background that overlap (more symbol objects in one group are not allowed),'; '- have the same symbol number,'; '- have the same rotation angle.'};
			app.Mod_Arrange_Group_Button.Text = 'Group';
			app.Mod_ColorReset_Button = uibutton(app.BasicTab, 'push');
			app.Mod_ColorReset_Button.ButtonPushedFcn = createCallbackFcn(app, @Mod_ColorReset_ButtonPushed, true);
			app.Mod_ColorReset_Button.Tooltip = {'Sets the color number of the selected map objects to the value specified in the project parameters.'};
			app.Mod_ColorReset_Button.Text = 'Color reset';
			app.Mod_CopyMapObjectsTableToClipboard_Button_2 = uibutton(app.BasicTab, 'push');
			app.Mod_CopyMapObjectsTableToClipboard_Button_2.ButtonPushedFcn = createCallbackFcn(app, @Mod_CopyMapObjectsTableToClipboard_Button_2Pushed, true);
			app.Mod_CopyMapObjectsTableToClipboard_Button_2.Tooltip = {'Copy the table of map objects as a tab-delimited list with additional data to the clipboard. The list can then be inserted into a spreadsheet for further analysis.'};
			app.Mod_CopyMapObjectsTableToClipboard_Button_2.Text = 'Copy table to clipboard';
			app.Mod_Arrange_Set_Button = uibutton(app.BasicTab, 'push');
			app.Mod_Arrange_Set_Button.ButtonPushedFcn = createCallbackFcn(app, @Mod_Arrange_Set_ButtonPushed, true);
			app.Mod_Arrange_Set_Button.Tooltip = {'Move the object in the map objects table to the specified PlotNo.'; 'This also works for several selected objects with consecutive PlotNo: the first element of the group receives the specified PlotNo.'};
			app.Mod_Arrange_Set_Button.Text = 'Set PlotNo';
			app.Mod_Deselectall_Button = uibutton(app.BasicTab, 'push');
			app.Mod_Deselectall_Button.ButtonPushedFcn = createCallbackFcn(app, @Mod_Deselectall_ButtonPushed, true);
			app.Mod_Deselectall_Button.Tooltip = {'Deselect all map objects.'; 'This can also be achieved by left-clicking on an empty area of the map.'};
			app.Mod_Deselectall_Button.Text = 'Deselect all';
			app.Mod_Arrange_Ungroup_Button = uibutton(app.BasicTab, 'push');
			app.Mod_Arrange_Ungroup_Button.ButtonPushedFcn = createCallbackFcn(app, @Mod_Arrange_Ungroup_ButtonPushed, true);
			app.Mod_Arrange_Ungroup_Button.Tooltip = {'Ungrouping of one or more groups of map objects.'};
			app.Mod_Arrange_Ungroup_Button.Text = 'Ungroup';
			app.Mod_Color_0_Button = uibutton(app.BasicTab, 'push');
			app.Mod_Color_0_Button.ButtonPushedFcn = createCallbackFcn(app, @Mod_Color_0_ButtonPushed, true);
			app.Mod_Color_0_Button.Tooltip = {'Sets the color number of the selected map objects to zero: the objects receive the color of the object below them.'};
			app.Mod_Color_0_Button.Text = 'Transparent';
			app.Mod_Reset_Button = uibutton(app.BasicTab, 'push');
			app.Mod_Reset_Button.ButtonPushedFcn = createCallbackFcn(app, @Mod_Reset_ButtonPushed, true);
			app.Mod_Reset_Button.Tooltip = {'Resets all selected map objects to the shape, position and rotation they had when they were created.'; 'Experimental: save the project first!'};
			app.Mod_Reset_Button.Text = 'Reset objects';
			app.Mod_Arrange_SortByPrio_Button = uibutton(app.BasicTab, 'push');
			app.Mod_Arrange_SortByPrio_Button.ButtonPushedFcn = createCallbackFcn(app, @Mod_Arrange_SortByPrio_ButtonPushed, true);
			app.Mod_Arrange_SortByPrio_Button.Tooltip = {'Standard sorting of all map objects with ascending object priority, so that as many map objects as possible are selectable by clicking:'; '1) United equal colors and cutting lines'; '2) areas and lines'; '3) texts, connection lines and symbols'; '4) legend'; '5) preview lines'};
			app.Mod_Arrange_SortByPrio_Button.Text = 'Sort by object priority';
			app.Mod_Dup_xmm_EditField = uieditfield(app.BasicTab, 'numeric');
			app.Mod_Dup_xmm_EditField.ValueDisplayFormat = '%1.9g';
			app.Mod_Dup_xmm_EditField.Tooltip = {'Step size in x-direction in mm.'};
			app.Mod_Dup_ymm_EditField = uieditfield(app.BasicTab, 'numeric');
			app.Mod_Dup_ymm_EditField.ValueDisplayFormat = '%1.9g';
			app.Mod_Dup_ymm_EditField.Tooltip = {'Step size in y-direction in mm.'};
			app.Mod_Scal_xperc_EditField = uieditfield(app.BasicTab, 'numeric');
			app.Mod_Scal_xperc_EditField.Limits = [0 Inf];
			app.Mod_Scal_xperc_EditField.ValueDisplayFormat = '%1.9g';
			app.Mod_Scal_xperc_EditField.Tooltip = {'Scale factor in x-direction in percent.'};
			app.Mod_Scal_xperc_EditField.Value = 100;
			app.Mod_Scal_yperc_EditField = uieditfield(app.BasicTab, 'numeric');
			app.Mod_Scal_yperc_EditField.Limits = [0 Inf];
			app.Mod_Scal_yperc_EditField.ValueDisplayFormat = '%1.9g';
			app.Mod_Scal_yperc_EditField.Tooltip = {'Scale factor in y-direction in percent.'};
			app.Mod_Scal_yperc_EditField.Value = 100;
			app.Mod_Arrange_Set_PlotNoEditField = uieditfield(app.BasicTab, 'numeric');
			app.Mod_Arrange_Set_PlotNoEditField.Limits = [1 Inf];
			app.Mod_Arrange_Set_PlotNoEditField.RoundFractionalValues = 'on';
			app.Mod_Arrange_Set_PlotNoEditField.Tooltip = {'Destination PlotNo.'};
			app.Mod_Arrange_Set_PlotNoEditField.Value = 1;
			app.Mod_Set_Dimensions_Button = uibutton(app.BasicTab, 'push');
			app.Mod_Set_Dimensions_Button.ButtonPushedFcn = createCallbackFcn(app, @Mod_Set_Dimensions_ButtonPushed, true);
			app.Mod_Set_Dimensions_Button.Tooltip = {'Change the width and height of the bounding box of the map object.'};
			app.Mod_Set_Dimensions_Button.Text = 'Set Dimensions';
			app.SeparatorImage_4 = uiimage(app.BasicTab);
			app.SeparatorImage_4.ImageSource = fullfile(pathToMLAPP, 'RGB33_10x10.png');
			app.SeparatorImage_5 = uiimage(app.BasicTab);
			app.SeparatorImage_5.ImageSource = fullfile(pathToMLAPP, 'RGB33_10x10.png');
			app.SeparatorImage_10 = uiimage(app.BasicTab);
			app.SeparatorImage_10.ImageSource = fullfile(pathToMLAPP, 'RGB33_10x10.png');
			app.SeparatorImage_14 = uiimage(app.BasicTab);
			app.SeparatorImage_14.ImageSource = fullfile(pathToMLAPP, 'RGB33_10x10.png');
			app.SeparatorImage_16 = uiimage(app.BasicTab);
			app.SeparatorImage_16.ImageSource = fullfile(pathToMLAPP, 'RGB33_10x10.png');
			app.SeparatorImage_15 = uiimage(app.BasicTab);
			app.SeparatorImage_15.ImageSource = fullfile(pathToMLAPP, 'RGB33_10x10.png');
			app.SeparatorImage_17 = uiimage(app.BasicTab);
			app.SeparatorImage_17.ImageSource = fullfile(pathToMLAPP, 'RGB33_10x10.png');
			app.SeparatorImage_18 = uiimage(app.BasicTab);
			app.SeparatorImage_18.ImageSource = fullfile(pathToMLAPP, 'RGB33_10x10.png');
			app.SeparatorImage_19 = uiimage(app.BasicTab);
			app.SeparatorImage_19.ImageSource = fullfile(pathToMLAPP, 'RGB33_10x10.png');
			app.Mod_HideTemporarily_Button_2 = uibutton(app.BasicTab, 'push');
			app.Mod_HideTemporarily_Button_2.ButtonPushedFcn = createCallbackFcn(app, @Mod_HideTemporarily_Button_2Pushed, true);
			app.Mod_HideTemporarily_Button_2.Tooltip = {'Restore the visibility of all selected temporarily hidden map objects (as previously shown or grayed out). Use “Restore” instead of “Show” if grayed-out objects should remain grayed out.'};
			app.Mod_HideTemporarily_Button_2.Text = 'Restore';
			app.AdvancedTab = uitab(app.TabGroup2);
			app.AdvancedTab.Tooltip = {'Advanced functions for editing the map.'; '- Creation and editing of preview objects,'; '- Conversion between preview and map objects,'; '- Basic functions for editing polygons,'; '- Cutting up objects using cutting lines,'; '- Change of the printout limits.'};
			app.AdvancedTab.Title = 'Advanced';
			app.Mod_Lines_Label_2 = uilabel(app.AdvancedTab);
			app.Mod_Lines_Label_2.Tooltip = {'Functions for creating or changing preview objects.'; 'Preview objects are an intermediate stage for creating map objects by yourself and are not (yet) assigned to an object number. To create a preview object, it is necessary to enter a description.'};
			app.Mod_Lines_Label_2.Text = 'Preview (L/P)';
			app.DescriptionLabel = uilabel(app.AdvancedTab);
			app.DescriptionLabel.Text = 'Description';
			app.Mod_Polygons_Label = uilabel(app.AdvancedTab);
			app.Mod_Polygons_Label.Tooltip = {'Functions for application on polygons (preview polygons or map objects).'};
			app.Mod_Polygons_Label.Text = 'Polygons (P)';
			app.Mod_Polygons_Regions_Button = uibutton(app.AdvancedTab, 'push');
			app.Mod_Polygons_Regions_Button.ButtonPushedFcn = createCallbackFcn(app, @Mod_Polygons_Regions_ButtonPushed, true);
			app.Mod_Polygons_Regions_Button.Tooltip = {'Convert the selected map objects into groups of solid regions, each consisting of only one contiguous area. In contrast to the “Boundaries” command, holes do not become independent objects, but remain holes. '; 'Notes on the behavior of this function:'; '- If the map object consists of foreground (for example, the dashs of dashed lines) and background, the foreground objects remain in a group with the respective underlying background regions.'; '- If there are several overlapping foreground or background objects in a map object (for example, if a group has more than two elements), the overlapping foreground and background objects are united before being split into regions.'; '- If the map object consists of foreground and background, foreground objects are trimmed to the background so that they can be clearly assigned to a background region.'; '- If the map object only consists of foreground objects (without background), the foreground is split into individual map objects.'; 'Application examples:'; '- Hiding or deleting built-up areas that are too small.'};
			app.Mod_Polygons_Regions_Button.Text = 'Regions';
			app.Mod_Polygons_ToLine_Button = uibutton(app.AdvancedTab, 'push');
			app.Mod_Polygons_ToLine_Button.ButtonPushedFcn = createCallbackFcn(app, @Mod_Polygons_ToLine_ButtonPushed, true);
			app.Mod_Polygons_ToLine_Button.Tooltip = {'Converts the selected preview polygons into preview lines.'; 'The first and last vertices of the lines are identical, but can be separated using the “Cut” function.'};
			app.Mod_Polygons_ToLine_Button.Text = 'To line';
			app.Mod_Polygons_DissolveBound_Button = uibutton(app.AdvancedTab, 'push');
			app.Mod_Polygons_DissolveBound_Button.ButtonPushedFcn = createCallbackFcn(app, @Mod_Polygons_DissolveBound_ButtonPushed, true);
			app.Mod_Polygons_DissolveBound_Button.Tooltip = {'Convert the selected map objects into groups containing all boundaries. In contrast to the “Regions” command, holes become independent objects. The total number of map objects does not change. The “Ungroup” command can then be used to divide these groups into individual map objects with their own PlotNo, each consisting of only one closed line.'};
			app.Mod_Polygons_DissolveBound_Button.Text = 'Boundaries';
			app.Mod_Polygons_Subtract_dside_Button = uibutton(app.AdvancedTab, 'push');
			app.Mod_Polygons_Subtract_dside_Button.ButtonPushedFcn = createCallbackFcn(app, @Mod_Polygons_Subtract_dside_ButtonPushed, true);
			app.Mod_Polygons_Subtract_dside_Button.Tooltip = {'Geometric difference of two map objects like "1 Subtract 2", but with manufacturing tolerance. Before the subtraction, the subtrahend (object 2) is enlarged by the maximum project parameter colorspec(i,1).d_side of the colors involved (horizontal distance to neighboring parts). Map object 1 is replaced by the output, map object 2 is not changed.'};
			app.Mod_Polygons_Subtract_dside_Button.Text = '1 Subtract (2+tolerance)';
			app.Mod_ConvMapobjToPrev_Apart_CheckBox = uicheckbox(app.AdvancedTab);
			app.Mod_ConvMapobjToPrev_Apart_CheckBox.Tooltip = {'When executing the “Map object to preview” function:'; '- If the “Apart” checkbox is enabled, a single copy of each map object is created, possibly separated into foreground and background.'; '- If the “Apart” checkbox is not enabled, the foreground and background of all selected map objects are combined into a single foreground and background. This only applies to polygons, not individual lines.'};
			app.Mod_ConvMapobjToPrev_Apart_CheckBox.Text = 'Apart';
			app.Mod_ConvMapobjToPrev_Button = uibutton(app.AdvancedTab, 'push');
			app.Mod_ConvMapobjToPrev_Button.ButtonPushedFcn = createCallbackFcn(app, @Mod_ConvMapobjToPrev_ButtonPushed, true);
			app.Mod_ConvMapobjToPrev_Button.Tooltip = {'Convert all selected map objects such as lines, areas, texts or symbols into preview objects for further editing.'; '- The result is a preview object containing the foreground of all selected objects (if available) and an additional preview object that contains the background of all selected objects.'; '- Preview objects can also be converted.'; '- The tags and object numbers stored in the source objects are deleted. The resulting destination preview objects can therefore be used to create map objects with different object numbers.'; '- If multiple map objects are selected:'; 'If the “Apart” checkbox is enabled, a single copy of each map object is created, possibly separated into foreground and background.'; 'If the “Apart” checkbox is not enabled, the foreground and background of all selected map objects are combined into a single foreground and background. This only applies to polygons, not individual lines.'};
			app.Mod_ConvMapobjToPrev_Button.Text = 'Map object to preview';
			app.Mod_LiReVe_ButtonGroup = uibuttongroup(app.AdvancedTab);
			app.Mod_LiReVe_ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @Mod_LiReVe_ButtonGroupSelChanged, true);
			app.MoveObjectButton = uitogglebutton(app.Mod_LiReVe_ButtonGroup);
			app.MoveObjectButton.Tooltip = {'Move a selected map object by clicking and dragging.'};
			app.MoveObjectButton.Text = 'Move';
			app.SplitObjectButton = uitogglebutton(app.Mod_LiReVe_ButtonGroup);
			app.SplitObjectButton.Tooltip = {'Delete vertices of a selected preview line by clicking and dragging an area. All vertices within the area are deleted. In contrast to “Delete vertices”, the remaining line segments are not reconnected, but become separate preview lines with their own PlotNo.'};
			app.SplitObjectButton.Text = 'Split';
			app.MoveVerticesButton = uitogglebutton(app.Mod_LiReVe_ButtonGroup);
			app.MoveVerticesButton.Tooltip = {'Move a vertex of a selected map object by clicking and dragging.'};
			app.MoveVerticesButton.Text = 'Move vertex';
			app.OffButton = uitogglebutton(app.Mod_LiReVe_ButtonGroup);
			app.OffButton.Tooltip = {'Switches off the changing of map objects using the mouse.'};
			app.OffButton.Text = 'Off';
			app.OffButton.Value = true;
			app.DrawlineButton = uitogglebutton(app.Mod_LiReVe_ButtonGroup);
			app.DrawlineButton.Tooltip = {'Draw a line by clicking and dragging. If the new line starts at the end point of an existing and selected line, the lines are connected and become a polygon.'};
			app.DrawlineButton.Text = 'Draw line';
			app.InsertVerticesButton = uitogglebutton(app.Mod_LiReVe_ButtonGroup);
			app.InsertVerticesButton.Tooltip = {'Add a new vertex to a selected map object by clicking on the line or the edge of the polygon at the desired location.'; 'The system then automatically switches to the “Move vertex” mode so that you can move the vertex as long as the mouse pointer is still in the same position.'};
			app.InsertVerticesButton.Text = 'Insert vertex';
			app.DrawrectangleButton = uitogglebutton(app.Mod_LiReVe_ButtonGroup);
			app.DrawrectangleButton.Tooltip = {'Draw a rectangle by clicking and dragging.'};
			app.DrawrectangleButton.Text = 'Draw rectangle';
			app.DeleteVerticesButton = uitogglebutton(app.Mod_LiReVe_ButtonGroup);
			app.DeleteVerticesButton.Tooltip = {'Delete vertices of a selected map object by clicking and dragging an area. All vertices within the area are deleted. In contrast to “Split”, the remaining line segments are reconnected to form a continuous line. At least 1 vertex must remain for a preview line and at least 3 vertices for a polygon, otherwise the map object will be deleted.'};
			app.DeleteVerticesButton.Text = 'Delete vertices';
			app.Mod_Lines_Draw_Label = uilabel(app.AdvancedTab);
			app.Mod_Lines_Draw_Label.Tooltip = {'Functions for creating preview objects or modifying map objects using the mouse.'};
			app.Mod_Lines_Draw_Label.Text = 'Draw';
			app.BuffdistLabel = uilabel(app.AdvancedTab);
			app.BuffdistLabel.Tooltip = {'Buffer distance by which the boundaries are shifted when using “Polybuffer”.'; '- If the distance is >0, then solid boundaries grow and hole boundaries shrink.'; '- If the distance is <0, then solid boundaries shrink and hole boundaries grow.'; '- If the distance is =0, then there is no change to the boundaries.'};
			app.BuffdistLabel.Text = 'Buffer dist.';
			app.Mod_Polygons_Polybuffer_Button = uibutton(app.AdvancedTab, 'push');
			app.Mod_Polygons_Polybuffer_Button.ButtonPushedFcn = createCallbackFcn(app, @Mod_Polygons_Polybuffer_ButtonPushed, true);
			app.Mod_Polygons_Polybuffer_Button.Tooltip = {'The boundaries of the selected map objects are shifted by the specified buffer distance. A positive distance moves the boundaries outwards and a negative distance moves them inwards.'; 'Application examples:'; '- Widening of lines.'; '- Shifting by the same distance first inwards and then outwards deletes small areas.'; '- Shifting by the same distance first outwards and then inwards deletes small holes or connects neighboring areas that are part of the same PlotNo.'};
			app.Mod_Polygons_Polybuffer_Button.Text = 'Polybuffer';
			app.Mod_Polygons_Simplify_Button = uibutton(app.AdvancedTab, 'push');
			app.Mod_Polygons_Simplify_Button.ButtonPushedFcn = createCallbackFcn(app, @Mod_Polygons_Simplify_ButtonPushed, true);
			app.Mod_Polygons_Simplify_Button.Tooltip = {'The number of vertices of the selected map objects is reduced so that the distance between two consecutive vertices is not less than the specified value. The minimum distance is entered after pressing the button.'; 'Application example: If raw data is manually converted into map objects using “Filter OSM-data”, the lines can consist of a large number of vertices, especially with large scale numbers. This simplification can significantly reduce the calculation time.'; 'Note: With “File - Create map”, the map objects are automatically simplified.'};
			app.Mod_Polygons_Simplify_Button.Text = 'Simplify';
			app.Mod_Polygons_Addboundary_Button = uibutton(app.AdvancedTab, 'push');
			app.Mod_Polygons_Addboundary_Button.ButtonPushedFcn = createCallbackFcn(app, @Mod_Polygons_Addboundary_ButtonPushed, true);
			app.Mod_Polygons_Addboundary_Button.Tooltip = {'Returns a map object that is made up of the existing map object 1 plus additional boundaries defined by object 2. Unlike “1 Union 2”, overlapping areas become holes. Map object 1 is replaced by the output, map object 2 is not changed.'};
			app.Mod_Polygons_Addboundary_Button.Text = 'Add boundary 2 to 1';
			app.Mod_ConvertPrevToMapObj_Label = uilabel(app.AdvancedTab);
			app.Mod_ConvertPrevToMapObj_Label.Tooltip = {'Functions for converting preview objects into map objects.'};
			app.Mod_ConvertPrevToMapObj_Label.Text = 'Preview to map object';
			app.PlotNo1Label = uilabel(app.AdvancedTab);
			app.PlotNo1Label.Tooltip = {'PlotNo of the first map object when using polygon functions that are applied to exactly two polygons (for example “1 Subtract 2”). It can be entered manually or using the mouse: left-clicking on a map object sets PlotNo 1 and deletes PlotNo 2.'};
			app.PlotNo1Label.Text = 'PlotNo 1';
			app.Mod_Polygons_Union_Button = uibutton(app.AdvancedTab, 'push');
			app.Mod_Polygons_Union_Button.ButtonPushedFcn = createCallbackFcn(app, @Mod_Polygons_Union_ButtonPushed, true);
			app.Mod_Polygons_Union_Button.Tooltip = {'Returns a map object whose regions are the union of map object 1 and 2. Map object 1 is replaced by the output, map object 2 is not changed.'};
			app.Mod_Polygons_Union_Button.Text = '1 Union 2';
			app.Mod_Polygons_Subtract_Button = uibutton(app.AdvancedTab, 'push');
			app.Mod_Polygons_Subtract_Button.ButtonPushedFcn = createCallbackFcn(app, @Mod_Polygons_Subtract_ButtonPushed, true);
			app.Mod_Polygons_Subtract_Button.Tooltip = {'Returns a map object whose regions are the geometric difference of two map objects. The output contains the regions of object 1 minus any part of object 2 that overlaps with object 1.  Map object 1 is replaced by the output, map object 2 is not changed.'};
			app.Mod_Polygons_Subtract_Button.Text = '1 Subtract 2';
			app.Mod_PrintoutLimits_Label = uilabel(app.AdvancedTab);
			app.Mod_PrintoutLimits_Label.Tooltip = {'The printout limits are by default a rectangle in the xy plane and either equal to the maximum possible rectangle within the OSM data or, if specified in the project parameters, a smaller area within the OSM data. Here it is possible to give the printout limits the shape of any preview polygon. For example, the map can be given the shape of a region or a country.'; 'Legend of the map:'; '- OSM data limits: black line'; '- Printout limits, tile base: blue line'; '- Printout limits, map objects: blue dotted line'; '(parameter general.dist_obj_printout)'; '- Tile grid: cyan line'};
			app.Mod_PrintoutLimits_Label.Text = 'Printout limits';
			app.Mod_Polygons_SetPrintout_Button = uibutton(app.AdvancedTab, 'push');
			app.Mod_Polygons_SetPrintout_Button.ButtonPushedFcn = createCallbackFcn(app, @Mod_Polygons_SetPrintout_ButtonPushed, true);
			app.Mod_Polygons_SetPrintout_Button.Tooltip = {'Sets the printout limits equal to the boundaries of the selected preview polygon. This also changes the shape of the frame and possibly the number of tiles. Only one preview polygon may be selected and it must not be a group. The printout limits are always restricted to the area of the OSM data.'; 'The preview polygon will not be deleted.'};
			app.Mod_Polygons_SetPrintout_Button.Text = 'From preview';
			app.MapobjectnumberLabel = uilabel(app.AdvancedTab);
			app.MapobjectnumberLabel.Tooltip = {'When converting a preview object into a map object, the settings of the object number (ObjNo) specified here from the project file are used. The object number can be entered manually or by left-clicking on a map object on the map.'};
			app.MapobjectnumberLabel.Text = 'Map object number';
			app.PlotNo2Label = uilabel(app.AdvancedTab);
			app.PlotNo2Label.Tooltip = {'PlotNo of the second map object when using polygon functions that are applied to exactly two polygons (for example “1 Subtract 2”). It can be entered manually or using the mouse: a click with the middle mouse button on a map object sets the PlotNo 2.'};
			app.PlotNo2Label.Text = 'PlotNo 2';
			app.Mod_Polygons_Xor_Button = uibutton(app.AdvancedTab, 'push');
			app.Mod_Polygons_Xor_Button.ButtonPushedFcn = createCallbackFcn(app, @Mod_Polygons_Xor_ButtonPushed, true);
			app.Mod_Polygons_Xor_Button.Tooltip = {'Returns a map object whose regions are the geometric exclusive OR of two map objects. The geometric exclusive OR contains the regions of object 1 and object 2 that do not overlap. Map object 1 is replaced by the output, map object 2 is not changed.'};
			app.Mod_Polygons_Xor_Button.Text = '1 Xor 2';
			app.Mod_Polygons_Intersect_Button = uibutton(app.AdvancedTab, 'push');
			app.Mod_Polygons_Intersect_Button.ButtonPushedFcn = createCallbackFcn(app, @Mod_Polygons_Intersect_ButtonPushed, true);
			app.Mod_Polygons_Intersect_Button.Tooltip = {'Returns a map object whose regions are the geometric intersection of two map objects. The intersection contains the regions of object 1 and object 2 that overlap. Map object 1 is replaced by the output, map object 2 is not changed.'};
			app.Mod_Polygons_Intersect_Button.Text = '1 Intersect 2';
			app.Mod_Polygons_PrintoutLimitsToPoly_Button = uibutton(app.AdvancedTab, 'push');
			app.Mod_Polygons_PrintoutLimitsToPoly_Button.ButtonPushedFcn = createCallbackFcn(app, @Mod_Polygons_PrintoutLimitsToPoly_ButtonPushed, true);
			app.Mod_Polygons_PrintoutLimitsToPoly_Button.Tooltip = {'Creates a preview polygon that is identical to the current printout limits. The preview polygon can be used, for example, to change the printout limits.'};
			app.Mod_Polygons_PrintoutLimitsToPoly_Button.Text = 'To preview';
			app.Mod_Polygons_ResetPrintout_Button = uibutton(app.AdvancedTab, 'push');
			app.Mod_Polygons_ResetPrintout_Button.ButtonPushedFcn = createCallbackFcn(app, @Mod_Polygons_ResetPrintout_ButtonPushed, true);
			app.Mod_Polygons_ResetPrintout_Button.Tooltip = {'Resets the printout limits to the default values: the maximum possible rectangle within the OSM data or the area specified in the project parameters.'};
			app.Mod_Polygons_ResetPrintout_Button.Text = 'Reset';
			app.Mod_ConvPrevToMapobj_Button = uibutton(app.AdvancedTab, 'push');
			app.Mod_ConvPrevToMapobj_Button.ButtonPushedFcn = createCallbackFcn(app, @Mod_ConvPrevToMapobj_ButtonPushed, true);
			app.Mod_ConvPrevToMapobj_Button.Tooltip = {'Conversion of the selected preview objects (lines or polygons) into map objects. The map objects are formatted based on the settings of the “Map object number” (ObjNo) from the project file. Whether, for example, a preview object is converted into a line or an area depends on the parameters display_as_line and display_as_area of this object. An open preview line can only be converted into a line, a closed preview line or a preview polygon can be converted into a line or an area. The “Merge” checkbox also affects the result of this function.'};
			app.Mod_ConvPrevToMapobj_Button.Text = 'Preview to map object';
			app.Mod_MergeWithExistObj_CheckBox = uicheckbox(app.AdvancedTab);
			app.Mod_MergeWithExistObj_CheckBox.ValueChangedFcn = createCallbackFcn(app, @Mod_MergeWithExistObj_CheckBoxValueChanged, true);
			app.Mod_MergeWithExistObj_CheckBox.Tooltip = {'If you activate the “Merge” checkbox for “Preview to map object” beforehand, the preview polygon is converted and added to the existing map object in the same way as for “Create map”. Adding an outer object to an existing inner object can turn the inner object into a hole.'; 'If the checkbox is not activated, another map object is created. If two areas with the same color overlap, they will be merged later with “Create map STL files”. An inner surface will be covered by an outer surface.'; 'Click the checkbox to see a detailed explanation of how it works.'};
			app.Mod_MergeWithExistObj_CheckBox.Text = 'Merge';
			app.Mod_Lines_Nodes_Label = uilabel(app.AdvancedTab);
			app.Mod_Lines_Nodes_Label.Tooltip = {'Functions for creating preview lines by entering the coordinates.'};
			app.Mod_Lines_Nodes_Label.Text = 'Nodes';
			app.Mod_LV_FirstLast_ButtonGroup = uibuttongroup(app.AdvancedTab);
			app.Mod_LV_FirstLast_ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @Mod_LV_FirstLast_ButtonGroupSelChanged, true);
			app.Mod_LV_First_Button = uitogglebutton(app.Mod_LV_FirstLast_ButtonGroup);
			app.Mod_LV_First_Button.Tooltip = {'The “Add” or “Delete” function is applied to the first vertex of the line.'};
			app.Mod_LV_First_Button.Text = 'First';
			app.Mod_LV_First_Button.Value = true;
			app.Mod_LV_Last_Button = uitogglebutton(app.Mod_LV_FirstLast_ButtonGroup);
			app.Mod_LV_Last_Button.Tooltip = {'The “Add” or “Delete” function is applied to the last vertex of the line.'};
			app.Mod_LV_Last_Button.Text = 'Last';
			app.xymmLabel = uilabel(app.AdvancedTab);
			app.xymmLabel.Tooltip = {'xy-coordinates of a new vertex.'};
			app.xymmLabel.Text = 'x,y / mm';
			app.Mod_Lines_Label = uilabel(app.AdvancedTab);
			app.Mod_Lines_Label.Tooltip = {'Functions for application on preview lines.'};
			app.Mod_Lines_Label.Text = 'Lines (L)';
			app.CuttinglinesLabel = uilabel(app.AdvancedTab);
			app.CuttinglinesLabel.Tooltip = {'Functions to create “cutting lines”. This has three possible purposes:'; '- Limit the maximum size of the individual parts to be printed to the maximum object size in x and y direction. This should be done after executing “File - Map editing - Simplify map”, because the size of the lines and areas can still change.'; '- Cut the lines and areas into smaller puzzle pieces if desired.'; '- Divide lines manually so that the printed lines don''t get too high and the slots don''t get too deep. This is only necessary if the automatic cutting of lines is switched off in “File - Map editing - Simplify map”. To cut lines manually, it is recommended to switch on the contour lines.'; 'Notes:'; '- The cutting lines can be applied as a test with “Cut” (undo with “Reset united equal colors”).'; '- With “File - Map editing - Cut into pieces” the cutting lines are finally applied (can no longer be undone).'};
			app.CuttinglinesLabel.Text = 'Cutting lines';
			app.Mod_UnitedColors_SelectColNo_DropDown = uidropdown(app.AdvancedTab);
			app.Mod_UnitedColors_SelectColNo_DropDown.Items = {'Color XXXX'};
			app.Mod_UnitedColors_SelectColNo_DropDown.ValueChangedFcn = createCallbackFcn(app, @Mod_UnitedColors_SelectColNo_DropDownValueChanged, true);
			app.Mod_UnitedColors_SelectColNo_DropDown.Tooltip = {'Selection of the color number for which cutting lines are to be created. In addition to the selected color, the following objects are also displayed:'; '- Texts and symbols of the same color: The cutting lines should not cut through texts and symbols.'; '- Underlying colors with lower priority: This can be helpful when cutting lines.'};
			app.Mod_UnitedColors_SelectColNo_DropDown.Value = 'Color XXXX';
			app.Mod_UnitedColors_Cut_Button = uibutton(app.AdvancedTab, 'push');
			app.Mod_UnitedColors_Cut_Button.ButtonPushedFcn = createCallbackFcn(app, @Mod_UnitedColors_Cut_ButtonPushed, true);
			app.Mod_UnitedColors_Cut_Button.Tooltip = {'Cuts the currently selected color with the cutting lines previously defined for this color as a test. By left and right clicking on a map object, you can then see whether the dimensions of the part are still outside the specification. Click on the context menu to zoom to the largest or smallest object.'};
			app.Mod_UnitedColors_Cut_Button.Text = 'Cut';
			app.Mod_ConvPrevLineToConnLine_Button = uibutton(app.AdvancedTab, 'push');
			app.Mod_ConvPrevLineToConnLine_Button.ButtonPushedFcn = createCallbackFcn(app, @Mod_ConvPrevLineToConnLine_ButtonPushed, true);
			app.Mod_ConvPrevLineToConnLine_Button.Tooltip = {'Conversion of the selected preview lines into connection lines. Formatting is based on the specified “Map object number”, the preview lines are deleted after the conversion.'; 'With connection lines, texts and symbols can be automatically connected when “File - Create map” is executed so that possibly small symbols do not have to be printed separately and inserted into the map. If texts are repositioned, the connection lines may have to be adjusted manually or recreated using this function.'};
			app.Mod_ConvPrevLineToConnLine_Button.Text = 'Preview line to connection line';
			app.Mod_Lines_Form_Label = uilabel(app.AdvancedTab);
			app.Mod_Lines_Form_Label.Tooltip = {'Functions to create preview  lines in certain forms.'};
			app.Mod_Lines_Form_Label.Text = 'Form';
			app.Mod_LV_Add_Button = uibutton(app.AdvancedTab, 'push');
			app.Mod_LV_Add_Button.ButtonPushedFcn = createCallbackFcn(app, @Mod_LV_Add_ButtonPushed, true);
			app.Mod_LV_Add_Button.Tooltip = {'Adds a new vertex to the selected preview line at the beginning (first) or end (last). The line must not intersect itself.'};
			app.Mod_LV_Add_Button.Text = 'Add';
			app.Mod_LV_Delete_Button = uibutton(app.AdvancedTab, 'push');
			app.Mod_LV_Delete_Button.ButtonPushedFcn = createCallbackFcn(app, @Mod_LV_Delete_ButtonPushed, true);
			app.Mod_LV_Delete_Button.Tooltip = {'If a preview line consists of at least two vertices: Deletes the vertex at the beginning (first) or end (last) of the selected preview line.'};
			app.Mod_LV_Delete_Button.Text = 'Delete';
			app.Mod_LV_New_Button = uibutton(app.AdvancedTab, 'push');
			app.Mod_LV_New_Button.ButtonPushedFcn = createCallbackFcn(app, @Mod_LV_New_ButtonPushed, true);
			app.Mod_LV_New_Button.Tooltip = {'Creates a new preview line by entering the first vertex.'};
			app.Mod_LV_New_Button.Text = 'New line';
			app.Mod_Lines_ToPolygon_Button = uibutton(app.AdvancedTab, 'push');
			app.Mod_Lines_ToPolygon_Button.ButtonPushedFcn = createCallbackFcn(app, @Mod_Lines_ToPolygon_ButtonPushed, true);
			app.Mod_Lines_ToPolygon_Button.Tooltip = {'Converts one selected preview line into a preview polygon.'; 'The first and last vertices of the line do not have to be identical, but it must be possible to connect them without a self-intersection. If not, the first and last vertices must be edited manually (“Move vertex”).'};
			app.Mod_Lines_ToPolygon_Button.Text = 'To poly';
			app.Mod_Lines_Connect_Button = uibutton(app.AdvancedTab, 'push');
			app.Mod_Lines_Connect_Button.ButtonPushedFcn = createCallbackFcn(app, @Mod_Lines_Connect_ButtonPushed, true);
			app.Mod_Lines_Connect_Button.Tooltip = {'Connects the first or last vertices of several selected preview lines if the distance is smaller than the specified tolerance. The tolerance is entered after pressing the button.'; '- Only preview lines that are not closed or consist of only one vertex are connected.'; '- More than two preview lines may be selected.'; '- If the distance between the first and last vertex of the same line is within the tolerance, the line is closed and converted into a polygon.'};
			app.Mod_Lines_Connect_Button.Text = 'Link';
			app.Mod_CreateUnitedColors_Button = uibutton(app.AdvancedTab, 'push');
			app.Mod_CreateUnitedColors_Button.ButtonPushedFcn = createCallbackFcn(app, @Mod_CreateUnitedColors_ButtonPushed, true);
			app.Mod_CreateUnitedColors_Button.Tooltip = {'Connects different objects of the same color to form contiguous areas when overlapping. These areas are equal to the base areas of the parts to be printed and must not exceed or fall below a minimum or maximum size:'; '- If the areas are too small and therefore cannot be printed: Hide or delete map objects, then execute “Create united equal colors” again.'; '- If the areas are too large: Cut up the areas with “cutting lines”.'; 'This function is also executed when the following functions are called up; it is not necessary to press this button again afterwards:'; '- Menü: File - Map editing - Simplify map'; '- Menü: File - Map editing - Cut into pieces'};
			app.Mod_CreateUnitedColors_Button.Text = 'Create united equal colors';
			app.Mod_UnitedColors_PrevCL_to_CL_Button = uibutton(app.AdvancedTab, 'push');
			app.Mod_UnitedColors_PrevCL_to_CL_Button.ButtonPushedFcn = createCallbackFcn(app, @Mod_UnitedColors_PrevCL_to_CL_ButtonPushed, true);
			app.Mod_UnitedColors_PrevCL_to_CL_Button.Tooltip = {'Converts a preview cutting line into a cutting line with a specified width (so that the separated parts do not stick together again when printing) and with a regular or random pattern that can be selected via the project parameters. Preview cutting lines are created manually beforehand for the currently selected color with “Draw line”. With individual straight preview cutting lines, the parts are cut as with a paper cutter, but the preview cutting lines can also have several vertices. A preview line description is not required here.'};
			app.Mod_UnitedColors_PrevCL_to_CL_Button.Text = 'Preview cut-line to cut-line';
			app.Mod_ConvPrevLineToLine_Button = uibutton(app.AdvancedTab, 'push');
			app.Mod_ConvPrevLineToLine_Button.ButtonPushedFcn = createCallbackFcn(app, @Mod_ConvPrevLineToLine_ButtonPushed, true);
			app.Mod_ConvPrevLineToLine_Button.Tooltip = {'Conversion of the selected preview lines into line map objects of a specified width. Formatting is based on the specified “Map object number”, the preview lines are deleted after the conversion.'; 'Applications: Connection of small objects or reinforcement of fragile parts.'};
			app.Mod_ConvPrevLineToLine_Button.Text = 'Preview line to line map object';
			app.Mod_EnterCircle_Button = uibutton(app.AdvancedTab, 'push');
			app.Mod_EnterCircle_Button.ButtonPushedFcn = createCallbackFcn(app, @Mod_EnterCircle_ButtonPushed, true);
			app.Mod_EnterCircle_Button.Tooltip = {'Creates a circular preview line by entering the data.'};
			app.Mod_EnterCircle_Button.Text = 'Circle';
			app.Mod_EnterRectangle_Button = uibutton(app.AdvancedTab, 'push');
			app.Mod_EnterRectangle_Button.ButtonPushedFcn = createCallbackFcn(app, @Mod_EnterRectangle_ButtonPushed, true);
			app.Mod_EnterRectangle_Button.Tooltip = {'Creates a rectangular preview line by entering the data.'};
			app.Mod_EnterRectangle_Button.Text = 'Rectangle';
			app.Mod_EnterFormToFile_Button = uibutton(app.AdvancedTab, 'push');
			app.Mod_EnterFormToFile_Button.ButtonPushedFcn = createCallbackFcn(app, @Mod_EnterFormToFile_ButtonPushed, true);
			app.Mod_EnterFormToFile_Button.Tooltip = {'Saves the xy coordinates of the selected map objects (lines or polygons) as a tab-delimited list in a text file. Column 1 contains the x-coordinates, column 2 the y-coordinates. Each line therefore contains a vertex. Several lines or boundaries of polygons are separated in the text file by an empty line. The information whether there are several map objects, groups with several elements or polygons with several boundaries (e.g. holes) is lost. Former polygons are displayed after loading (“From file”) as preview lines with the same start and end vertex.'};
			app.Mod_EnterFormToFile_Button.Text = 'To file';
			app.Mod_EnterFormFromFile_Button = uibutton(app.AdvancedTab, 'push');
			app.Mod_EnterFormFromFile_Button.ButtonPushedFcn = createCallbackFcn(app, @Mod_EnterFormFromFile_ButtonPushed, true);
			app.Mod_EnterFormFromFile_Button.Tooltip = {'Loads a text file with xy-coordinates, created for example with “To file”, and inserts the data into the map as individual preview lines.'};
			app.Mod_EnterFormFromFile_Button.Text = 'From file';
			app.Mod_LV_Close_Button = uibutton(app.AdvancedTab, 'push');
			app.Mod_LV_Close_Button.ButtonPushedFcn = createCallbackFcn(app, @Mod_LV_Close_ButtonPushed, true);
			app.Mod_LV_Close_Button.Tooltip = {'Connects the first and last vertex of the selected preview line by adding the first vertex to the end of the line.'; 'It must be possible to connect the first and last vertices without self-intersection. If not, the first and last vertices must be edited manually (“Move vertex”).'};
			app.Mod_LV_Close_Button.Text = 'Close';
			app.Mod_LV_Cut_Button = uibutton(app.AdvancedTab, 'push');
			app.Mod_LV_Cut_Button.ButtonPushedFcn = createCallbackFcn(app, @Mod_LV_Cut_ButtonPushed, true);
			app.Mod_LV_Cut_Button.Tooltip = {'If the first and last vertex of the selected preview line are identical, the last vertex is deleted.'};
			app.Mod_LV_Cut_Button.Text = 'Cut';
			app.Mod_UnitedColors_Reset_Button = uibutton(app.AdvancedTab, 'push');
			app.Mod_UnitedColors_Reset_Button.ButtonPushedFcn = createCallbackFcn(app, @Mod_UnitedColors_Reset_ButtonPushed, true);
			app.Mod_UnitedColors_Reset_Button.Tooltip = {'Undo the test application of the cutting lines with “Cut” (faster than executing “Create united equal colors” again). This way, the cutting lines can be modified if necessary.'};
			app.Mod_UnitedColors_Reset_Button.Text = 'Reset united equal colors';
			app.Mod_UnitedColors_CreateCLAutomatically_Button = uibutton(app.AdvancedTab, 'push');
			app.Mod_UnitedColors_CreateCLAutomatically_Button.ButtonPushedFcn = createCallbackFcn(app, @Mod_UnitedColors_CreateCLAutomatically_ButtonPushed, true);
			app.Mod_UnitedColors_CreateCLAutomatically_Button.Tooltip = {'Automatically creates cutting lines so that after executing “Cut” the size of all parts is within the specified range (colorspecific project parameters). This function is still experimental and may not work reliably with complex geometries. Texts or symbols could also be cut.'};
			app.Mod_UnitedColors_CreateCLAutomatically_Button.Text = 'Auto create cutting lines';
			app.LinewidthmmLabel = uilabel(app.AdvancedTab);
			app.LinewidthmmLabel.Tooltip = {'Line width used for the “Preview line to line map object” function.'};
			app.LinewidthmmLabel.Text = 'Line width / mm';
			app.Mod_LV_NewLine_Descr_EditField = uieditfield(app.AdvancedTab, 'text');
			app.Mod_LV_NewLine_Descr_EditField.Tooltip = {'Description for a new preview object.'; 'The description can help to identify the preview object in the map objects table.'};
			app.Mod_LV_xmm_EditField = uieditfield(app.AdvancedTab, 'numeric');
			app.Mod_LV_xmm_EditField.ValueDisplayFormat = '%1.9g';
			app.Mod_LV_xmm_EditField.Tooltip = {'x-coordinate of a new vertex.'};
			app.Mod_LV_ymm_EditField = uieditfield(app.AdvancedTab, 'numeric');
			app.Mod_LV_ymm_EditField.ValueDisplayFormat = '%1.9g';
			app.Mod_LV_ymm_EditField.Tooltip = {'y-coordinate of a new vertex.'};
			app.Mod_Polygons_BuffDist_EditField = uieditfield(app.AdvancedTab, 'numeric');
			app.Mod_Polygons_BuffDist_EditField.ValueDisplayFormat = '%1.9g';
			app.Mod_Polygons_BuffDist_EditField.Tooltip = {'Buffer distance by which the boundaries are shifted when using “Polybuffer”.'; '- If the distance is >0, then solid boundaries grow and hole boundaries shrink.'; '- If the distance is <0, then solid boundaries shrink and hole boundaries grow.'; '- If the distance is =0, then there is no change to the boundaries.'};
			app.Mod_Polygons_PlotNo1_EditField = uieditfield(app.AdvancedTab, 'numeric');
			app.Mod_Polygons_PlotNo1_EditField.ValueDisplayFormat = '%1.9g';
			app.Mod_Polygons_PlotNo1_EditField.Tooltip = {'PlotNo of the first map object when using polygon functions that are applied to exactly two polygons (for example “1 Subtract 2”). It can be entered manually or using the mouse: left-clicking on a map object sets PlotNo 1 and deletes PlotNo 2.'};
			app.Mod_Polygons_PlotNo2_EditField = uieditfield(app.AdvancedTab, 'numeric');
			app.Mod_Polygons_PlotNo2_EditField.ValueDisplayFormat = '%1.9g';
			app.Mod_Polygons_PlotNo2_EditField.Tooltip = {'PlotNo of the second map object when using polygon functions that are applied to exactly two polygons (for example “1 Subtract 2”). It can be entered manually or using the mouse: a click with the middle mouse button on a map object sets the PlotNo 2.'};
			app.Mod_AddPrevToOutput_ObjNo_EditField = uieditfield(app.AdvancedTab, 'numeric');
			app.Mod_AddPrevToOutput_ObjNo_EditField.ValueDisplayFormat = '%.0f';
			app.Mod_AddPrevToOutput_ObjNo_EditField.Tooltip = {'When converting a preview object into a map object, the settings of the object number (ObjNo) specified here from the project file are used. The object number can be entered manually or by left-clicking on a map object on the map.'};
			app.Mod_AddPrevToOutput_ObjNo_EditField.Value = 1;
			app.Mod_ConvPrevLineToLine_EditField = uieditfield(app.AdvancedTab, 'numeric');
			app.Mod_ConvPrevLineToLine_EditField.Limits = [0 Inf];
			app.Mod_ConvPrevLineToLine_EditField.ValueDisplayFormat = '%g';
			app.Mod_ConvPrevLineToLine_EditField.Tooltip = {'Line width used for the “Preview line to line map object” function.'};
			app.Mod_ConvPrevLineToLine_EditField.Value = 1;
			app.SeparatorImage_3 = uiimage(app.AdvancedTab);
			app.SeparatorImage_3.ImageSource = fullfile(pathToMLAPP, 'RGB33_10x10.png');
			app.SeparatorImage_6 = uiimage(app.AdvancedTab);
			app.SeparatorImage_6.ImageSource = fullfile(pathToMLAPP, 'RGB33_10x10.png');
			app.SeparatorImage_8 = uiimage(app.AdvancedTab);
			app.SeparatorImage_8.ImageSource = fullfile(pathToMLAPP, 'RGB33_10x10.png');
			app.SeparatorImage_9 = uiimage(app.AdvancedTab);
			app.SeparatorImage_9.ImageSource = fullfile(pathToMLAPP, 'RGB33_10x10.png');
			app.SeparatorImage_11 = uiimage(app.AdvancedTab);
			app.SeparatorImage_11.ImageSource = fullfile(pathToMLAPP, 'RGB33_10x10.png');
			app.SeparatorImage_13 = uiimage(app.AdvancedTab);
			app.SeparatorImage_13.ImageSource = fullfile(pathToMLAPP, 'RGB33_10x10.png');
			app.MapViewLabel = uilabel(app.EditmapTab);
			app.MapViewLabel.Tooltip = {'Zoom in, zoom out and scroll the map view.'};
			app.MapViewLabel.Text = 'Map View';
			app.MapView_In_Button = uibutton(app.EditmapTab, 'state');
			app.MapView_In_Button.ValueChangedFcn = createCallbackFcn(app, @MapView_In_ButtonValueChanged, true);
			app.MapView_In_Button.Tooltip = {'Zoom in: Activate the button, then drag the area on the map with the mouse.'};
			app.MapView_In_Button.Text = 'In';
			app.MapView_UnDo_Button = uibutton(app.EditmapTab, 'push');
			app.MapView_UnDo_Button.ButtonPushedFcn = createCallbackFcn(app, @MapView_UnDo_ButtonPushed, true);
			app.MapView_UnDo_Button.Icon = 'UnDo_grayedout.png';
			app.MapView_UnDo_Button.Tooltip = {'Undo last change to the map view.'};
			app.MapView_UnDo_Button.Text = '';
			app.MapView_Out_Button = uibutton(app.EditmapTab, 'push');
			app.MapView_Out_Button.ButtonPushedFcn = createCallbackFcn(app, @MapView_Out_ButtonPushed, true);
			app.MapView_Out_Button.Tooltip = {'Zoom out.'};
			app.MapView_Out_Button.Text = 'Out';
			app.MapView_ReDo_Button = uibutton(app.EditmapTab, 'push');
			app.MapView_ReDo_Button.ButtonPushedFcn = createCallbackFcn(app, @MapView_ReDo_ButtonPushed, true);
			app.MapView_ReDo_Button.Icon = 'ReDo_grayedout.png';
			app.MapView_ReDo_Button.Tooltip = {'Redo last change to the map view.'};
			app.MapView_ReDo_Button.Text = '';
			app.MapView_Sel_Button = uibutton(app.EditmapTab, 'push');
			app.MapView_Sel_Button.ButtonPushedFcn = createCallbackFcn(app, @MapView_Sel_ButtonPushed, true);
			app.MapView_Sel_Button.Tooltip = {'Zoom on all currently selected map objects.'};
			app.MapView_Sel_Button.Text = 'Sel';
			app.MapView_U1_Button = uibutton(app.EditmapTab, 'push');
			app.MapView_U1_Button.ButtonPushedFcn = createCallbackFcn(app, @MapView_U1_ButtonPushed, true);
			app.MapView_U1_Button.Icon = 'U1.png';
			app.MapView_U1_Button.Tooltip = {'tooltip_is_set_by_app'};
			app.MapView_U1_Button.Text = '';
			app.MapView_L1_Button = uibutton(app.EditmapTab, 'push');
			app.MapView_L1_Button.ButtonPushedFcn = createCallbackFcn(app, @MapView_L1_ButtonPushed, true);
			app.MapView_L1_Button.Icon = 'L1.png';
			app.MapView_L1_Button.Tooltip = {'tooltip_is_set_by_app'};
			app.MapView_L1_Button.Text = '';
			app.MapView_R1_Button = uibutton(app.EditmapTab, 'push');
			app.MapView_R1_Button.ButtonPushedFcn = createCallbackFcn(app, @MapView_R1_ButtonPushed, true);
			app.MapView_R1_Button.Icon = 'R1.png';
			app.MapView_R1_Button.Tooltip = {'tooltip_is_set_by_app'};
			app.MapView_R1_Button.Text = '';
			app.MapView_D1_Button = uibutton(app.EditmapTab, 'push');
			app.MapView_D1_Button.ButtonPushedFcn = createCallbackFcn(app, @MapView_D1_ButtonPushed, true);
			app.MapView_D1_Button.Icon = 'D1.png';
			app.MapView_D1_Button.Tooltip = {'tooltip_is_set_by_app'};
			app.MapView_D1_Button.Text = '';
			app.MapView_U2_Button = uibutton(app.EditmapTab, 'push');
			app.MapView_U2_Button.ButtonPushedFcn = createCallbackFcn(app, @MapView_U2_ButtonPushed, true);
			app.MapView_U2_Button.Icon = 'U2.png';
			app.MapView_U2_Button.Tooltip = {'tooltip_is_set_by_app'};
			app.MapView_U2_Button.Text = '';
			app.MapView_L2_Button = uibutton(app.EditmapTab, 'push');
			app.MapView_L2_Button.ButtonPushedFcn = createCallbackFcn(app, @MapView_L2_ButtonPushed, true);
			app.MapView_L2_Button.Icon = 'L2.png';
			app.MapView_L2_Button.Tooltip = {'tooltip_is_set_by_app'};
			app.MapView_L2_Button.Text = '';
			app.MapView_R2_Button = uibutton(app.EditmapTab, 'push');
			app.MapView_R2_Button.ButtonPushedFcn = createCallbackFcn(app, @MapView_R2_ButtonPushed, true);
			app.MapView_R2_Button.Icon = 'R2.png';
			app.MapView_R2_Button.Tooltip = {'tooltip_is_set_by_app'};
			app.MapView_R2_Button.Text = '';
			app.MapView_D2_Button = uibutton(app.EditmapTab, 'push');
			app.MapView_D2_Button.ButtonPushedFcn = createCallbackFcn(app, @MapView_D2_ButtonPushed, true);
			app.MapView_D2_Button.Icon = 'D2.png';
			app.MapView_D2_Button.Tooltip = {'tooltip_is_set_by_app'};
			app.MapView_D2_Button.Text = '';
			app.MapView_U3_Button = uibutton(app.EditmapTab, 'push');
			app.MapView_U3_Button.ButtonPushedFcn = createCallbackFcn(app, @MapView_U3_ButtonPushed, true);
			app.MapView_U3_Button.Icon = 'U3.png';
			app.MapView_U3_Button.Tooltip = {'tooltip_is_set_by_app'};
			app.MapView_U3_Button.Text = '';
			app.MapView_L3_Button = uibutton(app.EditmapTab, 'push');
			app.MapView_L3_Button.ButtonPushedFcn = createCallbackFcn(app, @MapView_L3_ButtonPushed, true);
			app.MapView_L3_Button.Icon = 'L3.png';
			app.MapView_L3_Button.Tooltip = {'tooltip_is_set_by_app'};
			app.MapView_L3_Button.Text = '';
			app.MapView_R3_Button = uibutton(app.EditmapTab, 'push');
			app.MapView_R3_Button.ButtonPushedFcn = createCallbackFcn(app, @MapView_R3_ButtonPushed, true);
			app.MapView_R3_Button.Icon = 'R3.png';
			app.MapView_R3_Button.Tooltip = {'tooltip_is_set_by_app'};
			app.MapView_R3_Button.Text = '';
			app.MapView_D3_Button = uibutton(app.EditmapTab, 'push');
			app.MapView_D3_Button.ButtonPushedFcn = createCallbackFcn(app, @MapView_D3_ButtonPushed, true);
			app.MapView_D3_Button.Icon = 'D3.png';
			app.MapView_D3_Button.Tooltip = {'tooltip_is_set_by_app'};
			app.MapView_D3_Button.Text = '';
			app.Mod_Vis_Label = uilabel(app.EditmapTab);
			app.Mod_Vis_Label.Tooltip = {'Change the visibility of selected map objects.'};
			app.Mod_Vis_Label.Text = 'Visibility';
			app.Mod_Show_Button = uibutton(app.EditmapTab, 'push');
			app.Mod_Show_Button.ButtonPushedFcn = createCallbackFcn(app, @Mod_Show_ButtonPushed, true);
			app.Mod_Show_Button.Tooltip = {'Show all selected map objects.'; 'This is a precondition for creating an STL model of the map object.'};
			app.Mod_Show_Button.Text = 'Show';
			app.Mod_Hide_Button = uibutton(app.EditmapTab, 'push');
			app.Mod_Hide_Button.ButtonPushedFcn = createCallbackFcn(app, @Mod_Hide_ButtonPushed, true);
			app.Mod_Hide_Button.Tooltip = {'Hide all selected map objects.'; 'The map objects are not visible and are not included in the STL data of the map.'};
			app.Mod_Hide_Button.Text = 'Hide';
			app.Mod_GrayOut_Button = uibutton(app.EditmapTab, 'push');
			app.Mod_GrayOut_Button.ButtonPushedFcn = createCallbackFcn(app, @Mod_GrayOut_ButtonPushed, true);
			app.Mod_GrayOut_Button.Tooltip = {'Gray out all selected map objects.'; 'The map objects are still visible, but are not included in the STL data of the map.'};
			app.Mod_GrayOut_Button.Text = 'Gray';
			app.Mod_DelObj_Button = uibutton(app.EditmapTab, 'push');
			app.Mod_DelObj_Button.ButtonPushedFcn = createCallbackFcn(app, @Mod_DelObj_ButtonPushed, true);
			app.Mod_DelObj_Button.Tooltip = {'Delete all selected map objects.'};
			app.Mod_DelObj_Button.Text = 'Delete';
			app.MAP_OBJECTS_TABLE = uitable(app.EditmapTab);
			app.MAP_OBJECTS_TABLE.ColumnName = {'PlotNo'; 'ObjNo'; 'ColNo'; 'Description'; 'Text/Tag'; 'Vis'; 'Group'; 'DispAs'};
			app.MAP_OBJECTS_TABLE.RowName = {};
			app.MAP_OBJECTS_TABLE.CellSelectionCallback = createCallbackFcn(app, @MAP_OBJECTS_TABLE_CellSelection, true);
			app.MAP_OBJECTS_TABLE.Tooltip = {'Table containing all map objects.'; '- Objects with a low PlotNo are located on the map below objects with a higher PlotNo, they may be covered and cannot be clicked on the map with the mouse. In this case, they can be selected in the table.'; '- Hidden objects can be selected in the table.'; '- Because updating the table may be slow, the table can be hidden (“View - Show map objects table”).'};
			app.MAP_OBJECTS_TABLE.KeyReleaseFcn = createCallbackFcn(app, @MAP_OBJECTS_TABLE_KeyRelease, true);
			app.MapView_Fit_Button = uibutton(app.EditmapTab, 'push');
			app.MapView_Fit_Button.ButtonPushedFcn = createCallbackFcn(app, @MapView_Fit_ButtonPushed, true);
			app.MapView_Fit_Button.Tooltip = {'Zoom on the printout limits.'};
			app.MapView_Fit_Button.Text = 'Fit';
			app.MapView_All_Button = uibutton(app.EditmapTab, 'push');
			app.MapView_All_Button.ButtonPushedFcn = createCallbackFcn(app, @MapView_All_ButtonPushed, true);
			app.MapView_All_Button.Tooltip = {'Zoom on the OSM data and the tiles.'};
			app.MapView_All_Button.Text = 'All';
			app.FilterOSMdataTab = uitab(app.TabGroup);
			app.FilterOSMdataTab.Tooltip = {'Search the currently loaded OSM raw data.'};
			app.FilterOSMdataTab.Title = 'Filter OSM-data';
			app.FilterOSMData_AutofillSource_ButtonGroup = uibuttongroup(app.FilterOSMdataTab);
			app.FilterOSMData_AutofillSource_ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @FilterOSMData_AutofillSource_ButtonGroupSelectionChanged, true);
			app.FilterOSMData_AutofillSource_ButtonGroup.Tooltip = {''};
			app.FilterOSMData_AutofillSource_LiAr_Button = uitogglebutton(app.FilterOSMData_AutofillSource_ButtonGroup);
			app.FilterOSMData_AutofillSource_LiAr_Button.Tooltip = {'Use the include and exclude tags of the lines and areas.'; 'These tags are entered under obj(ObjNo,1).tag_incl/tag_excl, normally these are also the criteria for selecting texts and symbols.'};
			app.FilterOSMData_AutofillSource_LiAr_Button.Text = 'Lines/Areas';
			app.FilterOSMData_AutofillSource_LiAr_Button.Value = true;
			app.FilterOSMData_AutofillSource_Text_Button = uitogglebutton(app.FilterOSMData_AutofillSource_ButtonGroup);
			app.FilterOSMData_AutofillSource_Text_Button.Tooltip = {'Use the include and exclude tags of the text parameters.'; 'In exceptional cases, it may be useful to define separate include and exclude tags for texts; these are then entered under obj(ObjNo,1).textpar.tag_incl/tag_excl.'};
			app.FilterOSMData_AutofillSource_Text_Button.Text = 'Texts';
			app.FilterOSMData_AutofillSource_Symb_Button = uitogglebutton(app.FilterOSMData_AutofillSource_ButtonGroup);
			app.FilterOSMData_AutofillSource_Symb_Button.Tooltip = {'Use the include and exclude tags of the symbol parameters.'; 'In exceptional cases, it may be useful to define separate include and exclude tags for symbols; these are then entered under obj(ObjNo,1).symbolpar.tag_incl/tag_excl.'};
			app.FilterOSMData_AutofillSource_Symb_Button.Text = 'Symbols';
			app.FilterOSMData_AutofillSourceButtonGroup_Label = uilabel(app.FilterOSMData_AutofillSource_ButtonGroup);
			app.FilterOSMData_AutofillSourceButtonGroup_Label.Tooltip = {'Selection of the data source for filling in the include and exclude keys and values.'};
			app.FilterOSMData_AutofillSourceButtonGroup_Label.Text = 'Source:';
			app.SelectiondescriptionEditFieldLabel = uilabel(app.FilterOSMdataTab);
			app.SelectiondescriptionEditFieldLabel.Text = 'Selection description:';
			app.ObjNoLabel = uilabel(app.FilterOSMdataTab);
			app.ObjNoLabel.Tooltip = {'Automatic filling of the include and exclude keys and values by selecting an object number.'};
			app.ObjNoLabel.Text = 'Autofill:';
			app.filterOSMdataButton = uibutton(app.FilterOSMdataTab, 'push');
			app.filterOSMdataButton.ButtonPushedFcn = createCallbackFcn(app, @filterOSMdataButtonPushed, true);
			app.filterOSMdataButton.Tooltip = {'Search in the currently loaded OSM data for nodes, ways and relations that fulfill the criteria in the tables of include and exclude keys and values.'};
			app.filterOSMdataButton.Text = 'Filter OSM-data';
			app.copyosmdataselectiontomapButton = uibutton(app.FilterOSMdataTab, 'push');
			app.copyosmdataselectiontomapButton.ButtonPushedFcn = createCallbackFcn(app, @copyosmdataselectiontomapButtonPushed, true);
			app.copyosmdataselectiontomapButton.Tooltip = {'Add the currently displayed items to the map as preview objects with their own PlotNo.'};
			app.copyosmdataselectiontomapButton.Text = 'Copy selection to map';
			app.clearosmdataselectionButton = uibutton(app.FilterOSMdataTab, 'push');
			app.clearosmdataselectionButton.ButtonPushedFcn = createCallbackFcn(app, @clearosmdataselectionButtonPushed, true);
			app.clearosmdataselectionButton.Tooltip = {'Cancel the display of all currently selected items on the map.'};
			app.clearosmdataselectionButton.Text = 'Deselect all';
			app.copyfilterresultstoclipboardButton = uibutton(app.FilterOSMdataTab, 'push');
			app.copyfilterresultstoclipboardButton.ButtonPushedFcn = createCallbackFcn(app, @copyfilterresultstoclipboardButtonPushed, true);
			app.copyfilterresultstoclipboardButton.Tooltip = {'Copy the contents of the table as a tab-delimited list to the clipboard. The list can be pasted into a spreadsheet for further analysis.'};
			app.copyfilterresultstoclipboardButton.Text = 'Copy table to clipboard';
			app.OSMDATA_TABLE = uitable(app.FilterOSMdataTab);
			app.OSMDATA_TABLE.ColumnName = {'No'; 'ID'; 'Type'; 'ObjNo'; 'NoN'; 'NoW'; 'NoR'; 'Dimx'; 'Dimy'; 'Diag'; 'Length'; 'Area'; 'Key1'; 'Val1'; 'Key2'; 'Val2'; 'Key3'; 'Val3'; 'Key4'; 'Val4'; 'Key5'; 'Val5'; 'Key6'; 'Val6'; 'Key7'; 'Val7'; 'Key8'; 'Val8'; 'Key9'; 'Val9'; 'Key10'; 'Val10'; 'Key11'; 'Val11'; 'Key12'; 'Val12'; 'Key13'; 'Val13'; 'Key14'; 'Val14'; 'Key15'; 'Val15'; 'Key16'; 'Val16'; 'Key17'; 'Val17'; 'Key18'; 'Val18'; 'Key19'; 'Val19'; 'Key20'; 'Val20'; 'Key21'; 'Val21'; 'Key22'; 'Val22'; 'Key23'; 'Val23'; 'Key24'; 'Val24'; 'Key25'; 'Val25'};
			app.OSMDATA_TABLE.RowName = {};
			app.OSMDATA_TABLE.CellSelectionCallback = createCallbackFcn(app, @OSMDATA_TABLECellSelection, true);
			app.OSMDATA_TABLE.Tooltip = {'Filtered OSM data: nodes, ways and relations that fulfill the criteria in the tables of include and exclude keys and values.'};
			app.exclude_values = uitable(app.FilterOSMdataTab);
			app.exclude_values.ColumnName = {'exclude value'; 'or'; 'or'; 'or'; 'or'};
			app.exclude_values.RowName = {'exclude value'; 'and'; 'and'; 'and'; 'and'};
			app.exclude_values.CellEditCallback = createCallbackFcn(app, @exclude_valuesCellEdit, true);
			app.exclude_values.Tooltip = {'Tables of include and exclude keys and values that are searched for in the currently loaded OSM data. For a more detailed description of the functionality, see project parameter reference (fields obj.tag_incl, obj.tag_excl).'; 'Example:'; 'include key = railway'; 'include value = rail'; 'exclude key = tunnel'; 'exclude value = yes'};
			app.exclude_keys = uitable(app.FilterOSMdataTab);
			app.exclude_keys.ColumnName = {'exclude key'; 'or'; 'or'; 'or'; 'or'};
			app.exclude_keys.RowName = {'exclude key,and,and,and,and'};
			app.exclude_keys.CellEditCallback = createCallbackFcn(app, @exclude_keysCellEdit, true);
			app.exclude_keys.Tooltip = {'Tables of include and exclude keys and values that are searched for in the currently loaded OSM data. For a more detailed description of the functionality, see project parameter reference (fields obj.tag_incl, obj.tag_excl).'; 'Example:'; 'include key = railway'; 'include value = rail'; 'exclude key = tunnel'; 'exclude value = yes'};
			app.include_values = uitable(app.FilterOSMdataTab);
			app.include_values.ColumnName = {'include value'; 'or'; 'or'; 'or'; 'or'};
			app.include_values.RowName = {'include value'; 'and'; 'and'; 'and'; 'and'};
			app.include_values.CellEditCallback = createCallbackFcn(app, @include_valuesCellEdit, true);
			app.include_values.Tooltip = {'Tables of include and exclude keys and values that are searched for in the currently loaded OSM data. For a more detailed description of the functionality, see project parameter reference (fields obj.tag_incl, obj.tag_excl).'; 'Example:'; 'include key = railway'; 'include value = rail'; 'exclude key = tunnel'; 'exclude value = yes'};
			app.include_keys = uitable(app.FilterOSMdataTab);
			app.include_keys.ColumnName = {'include key'; 'or'; 'or'; 'or'; 'or'};
			app.include_keys.RowName = {'include key,and,and,and,and'};
			app.include_keys.CellEditCallback = createCallbackFcn(app, @include_keysCellEdit, true);
			app.include_keys.Tooltip = {'Tables of include and exclude keys and values that are searched for in the currently loaded OSM data. For a more detailed description of the functionality, see project parameter reference (fields obj.tag_incl, obj.tag_excl).'; 'Example:'; 'include key = railway'; 'include value = rail'; 'exclude key = tunnel'; 'exclude value = yes'};
			app.PreviewdescriptionEditField = uieditfield(app.FilterOSMdataTab, 'text');
			app.PreviewdescriptionEditField.Tooltip = {'To be able to add the currently selected items as preview objects to the map, a description must be entered here.'};
			app.FilterOSMDataObjNoDropDown = uidropdown(app.FilterOSMdataTab);
			app.FilterOSMDataObjNoDropDown.Items = {'Option 1'};
			app.FilterOSMDataObjNoDropDown.ValueChangedFcn = createCallbackFcn(app, @FilterOSMDataObjNoDropDownValueChanged, true);
			app.FilterOSMDataObjNoDropDown.Tooltip = {'Fill the tables of include and exclude keys and values with the settings of an object number. This makes it possible to display the raw data of an object number on the map.'};
			app.filterOSMdata_nodes_CheckBox = uicheckbox(app.FilterOSMdataTab);
			app.filterOSMdata_nodes_CheckBox.Tooltip = {'Include nodes'};
			app.filterOSMdata_nodes_CheckBox.Text = 'Nodes';
			app.filterOSMdata_nodes_CheckBox.Value = true;
			app.filterOSMdata_ways_CheckBox = uicheckbox(app.FilterOSMdataTab);
			app.filterOSMdata_ways_CheckBox.Tooltip = {'Include ways'};
			app.filterOSMdata_ways_CheckBox.Text = 'Ways';

			app.filterOSMdata_ways_CheckBox.Value = true;
			app.filterOSMdata_relations_CheckBox = uicheckbox(app.FilterOSMdataTab);
			app.filterOSMdata_relations_CheckBox.Tooltip = {'Include relations'};
			app.filterOSMdata_relations_CheckBox.Text = 'Relations';
			app.filterOSMdata_relations_CheckBox.Value = true;
			app.FilterOSMDataDeleteAllEntriesButton = uibutton(app.FilterOSMdataTab, 'push');
			app.FilterOSMDataDeleteAllEntriesButton.ButtonPushedFcn = createCallbackFcn(app, @FilterOSMDataDeleteAllEntriesButtonPushed, true);
			app.FilterOSMDataDeleteAllEntriesButton.Tooltip = {'Delete all entries in the tables of include and exclude keys and values.'};
			app.FilterOSMDataDeleteAllEntriesButton.Text = 'Delete all entries';
			app.SeparatorImage_20 = uiimage(app.FilterOSMdataTab);
			app.SeparatorImage_20.ImageSource = fullfile(pathToMLAPP, 'RGB33_10x10.png');
			app.FilterOSMData_InExcludeTags_Label = uilabel(app.FilterOSMdataTab);
			app.FilterOSMData_InExcludeTags_Label.Text = 'Tags:';
			app.FilterOSMData_FilterResults_Label = uilabel(app.FilterOSMdataTab);
			app.FilterOSMData_FilterResults_Label.Text = 'Filter results:';
			app.LatLonXYTab = uitab(app.TabGroup);
			app.LatLonXYTab.Tooltip = {'Conversion between latitude/longitude and the dimensions of the map: Use these functions at the beginning to set the values in the relevant lines of the project parameter file. This is helpful for planning:'; '- the bounding box of the OSM data,'; '- the scale,'; '- the size of the printed map and'; '- the optimal tile size'};
			app.LatLonXYTab.Title = 'lon,lat - x,y';
			app.LatLonXYTab_GeneralData_Label = uilabel(app.LatLonXYTab);
			app.LatLonXYTab_GeneralData_Label.Tooltip = {'If these values are changed, the framed values are retained.'};
			app.LatLonXYTab_GeneralData_Label.Text = 'General data:';
			app.LatLonXYTab_NumberOfTilesAndPrintoutSize_Label = uilabel(app.LatLonXYTab);
			app.LatLonXYTab_NumberOfTilesAndPrintoutSize_Label.Tooltip = {'If these values are changed, the framed values are retained.'};
			app.LatLonXYTab_NumberOfTilesAndPrintoutSize_Label.Text = 'Number of tiles and printout size:';
			app.LatLonXYTab_Scale_Label = uilabel(app.LatLonXYTab);
			app.LatLonXYTab_Scale_Label.Tooltip = {'Line number of project parameter:'; 'Scale'};
			app.LatLonXYTab_Scale_Label.Text = '888:';
			app.ScaleLabel = uilabel(app.LatLonXYTab);
			app.ScaleLabel.Text = 'Scale';
			app.LatLonXYTab_Scale_ResetButton = uibutton(app.LatLonXYTab, 'push');
			app.LatLonXYTab_Scale_ResetButton.ButtonPushedFcn = createCallbackFcn(app, @LatLonXYTab_Scale_ResetButtonPushed, true);
			app.LatLonXYTab_Scale_ResetButton.Tooltip = {'Set the scale to the value specified in the project parameters or to a default value if no project is loaded.'};
			app.LatLonXYTab_Scale_ResetButton.Text = 'Reset';
			app.LatLonXYTab_FrameWidth_Label = uilabel(app.LatLonXYTab);
			app.LatLonXYTab_FrameWidth_Label.Tooltip = {'Line number of project parameter:'; 'Outer frame width / mm'};
			app.LatLonXYTab_FrameWidth_Label.Text = '888:';
			app.OuterframewidthmmLabel = uilabel(app.LatLonXYTab);
			app.OuterframewidthmmLabel.Text = 'Outer frame width / mm';
			app.LatLonXYTab_OuterFrameWidth_ResetButton = uibutton(app.LatLonXYTab, 'push');
			app.LatLonXYTab_OuterFrameWidth_ResetButton.ButtonPushedFcn = createCallbackFcn(app, @LatLonXYTab_OuterFrameWidth_ResetButtonPushed, true);
			app.LatLonXYTab_OuterFrameWidth_ResetButton.Tooltip = {'Set the outer frame width to the value specified in the project parameters or to a default value if no project is loaded.'};
			app.LatLonXYTab_OuterFrameWidth_ResetButton.Text = 'Reset';
			app.LatLonXYTab_OriginLongitude_Label = uilabel(app.LatLonXYTab);
			app.LatLonXYTab_OriginLongitude_Label.Tooltip = {'Line number of project parameter:'; 'Origin longitude / degree'};
			app.LatLonXYTab_OriginLongitude_Label.Text = '888:';
			app.OriginlongitudedegreeLabel = uilabel(app.LatLonXYTab);
			app.OriginlongitudedegreeLabel.Text = 'Origin longitude / degree';
			app.LatLonXYTab_Origin_ResetButton = uibutton(app.LatLonXYTab, 'push');
			app.LatLonXYTab_Origin_ResetButton.ButtonPushedFcn = createCallbackFcn(app, @LatLonXYTab_Origin_ResetButtonPushed, true);
			app.LatLonXYTab_Origin_ResetButton.Tooltip = {'Set the map origin to the values specified in the project parameters or to default values if no project is loaded.'};
			app.LatLonXYTab_Origin_ResetButton.Text = 'Reset';
			app.LatLonXYTab_TileWidth_Label = uilabel(app.LatLonXYTab);
			app.LatLonXYTab_TileWidth_Label.Tooltip = {'Line number of project parameter:'; 'Tile width / mm'};
			app.LatLonXYTab_TileWidth_Label.Text = '888:';
			app.TilewidthmmLabel = uilabel(app.LatLonXYTab);
			app.TilewidthmmLabel.Text = 'Tile width / mm';
			app.LatLonXYTab_TileSize_ResetButton = uibutton(app.LatLonXYTab, 'push');
			app.LatLonXYTab_TileSize_ResetButton.ButtonPushedFcn = createCallbackFcn(app, @LatLonXYTab_TileSize_ResetButtonPushed, true);
			app.LatLonXYTab_TileSize_ResetButton.Tooltip = {'Set the tile dimensions to the values specified in the project parameters or to default values if no project is loaded.'};
			app.LatLonXYTab_TileSize_ResetButton.Text = 'Reset';
			app.LatLonXYTab_OriginLatitude_Label = uilabel(app.LatLonXYTab);
			app.LatLonXYTab_OriginLatitude_Label.Tooltip = {'Line number of project parameter:'; 'Origin latitude / degree'};
			app.LatLonXYTab_OriginLatitude_Label.Text = '888:';
			app.LatLonXYTab_Dist_OSM_MapPrintout_Label = uilabel(app.LatLonXYTab);
			app.LatLonXYTab_Dist_OSM_MapPrintout_Label.Tooltip = {'Line number of project parameter:'; 'Distance between OSM-data and map printout limits / mm'};
			app.LatLonXYTab_Dist_OSM_MapPrintout_Label.Text = '888:';
			app.OriginlatitudedegreeLabel = uilabel(app.LatLonXYTab);
			app.OriginlatitudedegreeLabel.Text = 'Origin latitude / degree';
			app.DistancebetweenOSMdataandmapprintoutlimitsmmLabel = uilabel(app.LatLonXYTab);
			app.DistancebetweenOSMdataandmapprintoutlimitsmmLabel.Text = 'Distance between OSM-data and map printout limits / mm';
			app.LatLonXYTab_TileHeight_Label = uilabel(app.LatLonXYTab);
			app.LatLonXYTab_TileHeight_Label.Tooltip = {'Line number of project parameter:'; 'Tile depth / mm'};
			app.LatLonXYTab_TileHeight_Label.Text = '888:';
			app.TileheightmmLabel = uilabel(app.LatLonXYTab);
			app.TileheightmmLabel.Text = 'Tile depth / mm';
			app.LatLonXYTab_OSM_Label = uilabel(app.LatLonXYTab);
			app.LatLonXYTab_OSM_Label.Tooltip = {'Bounding box of the currently loaded OSM data.'; 'If the values of the “General data” are changed, the framed values are retained.'};
			app.LatLonXYTab_OSM_Label.Text = 'OSM data:';
			app.LatLonXYTab_OSM_Frame_Image = uiimage(app.LatLonXYTab);
			app.LatLonXYTab_OSM_Frame_Image.ImageSource = 'Frame_latlonxy_1.png';
			app.LatLonXYTab_OSM_SetOriginToBBC_CheckBox = uicheckbox(app.LatLonXYTab);
			app.LatLonXYTab_OSM_SetOriginToBBC_CheckBox.ValueChangedFcn = createCallbackFcn(app, @LatLonXYTab_OSM_SetOriginToBBC_CheckBoxValueChanged, true);
			app.LatLonXYTab_OSM_SetOriginToBBC_CheckBox.Tooltip = {'The map origin should be in the center to minimize the maximum distortion when converting from Greenwich to equal area coordinates.'; '- If the checkbox is set, the center of the bounding box in degrees is used.'; '- If the checkbox is not set, the values of the origin displayed under “General data” are used. If xy values are changed, the origin of the “General data” is always used to calculate longitude, latitude and center!'};
			app.LatLonXYTab_OSM_SetOriginToBBC_CheckBox.Text = 'Set origin to bounding box center';
			app.LatLonXYTab_OSM_SetOriginToBBC_CheckBox.Value = true;
			app.LatLonXYTab_OSM_ResetButton = uibutton(app.LatLonXYTab, 'push');
			app.LatLonXYTab_OSM_ResetButton.ButtonPushedFcn = createCallbackFcn(app, @LatLonXYTab_OSM_ResetButtonPushed, true);
			app.LatLonXYTab_OSM_ResetButton.Tooltip = {'Set the latitude and longitude in degrees of the OSM data bounding box equal to the boundaries of the currently loaded OSM data or equal to default values if no OSM data is loaded.'};
			app.LatLonXYTab_OSM_ResetButton.Text = 'Reset';
			app.LatLonXYTab_OSM_PlotButton = uibutton(app.LatLonXYTab, 'push');
			app.LatLonXYTab_OSM_PlotButton.ButtonPushedFcn = createCallbackFcn(app, @LatLonXYTab_OSM_PlotButtonPushed, true);
			app.LatLonXYTab_OSM_PlotButton.Tooltip = {'Preview of the map with the current dimensions.'};
			app.LatLonXYTab_OSM_PlotButton.Text = 'Plot';
			app.LatLonXYTab_OSM_Printoutsize_Label = uilabel(app.LatLonXYTab);
			app.LatLonXYTab_OSM_Printoutsize_Label.Tooltip = {'Width and depth of the map without frame.'};
			app.LatLonXYTab_OSM_Printoutsize_Label.Text = 'Printout size:';
			app.LatLonXYTab_OSM_W_Label = uilabel(app.LatLonXYTab);
			app.LatLonXYTab_OSM_W_Label.Text = 'W = xmax-xmin =';
			app.LatLonXYTab_OSM_Wmm_Label = uilabel(app.LatLonXYTab);
			app.LatLonXYTab_OSM_Wmm_Label.Tooltip = {'Width of the map without frame.'};
			app.LatLonXYTab_OSM_Wmm_Label.Text = '88888888888.88 mm';
			app.LongitudeoftheleftedgeoftheboundingboxdegreeLabel = uilabel(app.LatLonXYTab);
			app.LongitudeoftheleftedgeoftheboundingboxdegreeLabel.Text = 'Longitude of the left edge of the bounding box / degree';
			app.xminmmEditFieldLabel = uilabel(app.LatLonXYTab);
			app.xminmmEditFieldLabel.Text = 'xmin / mm';
			app.LatLonXYTab_OSM_H_Label = uilabel(app.LatLonXYTab);
			app.LatLonXYTab_OSM_H_Label.Text = 'D = ymax-ymin =';
			app.LatLonXYTab_OSM_Hmm_Label = uilabel(app.LatLonXYTab);
			app.LatLonXYTab_OSM_Hmm_Label.Tooltip = {'Depth of the map without frame.'};
			app.LatLonXYTab_OSM_Hmm_Label.Text = '88888888888.88 mm';
			app.LatitudeofthebottomedgeoftheboundingboxdegreeLabel = uilabel(app.LatLonXYTab);
			app.LatitudeofthebottomedgeoftheboundingboxdegreeLabel.Text = 'Latitude of the bottom edge of the bounding box / degree';
			app.LongitudeoftherightedgeoftheboundingboxdegreeLabel = uilabel(app.LatLonXYTab);
			app.LongitudeoftherightedgeoftheboundingboxdegreeLabel.Text = 'Longitude of the right edge of the bounding box / degree';
			app.xmaxmmEditFieldLabel = uilabel(app.LatLonXYTab);
			app.xmaxmmEditFieldLabel.Text = 'xmax / mm';
			app.yminmmEditFieldLabel = uilabel(app.LatLonXYTab);
			app.yminmmEditFieldLabel.Text = 'ymin / mm';
			app.LatLonXYTab_OSM_Tiles_Label = uilabel(app.LatLonXYTab);
			app.LatLonXYTab_OSM_Tiles_Label.Tooltip = {'Number of tiles and mininum and maximum tile size at the same number of tiles and the same printout size.'};
			app.LatLonXYTab_OSM_Tiles_Label.Text = 'Tiles:';
			app.LatLonXYTab_OSM_NoTilesW_Label = uilabel(app.LatLonXYTab);
			app.LatLonXYTab_OSM_NoTilesW_Label.Tooltip = {'Number of tiles in x-direction.'};
			app.LatLonXYTab_OSM_NoTilesW_Label.Text = '888888888';
			app.LatLonXYTab_OSM_Width2_Label = uilabel(app.LatLonXYTab);
			app.LatLonXYTab_OSM_Width2_Label.Text = 'x Width:';
			app.LatLonXYTab_OSM_TWminmm_Label = uilabel(app.LatLonXYTab);
			app.LatLonXYTab_OSM_TWminmm_Label.Tooltip = {'Minimum width of the tiles at the same number of tiles in x-direction.'};
			app.LatLonXYTab_OSM_TWminmm_Label.Text = '(88888.88 mm)';
			app.LatLonXYTab_OSM_To3_Label = uilabel(app.LatLonXYTab);
			app.LatLonXYTab_OSM_To3_Label.Text = 'to';
			app.LatLonXYTab_OSM_TWmaxmm_Label = uilabel(app.LatLonXYTab);
			app.LatLonXYTab_OSM_TWmaxmm_Label.Tooltip = {'Width of the tiles at which the number of tiles in x-direction decreases by 1.'};
			app.LatLonXYTab_OSM_TWmaxmm_Label.Text = '(88888.88 mm)';
			app.LatitudeofthetopedgeoftheboundingboxdegreeLabel = uilabel(app.LatLonXYTab);
			app.LatitudeofthetopedgeoftheboundingboxdegreeLabel.Text = 'Latitude of the top edge of the bounding box / degree';
			app.ymaxmmEditFieldLabel = uilabel(app.LatLonXYTab);
			app.ymaxmmEditFieldLabel.Text = 'ymax / mm';
			app.LatLonXYTab_OSM_NoTilesH_Label = uilabel(app.LatLonXYTab);
			app.LatLonXYTab_OSM_NoTilesH_Label.Tooltip = {'Number of tiles in y-direction.'};
			app.LatLonXYTab_OSM_NoTilesH_Label.Text = '888888888';
			app.LatLonXYTab_OSM_Height2_Label = uilabel(app.LatLonXYTab);
			app.LatLonXYTab_OSM_Height2_Label.Text = 'x Depth:';
			app.LatLonXYTab_OSM_THminmm_Label = uilabel(app.LatLonXYTab);
			app.LatLonXYTab_OSM_THminmm_Label.Tooltip = {'Minimum depth of the tiles at the same number of tiles in y-direction.'};
			app.LatLonXYTab_OSM_THminmm_Label.Text = '(88888.88 mm)';
			app.LatLonXYTab_OSM_To4_Label = uilabel(app.LatLonXYTab);
			app.LatLonXYTab_OSM_To4_Label.Text = 'to';
			app.LatLonXYTab_OSM_THmaxmm_Label = uilabel(app.LatLonXYTab);
			app.LatLonXYTab_OSM_THmaxmm_Label.Tooltip = {'Depth of the tiles at which the number of tiles in y-direction decreases by 1.'};
			app.LatLonXYTab_OSM_THmaxmm_Label.Text = '(88888.88 mm)';
			app.LongitudeofthecenteroftheboundingboxdegreeLabel = uilabel(app.LatLonXYTab);
			app.LongitudeofthecenteroftheboundingboxdegreeLabel.Text = 'Longitude of the center of the bounding box / degree';
			app.LatitudeofthecenteroftheboundingboxdegreeLabel = uilabel(app.LatLonXYTab);
			app.LatitudeofthecenteroftheboundingboxdegreeLabel.Text = 'Latitude of the center of the bounding box / degree';
			app.LatLonXYTab_OSM_CurrOriginNotAtBBCenter_Label = uilabel(app.LatLonXYTab);
			app.LatLonXYTab_OSM_CurrOriginNotAtBBCenter_Label.Text = {'The origin differs to the'; 'bounding box center by 1%'};
			app.LatLonXYTab_OSM_Width1_Label = uilabel(app.LatLonXYTab);
			app.LatLonXYTab_OSM_Width1_Label.Tooltip = {'Minimum and maximum printout width at the same number of tiles and tile width.'};
			app.LatLonXYTab_OSM_Width1_Label.Text = 'W =';
			app.LatLonXYTab_OSM_Height1_Label = uilabel(app.LatLonXYTab);
			app.LatLonXYTab_OSM_Height1_Label.Tooltip = {'Minimum and maximum printout depth at the same number of tiles and tile depth.'};
			app.LatLonXYTab_OSM_Height1_Label.Text = 'D =';
			app.LatLonXYTab_OSM_Wminmm_Label = uilabel(app.LatLonXYTab);
			app.LatLonXYTab_OSM_Wminmm_Label.Tooltip = {'Printout width at which the number of tiles in x-direction decreases by 1.'};
			app.LatLonXYTab_OSM_Wminmm_Label.Text = '(88888888888.88 mm)';
			app.LatLonXYTab_OSM_Hminmm_Label = uilabel(app.LatLonXYTab);
			app.LatLonXYTab_OSM_Hminmm_Label.Tooltip = {'Printout depth at which the number of tiles in y-direction decreases by 1.'};
			app.LatLonXYTab_OSM_Hminmm_Label.Text = '(88888888888.88 mm)';
			app.LatLonXYTab_OSM_To1_Label = uilabel(app.LatLonXYTab);
			app.LatLonXYTab_OSM_To1_Label.Text = 'to';
			app.LatLonXYTab_OSM_To2_Label = uilabel(app.LatLonXYTab);
			app.LatLonXYTab_OSM_To2_Label.Text = 'to';
			app.LatLonXYTab_OSM_Wmaxmm_Label = uilabel(app.LatLonXYTab);
			app.LatLonXYTab_OSM_Wmaxmm_Label.Tooltip = {'Maximum printout width at the same number of tiles and tile width.'};
			app.LatLonXYTab_OSM_Wmaxmm_Label.Text = '88888888888.88 mm';
			app.LatLonXYTab_OSM_Hmaxmm_Label = uilabel(app.LatLonXYTab);
			app.LatLonXYTab_OSM_Hmaxmm_Label.Tooltip = {'Maximum printout depth at the same number of tiles and tile depth.'};
			app.LatLonXYTab_OSM_Hmaxmm_Label.Text = '88888888888.88 mm';
			app.LatLonXYTab_Osmosis_Label = uilabel(app.LatLonXYTab);
			app.LatLonXYTab_Osmosis_Label.Tooltip = {'Osmosis is a program that can be used to:'; '- Extract a subset of OSM data from a large downloaded file.'; '- Reduce the file size by removing unused OSM data.'; 'Osmosis must be installed before it can be called with “Extra - Call Osmosis”. The longitude latitude values required for calling Osmosis can be found here and copied to the project parameters.'; 'If the values of the “General data” are changed, the framed values are retained.'};
			app.LatLonXYTab_Osmosis_Label.Text = 'Osmosis bounding box:';
			app.LatLonXYTab_Osmosis_SetOriginToBBC_CheckBox = uicheckbox(app.LatLonXYTab);
			app.LatLonXYTab_Osmosis_SetOriginToBBC_CheckBox.ValueChangedFcn = createCallbackFcn(app, @LatLonXYTab_Osmosis_SetOriginToBBC_CheckBoxValueChanged, true);
			app.LatLonXYTab_Osmosis_SetOriginToBBC_CheckBox.Tooltip = {'The map origin should be in the center to minimize the maximum distortion when converting from Greenwich to equal area coordinates.'; '- If the checkbox is set, the center of the bounding box in degrees is used.'; '- If the checkbox is not set, the values of the origin displayed under “General data” are used. If xy values are changed, the origin of the “General data” is always used to calculate longitude, latitude and center!'};
			app.LatLonXYTab_Osmosis_SetOriginToBBC_CheckBox.Text = 'Set origin to bounding box center';
			app.LatLonXYTab_Osmosis_SetOriginToBBC_CheckBox.Value = true;
			app.LatLonXYTab_Osmosis_Frame_Image = uiimage(app.LatLonXYTab);
			app.LatLonXYTab_Osmosis_Frame_Image.ImageSource = 'Frame_latlonxy_1.png';
			app.LatLonXYTab_Osmosis_ResetButton = uibutton(app.LatLonXYTab, 'push');
			app.LatLonXYTab_Osmosis_ResetButton.ButtonPushedFcn = createCallbackFcn(app, @LatLonXYTab_Osmosis_ResetButtonPushed, true);
			app.LatLonXYTab_Osmosis_ResetButton.Tooltip = {'Set the longitude and latitude in degrees of the bounding box for calling Osmosis equal to the values specified in the project parameters or equal to default values if no project is loaded.'};
			app.LatLonXYTab_Osmosis_ResetButton.Text = 'Reset';
			app.LatLonXYTab_Osmosis_PlotButton = uibutton(app.LatLonXYTab, 'push');
			app.LatLonXYTab_Osmosis_PlotButton.ButtonPushedFcn = createCallbackFcn(app, @LatLonXYTab_Osmosis_PlotButtonPushed, true);
			app.LatLonXYTab_Osmosis_PlotButton.Tooltip = {'Preview of the map with the current dimensions.'};
			app.LatLonXYTab_Osmosis_PlotButton.Text = 'Plot';
			app.LatLonXYTab_Osmosis_Printoutsize_Label = uilabel(app.LatLonXYTab);
			app.LatLonXYTab_Osmosis_Printoutsize_Label.Tooltip = {'Width and depth of the map without frame.'};
			app.LatLonXYTab_Osmosis_Printoutsize_Label.Text = 'Printout size:';
			app.LatLonXYTab_Osmosis_W_Label = uilabel(app.LatLonXYTab);
			app.LatLonXYTab_Osmosis_W_Label.Text = 'W = xmax-xmin =';
			app.LatLonXYTab_Osmosis_Wmm_Label = uilabel(app.LatLonXYTab);
			app.LatLonXYTab_Osmosis_Wmm_Label.Tooltip = {'Width of the map without frame.'};
			app.LatLonXYTab_Osmosis_Wmm_Label.Text = '8888 mm';
			app.LatLonXYTab_Osmosis_lonmin_Label = uilabel(app.LatLonXYTab);
			app.LatLonXYTab_Osmosis_lonmin_Label.Tooltip = {'Line number of project parameter:'; 'Osmosis bounding box:'; 'Longitude of the left edge / degree'};
			app.LatLonXYTab_Osmosis_lonmin_Label.Text = '888:';
			app.LongitudeoftheleftedgeoftheboundingboxdegreeLabel_2 = uilabel(app.LatLonXYTab);
			app.LongitudeoftheleftedgeoftheboundingboxdegreeLabel_2.Text = 'Longitude of the left edge of the bounding box / degree';
			app.xminmmEditFieldLabel_2 = uilabel(app.LatLonXYTab);
			app.xminmmEditFieldLabel_2.Text = 'xmin / mm';
			app.LatLonXYTab_Osmosis_H_Label = uilabel(app.LatLonXYTab);
			app.LatLonXYTab_Osmosis_H_Label.Text = 'D = ymax-ymin =';
			app.LatLonXYTab_Osmosis_Hmm_Label = uilabel(app.LatLonXYTab);
			app.LatLonXYTab_Osmosis_Hmm_Label.Tooltip = {'Depth of the map without frame.'};
			app.LatLonXYTab_Osmosis_Hmm_Label.Text = '8888 mm';
			app.LatLonXYTab_Osmosis_lonmax_Label = uilabel(app.LatLonXYTab);
			app.LatLonXYTab_Osmosis_lonmax_Label.Tooltip = {'Line number of project parameter:'; 'Osmosis bounding box:'; 'Longitude of the right edge / degree'};
			app.LatLonXYTab_Osmosis_lonmax_Label.Text = '888:';
			app.LatLonXYTab_Osmosis_latmin_Label = uilabel(app.LatLonXYTab);
			app.LatLonXYTab_Osmosis_latmin_Label.Tooltip = {'Line number of project parameter:'; 'Osmosis bounding box:'; 'Latitude of the bottom edge / degree'};
			app.LatLonXYTab_Osmosis_latmin_Label.Text = '888:';
			app.LatitudeofthebottomedgeoftheboundingboxdegreeLabel_2 = uilabel(app.LatLonXYTab);
			app.LatitudeofthebottomedgeoftheboundingboxdegreeLabel_2.Text = 'Latitude of the bottom edge of the bounding box / degree';
			app.LongitudeoftherightedgeoftheboundingboxdegreeLabel_2 = uilabel(app.LatLonXYTab);
			app.LongitudeoftherightedgeoftheboundingboxdegreeLabel_2.Text = 'Longitude of the right edge of the bounding box / degree';
			app.xmaxmmEditFieldLabel_2 = uilabel(app.LatLonXYTab);
			app.xmaxmmEditFieldLabel_2.Text = 'xmax / mm';
			app.yminmmEditFieldLabel_2 = uilabel(app.LatLonXYTab);
			app.yminmmEditFieldLabel_2.Text = 'ymin / mm';
			app.LatLonXYTab_Osmosis_Tiles_Label = uilabel(app.LatLonXYTab);
			app.LatLonXYTab_Osmosis_Tiles_Label.Tooltip = {'Number of tiles and mininum and maximum tile size at the same number of tiles and the same printout size.'};
			app.LatLonXYTab_Osmosis_Tiles_Label.Text = 'Tiles:';
			app.LatLonXYTab_Osmosis_NoTilesW_Label = uilabel(app.LatLonXYTab);
			app.LatLonXYTab_Osmosis_NoTilesW_Label.Tooltip = {'Number of tiles in x-direction.'};
			app.LatLonXYTab_Osmosis_NoTilesW_Label.Text = '888888888';
			app.LatLonXYTab_Osmosis_Width2_Label = uilabel(app.LatLonXYTab);
			app.LatLonXYTab_Osmosis_Width2_Label.Text = 'x Width:';
			app.LatLonXYTab_Osmosis_TWminmm_Label = uilabel(app.LatLonXYTab);
			app.LatLonXYTab_Osmosis_TWminmm_Label.Tooltip = {'Minimum width of the tiles at the same number of tiles in x-direction.'};
			app.LatLonXYTab_Osmosis_TWminmm_Label.Text = '(88888.88 mm)';
			app.LatLonXYTab_Osmosis_To3_Label = uilabel(app.LatLonXYTab);
			app.LatLonXYTab_Osmosis_To3_Label.Text = 'to';
			app.LatLonXYTab_Osmosis_TWmaxmm_Label = uilabel(app.LatLonXYTab);
			app.LatLonXYTab_Osmosis_TWmaxmm_Label.Tooltip = {'Width of the tiles at which the number of tiles in x-direction decreases by 1.'};
			app.LatLonXYTab_Osmosis_TWmaxmm_Label.Text = '(88888.88 mm)';
			app.LatLonXYTab_Osmosis_latmax_Label = uilabel(app.LatLonXYTab);
			app.LatLonXYTab_Osmosis_latmax_Label.Tooltip = {'Line number of project parameter:'; 'Osmosis bounding box:'; 'Latitude of the top edge / degree'};
			app.LatLonXYTab_Osmosis_latmax_Label.Text = '888:';
			app.LatitudeofthetopedgeoftheboundingboxdegreeLabel_2 = uilabel(app.LatLonXYTab);
			app.LatitudeofthetopedgeoftheboundingboxdegreeLabel_2.Text = 'Latitude of the top edge of the bounding box / degree';
			app.ymaxmmEditFieldLabel_2 = uilabel(app.LatLonXYTab);
			app.ymaxmmEditFieldLabel_2.Text = 'ymax / mm';
			app.LatLonXYTab_Osmosis_NoTilesH_Label = uilabel(app.LatLonXYTab);
			app.LatLonXYTab_Osmosis_NoTilesH_Label.Tooltip = {'Number of tiles in y-direction.'};
			app.LatLonXYTab_Osmosis_NoTilesH_Label.Text = '888888888';
			app.LatLonXYTab_Osmosis_Height2_Label = uilabel(app.LatLonXYTab);
			app.LatLonXYTab_Osmosis_Height2_Label.Text = 'x Depth:';
			app.LatLonXYTab_Osmosis_THminmm_Label = uilabel(app.LatLonXYTab);
			app.LatLonXYTab_Osmosis_THminmm_Label.Tooltip = {'Minimum depth of the tiles at the same number of tiles in y-direction.'};
			app.LatLonXYTab_Osmosis_THminmm_Label.Text = '(88888.88 mm)';
			app.LatLonXYTab_Osmosis_To4_Label = uilabel(app.LatLonXYTab);
			app.LatLonXYTab_Osmosis_To4_Label.Text = 'to';
			app.LatLonXYTab_Osmosis_THmaxmm_Label = uilabel(app.LatLonXYTab);
			app.LatLonXYTab_Osmosis_THmaxmm_Label.Tooltip = {'Depth of the tiles at which the number of tiles in y-direction decreases by 1.'};
			app.LatLonXYTab_Osmosis_THmaxmm_Label.Text = '(88888.88 mm)';
			app.LongitudeofthecenteroftheboundingboxdegreeLabel_2 = uilabel(app.LatLonXYTab);
			app.LongitudeofthecenteroftheboundingboxdegreeLabel_2.Text = 'Longitude of the center of the bounding box / degree';
			app.LatitudeofthecenteroftheboundingboxdegreeLabel_2 = uilabel(app.LatLonXYTab);
			app.LatitudeofthecenteroftheboundingboxdegreeLabel_2.Text = 'Latitude of the center of the bounding box / degree';
			app.LatLonXYTab_Osmosis_CurrOriginNotAtBBCenter_Label = uilabel(app.LatLonXYTab);
			app.LatLonXYTab_Osmosis_CurrOriginNotAtBBCenter_Label.Text = {'The origin differs to the'; 'bounding box center by 1%'};
			app.LatLonXYTab_Osmosis_Width1_Label = uilabel(app.LatLonXYTab);
			app.LatLonXYTab_Osmosis_Width1_Label.Tooltip = {'Minimum and maximum printout width at the same number of tiles and tile width.'};
			app.LatLonXYTab_Osmosis_Width1_Label.Text = 'W =';
			app.LatLonXYTab_Osmosis_Height1_Label = uilabel(app.LatLonXYTab);
			app.LatLonXYTab_Osmosis_Height1_Label.Tooltip = {'Minimum and maximum printout depth at the same number of tiles and tile depth.'};
			app.LatLonXYTab_Osmosis_Height1_Label.Text = 'D =';
			app.LatLonXYTab_Osmosis_Wminmm_Label = uilabel(app.LatLonXYTab);
			app.LatLonXYTab_Osmosis_Wminmm_Label.Tooltip = {'Printout width at which the number of tiles in x-direction decreases by 1.'};
			app.LatLonXYTab_Osmosis_Wminmm_Label.Text = '(88888888888.88 mm)';
			app.LatLonXYTab_Osmosis_Hminmm_Label = uilabel(app.LatLonXYTab);
			app.LatLonXYTab_Osmosis_Hminmm_Label.Tooltip = {'Printout depth at which the number of tiles in y-direction decreases by 1.'};
			app.LatLonXYTab_Osmosis_Hminmm_Label.Text = '(88888888888.88 mm)';
			app.LatLonXYTab_Osmosis_To1_Label = uilabel(app.LatLonXYTab);
			app.LatLonXYTab_Osmosis_To1_Label.Text = 'to';
			app.LatLonXYTab_Osmosis_To2_Label = uilabel(app.LatLonXYTab);
			app.LatLonXYTab_Osmosis_To2_Label.Text = 'to';
			app.LatLonXYTab_Osmosis_Wmaxmm_Label = uilabel(app.LatLonXYTab);
			app.LatLonXYTab_Osmosis_Wmaxmm_Label.Tooltip = {'Maximum printout width at the same number of tiles and tile width.'};
			app.LatLonXYTab_Osmosis_Wmaxmm_Label.Text = '88888888888.88 mm';
			app.LatLonXYTab_Osmosis_Hmaxmm_Label = uilabel(app.LatLonXYTab);
			app.LatLonXYTab_Osmosis_Hmaxmm_Label.Tooltip = {'Maximum printout depth at the same number of tiles and tile depth.'};
			app.LatLonXYTab_Osmosis_Hmaxmm_Label.Text = '88888888888.88 mm';
			app.LongitudeoftheleftedgeoftheboundingboxdegreeLabel_3 = uilabel(app.LatLonXYTab);
			app.LongitudeoftheleftedgeoftheboundingboxdegreeLabel_3.Text = 'Longitude of the left edge of the bounding box / degree';
			app.LatLonXYTab_Map_Label = uilabel(app.LatLonXYTab);
			app.LatLonXYTab_Map_Label.Tooltip = {'Limits of the map printout in mm and the corresponding longitude and latitude in degrees.'; 'The limits of the loaded OSM data should be larger than the limits of the map printout so that the objects at the edge of the map are loaded in full. The values determined here can be transferred to the project parameters in order to set these limits.'; 'If the values of the “General data” are changed, the framed values are retained.'};
			app.LatLonXYTab_Map_Label.Text = 'Map printout limits:';
			app.LatLonXYTab_Map_Origin_Label = uilabel(app.LatLonXYTab);
			app.LatLonXYTab_Map_Origin_Label.Tooltip = {'In this section, the values of the origin displayed under “General data” are always used for the transformation between latlon values and xy values.'};
			app.LatLonXYTab_Map_Origin_Label.Text = 'Origin see general data';
			app.xminmmLabel = uilabel(app.LatLonXYTab);
			app.xminmmLabel.Text = 'xmin / mm';
			app.LatLonXYTab_Map_PlotButton = uibutton(app.LatLonXYTab, 'push');
			app.LatLonXYTab_Map_PlotButton.ButtonPushedFcn = createCallbackFcn(app, @LatLonXYTab_Map_PlotButtonPushed, true);
			app.LatLonXYTab_Map_PlotButton.Tooltip = {'Preview of the map with the current dimensions.'};
			app.LatLonXYTab_Map_PlotButton.Text = 'Plot';
			app.LatLonXYTab_Map_xminmm_Label = uilabel(app.LatLonXYTab);
			app.LatLonXYTab_Map_xminmm_Label.Tooltip = {'Line number of project parameter:'; 'Map printout limits:'; 'xmin / mm'};
			app.LatLonXYTab_Map_xminmm_Label.Text = '888:';
			app.LatLonXYTab_Map_Frame_Image = uiimage(app.LatLonXYTab);
			app.LatLonXYTab_Map_Frame_Image.ImageSource = 'Frame_latlonxy_1.png';
			app.LatLonXYTab_Map_ResetButton = uibutton(app.LatLonXYTab, 'push');
			app.LatLonXYTab_Map_ResetButton.ButtonPushedFcn = createCallbackFcn(app, @LatLonXYTab_Map_ResetButtonPushed, true);
			app.LatLonXYTab_Map_ResetButton.Tooltip = {'Set the x and y values in mm of the edges of the map to the values specified in the project parameters or to default values if no project is loaded.'};
			app.LatLonXYTab_Map_ResetButton.Text = 'Reset';
			app.LatLonXYTab_Map_Printoutsize_Label = uilabel(app.LatLonXYTab);
			app.LatLonXYTab_Map_Printoutsize_Label.Tooltip = {'Width and depth of the map without frame.'};
			app.LatLonXYTab_Map_Printoutsize_Label.Text = 'Printout size:';
			app.LatLonXYTab_Map_W_Label = uilabel(app.LatLonXYTab);
			app.LatLonXYTab_Map_W_Label.Text = 'W = xmax-xmin =';
			app.LatLonXYTab_Map_H_Label = uilabel(app.LatLonXYTab);
			app.LatLonXYTab_Map_H_Label.Text = 'D = ymax-ymin =';
			app.LatLonXYTab_Map_Wmm_Label = uilabel(app.LatLonXYTab);
			app.LatLonXYTab_Map_Wmm_Label.Tooltip = {'Width of the map without frame.'};
			app.LatLonXYTab_Map_Wmm_Label.Text = '8888 mm';
			app.LatLonXYTab_Map_Hmm_Label = uilabel(app.LatLonXYTab);
			app.LatLonXYTab_Map_Hmm_Label.Tooltip = {'Depth of the map without frame.'};
			app.LatLonXYTab_Map_Hmm_Label.Text = '8888 mm';
			app.LatitudeofthebottomedgeoftheboundingboxdegreeLabel_3 = uilabel(app.LatLonXYTab);
			app.LatitudeofthebottomedgeoftheboundingboxdegreeLabel_3.Text = 'Latitude of the bottom edge of the bounding box / degree';
			app.LongitudeoftherightedgeoftheboundingboxdegreeLabel_3 = uilabel(app.LatLonXYTab);
			app.LongitudeoftherightedgeoftheboundingboxdegreeLabel_3.Text = 'Longitude of the right edge of the bounding box / degree';
			app.xmaxmmLabel = uilabel(app.LatLonXYTab);
			app.xmaxmmLabel.Text = 'xmax / mm';
			app.yminmmLabel = uilabel(app.LatLonXYTab);
			app.yminmmLabel.Text = 'ymin / mm';
			app.LatLonXYTab_Map_xmaxmm_Label = uilabel(app.LatLonXYTab);
			app.LatLonXYTab_Map_xmaxmm_Label.Tooltip = {'Line number of project parameter:'; 'Map printout limits:'; 'xmax / mm'};
			app.LatLonXYTab_Map_xmaxmm_Label.Text = '888:';
			app.LatLonXYTab_Map_yminmm_Label = uilabel(app.LatLonXYTab);
			app.LatLonXYTab_Map_yminmm_Label.Tooltip = {'Line number of project parameter:'; 'Map printout limits:'; 'ymin / mm'};
			app.LatLonXYTab_Map_yminmm_Label.Text = '888:';
			app.LatLonXYTab_Map_Tiles_Label = uilabel(app.LatLonXYTab);
			app.LatLonXYTab_Map_Tiles_Label.Tooltip = {'Number of tiles and mininum and maximum tile size at the same number of tiles and the same printout size.'};
			app.LatLonXYTab_Map_Tiles_Label.Text = 'Tiles:';
			app.LatLonXYTab_Map_NoTilesW_Label = uilabel(app.LatLonXYTab);
			app.LatLonXYTab_Map_NoTilesW_Label.Tooltip = {'Number of tiles in x-direction.'};
			app.LatLonXYTab_Map_NoTilesW_Label.Text = '888888888';
			app.LatLonXYTab_Map_Width2_Label = uilabel(app.LatLonXYTab);
			app.LatLonXYTab_Map_Width2_Label.Text = 'x Width:';
			app.LatLonXYTab_Map_TWminmm_Label = uilabel(app.LatLonXYTab);
			app.LatLonXYTab_Map_TWminmm_Label.Tooltip = {'Minimum width of the tiles at the same number of tiles in x-direction.'};
			app.LatLonXYTab_Map_TWminmm_Label.Text = '(88888.88 mm)';
			app.LatLonXYTab_Map_To3_Label = uilabel(app.LatLonXYTab);
			app.LatLonXYTab_Map_To3_Label.Text = 'to';
			app.LatLonXYTab_Map_TWmaxmm_Label = uilabel(app.LatLonXYTab);
			app.LatLonXYTab_Map_TWmaxmm_Label.Tooltip = {'Width of the tiles at which the number of tiles in x-direction decreases by 1.'};
			app.LatLonXYTab_Map_TWmaxmm_Label.Text = '(88888.88 mm)';
			app.LatitudeofthetopedgeoftheboundingboxdegreeLabel_3 = uilabel(app.LatLonXYTab);
			app.LatitudeofthetopedgeoftheboundingboxdegreeLabel_3.Text = 'Latitude of the top edge of the bounding box / degree';
			app.ymaxmmLabel = uilabel(app.LatLonXYTab);
			app.ymaxmmLabel.Text = 'ymax / mm';
			app.LatLonXYTab_Map_ymaxmm_Label = uilabel(app.LatLonXYTab);
			app.LatLonXYTab_Map_ymaxmm_Label.Tooltip = {'Line number of project parameter:'; 'Map printout limits:'; 'ymax / mm'};
			app.LatLonXYTab_Map_ymaxmm_Label.Text = '888:';
			app.LatLonXYTab_Map_NoTilesH_Label = uilabel(app.LatLonXYTab);
			app.LatLonXYTab_Map_NoTilesH_Label.Tooltip = {'Number of tiles in y-direction.'};
			app.LatLonXYTab_Map_NoTilesH_Label.Text = '888888888';
			app.LatLonXYTab_Map_Height2_Label = uilabel(app.LatLonXYTab);
			app.LatLonXYTab_Map_Height2_Label.Text = 'x Depth:';
			app.LatLonXYTab_Map_THminmm_Label = uilabel(app.LatLonXYTab);
			app.LatLonXYTab_Map_THminmm_Label.Tooltip = {'Minimum depth of the tiles at the same number of tiles in y-direction.'};
			app.LatLonXYTab_Map_THminmm_Label.Text = '(88888.88 mm)';
			app.LatLonXYTab_Map_To4_Label = uilabel(app.LatLonXYTab);
			app.LatLonXYTab_Map_To4_Label.Text = 'to';
			app.LatLonXYTab_Map_THmaxmm_Label = uilabel(app.LatLonXYTab);
			app.LatLonXYTab_Map_THmaxmm_Label.Tooltip = {'Depth of the tiles at which the number of tiles in y-direction decreases by 1.'};
			app.LatLonXYTab_Map_THmaxmm_Label.Text = '(88888.88 mm)';
			app.LongitudeofthecenteroftheboundingboxdegreeLabel_3 = uilabel(app.LatLonXYTab);
			app.LongitudeofthecenteroftheboundingboxdegreeLabel_3.Text = 'Longitude of the center of the bounding box / degree';
			app.LatitudeofthecenteroftheboundingboxdegreeLabel_3 = uilabel(app.LatLonXYTab);
			app.LatitudeofthecenteroftheboundingboxdegreeLabel_3.Text = 'Latitude of the center of the bounding box / degree';
			app.LatLonXYTab_Map_CurrOriginNotAtBBCenter_Label = uilabel(app.LatLonXYTab);
			app.LatLonXYTab_Map_CurrOriginNotAtBBCenter_Label.Text = {'The origin differs to the'; 'bounding box center by 1%'};
			app.LatLonXYTab_Map_Width1_Label = uilabel(app.LatLonXYTab);
			app.LatLonXYTab_Map_Width1_Label.Tooltip = {'Minimum and maximum printout width at the same number of tiles and tile width.'};
			app.LatLonXYTab_Map_Width1_Label.Text = 'W =';
			app.LatLonXYTab_Map_Height1_Label = uilabel(app.LatLonXYTab);
			app.LatLonXYTab_Map_Height1_Label.Tooltip = {'Minimum and maximum printout depth at the same number of tiles and tile depth.'};
			app.LatLonXYTab_Map_Height1_Label.Text = 'D =';
			app.LatLonXYTab_Map_Wminmm_Label = uilabel(app.LatLonXYTab);
			app.LatLonXYTab_Map_Wminmm_Label.Tooltip = {'Printout width at which the number of tiles in x-direction decreases by 1.'};
			app.LatLonXYTab_Map_Wminmm_Label.Text = '(88888888888.88 mm)';
			app.LatLonXYTab_Map_Hminmm_Label = uilabel(app.LatLonXYTab);
			app.LatLonXYTab_Map_Hminmm_Label.Tooltip = {'Printout depth at which the number of tiles in y-direction decreases by 1.'};
			app.LatLonXYTab_Map_Hminmm_Label.Text = '(88888888888.88 mm)';
			app.LatLonXYTab_Map_To1_Label = uilabel(app.LatLonXYTab);
			app.LatLonXYTab_Map_To1_Label.Text = 'to';
			app.LatLonXYTab_Map_To2_Label = uilabel(app.LatLonXYTab);
			app.LatLonXYTab_Map_To2_Label.Text = 'to';
			app.LatLonXYTab_Map_Wmaxmm_Label = uilabel(app.LatLonXYTab);
			app.LatLonXYTab_Map_Wmaxmm_Label.Tooltip = {'Maximum printout width at the same number of tiles and tile width.'};
			app.LatLonXYTab_Map_Wmaxmm_Label.Text = '88888888888.88 mm';
			app.LatLonXYTab_Map_Hmaxmm_Label = uilabel(app.LatLonXYTab);
			app.LatLonXYTab_Map_Hmaxmm_Label.Tooltip = {'Maximum printout depth at the same number of tiles and tile depth.'};
			app.LatLonXYTab_Map_Hmaxmm_Label.Text = '88888888888.88 mm';
			app.LatLonXYTab_ScaleEditField = uieditfield(app.LatLonXYTab, 'numeric');
			app.LatLonXYTab_ScaleEditField.Limits = [1 Inf];
			app.LatLonXYTab_ScaleEditField.ValueDisplayFormat = '%g';
			app.LatLonXYTab_ScaleEditField.ValueChangedFcn = createCallbackFcn(app, @LatLonXYTab_ScaleEditFieldValueChanged, true);
			app.LatLonXYTab_ScaleEditField.Tooltip = {'tooltip_is_set_by_app'};
			app.LatLonXYTab_ScaleEditField.Value = 1;
			app.LatLonXYTab_OriginLongitudeEditField = uieditfield(app.LatLonXYTab, 'numeric');
			app.LatLonXYTab_OriginLongitudeEditField.Limits = [-180 180];
			app.LatLonXYTab_OriginLongitudeEditField.ValueDisplayFormat = '%.5f';
			app.LatLonXYTab_OriginLongitudeEditField.ValueChangedFcn = createCallbackFcn(app, @LatLonXYTab_OriginLongitudeEditFieldValueChanged, true);
			app.LatLonXYTab_OriginLongitudeEditField.Tooltip = {'tooltip_is_set_by_app'};
			app.LatLonXYTab_OriginLatitudeEditField = uieditfield(app.LatLonXYTab, 'numeric');
			app.LatLonXYTab_OriginLatitudeEditField.Limits = [-90 90];
			app.LatLonXYTab_OriginLatitudeEditField.ValueDisplayFormat = '%.5f';
			app.LatLonXYTab_OriginLatitudeEditField.ValueChangedFcn = createCallbackFcn(app, @LatLonXYTab_OriginLatitudeEditFieldValueChanged, true);
			app.LatLonXYTab_OriginLatitudeEditField.Tooltip = {'tooltip_is_set_by_app'};
			app.LatLonXYTab_Dist_OSM_MapPrintout_EditField = uieditfield(app.LatLonXYTab, 'numeric');
			app.LatLonXYTab_Dist_OSM_MapPrintout_EditField.ValueDisplayFormat = '%.2f';
			app.LatLonXYTab_Dist_OSM_MapPrintout_EditField.ValueChangedFcn = createCallbackFcn(app, @LatLonXYTab_Dist_OSM_MapPrintout_EditFieldValueChanged, true);
			app.LatLonXYTab_Dist_OSM_MapPrintout_EditField.Tooltip = {'tooltip_is_set_by_app'};
			app.LatLonXYTab_FrameWidth_EditField = uieditfield(app.LatLonXYTab, 'numeric');
			app.LatLonXYTab_FrameWidth_EditField.Limits = [0 Inf];
			app.LatLonXYTab_FrameWidth_EditField.ValueDisplayFormat = '%.2f';
			app.LatLonXYTab_FrameWidth_EditField.ValueChangedFcn = createCallbackFcn(app, @LatLonXYTab_FrameWidth_EditFieldValueChanged, true);
			app.LatLonXYTab_FrameWidth_EditField.Tooltip = {'tooltip_is_set_by_app'};
			app.LatLonXYTab_TileWidth_EditField = uieditfield(app.LatLonXYTab, 'numeric');
			app.LatLonXYTab_TileWidth_EditField.Limits = [0 Inf];
			app.LatLonXYTab_TileWidth_EditField.ValueDisplayFormat = '%.2f';
			app.LatLonXYTab_TileWidth_EditField.ValueChangedFcn = createCallbackFcn(app, @LatLonXYTab_TileWidth_EditFieldValueChanged, true);
			app.LatLonXYTab_TileWidth_EditField.Tooltip = {'tooltip_is_set_by_app'};
			app.LatLonXYTab_TileHeight_EditField = uieditfield(app.LatLonXYTab, 'numeric');
			app.LatLonXYTab_TileHeight_EditField.Limits = [0 Inf];
			app.LatLonXYTab_TileHeight_EditField.ValueDisplayFormat = '%.2f';
			app.LatLonXYTab_TileHeight_EditField.ValueChangedFcn = createCallbackFcn(app, @LatLonXYTab_TileHeight_EditFieldValueChanged, true);
			app.LatLonXYTab_TileHeight_EditField.Tooltip = {'tooltip_is_set_by_app'};
			app.LatLonXYTab_OSM_lonminEditField = uieditfield(app.LatLonXYTab, 'numeric');
			app.LatLonXYTab_OSM_lonminEditField.Limits = [-180 180];
			app.LatLonXYTab_OSM_lonminEditField.ValueDisplayFormat = '%.5f';
			app.LatLonXYTab_OSM_lonminEditField.ValueChangedFcn = createCallbackFcn(app, @LatLonXYTab_OSM_lonminEditFieldValueChanged, true);
			app.LatLonXYTab_OSM_lonmaxEditField = uieditfield(app.LatLonXYTab, 'numeric');
			app.LatLonXYTab_OSM_lonmaxEditField.Limits = [-180 180];
			app.LatLonXYTab_OSM_lonmaxEditField.ValueDisplayFormat = '%.5f';
			app.LatLonXYTab_OSM_lonmaxEditField.ValueChangedFcn = createCallbackFcn(app, @LatLonXYTab_OSM_lonmaxEditFieldValueChanged, true);
			app.LatLonXYTab_OSM_latminEditField = uieditfield(app.LatLonXYTab, 'numeric');
			app.LatLonXYTab_OSM_latminEditField.Limits = [-90 90];
			app.LatLonXYTab_OSM_latminEditField.ValueDisplayFormat = '%.5f';
			app.LatLonXYTab_OSM_latminEditField.ValueChangedFcn = createCallbackFcn(app, @LatLonXYTab_OSM_latminEditFieldValueChanged, true);
			app.LatLonXYTab_OSM_latmaxEditField = uieditfield(app.LatLonXYTab, 'numeric');
			app.LatLonXYTab_OSM_latmaxEditField.Limits = [-90 90];
			app.LatLonXYTab_OSM_latmaxEditField.ValueDisplayFormat = '%.5f';
			app.LatLonXYTab_OSM_latmaxEditField.ValueChangedFcn = createCallbackFcn(app, @LatLonXYTab_OSM_latmaxEditFieldValueChanged, true);
			app.LatLonXYTab_OSM_LonCenter_EditField = uieditfield(app.LatLonXYTab, 'numeric');
			app.LatLonXYTab_OSM_LonCenter_EditField.Limits = [-180 180];
			app.LatLonXYTab_OSM_LonCenter_EditField.ValueDisplayFormat = '%.5f';
			app.LatLonXYTab_OSM_LonCenter_EditField.Editable = 'off';
			app.LatLonXYTab_OSM_LatCenter_EditField = uieditfield(app.LatLonXYTab, 'numeric');
			app.LatLonXYTab_OSM_LatCenter_EditField.Limits = [-90 90];
			app.LatLonXYTab_OSM_LatCenter_EditField.ValueDisplayFormat = '%.5f';
			app.LatLonXYTab_OSM_LatCenter_EditField.Editable = 'off';
			app.LatLonXYTab_OSM_xminmmEditField = uieditfield(app.LatLonXYTab, 'numeric');
			app.LatLonXYTab_OSM_xminmmEditField.ValueDisplayFormat = '%.2f';
			app.LatLonXYTab_OSM_xminmmEditField.ValueChangedFcn = createCallbackFcn(app, @LatLonXYTab_OSM_xminmmEditFieldValueChanged, true);
			app.LatLonXYTab_OSM_xmaxmmEditField = uieditfield(app.LatLonXYTab, 'numeric');
			app.LatLonXYTab_OSM_xmaxmmEditField.ValueDisplayFormat = '%.2f';
			app.LatLonXYTab_OSM_xmaxmmEditField.ValueChangedFcn = createCallbackFcn(app, @LatLonXYTab_OSM_xmaxmmEditFieldValueChanged, true);
			app.LatLonXYTab_OSM_yminmmEditField = uieditfield(app.LatLonXYTab, 'numeric');
			app.LatLonXYTab_OSM_yminmmEditField.ValueDisplayFormat = '%.2f';
			app.LatLonXYTab_OSM_yminmmEditField.ValueChangedFcn = createCallbackFcn(app, @LatLonXYTab_OSM_yminmmEditFieldValueChanged, true);
			app.LatLonXYTab_OSM_ymaxmmEditField = uieditfield(app.LatLonXYTab, 'numeric');
			app.LatLonXYTab_OSM_ymaxmmEditField.ValueDisplayFormat = '%.2f';
			app.LatLonXYTab_OSM_ymaxmmEditField.ValueChangedFcn = createCallbackFcn(app, @LatLonXYTab_OSM_ymaxmmEditFieldValueChanged, true);
			app.LatLonXYTab_Osmosis_lonminEditField = uieditfield(app.LatLonXYTab, 'numeric');
			app.LatLonXYTab_Osmosis_lonminEditField.Limits = [-180 180];
			app.LatLonXYTab_Osmosis_lonminEditField.ValueDisplayFormat = '%.5f';
			app.LatLonXYTab_Osmosis_lonminEditField.ValueChangedFcn = createCallbackFcn(app, @LatLonXYTab_Osmosis_lonminEditFieldValueChanged, true);
			app.LatLonXYTab_Osmosis_lonminEditField.Tooltip = {'tooltip_is_set_by_app'};
			app.LatLonXYTab_Osmosis_lonmaxEditField = uieditfield(app.LatLonXYTab, 'numeric');
			app.LatLonXYTab_Osmosis_lonmaxEditField.Limits = [-180 180];
			app.LatLonXYTab_Osmosis_lonmaxEditField.ValueDisplayFormat = '%.5f';
			app.LatLonXYTab_Osmosis_lonmaxEditField.ValueChangedFcn = createCallbackFcn(app, @LatLonXYTab_Osmosis_lonmaxEditFieldValueChanged, true);
			app.LatLonXYTab_Osmosis_lonmaxEditField.Tooltip = {'tooltip_is_set_by_app'};
			app.LatLonXYTab_Osmosis_latminEditField = uieditfield(app.LatLonXYTab, 'numeric');
			app.LatLonXYTab_Osmosis_latminEditField.Limits = [-90 90];
			app.LatLonXYTab_Osmosis_latminEditField.ValueDisplayFormat = '%.5f';
			app.LatLonXYTab_Osmosis_latminEditField.ValueChangedFcn = createCallbackFcn(app, @LatLonXYTab_Osmosis_latminEditFieldValueChanged, true);
			app.LatLonXYTab_Osmosis_latminEditField.Tooltip = {'tooltip_is_set_by_app'};
			app.LatLonXYTab_Osmosis_latmaxEditField = uieditfield(app.LatLonXYTab, 'numeric');
			app.LatLonXYTab_Osmosis_latmaxEditField.Limits = [-90 90];
			app.LatLonXYTab_Osmosis_latmaxEditField.ValueDisplayFormat = '%.5f';
			app.LatLonXYTab_Osmosis_latmaxEditField.ValueChangedFcn = createCallbackFcn(app, @LatLonXYTab_Osmosis_latmaxEditFieldValueChanged, true);
			app.LatLonXYTab_Osmosis_latmaxEditField.Tooltip = {'tooltip_is_set_by_app'};
			app.LatLonXYTab_Osmosis_LonCenter_EditField = uieditfield(app.LatLonXYTab, 'numeric');
			app.LatLonXYTab_Osmosis_LonCenter_EditField.Limits = [-180 180];
			app.LatLonXYTab_Osmosis_LonCenter_EditField.ValueDisplayFormat = '%.5f';
			app.LatLonXYTab_Osmosis_LonCenter_EditField.Editable = 'off';
			app.LatLonXYTab_Osmosis_LatCenter_EditField = uieditfield(app.LatLonXYTab, 'numeric');
			app.LatLonXYTab_Osmosis_LatCenter_EditField.Limits = [-90 90];
			app.LatLonXYTab_Osmosis_LatCenter_EditField.ValueDisplayFormat = '%.5f';
			app.LatLonXYTab_Osmosis_LatCenter_EditField.Editable = 'off';
			app.LatLonXYTab_Osmosis_xminmmEditField = uieditfield(app.LatLonXYTab, 'numeric');
			app.LatLonXYTab_Osmosis_xminmmEditField.ValueDisplayFormat = '%.2f';
			app.LatLonXYTab_Osmosis_xminmmEditField.ValueChangedFcn = createCallbackFcn(app, @LatLonXYTab_Osmosis_xminmmEditFieldValueChanged, true);
			app.LatLonXYTab_Osmosis_xmaxmmEditField = uieditfield(app.LatLonXYTab, 'numeric');
			app.LatLonXYTab_Osmosis_xmaxmmEditField.ValueDisplayFormat = '%.2f';
			app.LatLonXYTab_Osmosis_xmaxmmEditField.ValueChangedFcn = createCallbackFcn(app, @LatLonXYTab_Osmosis_xmaxmmEditFieldValueChanged, true);
			app.LatLonXYTab_Osmosis_yminmmEditField = uieditfield(app.LatLonXYTab, 'numeric');
			app.LatLonXYTab_Osmosis_yminmmEditField.ValueDisplayFormat = '%.2f';
			app.LatLonXYTab_Osmosis_yminmmEditField.ValueChangedFcn = createCallbackFcn(app, @LatLonXYTab_Osmosis_yminmmEditFieldValueChanged, true);
			app.LatLonXYTab_Osmosis_ymaxmmEditField = uieditfield(app.LatLonXYTab, 'numeric');
			app.LatLonXYTab_Osmosis_ymaxmmEditField.ValueDisplayFormat = '%.2f';
			app.LatLonXYTab_Osmosis_ymaxmmEditField.ValueChangedFcn = createCallbackFcn(app, @LatLonXYTab_Osmosis_ymaxmmEditFieldValueChanged, true);
			app.LatLonXYTab_Map_lonminEditField = uieditfield(app.LatLonXYTab, 'numeric');
			app.LatLonXYTab_Map_lonminEditField.Limits = [-180 180];
			app.LatLonXYTab_Map_lonminEditField.ValueDisplayFormat = '%.5f';
			app.LatLonXYTab_Map_lonminEditField.ValueChangedFcn = createCallbackFcn(app, @LatLonXYTab_Map_lonminEditFieldValueChanged, true);
			app.LatLonXYTab_Map_lonmaxEditField = uieditfield(app.LatLonXYTab, 'numeric');
			app.LatLonXYTab_Map_lonmaxEditField.Limits = [-180 180];
			app.LatLonXYTab_Map_lonmaxEditField.ValueDisplayFormat = '%.5f';
			app.LatLonXYTab_Map_lonmaxEditField.ValueChangedFcn = createCallbackFcn(app, @LatLonXYTab_Map_lonmaxEditFieldValueChanged, true);
			app.LatLonXYTab_Map_latminEditField = uieditfield(app.LatLonXYTab, 'numeric');
			app.LatLonXYTab_Map_latminEditField.Limits = [-90 90];
			app.LatLonXYTab_Map_latminEditField.ValueDisplayFormat = '%.5f';
			app.LatLonXYTab_Map_latminEditField.ValueChangedFcn = createCallbackFcn(app, @LatLonXYTab_Map_latminEditFieldValueChanged, true);
			app.LatLonXYTab_Map_latmaxEditField = uieditfield(app.LatLonXYTab, 'numeric');
			app.LatLonXYTab_Map_latmaxEditField.Limits = [-90 90];
			app.LatLonXYTab_Map_latmaxEditField.ValueDisplayFormat = '%.5f';
			app.LatLonXYTab_Map_latmaxEditField.ValueChangedFcn = createCallbackFcn(app, @LatLonXYTab_Map_latmaxEditFieldValueChanged, true);
			app.LatLonXYTab_Map_LonCenter_EditField = uieditfield(app.LatLonXYTab, 'numeric');
			app.LatLonXYTab_Map_LonCenter_EditField.Limits = [-180 180];
			app.LatLonXYTab_Map_LonCenter_EditField.ValueDisplayFormat = '%.5f';
			app.LatLonXYTab_Map_LonCenter_EditField.Editable = 'off';
			app.LatLonXYTab_Map_LatCenter_EditField = uieditfield(app.LatLonXYTab, 'numeric');
			app.LatLonXYTab_Map_LatCenter_EditField.Limits = [-90 90];
			app.LatLonXYTab_Map_LatCenter_EditField.ValueDisplayFormat = '%.5f';
			app.LatLonXYTab_Map_LatCenter_EditField.Editable = 'off';
			app.LatLonXYTab_Map_xminmmEditField = uieditfield(app.LatLonXYTab, 'numeric');
			app.LatLonXYTab_Map_xminmmEditField.ValueDisplayFormat = '%.2f';
			app.LatLonXYTab_Map_xminmmEditField.ValueChangedFcn = createCallbackFcn(app, @LatLonXYTab_Map_xminmmEditFieldValueChanged, true);
			app.LatLonXYTab_Map_xminmmEditField.Tooltip = {'tooltip_is_set_by_app'};
			app.LatLonXYTab_Map_xmaxmmEditField = uieditfield(app.LatLonXYTab, 'numeric');
			app.LatLonXYTab_Map_xmaxmmEditField.ValueDisplayFormat = '%.2f';
			app.LatLonXYTab_Map_xmaxmmEditField.ValueChangedFcn = createCallbackFcn(app, @LatLonXYTab_Map_xmaxmmEditFieldValueChanged, true);
			app.LatLonXYTab_Map_xmaxmmEditField.Tooltip = {'tooltip_is_set_by_app'};
			app.LatLonXYTab_Map_yminmmEditField = uieditfield(app.LatLonXYTab, 'numeric');
			app.LatLonXYTab_Map_yminmmEditField.ValueDisplayFormat = '%.2f';
			app.LatLonXYTab_Map_yminmmEditField.ValueChangedFcn = createCallbackFcn(app, @LatLonXYTab_Map_yminmmEditFieldValueChanged, true);
			app.LatLonXYTab_Map_yminmmEditField.Tooltip = {'tooltip_is_set_by_app'};
			app.LatLonXYTab_Map_ymaxmmEditField = uieditfield(app.LatLonXYTab, 'numeric');
			app.LatLonXYTab_Map_ymaxmmEditField.ValueDisplayFormat = '%.2f';
			app.LatLonXYTab_Map_ymaxmmEditField.ValueChangedFcn = createCallbackFcn(app, @LatLonXYTab_Map_ymaxmmEditFieldValueChanged, true);
			app.LatLonXYTab_Map_ymaxmmEditField.Tooltip = {'tooltip_is_set_by_app'};
			% Show the figure after all components are created
			app.MapLab3D.Visible = 'on';
		end
	end

	% App creation and deletion
	methods (Access = public)

		% Construct app
		function app = maplab3d

			% Create UIFigure and components
			createComponents(app)

			% Register the app with App Designer
			registerApp(app, app.MapLab3D)

			% Execute the startup function
			runStartupFcn(app, @startupFcn)

			if nargout == 0
				clear app
			end
		end

		% Code that executes before app deletion
		function delete(app)

			% Delete UIFigure when app is deleted
			delete(app.MapLab3D)
		end
	end
end