classdef maplab3d_about < matlab.apps.AppBase

	% Properties that correspond to app components
	properties (Access = public)
		MapLab3DAboutUIFigure        matlab.ui.Figure
		Title_Label                  matlab.ui.control.Label
		Summary_Label                matlab.ui.control.Label
		Version_Label                matlab.ui.control.Label
		Description_Label            matlab.ui.control.Label
		Contributors_Wiki_Hyperlink  matlab.ui.control.Hyperlink
		MapLab3D_Image               matlab.ui.control.Image
	end

	
	properties (Access = private)
		
	end
	
	methods (Access = private)
		
	end
	

	% Callbacks that handle component events
	methods (Access = private)

		% Code that executes after component creation
		function startupFcn(app)
			
			global SETTINGS VER APP
			
			% Figure position:
			maplab3d_pos	= APP.MapLab3D.Position;
			about_pos		= app.MapLab3DAboutUIFigure.Position;
			about_pos(1)	= maplab3d_pos(1)+maplab3d_pos(3)/2-about_pos(3)/2;
			about_pos(2)	= maplab3d_pos(2)+maplab3d_pos(4)/2-about_pos(4)/2;
			about_pos(2)	= min(...
				about_pos(2),...
				maplab3d_pos(2)+maplab3d_pos(4)-about_pos(4));
			about_pos(2)	= max(about_pos(2),50);
			app.MapLab3DAboutUIFigure.Position	= about_pos;
			
			% Set version number:
			if ~isempty(SETTINGS.build_datetime)
				build_datetime		= sprintf(' - %s',datetime(SETTINGS.build_datetime,'Format','yyyy-MMM-dd'));
			else
				build_datetime		= sprintf(' - %s',datetime('now'                  ,'Format','yyyy-MMM-dd'));
			end
			app.Version_Label.Text		= sprintf('Version %g.%g.%g.%g%s%s',VER.no1,VER.no2,VER.no3,VER.no4,VER.str,build_datetime);
			
		end
	end

	% Component initialization
	methods (Access = private)

		% Create UIFigure and components
		function createComponents(app)
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			% Get the file path for locating images
			pathToMLAPP = fileparts(mfilename('fullpath'));
			app.MapLab3DAboutUIFigure = uifigure('Visible', 'off');
			app.MapLab3DAboutUIFigure.Name = 'MapLab3D - About';
			app.MapLab3DAboutUIFigure.Icon = fullfile(pathToMLAPP, 'MapLab3D_Logo_400x400_RGB.png');
			app.MapLab3DAboutUIFigure.Tag = 'maplab3d_figure';
			app.MapLab3D_Image = uiimage(app.MapLab3DAboutUIFigure);
			app.MapLab3D_Image.Tooltip = {'Baden-Württemberg Cooperative State University (DHBW).'};
			app.MapLab3D_Image.ImageSource = fullfile(pathToMLAPP, 'MapLab3D_Logo_400x400_RGB.png');
			app.Contributors_Wiki_Hyperlink = uihyperlink(app.MapLab3DAboutUIFigure);
			app.Contributors_Wiki_Hyperlink.URL = 'https://wiki.openstreetmap.org/wiki/MapLab3D';
			app.Contributors_Wiki_Hyperlink.Text = 'Online Wiki';
			app.Description_Label = uilabel(app.MapLab3DAboutUIFigure);
			app.Description_Label.Text = {'MapLab3D is a tool for creating 3D models of maps in STL format from freely '; 'available OpenStreetMap data and elevation data which can be printed on 3D printers.'};
			app.Version_Label = uilabel(app.MapLab3DAboutUIFigure);
			app.Version_Label.Text = 'Version 0.0.0.0';
			app.Summary_Label = uilabel(app.MapLab3DAboutUIFigure);
			app.Summary_Label.Text = '3D printing of relief maps';
			app.Title_Label = uilabel(app.MapLab3DAboutUIFigure);
			app.Title_Label.Text = 'MapLab3D';
			% Show the figure after all components are created
			app.MapLab3DAboutUIFigure.Visible = 'on';
		end
	end

	% App creation and deletion
	methods (Access = public)

		% Construct app
		function app = maplab3d_about

			% Create UIFigure and components
			createComponents(app)

			% Register the app with App Designer
			registerApp(app, app.MapLab3DAboutUIFigure)

			% Execute the startup function
			runStartupFcn(app, @startupFcn)

			if nargout == 0
				clear app
			end
		end

		% Code that executes before app deletion
		function delete(app)

			% Delete UIFigure when app is deleted
			delete(app.MapLab3DAboutUIFigure)
		end
	end
end