classdef mapobjects_select_by_filter_getuserinput < matlab.apps.AppBase

	% Properties that correspond to app components
	properties (Access = public)
		SelectbyfilterUIFigure          matlab.ui.Figure
		MaxLineLength_EditField         matlab.ui.control.NumericEditField
		MaximumLabel_3                  matlab.ui.control.Label
		MinLineLength_EditField         matlab.ui.control.NumericEditField
		MinimumLabel_3                  matlab.ui.control.Label
		MinMaxLineLength_Label          matlab.ui.control.Label
		PreviewObjects_CheckBox         matlab.ui.control.CheckBox
		SelectObjects_ExceptThoseThatMeetTheCond_CheckBox  matlab.ui.control.CheckBox
		SelectObjects_ThatMeetTheCond_CheckBox  matlab.ui.control.CheckBox
		SelectObjects_Label             matlab.ui.control.Label
		Reset_Button                    matlab.ui.control.Button
		Visibility_Label                matlab.ui.control.Label
		Cancel_Button                   matlab.ui.control.Button
		Select_Button                   matlab.ui.control.Button
		MinMaxArea_Label                matlab.ui.control.Label
		KeywordSearchTextTag_EditField  matlab.ui.control.EditField
		TextTagLabel                    matlab.ui.control.Label
		MinMaxDiag_Label                matlab.ui.control.Label
		KeywordSearch_Label             matlab.ui.control.Label
		KeywordSearchDescription_EditField  matlab.ui.control.EditField
		MaxArea_EditField               matlab.ui.control.NumericEditField
		MaximumLabel_2                  matlab.ui.control.Label
		MinArea_EditField               matlab.ui.control.NumericEditField
		MinimumLabel_2                  matlab.ui.control.Label
		DescriptionLabel                matlab.ui.control.Label
		ObjectType_Label                matlab.ui.control.Label
		MaxDiag_EditField               matlab.ui.control.NumericEditField
		MaximumLabel                    matlab.ui.control.Label
		Symbols_ListBox                 matlab.ui.control.ListBox
		SymbolsLabel                    matlab.ui.control.Label
		ConnectionLines_CheckBox        matlab.ui.control.CheckBox
		CuttingLines_CheckBox           matlab.ui.control.CheckBox
		PreviewCuttingLines_CheckBox    matlab.ui.control.CheckBox
		Symbols_CheckBox                matlab.ui.control.CheckBox
		Texts_CheckBox                  matlab.ui.control.CheckBox
		Areas_CheckBox                  matlab.ui.control.CheckBox
		Lines_CheckBox                  matlab.ui.control.CheckBox
		Hidden_CheckBox                 matlab.ui.control.CheckBox
		GrayedOut_CheckBox              matlab.ui.control.CheckBox
		Visible_CheckBox                matlab.ui.control.CheckBox
		TempHidden_CheckBox             matlab.ui.control.CheckBox
		CharacterStyleNumbers_ListBox   matlab.ui.control.ListBox
		CharacterstylenumbersLabel      matlab.ui.control.Label
		ColorNumbers_ListBox            matlab.ui.control.ListBox
		ColornumbersLabel               matlab.ui.control.Label
		ObjectNumbers_ListBox           matlab.ui.control.ListBox
		ObjectnumbersLabel              matlab.ui.control.Label
		MinDiag_EditField               matlab.ui.control.NumericEditField
		MinimumLabel                    matlab.ui.control.Label
	end

	
	methods (Access = private)
		
		%------------------------------------------------------------------------------------------------------------
		function val = set_app_listbox_values(app,fn_listbox,val)
			
			try
				
				% Keep only values in val.(fn_listbox).Value, that exist as values in the listbox:
				k_val_keep		= false(size(val.(fn_listbox).Value));
				for k_val=1:length(val.(fn_listbox).Value)
					for k_listbox=1:length(app.(fn_listbox).Items)
						if strcmp(...
								val.(fn_listbox).Value{k_val},...
								app.(fn_listbox).Items{k_listbox})
							k_val_keep(k_val)		= true;
							break
						end
					end
				end
				val.(fn_listbox).Value(~k_val_keep)	= [];
				
				% Now val.(fn_listbox).Value only contains values ��that also appear in the listbox.
				% Delete duplicate elements in val.(fn_listbox).Value:
				if length(val.(fn_listbox).Value)>=2
					k_val_delete		= false(size(val.(fn_listbox).Value));
					for k1=1:(length(val.(fn_listbox).Value)-1)
						for k2=(k1+1):length(val.(fn_listbox).Value)
							if strcmp(...
									val.(fn_listbox).Value{k1},...
									val.(fn_listbox).Value{k2})
								k_val_delete(k2)		= true;
							end
						end
					end
					val.(fn_listbox).Value(k_val_delete)	= [];
				end
				
				% If there are more than 2 elements and one of them is {'---'}: Delete {'---'}:
				if length(val.(fn_listbox).Value)>=2
					k_val_delete		= false(size(val.(fn_listbox).Value));
					for k1=1:(length(val.(fn_listbox).Value))
						if strcmp(val.(fn_listbox).Value{k1},'---')
							k_val_delete(k1)		= true;
						end
					end
					val.(fn_listbox).Value(k_val_delete)	= [];
				end
				
				% If val.(fn_listbox).Value is empty: assign {'---'}:
				if isempty(val.(fn_listbox).Value)
					val.(fn_listbox).Value		= {'---'};
				end
				
				% Assign val.(fn_listbox).Value to app.(fn_listbox).Value:
				app.(fn_listbox).Value	= val.(fn_listbox).Value;
				
			catch ME
				errormessage('',ME);
			end
			
		end
		
		%------------------------------------------------------------------------------------------------------------
		function set_app_values(app,fn_val)
			% Sets the value-properties of the app elements.
			% Default-Settings:		fn_val       = 'def_val'
			% User-Settings:			fn_val       = 'val'
			% Default-settings:
			% GV.selbyfilt.def_val.Visible_CheckBox.Value												= true;
			% GV.selbyfilt.def_val.GrayedOut_CheckBox.Value												= true;
			% GV.selbyfilt.def_val.Hidden_CheckBox.Value													= false;
			% GV.selbyfilt.def_val.TempHidden_CheckBox.Value											= false;
			% GV.selbyfilt.def_val.Lines_CheckBox.Value													= true;
			% GV.selbyfilt.def_val.Areas_CheckBox.Value													= true;
			% GV.selbyfilt.def_val.Texts_CheckBox.Value													= true;
			% GV.selbyfilt.def_val.Symbols_CheckBox.Value												= true;
			% GV.selbyfilt.def_val.ConnectionLines_CheckBox.Value										= true;
			% GV.selbyfilt.def_val.PreviewObjects_CheckBox.Value										= true;
			% GV.selbyfilt.def_val.PreviewCuttingLines_CheckBox.Value								= false;
			% GV.selbyfilt.def_val.CuttingLines_CheckBox.Value											= false;
			% GV.selbyfilt.def_val.KeywordSearchDescription_EditField.Value						= '';
			% GV.selbyfilt.def_val.KeywordSearchTextTag_EditField.Value								= '';
			% GV.selbyfilt.def_val.MinDiag_EditField.Value												= 0;
			% GV.selbyfilt.def_val.MaxDiag_EditField.Value												= 1e10;
			% GV.selbyfilt.def_val.MinArea_EditField.Value												= 0;
			% GV.selbyfilt.def_val.MaxArea_EditField.Value												= 1e10;
			% GV.selbyfilt.def_val.MinLineLength_EditField.Value										= 0;
			% GV.selbyfilt.def_val.MaxLineLength_EditField.Value										= 1e10;
			% GV.selbyfilt.def_val.ObjectNumbers_ListBox.Value											= {'---'};
			% GV.selbyfilt.def_val.ColorNumbers_ListBox.Value											= {'---'};
			% GV.selbyfilt.def_val.CharacterStyleNumbers_ListBox.Value								= {'---'};
			% GV.selbyfilt.def_val.Symbols_ListBox.Value													= {'---'};
			% GV.selbyfilt.def_val.SelectObjects_ThatMeetTheCond_CheckBox.Value					= true;
			% GV.selbyfilt.def_val.SelectObjects_ExceptThoseThatMeetTheCond_CheckBox.Value	= false;
			
			global GV
			
			try
				
				% Select all objects:
				app.SelectObjects_ThatMeetTheCond_CheckBox.Value					= GV.selbyfilt.(fn_val).SelectObjects_ThatMeetTheCond_CheckBox.Value;
				app.SelectObjects_ExceptThoseThatMeetTheCond_CheckBox.Value		= GV.selbyfilt.(fn_val).SelectObjects_ExceptThoseThatMeetTheCond_CheckBox.Value;
				
				% Visibility:
				app.Visible_CheckBox.Value									= GV.selbyfilt.(fn_val).Visible_CheckBox.Value;
				app.GrayedOut_CheckBox.Value								= GV.selbyfilt.(fn_val).GrayedOut_CheckBox.Value;
				app.Hidden_CheckBox.Value									= GV.selbyfilt.(fn_val).Hidden_CheckBox.Value;
				app.TempHidden_CheckBox.Value								= GV.selbyfilt.(fn_val).TempHidden_CheckBox.Value;
				
				% Object type:
				app.Lines_CheckBox.Value									= GV.selbyfilt.(fn_val).Lines_CheckBox.Value;
				app.Areas_CheckBox.Value									= GV.selbyfilt.(fn_val).Areas_CheckBox.Value;
				app.Texts_CheckBox.Value									= GV.selbyfilt.(fn_val).Texts_CheckBox.Value;
				app.Symbols_CheckBox.Value									= GV.selbyfilt.(fn_val).Symbols_CheckBox.Value;
				app.ConnectionLines_CheckBox.Value						= GV.selbyfilt.(fn_val).ConnectionLines_CheckBox.Value;
				app.PreviewObjects_CheckBox.Value						= GV.selbyfilt.(fn_val).PreviewObjects_CheckBox.Value;
				app.PreviewCuttingLines_CheckBox.Value					= GV.selbyfilt.(fn_val).PreviewCuttingLines_CheckBox.Value;
				app.CuttingLines_CheckBox.Value							= GV.selbyfilt.(fn_val).CuttingLines_CheckBox.Value;
				
				% Keyword search:
				app.KeywordSearchDescription_EditField.Value			= GV.selbyfilt.(fn_val).KeywordSearchDescription_EditField.Value;
				app.KeywordSearchTextTag_EditField.Value				= GV.selbyfilt.(fn_val).KeywordSearchTextTag_EditField.Value;
				
				% Diagonal of the bounding box / mm
				app.MinDiag_EditField.Value								= GV.selbyfilt.(fn_val).MinDiag_EditField.Value;
				app.MaxDiag_EditField.Value								= GV.selbyfilt.(fn_val).MaxDiag_EditField.Value;
				
				% Area / mm^2
				app.MinArea_EditField.Value								= GV.selbyfilt.(fn_val).MinArea_EditField.Value;
				app.MaxArea_EditField.Value								= GV.selbyfilt.(fn_val).MaxArea_EditField.Value;
				
				% LineLength / mm
				app.MinLineLength_EditField.Value						= GV.selbyfilt.(fn_val).MinLineLength_EditField.Value;
				app.MaxLineLength_EditField.Value						= GV.selbyfilt.(fn_val).MaxLineLength_EditField.Value;
				
				% List boxes:
				GV.selbyfilt.(fn_val) = set_app_listbox_values(app,'ObjectNumbers_ListBox'        ,GV.selbyfilt.(fn_val));
				GV.selbyfilt.(fn_val) = set_app_listbox_values(app,'ColorNumbers_ListBox'         ,GV.selbyfilt.(fn_val));
				GV.selbyfilt.(fn_val) = set_app_listbox_values(app,'CharacterStyleNumbers_ListBox',GV.selbyfilt.(fn_val));
				GV.selbyfilt.(fn_val) = set_app_listbox_values(app,'Symbols_ListBox'              ,GV.selbyfilt.(fn_val));
				
			catch ME
				errormessage('',ME);
			end
			
		end
		
		
	end
	

	% Callbacks that handle component events
	methods (Access = private)

		% Code that executes after component creation
		function startupFcn(app)
			
			global MAP_OBJECTS PP SY GV GV_H APP
			
			try
				
				% For polling:
				GV_H.mapobjects_select_by_filter_getuserinput	= app;
				
				% Select objects only if the button "Select" has been pressed:
				GV.selbyfilt.select									= false;
				
				% Figure position:
				maplab3d_pos		= APP.MapLab3D.Position;
				selbyfil_pos		= app.SelectbyfilterUIFigure.Position;
				selbyfil_pos(1)	= maplab3d_pos(1)+maplab3d_pos(3)/2-selbyfil_pos(3)/2;
				selbyfil_pos(2)	= maplab3d_pos(2)+maplab3d_pos(4)/2-selbyfil_pos(4)/2;
				selbyfil_pos(2)	= min(selbyfil_pos(2),maplab3d_pos(2)+maplab3d_pos(4)-selbyfil_pos(4));
				selbyfil_pos(2)	= max(selbyfil_pos(2),50);
				app.SelectbyfilterUIFigure.Position	= selbyfil_pos;
				
				% Prepare the list boxes:
				% Object number(s):
				iobj_v		= unique([MAP_OBJECTS.iobj]);
				iobj_v((iobj_v<0)|(iobj_v>size(PP.obj,1)))	= [];
				kmax			= length(iobj_v);
				items			= cell(1,kmax);
				for k=1:kmax
					iobj	= iobj_v(k);
					if iobj==0
						items{k}	= '0: legend';
					else
						items{k}	= sprintf('%1.0f: %s',iobj,PP.obj(iobj,1).description);
					end
				end
				items			= ['---' items];
				app.ObjectNumbers_ListBox.Items		= items;
				app.ObjectNumbers_ListBox.UserData	= iobj_v;
				
				% Color number(s):
				% Character style number(s):
				% Symbol(s):
				color_no_v		= [];
				chstno_v			= [];
				isym_v			= [];
				for imapobj=1:size(MAP_OBJECTS,1)
					for i=1:size(MAP_OBJECTS(imapobj,1).h,1)
						if isfield(MAP_OBJECTS(imapobj,1).h(i,1).UserData,'color_no')
							color_no_v	= [color_no_v;MAP_OBJECTS(imapobj,1).h(i,1).UserData.color_no];
						end
						if isfield(MAP_OBJECTS(imapobj,1).h(i,1).UserData,'chstno')
							chstno_v		= [chstno_v  ;MAP_OBJECTS(imapobj,1).h(i,1).UserData.chstno  ];
						end
						if isfield(MAP_OBJECTS(imapobj,1).h(i,1).UserData,'isym')
							isym_v		= [isym_v    ;MAP_OBJECTS(imapobj,1).h(i,1).UserData.isym    ];
						end
					end
				end
				color_no_v		= unique(color_no_v);
				chstno_v			= unique(chstno_v);
				isym_v			= unique(isym_v);
				color_no_v((color_no_v<0)|(color_no_v>size(PP.color,1)))	= [];
				chstno_v((chstno_v<1)|(chstno_v>size(PP.charstyle,1)))	= [];
				isym_v((isym_v<1)|(isym_v>size(SY,1)))							= [];
				
				% Color number(s):
				kmax			= length(color_no_v);
				items			= cell(1,kmax);
				for k=1:kmax
					color_no	= color_no_v(k);
					if color_no==0
						items{k}	= '0: color of the object below';
					else
						items{k}	= sprintf('%1.0f: %s',color_no,PP.color(color_no,1).description);
					end
				end
				items			= ['---' items];
				app.ColorNumbers_ListBox.Items		= items;
				app.ColorNumbers_ListBox.UserData	= color_no_v;
				
				% Character style number(s):
				kmax			= length(chstno_v);
				items			= cell(1,kmax);
				for k=1:kmax
					chstno	= chstno_v(k);
					items{k}	= sprintf('%1.0f: %s',chstno,PP.charstyle(chstno,1).description);
				end
				items			= ['---' items];
				app.CharacterStyleNumbers_ListBox.Items		= items;
				app.CharacterStyleNumbers_ListBox.UserData	= chstno_v;
				
				% Symbol(s):
				kmax			= length(isym_v);
				items			= cell(1,kmax);
				for k=1:kmax
					isym		= isym_v(k);
					items{k}	= sprintf('%1.0f: %s=%s',isym,SY(isym,1).k,SY(isym,1).v);
				end
				items			= ['---' items];
				app.Symbols_ListBox.Items		= items;
				app.Symbols_ListBox.UserData	= isym_v;
				
				% Set the values of the objects to the last user settings:
				set_app_values(app,'val');
				
			catch ME
				errormessage('',ME);
			end
			
		end

		% Button pushed function: Cancel_Button
		function Cancel_ButtonPushed(app, event)
			SelectbyfilterUIFigureCloseRequest(app, event);
			
		end

		% Button pushed function: Reset_Button
		function Reset_ButtonPushed(app, event)
			try
				set_app_values(app,'def_val');
			catch ME
				errormessage('',ME);
			end
			
		end

		% Button pushed function: Select_Button
		function Select_ButtonPushed(app, event)
			global GV GV_H
			
			try
				
				% Check for incorrect user settings:
				warntext				= '';
				incorrect_input	= (...
					~app.Visible_CheckBox.Value             &&...
					~app.GrayedOut_CheckBox.Value           &&...
					~app.Hidden_CheckBox.Value              &&...
					~app.TempHidden_CheckBox.Value               )||(...
					~app.Lines_CheckBox.Value               &&...
					~app.Areas_CheckBox.Value               &&...
					~app.Texts_CheckBox.Value               &&...
					~app.Symbols_CheckBox.Value             &&...
					~app.ConnectionLines_CheckBox.Value     &&...
					~app.PreviewObjects_CheckBox.Value      &&...
					~app.PreviewCuttingLines_CheckBox.Value &&...
					~app.CuttingLines_CheckBox.Value                     );
				if incorrect_input
					warntext	= sprintf([...
						'You have to choose something from\n',...
						'- Visible objects\n',...
						'- Grayed out objects\n',...
						'- Hidden objects\n',...
						'and something from\n',...
						'- Lines\n',...
						'- Areas\n',...
						'- Texts\n',...
						'- Symbols\n',...
						'- Connection lines\n',...
						'- Preview cutting lines\n',...
						'- Cutting lines']);
				end
				incorrect_input	= (app.MinDiag_EditField.Value>app.MaxDiag_EditField.Value);
				if incorrect_input
					warntext	= sprintf([...
						'%s:\n',...			% Diagonal of the bounding box / mm
						'The minimum must be smaller than the maximum.'],app.MinMaxDiag_Label.Text);
				end
				incorrect_input	= (app.MinArea_EditField.Value>app.MaxArea_EditField.Value);
				if incorrect_input
					warntext	= sprintf([...
						'%s:\n',...			% Area / mm^2
						'The minimum must be smaller than the maximum.'],app.MinMaxArea_Label.Text);
				end
				incorrect_input	= (app.MinLineLength_EditField.Value>app.MaxLineLength_EditField.Value);
				if incorrect_input
					warntext	= sprintf([...
						'%s:\n',...			% LineLength / mm
						'The minimum must be smaller than the maximum.'],app.MinMaxLineLength_Label.Text);
				end
				if ~isempty(warntext)
					if isfield(GV_H.warndlg,'select_by_filter')
						if ishandle(GV_H.warndlg.select_by_filter)
							close(GV_H.warndlg.select_by_filter);
						end
					end
					GV_H.warndlg.select_by_filter		= warndlg(warntext,'Warning');
					GV_H.warndlg.select_by_filter.Tag	= 'maplab3d_figure';
					return
				end
				
				% Save User-settings:
				GV.selbyfilt.val.Visible_CheckBox.Value							= app.Visible_CheckBox.Value;
				GV.selbyfilt.val.GrayedOut_CheckBox.Value							= app.GrayedOut_CheckBox.Value;
				GV.selbyfilt.val.Hidden_CheckBox.Value								= app.Hidden_CheckBox.Value;
				GV.selbyfilt.val.TempHidden_CheckBox.Value						= app.TempHidden_CheckBox.Value;
				GV.selbyfilt.val.Lines_CheckBox.Value								= app.Lines_CheckBox.Value;
				GV.selbyfilt.val.Areas_CheckBox.Value								= app.Areas_CheckBox.Value;
				GV.selbyfilt.val.Texts_CheckBox.Value								= app.Texts_CheckBox.Value;
				GV.selbyfilt.val.Symbols_CheckBox.Value							= app.Symbols_CheckBox.Value;
				GV.selbyfilt.val.ConnectionLines_CheckBox.Value					= app.ConnectionLines_CheckBox.Value;
				GV.selbyfilt.val.PreviewObjects_CheckBox.Value					= app.PreviewObjects_CheckBox.Value;
				GV.selbyfilt.val.PreviewCuttingLines_CheckBox.Value			= app.PreviewCuttingLines_CheckBox.Value;
				GV.selbyfilt.val.CuttingLines_CheckBox.Value						= app.CuttingLines_CheckBox.Value;
				GV.selbyfilt.val.KeywordSearchDescription_EditField.Value	= app.KeywordSearchDescription_EditField.Value;
				GV.selbyfilt.val.KeywordSearchTextTag_EditField.Value			= app.KeywordSearchTextTag_EditField.Value;
				GV.selbyfilt.val.MinDiag_EditField.Value							= app.MinDiag_EditField.Value;
				GV.selbyfilt.val.MaxDiag_EditField.Value							= app.MaxDiag_EditField.Value;
				GV.selbyfilt.val.MinArea_EditField.Value							= app.MinArea_EditField.Value;
				GV.selbyfilt.val.MaxArea_EditField.Value							= app.MaxArea_EditField.Value;
				GV.selbyfilt.val.MinLineLength_EditField.Value					= app.MinLineLength_EditField.Value;
				GV.selbyfilt.val.MaxLineLength_EditField.Value					= app.MaxLineLength_EditField.Value;
				GV.selbyfilt.val.ObjectNumbers_ListBox.Value						= app.ObjectNumbers_ListBox.Value;
				GV.selbyfilt.val.ColorNumbers_ListBox.Value						= app.ColorNumbers_ListBox.Value;
				GV.selbyfilt.val.CharacterStyleNumbers_ListBox.Value			= app.CharacterStyleNumbers_ListBox.Value;
				GV.selbyfilt.val.Symbols_ListBox.Value								= app.Symbols_ListBox.Value;
				GV.selbyfilt.val.SelectObjects_ThatMeetTheCond_CheckBox.Value					= app.SelectObjects_ThatMeetTheCond_CheckBox.Value;
				GV.selbyfilt.val.SelectObjects_ExceptThoseThatMeetTheCond_CheckBox.Value	= app.SelectObjects_ExceptThoseThatMeetTheCond_CheckBox.Value;
				
				% Select map objects:
				GV.selbyfilt.select									= true;
				if     app.SelectObjects_ThatMeetTheCond_CheckBox.Value           &&...
						~app.SelectObjects_ExceptThoseThatMeetTheCond_CheckBox.Value
					GV.selbyfilt.sel.logic = true;					% 'that meet these conditions'
				else
					GV.selbyfilt.sel.logic = false;					% 'except those that meet these conditions'
				end
				GV.selbyfilt.sel.visible							= app.Visible_CheckBox.Value;
				GV.selbyfilt.sel.grayedout							= app.GrayedOut_CheckBox.Value;
				GV.selbyfilt.sel.hidden								= app.Hidden_CheckBox.Value;
				GV.selbyfilt.sel.tempHidden						= app.TempHidden_CheckBox.Value;
				GV.selbyfilt.sel.lines								= app.Lines_CheckBox.Value;
				GV.selbyfilt.sel.areas								= app.Areas_CheckBox.Value;
				GV.selbyfilt.sel.texts								= app.Texts_CheckBox.Value;
				GV.selbyfilt.sel.symbols							= app.Symbols_CheckBox.Value;
				GV.selbyfilt.sel.connectionlines					= app.ConnectionLines_CheckBox.Value;
				GV.selbyfilt.sel.previewobjects					= app.PreviewObjects_CheckBox.Value;
				GV.selbyfilt.sel.previewcuttinglines			= app.PreviewCuttingLines_CheckBox.Value;
				GV.selbyfilt.sel.cuttinglines						= app.CuttingLines_CheckBox.Value;
				GV.selbyfilt.sel.description						= app.KeywordSearchDescription_EditField.Value;
				GV.selbyfilt.sel.texttag							= app.KeywordSearchTextTag_EditField.Value;
				GV.selbyfilt.sel.mindiag							= app.MinDiag_EditField.Value;
				GV.selbyfilt.sel.maxdiag							= app.MaxDiag_EditField.Value;
				GV.selbyfilt.sel.minarea							= app.MinArea_EditField.Value;
				GV.selbyfilt.sel.maxarea							= app.MaxArea_EditField.Value;
				GV.selbyfilt.sel.minlile							= app.MinLineLength_EditField.Value;
				GV.selbyfilt.sel.maxlile							= app.MaxLineLength_EditField.Value;
				
				% List boxes:
				% GV.selbyfilt.sel.iobj_v
				% GV.selbyfilt.sel.colno_v
				% GV.selbyfilt.sel.chstno_v
				% GV.selbyfilt.sel.isym_v
				
				% Example:
				% app.ColorNumbers_ListBox.UserData
				% [13×1 double]
				% ans =
				%      0
				%      2
				%      4
				%      7
				%      9
				%     11
				%     14
				%     15
				%     16
				%     17
				%     18
				%     22
				%     25
				%
				% app.ColorNumbers_ListBox.Value
				%   1×3 cell array
				%     {'0: color of the o…'}    {'4: administrative…'}    {'9: forests'}
				%
				% app.ColorNumbers_ListBox.Items
				%   1×14 cell array
				%   Columns 1 through 4
				%     {'---'}    {'0: color of the…'}    {'2: legend'}    {'4: administrati…'}
				%   Columns 5 through 7
				%     {'7: settlement a…'}    {'9: forests'}    {'11: administrat…'}
				%   Columns 8 through 10
				%     {'14: water'}    {'15: routes: hik…'}    {'16: tracks: unp…'}
				%   Columns 11 through 13
				%     {'17: highways: t…'}    {'18: highways: s…'}    {'22: railways'}
				%   Column 14
				%     {'25: texts'}
				
				% Object numbers:
				GV.selbyfilt.sel.iobj_v		= [];
				for i_val=1:length(app.ObjectNumbers_ListBox.Value)
					if ~strcmp(app.ObjectNumbers_ListBox.Value{i_val},'---')
						for i_item=2:length(app.ObjectNumbers_ListBox.Items)
							if strcmp(app.ObjectNumbers_ListBox.Value{i_val},app.ObjectNumbers_ListBox.Items{i_item})
								GV.selbyfilt.sel.iobj_v		= [...
									GV.selbyfilt.sel.iobj_v ...
									app.ObjectNumbers_ListBox.UserData(i_item-1)];
							end
						end
					end
				end
				
				% Color numbers:
				GV.selbyfilt.sel.colno_v		= [];
				for i_val=1:length(app.ColorNumbers_ListBox.Value)
					if ~strcmp(app.ColorNumbers_ListBox.Value{i_val},'---')
						for i_item=2:length(app.ColorNumbers_ListBox.Items)
							if strcmp(app.ColorNumbers_ListBox.Value{i_val},app.ColorNumbers_ListBox.Items{i_item})
								GV.selbyfilt.sel.colno_v		= [...
									GV.selbyfilt.sel.colno_v ...
									app.ColorNumbers_ListBox.UserData(i_item-1)];
							end
						end
					end
				end
				
				
				% Character style numbers:
				GV.selbyfilt.sel.chstno_v		= [];
				for i_val=1:length(app.CharacterStyleNumbers_ListBox.Value)
					if ~strcmp(app.CharacterStyleNumbers_ListBox.Value{i_val},'---')
						for i_item=2:length(app.CharacterStyleNumbers_ListBox.Items)
							if strcmp(app.CharacterStyleNumbers_ListBox.Value{i_val},app.CharacterStyleNumbers_ListBox.Items{i_item})
								GV.selbyfilt.sel.chstno_v		= [...
									GV.selbyfilt.sel.chstno_v ...
									app.CharacterStyleNumbers_ListBox.UserData(i_item-1)];
							end
						end
					end
				end
				
				% Symbol numbers:
				GV.selbyfilt.sel.isym_v		= [];
				for i_val=1:length(app.Symbols_ListBox.Value)
					if ~strcmp(app.Symbols_ListBox.Value{i_val},'---')
						for i_item=2:length(app.Symbols_ListBox.Items)
							if strcmp(app.Symbols_ListBox.Value{i_val},app.Symbols_ListBox.Items{i_item})
								GV.selbyfilt.sel.isym_v		= [...
									GV.selbyfilt.sel.isym_v ...
									app.Symbols_ListBox.UserData(i_item-1)];
							end
						end
					end
				end
				
				% Close app:
				SelectbyfilterUIFigureCloseRequest(app, event)
				
			catch ME
				errormessage('',ME);
			end
			
		end

		% Close request function: SelectbyfilterUIFigure
		function SelectbyfilterUIFigureCloseRequest(app, event)
			try
				delete(app);		% Close app
			catch ME
				errormessage('',ME);
			end
			
		end

		% Value changed function: SelectObjects_ThatMeetTheCond_CheckBox
		function SelectObjects_ThatMeetTheCond_CheckBoxValueChanged(app, event)
			try
				value = app.SelectObjects_ThatMeetTheCond_CheckBox.Value;
				app.SelectObjects_ThatMeetTheCond_CheckBox.Value				= true;
				app.SelectObjects_ExceptThoseThatMeetTheCond_CheckBox.Value	= false;
			catch ME
				errormessage('',ME);
			end
			
		end

		% Value changed function: 
		% SelectObjects_ExceptThoseThatMeetTheCond_CheckBox
		function SelectObjects_ExceptThoseThatMeetTheCond_CheckBoxValueChanged(app, event)
			try
				value = app.SelectObjects_ExceptThoseThatMeetTheCond_CheckBox.Value;
				app.SelectObjects_ThatMeetTheCond_CheckBox.Value				= false;
				app.SelectObjects_ExceptThoseThatMeetTheCond_CheckBox.Value	= true;
			catch ME
				errormessage('',ME);
			end
			
		end
	end

	% Component initialization
	methods (Access = private)

		% Create UIFigure and components
		function createComponents(app)
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			% Get the file path for locating images
			pathToMLAPP = fileparts(mfilename('fullpath'));
			app.SelectbyfilterUIFigure = uifigure('Visible', 'off');
			app.SelectbyfilterUIFigure.Name = 'Select by filter';
			app.SelectbyfilterUIFigure.Icon = fullfile(pathToMLAPP, 'MapLab3D_Logo_400x400_RGB.png');
			app.SelectbyfilterUIFigure.CloseRequestFcn = createCallbackFcn(app, @SelectbyfilterUIFigureCloseRequest, true);
			app.SelectbyfilterUIFigure.Tag = 'maplab3d_figure';
			app.MinimumLabel = uilabel(app.SelectbyfilterUIFigure);
			app.MinimumLabel.Text = 'Minimum:';
			app.MinDiag_EditField = uieditfield(app.SelectbyfilterUIFigure, 'numeric');
			app.MinDiag_EditField.Limits = [0 Inf];
			app.ObjectnumbersLabel = uilabel(app.SelectbyfilterUIFigure);
			app.ObjectnumbersLabel.Text = 'Object number(s):';
			app.ObjectNumbers_ListBox = uilistbox(app.SelectbyfilterUIFigure);
			app.ObjectNumbers_ListBox.Items = {'---', 'Item 1', 'Item 2', 'Item 3', 'Item 4', 'Item 5', 'Item 6', 'Item 7', 'Item 8', 'Item 9', 'Item 10'};
			app.ObjectNumbers_ListBox.Multiselect = 'on';
			app.ObjectNumbers_ListBox.Value = {'Item 9'};
			app.ColornumbersLabel = uilabel(app.SelectbyfilterUIFigure);
			app.ColornumbersLabel.Text = 'Color number(s):';
			app.ColorNumbers_ListBox = uilistbox(app.SelectbyfilterUIFigure);
			app.ColorNumbers_ListBox.Items = {'---', 'Item 1', 'Item 2', 'Item 3', 'Item 4', 'Item 5', 'Item 6', 'Item 7', 'Item 8', 'Item 9', 'Item 10'};
			app.ColorNumbers_ListBox.Multiselect = 'on';
			app.ColorNumbers_ListBox.Value = {'Item 6'};
			app.CharacterstylenumbersLabel = uilabel(app.SelectbyfilterUIFigure);
			app.CharacterstylenumbersLabel.Text = 'Character style number(s):';
			app.CharacterStyleNumbers_ListBox = uilistbox(app.SelectbyfilterUIFigure);
			app.CharacterStyleNumbers_ListBox.Items = {'---', 'Item 1', 'Item 2', 'Item 3', 'Item 4', 'Item 5', 'Item 6', 'Item 7', 'Item 8', 'Item 9', 'Item 10'};
			app.CharacterStyleNumbers_ListBox.Multiselect = 'on';
			app.CharacterStyleNumbers_ListBox.Value = {'Item 1'};
			app.TempHidden_CheckBox = uicheckbox(app.SelectbyfilterUIFigure);
			app.TempHidden_CheckBox.Text = 'Temporarily hidden objects (Vis=HT)';
			app.TempHidden_CheckBox.Value = true;
			app.Visible_CheckBox = uicheckbox(app.SelectbyfilterUIFigure);
			app.Visible_CheckBox.Text = 'Visible objects';
			app.Visible_CheckBox.Value = true;
			app.GrayedOut_CheckBox = uicheckbox(app.SelectbyfilterUIFigure);
			app.GrayedOut_CheckBox.Text = 'Grayed out objects (Vis=GO)';
			app.GrayedOut_CheckBox.Value = true;
			app.Hidden_CheckBox = uicheckbox(app.SelectbyfilterUIFigure);
			app.Hidden_CheckBox.Text = 'Hidden objects (Vis=H)';
			app.Hidden_CheckBox.Value = true;
			app.Lines_CheckBox = uicheckbox(app.SelectbyfilterUIFigure);
			app.Lines_CheckBox.Text = 'Lines';
			app.Lines_CheckBox.Value = true;
			app.Areas_CheckBox = uicheckbox(app.SelectbyfilterUIFigure);
			app.Areas_CheckBox.Text = 'Areas';
			app.Areas_CheckBox.Value = true;
			app.Texts_CheckBox = uicheckbox(app.SelectbyfilterUIFigure);
			app.Texts_CheckBox.Text = 'Texts';
			app.Texts_CheckBox.Value = true;
			app.Symbols_CheckBox = uicheckbox(app.SelectbyfilterUIFigure);
			app.Symbols_CheckBox.Text = 'Symbols';
			app.Symbols_CheckBox.Value = true;
			app.PreviewCuttingLines_CheckBox = uicheckbox(app.SelectbyfilterUIFigure);
			app.PreviewCuttingLines_CheckBox.Text = 'Preview cutting lines';
			app.CuttingLines_CheckBox = uicheckbox(app.SelectbyfilterUIFigure);
			app.CuttingLines_CheckBox.Text = 'Cutting lines';
			app.ConnectionLines_CheckBox = uicheckbox(app.SelectbyfilterUIFigure);
			app.ConnectionLines_CheckBox.Text = 'Connection lines';
			app.ConnectionLines_CheckBox.Value = true;
			app.SymbolsLabel = uilabel(app.SelectbyfilterUIFigure);
			app.SymbolsLabel.Text = 'Symbol(s):';
			app.Symbols_ListBox = uilistbox(app.SelectbyfilterUIFigure);
			app.Symbols_ListBox.Items = {'---', 'Item 1', 'Item 2', 'Item 3', 'Item 4', 'Item 5', 'Item 6', 'Item 7', 'Item 8', 'Item 9', 'Item 10'};
			app.Symbols_ListBox.Multiselect = 'on';
			app.Symbols_ListBox.Value = {'Item 1'};
			app.MaximumLabel = uilabel(app.SelectbyfilterUIFigure);
			app.MaximumLabel.Text = 'Maximum:';
			app.MaxDiag_EditField = uieditfield(app.SelectbyfilterUIFigure, 'numeric');
			app.MaxDiag_EditField.Limits = [0 Inf];
			app.ObjectType_Label = uilabel(app.SelectbyfilterUIFigure);
			app.ObjectType_Label.Text = 'Object type:';
			app.DescriptionLabel = uilabel(app.SelectbyfilterUIFigure);
			app.DescriptionLabel.Text = 'Description:';
			app.MinimumLabel_2 = uilabel(app.SelectbyfilterUIFigure);
			app.MinimumLabel_2.Text = 'Minimum:';
			app.MinArea_EditField = uieditfield(app.SelectbyfilterUIFigure, 'numeric');
			app.MinArea_EditField.Limits = [0 Inf];
			app.MaximumLabel_2 = uilabel(app.SelectbyfilterUIFigure);
			app.MaximumLabel_2.Text = 'Maximum:';
			app.MaxArea_EditField = uieditfield(app.SelectbyfilterUIFigure, 'numeric');
			app.MaxArea_EditField.Limits = [0 Inf];
			app.KeywordSearchDescription_EditField = uieditfield(app.SelectbyfilterUIFigure, 'text');
			app.KeywordSearch_Label = uilabel(app.SelectbyfilterUIFigure);
			app.KeywordSearch_Label.Text = 'Keyword search (case-insensitive):';
			app.MinMaxDiag_Label = uilabel(app.SelectbyfilterUIFigure);
			app.MinMaxDiag_Label.Text = 'Diagonal of the bounding box / mm';
			app.TextTagLabel = uilabel(app.SelectbyfilterUIFigure);
			app.TextTagLabel.Text = 'Text/Tag:';
			app.KeywordSearchTextTag_EditField = uieditfield(app.SelectbyfilterUIFigure, 'text');
			app.MinMaxArea_Label = uilabel(app.SelectbyfilterUIFigure);
			app.MinMaxArea_Label.Text = 'Area / mm^2';
			app.Select_Button = uibutton(app.SelectbyfilterUIFigure, 'push');
			app.Select_Button.ButtonPushedFcn = createCallbackFcn(app, @Select_ButtonPushed, true);
			app.Select_Button.Text = 'Select';
			app.Cancel_Button = uibutton(app.SelectbyfilterUIFigure, 'push');
			app.Cancel_Button.ButtonPushedFcn = createCallbackFcn(app, @Cancel_ButtonPushed, true);
			app.Cancel_Button.Text = 'Cancel';
			app.Visibility_Label = uilabel(app.SelectbyfilterUIFigure);
			app.Visibility_Label.Text = 'Visibility:';
			app.Reset_Button = uibutton(app.SelectbyfilterUIFigure, 'push');
			app.Reset_Button.ButtonPushedFcn = createCallbackFcn(app, @Reset_ButtonPushed, true);
			app.Reset_Button.Text = 'Reset all settings';
			app.SelectObjects_Label = uilabel(app.SelectbyfilterUIFigure);
			app.SelectObjects_Label.Text = 'Select all objects';
			app.SelectObjects_ThatMeetTheCond_CheckBox = uicheckbox(app.SelectbyfilterUIFigure);
			app.SelectObjects_ThatMeetTheCond_CheckBox.ValueChangedFcn = createCallbackFcn(app, @SelectObjects_ThatMeetTheCond_CheckBoxValueChanged, true);
			app.SelectObjects_ThatMeetTheCond_CheckBox.Text = 'that meet these conditions';
			app.SelectObjects_ThatMeetTheCond_CheckBox.Value = true;
			app.SelectObjects_ExceptThoseThatMeetTheCond_CheckBox = uicheckbox(app.SelectbyfilterUIFigure);
			app.SelectObjects_ExceptThoseThatMeetTheCond_CheckBox.ValueChangedFcn = createCallbackFcn(app, @SelectObjects_ExceptThoseThatMeetTheCond_CheckBoxValueChanged, true);
			app.SelectObjects_ExceptThoseThatMeetTheCond_CheckBox.Text = 'except those that meet these conditions';
			app.PreviewObjects_CheckBox = uicheckbox(app.SelectbyfilterUIFigure);
			app.PreviewObjects_CheckBox.Text = 'Preview objects';
			app.PreviewObjects_CheckBox.Value = true;
			app.MinMaxLineLength_Label = uilabel(app.SelectbyfilterUIFigure);
			app.MinMaxLineLength_Label.Tooltip = {'This criterion applies only to lines that are basically unbranched.'; 'This applies to line style No. 3.'};
			app.MinMaxLineLength_Label.Text = 'Line length / mm';
			app.MinimumLabel_3 = uilabel(app.SelectbyfilterUIFigure);
			app.MinimumLabel_3.Text = 'Minimum:';
			app.MinLineLength_EditField = uieditfield(app.SelectbyfilterUIFigure, 'numeric');
			app.MinLineLength_EditField.Limits = [0 Inf];
			app.MaximumLabel_3 = uilabel(app.SelectbyfilterUIFigure);
			app.MaximumLabel_3.Text = 'Maximum:';
			app.MaxLineLength_EditField = uieditfield(app.SelectbyfilterUIFigure, 'numeric');
			app.MaxLineLength_EditField.Limits = [0 Inf];
			% Show the figure after all components are created
			app.SelectbyfilterUIFigure.Visible = 'on';
		end
	end

	% App creation and deletion
	methods (Access = public)

		% Construct app
		function app = mapobjects_select_by_filter_getuserinput

			% Create UIFigure and components
			createComponents(app)

			% Register the app with App Designer
			registerApp(app, app.SelectbyfilterUIFigure)

			% Execute the startup function
			runStartupFcn(app, @startupFcn)

			if nargout == 0
				clear app
			end
		end

		% Code that executes before app deletion
		function delete(app)

			% Delete UIFigure when app is deleted
			delete(app.SelectbyfilterUIFigure)
		end
	end
end